classdef bidirectional_shift_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BidirectionalShiftUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        AutoButton                  matlab.ui.control.Button
        VerticalShiftSpinner        matlab.ui.control.Spinner
        VerticalShiftLabel          matlab.ui.control.Label
    end

    %%%%%%%%%%%%
    % Simple helper window to set the shift between even and off frames
    % when processing slow axis bidirectional data.
    %%%%%%%%%%%%

    properties (Access = public)
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, shift)
            app.callingApp = callingApp;
            app.VerticalShiftSpinner.Value = shift;
        end

        % Value changed function: VerticalShiftSpinner
        function chngShift(app, event)
            app.callingApp.chngYShift(app.VerticalShiftSpinner.Value);
        end

        % Close request function: BidirectionalShiftUIFigure
        function BidirectionalShiftUIFigureCloseRequest(app, event)
            app.callingApp.closeShift();
            delete(app);
        end

        % Button pushed function: AutoButton
        function autoYShift(app, event)
            % tell main app to auto-detect shift between frames and update
            % ui
            yShift = app.callingApp.autoYShift();
            app.VerticalShiftSpinner.Value = yShift;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BidirectionalShiftUIFigure = uifigure('Visible', 'off');
            app.BidirectionalShiftUIFigure.Name = 'Bidirectional Shift';
            app.BidirectionalShiftUIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.BidirectionalShiftUIFigure.CloseRequestFcn = createCallbackFcn(app, @BidirectionalShiftUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.BidirectionalShiftUIFigure);
            app.VerticalShiftLabel = uilabel(app.GridLayout);
            app.VerticalShiftLabel.Text = 'Vertical Shift:';
            app.VerticalShiftSpinner = uispinner(app.GridLayout);
            app.VerticalShiftSpinner.ValueChangedFcn = createCallbackFcn(app, @chngShift, true);
            app.AutoButton = uibutton(app.GridLayout, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @autoYShift, true);
            app.AutoButton.Text = 'Auto';
            % Show the figure after all components are created
            app.BidirectionalShiftUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bidirectional_shift_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BidirectionalShiftUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BidirectionalShiftUIFigure)
        end
    end
end