classdef mtn_output_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OutputSettingsUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PreviewButton                   matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        TraceExtractionPanel            matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        BgProfileCheckBox               matlab.ui.control.CheckBox
        OverlapCheckBox                 matlab.ui.control.CheckBox
        SaveMtnCheckBox                 matlab.ui.control.CheckBox
        BgMeanCheckBox                  matlab.ui.control.CheckBox
        AesRoiCheckBox                  matlab.ui.control.CheckBox
        SmplRoiCheckBox                 matlab.ui.control.CheckBox
        OtherPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ProjectionCheckBox              matlab.ui.control.CheckBox
        SubtractBgCheckBox              matlab.ui.control.CheckBox
        SaveMasksCheckBox               matlab.ui.control.CheckBox
        CompressCheckBox                matlab.ui.control.CheckBox
        MotionCorrectionPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        InitialPassesEditField          matlab.ui.control.NumericEditField
        InitialWindowPassesEditFieldLabel  matlab.ui.control.Label
        FFTCheckBox                     matlab.ui.control.CheckBox
        SpatialBlurEditField            matlab.ui.control.NumericEditField
        SpatialBlurEditFieldLabel       matlab.ui.control.Label
        PassesEditField                 matlab.ui.control.NumericEditField
        CorrectionPassesEditFieldLabel  matlab.ui.control.Label
        MainChannelEditField            matlab.ui.control.NumericEditField
        MainChannelLabel                matlab.ui.control.Label
        SaveMtnVidCheckBox              matlab.ui.control.CheckBox
        CorrectMtnCheckBox              matlab.ui.control.CheckBox
        MtnWindowEditField              matlab.ui.control.NumericEditField
        TemporalsmoothingwindowEditFieldLabel  matlab.ui.control.Label
        IntlWindowEditField             matlab.ui.control.NumericEditField
        InitialtemplatewindowEditFieldLabel  matlab.ui.control.Label
        BatchProcessingPanel            matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MultiFileCheckBox               matlab.ui.control.CheckBox
    end

    %%%%%%%%%%%%%%%
    % helper window for selecting file processing and output settings
    %%%%%%%%%%%%%%%

    properties (Access = private)
        callingApp
        params

        active
    end

    properties (GetAccess = public)
        interruptDialog
    end
    
    methods (Access = private)
        
        % builds params struct from user selection
        % 'params' is struct used store the information needed when
        % processing or motion correcting a video
        function updateParams(app)
            app.params.multiVid = app.MultiFileCheckBox.Value;
            app.params.mtn = app.CorrectMtnCheckBox.Value;
            app.params.mtnVid = app.SaveMtnVidCheckBox.Value;
            app.params.mtnIntlWndw = app.IntlWindowEditField.Value;
            app.params.mtnWndw = app.MtnWindowEditField.Value;
            app.params.compress = app.CompressCheckBox.Value;
            app.params.saveMasks = app.SaveMasksCheckBox.Value;
            app.params.zeroVid = app.SubtractBgCheckBox.Value;
            app.params.bgTrace = app.BgMeanCheckBox.Value;
            app.params.bgProfile = app.BgProfileCheckBox.Value;
            app.params.aesTrace = app.AesRoiCheckBox.Value;
            app.params.smplTrace = app.SmplRoiCheckBox.Value;
            app.params.mtnTrace = app.SaveMtnCheckBox.Value;
            app.params.mtnOverlap = app.OverlapCheckBox.Value;
            app.params.mainChannel = app.MainChannelEditField.Value;
            app.params.sptlWndw = app.SpatialBlurEditField.Value;
            if (app.params.mtn)
                app.params.passes = app.PassesEditField.Value;
                app.params.intlPasses = app.InitialPassesEditField.Value;
            else
                app.params.passes = 1;
                app.params.intlPasses = 1;
            end

            app.params.proj = app.ProjectionCheckBox.Value;
            app.params.fft = app.FFTCheckBox.Value;
        end
    end

    methods (Access = public)
        % 'active' is a boolean determining if a progress dialog window is
        % used to prevent user interaction
        function setActive(app, active)
            if (app.active)
                if (~active)
                    app.interruptDialog = uiprogressdlg(app.OutputSettingsUIFigure,"Title","Busy...");
                end
            else
                if (active)
                    close(app.interruptDialog);
                    app.interruptDialog = [];
                end
            end

            app.active = active;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, params)
            % set usable fields using partial information available in
            % 'params' struct
            app.callingApp = callingApp;
            app.params = validateParams(params);

            if (~app.params.multiVid)
                app.MultiFileCheckBox.Enable = false;
            else
                app.MultiFileCheckBox.Value = true;
            end

            app.MainChannelEditField.Value = app.params.mainChannel;
            app.MainChannelEditField.Limits = [1,app.params.channels];

            if (sum(app.params.numRoiAes,'all')>0)
                app.AesRoiCheckBox.Enable = true;
                app.CorrectMtnCheckBox.Enable = true;
            end

            if (sum(app.params.numRoiSmpl,'all')>0)
                app.SmplRoiCheckBox.Enable = true;
            end

            if (sum(app.params.numRoiAes.*app.params.numRoiSmpl)>0)
                app.OverlapCheckBox.Enable = true;
            end

            app.active = true;
        end

        % Value changed function: CorrectMtnCheckBox
        function correctMtn(app, event)
            % makes sure relavent settings are available only if you plan
            % on motion correcting
            app.MtnWindowEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.IntlWindowEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.SaveMtnVidCheckBox.Enable = app.CorrectMtnCheckBox.Value;
            app.SaveMtnCheckBox.Enable = app.CorrectMtnCheckBox.Value;
            app.MainChannelEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.SpatialBlurEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.FFTCheckBox.Enable = app.CorrectMtnCheckBox.Value;
            app.PassesEditField.Enable = app.CorrectMtnCheckBox.Value;
            app.InitialPassesEditField.Enable = app.CorrectMtnCheckBox.Value;
            if (app.SmplRoiCheckBox.Enable)
                app.OverlapCheckBox.Enable = app.CorrectMtnCheckBox.Value;
            end
        end

        % Close request function: OutputSettingsUIFigure
        function OutputSettingsUIFigureCloseRequest(app, event)
            app.callingApp.closeOutputDlg();
            delete(app);
        end

        % Button pushed function: ProcessButton
        function process(app, event)
            app.updateParams();
            app.callingApp.processVideos(app.params);
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.callingApp.closeOutputDlg();
            delete(app);
        end

        % Value changed function: MtnWindowEditField
        function chkSmthVal(app, event)
            % makes sure motion smoothing window is odd (otherwise you have
            % amibguity in weighting of current frame)
            value = app.MtnWindowEditField.Value;
            if (mod(value,2)==0)
                app.MtnWindowEditField.Value = value + 1;
            end
        end

        % Button pushed function: PreviewButton
        function preview(app, event)
            % tell main app to build preview and make sure this window is
            % not interactable
            app.updateParams();
            app.callingApp.buildPreview(app.params);
            app.setActive(false);
        end

        % Value changed function: PassesEditField
        function chngPasses(app, event)
            value = app.PassesEditField.Value;
            if (app.InitialPassesEditField.Value < value)
                app.InitialPassesEditField.Value = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OutputSettingsUIFigure = uifigure('Visible', 'off');
            app.OutputSettingsUIFigure.Name = 'Output Settings';
            app.OutputSettingsUIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.OutputSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OutputSettingsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.OutputSettingsUIFigure);
            app.BatchProcessingPanel = uipanel(app.GridLayout);
            app.BatchProcessingPanel.Title = 'Batch Processing';
            app.GridLayout2 = uigridlayout(app.BatchProcessingPanel);
            app.MultiFileCheckBox = uicheckbox(app.GridLayout2);
            app.MultiFileCheckBox.Text = 'Process multiple videos';
            app.MotionCorrectionPanel = uipanel(app.GridLayout);
            app.MotionCorrectionPanel.Title = 'Motion Correction';
            app.GridLayout3 = uigridlayout(app.MotionCorrectionPanel);
            app.InitialtemplatewindowEditFieldLabel = uilabel(app.GridLayout3);
            app.InitialtemplatewindowEditFieldLabel.Text = 'Initial template window:';
            app.IntlWindowEditField = uieditfield(app.GridLayout3, 'numeric');
            app.IntlWindowEditField.Limits = [1 Inf];
            app.IntlWindowEditField.RoundFractionalValues = 'on';
            app.IntlWindowEditField.Enable = 'off';
            app.IntlWindowEditField.Value = 200;
            app.TemporalsmoothingwindowEditFieldLabel = uilabel(app.GridLayout3);
            app.TemporalsmoothingwindowEditFieldLabel.Text = 'Temporal smoothing window:';
            app.MtnWindowEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MtnWindowEditField.Limits = [1 Inf];
            app.MtnWindowEditField.RoundFractionalValues = 'on';
            app.MtnWindowEditField.ValueChangedFcn = createCallbackFcn(app, @chkSmthVal, true);
            app.MtnWindowEditField.Enable = 'off';
            app.MtnWindowEditField.Value = 11;
            app.CorrectMtnCheckBox = uicheckbox(app.GridLayout3);
            app.CorrectMtnCheckBox.ValueChangedFcn = createCallbackFcn(app, @correctMtn, true);
            app.CorrectMtnCheckBox.Text = 'Correct motion';
            app.SaveMtnVidCheckBox = uicheckbox(app.GridLayout3);
            app.SaveMtnVidCheckBox.Enable = 'off';
            app.SaveMtnVidCheckBox.Text = 'Save corrected video';
            app.MainChannelLabel = uilabel(app.GridLayout3);
            app.MainChannelLabel.Text = 'Main Channel:';
            app.MainChannelEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MainChannelEditField.Limits = [1 Inf];
            app.MainChannelEditField.RoundFractionalValues = 'on';
            app.MainChannelEditField.Enable = 'off';
            app.MainChannelEditField.Value = 1;
            app.CorrectionPassesEditFieldLabel = uilabel(app.GridLayout3);
            app.CorrectionPassesEditFieldLabel.Text = 'Correction Passes';
            app.PassesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PassesEditField.Limits = [1 Inf];
            app.PassesEditField.RoundFractionalValues = 'on';
            app.PassesEditField.ValueChangedFcn = createCallbackFcn(app, @chngPasses, true);
            app.PassesEditField.Enable = 'off';
            app.PassesEditField.Value = 1;
            app.SpatialBlurEditFieldLabel = uilabel(app.GridLayout3);
            app.SpatialBlurEditFieldLabel.Text = 'Spatial Blur';
            app.SpatialBlurEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SpatialBlurEditField.Limits = [0 Inf];
            app.SpatialBlurEditField.Enable = 'off';
            app.FFTCheckBox = uicheckbox(app.GridLayout3);
            app.FFTCheckBox.Enable = 'off';
            app.FFTCheckBox.Text = 'FFT XCorrelation';
            app.InitialWindowPassesEditFieldLabel = uilabel(app.GridLayout3);
            app.InitialWindowPassesEditFieldLabel.Text = 'Initial Window Passes';
            app.InitialPassesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.InitialPassesEditField.Limits = [1 Inf];
            app.InitialPassesEditField.RoundFractionalValues = 'on';
            app.InitialPassesEditField.Enable = 'off';
            app.InitialPassesEditField.Value = 1;
            app.OtherPanel = uipanel(app.GridLayout);
            app.OtherPanel.Title = 'Other';
            app.GridLayout4 = uigridlayout(app.OtherPanel);
            app.CompressCheckBox = uicheckbox(app.GridLayout4);
            app.CompressCheckBox.Text = 'Compress video';
            app.SaveMasksCheckBox = uicheckbox(app.GridLayout4);
            app.SaveMasksCheckBox.Text = 'Save adjusted masks';
            app.SubtractBgCheckBox = uicheckbox(app.GridLayout4);
            app.SubtractBgCheckBox.Text = 'Subtract background mean';
            app.ProjectionCheckBox = uicheckbox(app.GridLayout4);
            app.ProjectionCheckBox.Text = 'Save Projection';
            app.TraceExtractionPanel = uipanel(app.GridLayout);
            app.TraceExtractionPanel.Title = 'Trace Extraction';
            app.GridLayout5 = uigridlayout(app.TraceExtractionPanel);
            app.SmplRoiCheckBox = uicheckbox(app.GridLayout5);
            app.SmplRoiCheckBox.Enable = 'off';
            app.SmplRoiCheckBox.Text = 'Extract sample ROI trace';
            app.AesRoiCheckBox = uicheckbox(app.GridLayout5);
            app.AesRoiCheckBox.Enable = 'off';
            app.AesRoiCheckBox.Text = 'Extract AES ROI trace';
            app.BgMeanCheckBox = uicheckbox(app.GridLayout5);
            app.BgMeanCheckBox.Text = 'Extract background mean';
            app.SaveMtnCheckBox = uicheckbox(app.GridLayout5);
            app.SaveMtnCheckBox.Enable = 'off';
            app.SaveMtnCheckBox.Text = 'Save motion trace';
            app.OverlapCheckBox = uicheckbox(app.GridLayout5);
            app.OverlapCheckBox.Enable = 'off';
            app.OverlapCheckBox.Text = 'Verify Sample/AES overlap';
            app.BgProfileCheckBox = uicheckbox(app.GridLayout5);
            app.BgProfileCheckBox.Text = 'Subtract BG profile';
            app.ProcessButton = uibutton(app.GridLayout, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @process, true);
            app.ProcessButton.Text = 'Process';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.PreviewButton = uibutton(app.GridLayout, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @preview, true);
            app.PreviewButton.Text = 'Preview';
            % Show the figure after all components are created
            app.OutputSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mtn_output_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OutputSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OutputSettingsUIFigure)
        end
    end
end