classdef channel_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        MainChannelEditField       matlab.ui.control.NumericEditField
        MainChannelEditFieldLabel  matlab.ui.control.Label
        FrameLimitEditField        matlab.ui.control.NumericEditField
        FrameLimitEditFieldLabel   matlab.ui.control.Label
        VolumeCheckBox             matlab.ui.control.CheckBox
        LimitFramesCheckBox        matlab.ui.control.CheckBox
        BidirectionalCheckBox      matlab.ui.control.CheckBox
        SlicesEditField            matlab.ui.control.NumericEditField
        SlicesEditFieldLabel       matlab.ui.control.Label
        ChannelsEditField          matlab.ui.control.NumericEditField
        ChannelsLabel              matlab.ui.control.Label
        CancelButton               matlab.ui.control.StateButton
        OkayButton                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: VolumeCheckBox
        function enableVolume(app, event)
            app.SlicesEditField.Enable = app.VolumeCheckBox.Value;
        end

        % Value changed function: LimitFramesCheckBox
        function enableFrameLimit(app, event)
            app.FrameLimitEditField.Enable = app.LimitFramesCheckBox.Value;
        end

        % Value changed function: CancelButton
        function cancel(app, event)
            app.callingApp.loadRefClbk([],0);
        end

        % Value changed function: OkayButton
        function okay(app, event)
            params.channels = app.ChannelsEditField.Value;
            params.mainChannel = app.MainChannelEditField.Value;
            params.bidirectional = app.BidirectionalCheckBox.Value;
            params.volume = app.VolumeCheckBox.Value;
            if (params.volume)
                params.slices = app.SlicesEditField.Value;
            else
                params.slices = 1;
            end

            app.callingApp.loadRefClbk(params,app.LimitFramesCheckBox.Value*app.FrameLimitEditField.Value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.loadRefClbk([],0);
            delete(app);
        end

        % Value changed function: ChannelsEditField
        function chngChannels(app, event)
            value = app.ChannelsEditField.Value;
            if (app.MainChannelEditField.Value>value)
                app.MainChannelEditField.Value = value;
            end
            
            if (value==1)
                app.MainChannelEditField.Limits = [1,1.1];
            else
                app.MainChannelEditField.Limits = [1,value];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Stack Details';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'bloodflow_mask_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OkayButton = uibutton(app.GridLayout, 'state');
            app.OkayButton.ValueChangedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ChannelsLabel = uilabel(app.GridLayout);
            app.ChannelsLabel.Text = 'Channels:';
            app.ChannelsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChannelsEditField.Limits = [1 Inf];
            app.ChannelsEditField.RoundFractionalValues = 'on';
            app.ChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @chngChannels, true);
            app.ChannelsEditField.Value = 1;
            app.SlicesEditFieldLabel = uilabel(app.GridLayout);
            app.SlicesEditFieldLabel.Text = 'Slices:';
            app.SlicesEditField = uieditfield(app.GridLayout, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.RoundFractionalValues = 'on';
            app.SlicesEditField.Enable = 'off';
            app.SlicesEditField.Value = 1;
            app.BidirectionalCheckBox = uicheckbox(app.GridLayout);
            app.BidirectionalCheckBox.Text = 'Bidirectional';
            app.LimitFramesCheckBox = uicheckbox(app.GridLayout);
            app.LimitFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @enableFrameLimit, true);
            app.LimitFramesCheckBox.Text = 'Limit Frames';
            app.VolumeCheckBox = uicheckbox(app.GridLayout);
            app.VolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @enableVolume, true);
            app.VolumeCheckBox.Text = 'Volume';
            app.FrameLimitEditFieldLabel = uilabel(app.GridLayout);
            app.FrameLimitEditFieldLabel.Text = 'Frame Limit';
            app.FrameLimitEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameLimitEditField.Limits = [1 Inf];
            app.FrameLimitEditField.Enable = 'off';
            app.FrameLimitEditField.Value = 500;
            app.MainChannelEditFieldLabel = uilabel(app.GridLayout);
            app.MainChannelEditFieldLabel.Text = 'Main Channel';
            app.MainChannelEditField = uieditfield(app.GridLayout, 'numeric');
            app.MainChannelEditField.Limits = [1 1.1];
            app.MainChannelEditField.RoundFractionalValues = 'on';
            app.MainChannelEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end