classdef mask_threshold_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        FeatureSizeEditField       matlab.ui.control.NumericEditField
        MinimumFeatureSizeEditFieldLabel  matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdLabel             matlab.ui.control.Label
        MultiSliceCheckBox         matlab.ui.control.CheckBox
        AutomaticThresholdPanel    matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        SensitivityEditField       matlab.ui.control.NumericEditField
        SensitivityEditFieldLabel  matlab.ui.control.Label
        ThresholdMethodDropDown    matlab.ui.control.DropDown
        ThresholdMethodLabel       matlab.ui.control.Label
        ApplyButton                matlab.ui.control.Button
        SizeAxes                   matlab.ui.control.UIAxes
        PixelValAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp
        threshold
        minFeature

        imhisthandle
        imhistMax
        imNobHeight
        defaultNobHeight = 25;
        nobWidth = 3;
        otsuThresh

        szhisthandle
        szhistMax
        szNobHeight
        szNobWidth

        threshRect
        threshLine
        sizeRect
        sizeLine
    end
    
    methods (Access = public)
        function reinit(app,image,threshold,sizes,minFeature)
            app.reinitImage(image,threshold);
            app.reinitMinFeature(sizes,minFeature);
        end

        function reinitImage(app,image,threshold)            
            [counts,bins] = imhist(image);
            bins = bins*100;
            counts2 = log10(counts+(counts==0));
            app.imhisthandle = bar(app.PixelValAxes,bins,counts2);
            app.imhistMax = max(counts2);
            set(app.PixelValAxes,'xlim',[0,100]);
            set(app.PixelValAxes,'ylim',[0,app.imhistMax]);
            disableDefaultInteractivity(app.PixelValAxes);
            app.imNobHeight = app.imhistMax * app.defaultNobHeight / 100;

            hold(app.PixelValAxes,'on');
            yData = app.calculateOtsu(bins,counts);
            plot(app.PixelValAxes,bins(1:(end-1)),yData*app.imhistMax/max(yData),'k-');
            hold(app.PixelValAxes,'off');
            [~,ind] = max(yData);
            app.otsuThresh = bins(ind);

            app.threshLine = images.roi.Line(app.PixelValAxes);
            app.threshLine.InteractionsAllowed = "none";
            app.threshLine.MarkerSize = 0.1;
            app.threshLine.Color = 'r';
            app.threshRect = images.roi.Rectangle(app.PixelValAxes);
            app.threshRect.Deletable = 0;
            app.threshRect.DrawingArea = [-app.nobWidth/2,app.imhistMax/2 - app.imNobHeight / 2,100+app.nobWidth/2,app.imNobHeight];
            app.threshRect.InteractionsAllowed = "translate";
            addlistener(app.threshRect,'ROIMoved',@app.threshRectChng);
            addlistener(app.threshRect,'MovingROI',@app.threshRectMv);
            app.threshRect.MarkerSize = 1;
            app.threshRect.FaceAlpha = 0.5;
            app.threshRect.Color = 'r';

            if (threshold<0)
                app.threshold = 100;
                app.SensitivityEditField.Value = -(threshold+1);
                app.SensitivityEditField.Enable = true;
                app.ThresholdMethodDropDown.Value = 3;
                app.ApplyButton.Enable = true;
                app.enableThresh(false);
            else
                app.threshold = 100*threshold;
                app.SensitivityEditField.Value = 0.5;
                app.SensitivityEditField.Enable = false;
                app.ApplyButton.Enable = false;
                app.enableThresh(true);
            end
            app.ThresholdEditField.Value = app.threshold;
            app.threshLine.Position = [app.threshold, 2*app.imhistMax; app.threshold, 0];
            app.threshRect.Position = [app.threshold-app.nobWidth/2,(app.imhistMax - app.imNobHeight)/2,app.nobWidth,app.imNobHeight];
        end

        function reinitMinFeature(app,sizes,minFeature)
            app.minFeature = minFeature;

            if (isempty(sizes))
                app.szhisthandle = bar(app.SizeAxes,[],[]);
                app.szhistMax = 1;
                maxVal = max(1,minFeature);
            else
                [tempCounts,tempBins] = hist(sizes,20);
                app.szhisthandle = bar(app.SizeAxes,tempBins,tempCounts);
                app.szhistMax = max(tempCounts);
                maxVal = ceil((max(sizes)+1)/5)*5;
                maxVal = max(maxVal,minFeature);
            end
            set(app.SizeAxes,'xlim',[0,maxVal]);
            set(app.SizeAxes,'ylim',[0,app.szhistMax]);
            disableDefaultInteractivity(app.SizeAxes);
            app.szNobHeight = app.szhistMax * app.defaultNobHeight / 100;
            app.szNobWidth = maxVal * app.nobWidth / 100;
            
            app.sizeLine = images.roi.Line(app.SizeAxes);
            app.sizeLine.InteractionsAllowed = "none";
            app.sizeLine.MarkerSize = 0.1;
            app.sizeLine.Color = 'g';
            app.sizeRect = images.roi.Rectangle(app.SizeAxes);
            app.sizeRect.Deletable = 0;
            app.sizeRect.DrawingArea = [-app.szNobWidth/2,app.szhistMax/2 - app.szNobHeight / 2,100+app.szNobWidth/2,app.szNobHeight];
            app.sizeRect.InteractionsAllowed = "translate";
            addlistener(app.sizeRect,'ROIMoved',@app.sizeRectChng);
            addlistener(app.sizeRect,'MovingROI',@app.sizeRectMv);
            app.sizeRect.MarkerSize = 1;
            app.sizeRect.FaceAlpha = 0.5;
            app.sizeRect.Color = 'g';

            app.FeatureSizeEditField.Limits = [0,maxVal];
            app.FeatureSizeEditField.Value = app.minFeature;
            app.sizeLine.Position = [app.minFeature, 2*app.szhistMax; app.minFeature, 0];
            app.sizeRect.Position = [app.minFeature-app.szNobWidth/2,(app.szhistMax - app.szNobHeight)/2,app.szNobWidth,app.szNobHeight];
        end

        function threshRectMv(app,~,~)
            app.threshold = app.threshRect.Position(1) + app.nobWidth/2;
            app.updateRects();
            app.ThresholdEditField.Value = app.threshold;
            app.callingApp.chngThresholdFast(app.threshold/100);
        end

        function threshRectChng(app,~,~)
            app.threshold = app.threshRect.Position(1) + app.nobWidth/2;
            app.updateRects();
            app.ThresholdEditField.Value = app.threshold;
            if (app.ThresholdMethodDropDown.Value==2)
                app.ThresholdMethodDropDown.Value = 1;
                app.ApplyButton.Enable = false;
            end
            app.callingApp.chngThreshold(app.threshold/100);
        end

        function enableThresh(app,enable)
            if (enable)
                app.threshRect.Visible = 'on';
                app.threshLine.Visible = 'on';
            else
                app.threshRect.Visible = 'off';
                app.threshLine.Visible = 'off';
            end
            app.ThresholdEditField.Enable = enable;
        end

        function sizeRectMv(app,~,~)
            app.minFeature = app.sizeRect.Position(1) + app.szNobWidth/2;
            app.updateRects();
            app.FeatureSizeEditField.Value = app.minFeature;
            app.callingApp.chngSizeFast(app.minFeature);
        end

        function sizeRectChng(app,~,~)
            app.minFeature = app.sizeRect.Position(1) + app.szNobWidth/2;
            app.updateRects();
            app.FeatureSizeEditField.Value = app.minFeature;
            app.callingApp.chngSize(app.minFeature);
        end

        function updateRects(app)
            app.threshLine.Position = [app.threshold, 2*app.imhistMax; app.threshold, 0];
            app.threshRect.Position = [app.threshold-app.nobWidth/2,(app.imhistMax - app.imNobHeight)/2,app.nobWidth,app.imNobHeight];
            app.sizeLine.Position = [app.minFeature, 2*app.szhistMax; app.minFeature, 0];
            app.sizeRect.Position = [app.minFeature-app.szNobWidth/2,(app.szhistMax - app.szNobHeight)/2,app.szNobWidth,app.szNobHeight];
        end

        function updateFields(app)
            app.ThresholdEditField.Value = app.threshold;
            app.FeatureSizeEditField.Value = app.minFeature;
        end

        function yData = calculateOtsu(app,bins,counts)
            yData = bins(1:(end-1))*0;
            muPre = bins.*counts;
            for ii=1:(length(bins)-1)
                mu0 = sum(muPre(1:ii));
                mu1 = sum(muPre((ii+1):end));
                w0 = sum(counts(1:ii));
                w1 = sum(counts((ii+1):end));
                yData(ii) = w0*w1*(mu0-mu1)^2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, image, threshold, sizes, minFeature, volume)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            if (volume>0)
                app.MultiSliceCheckBox.Enable = true;
                if (volume > 1)
                    app.MultiSliceCheckBox.Value = true;
                else
                    app.MultiSliceCheckBox.Value = false;
                end
            else
                app.MultiSliceCheckBox.Enable = false;
            end
            app.ThresholdMethodDropDown.ItemsData = [1,2,3];
            app.reinit(image,threshold,sizes,minFeature);
            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: ThresholdEditField
        function chngThreshold(app, event)
            app.threshold = app.ThresholdEditField.Value;
            app.updateRects();
            if (app.ThresholdMethodDropDown.Value==2)
                app.ThresholdMethodDropDown.Value = 1;
                app.ApplyButton.Enable = false;
            end
            app.callingApp.chngThreshold(app.threshold/100);
        end

        % Value changed function: FeatureSizeEditField
        function chngSize(app, event)
            app.minFeature = app.FeatureSizeEditField.Value;
            app.updateRects();
            app.callingApp.chngSize(app.minFeature);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeThreshold();
            delete(app);
        end

        % Button pushed function: ApplyButton
        function autoThresh(app, event)
            value = app.ThresholdMethodDropDown.Value;
            if (value==2)
                app.enableThresh(true);
                app.threshold = app.otsuThresh;
                app.updateRects();
                app.ThresholdEditField.Value = app.threshold;
                app.callingApp.chngThreshold(app.threshold/100);
            elseif (value==3)
                app.enableThresh(false);
                app.callingApp.chngThreshold(-(1+app.SensitivityEditField.Value));
            end
        end

        % Value changed function: MultiSliceCheckBox
        function chngAllSlices(app, event)
            app.callingApp.setChngAllSlices(app.MultiSliceCheckBox.Value);
        end

        % Value changed function: ThresholdMethodDropDown
        function chngThreshMethod(app, event)
            value = app.ThresholdMethodDropDown.Value;
            if (value==1)
                app.enableThresh(true);
                app.SensitivityEditField.Enable = false;
                app.ApplyButton.Enable = false;
            elseif (value==2)
                app.SensitivityEditField.Enable = false;
                app.ApplyButton.Enable = true;
            elseif (value==3)
                app.enableThresh(false);
                app.SensitivityEditField.Enable = true;
                app.ApplyButton.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Mask Threshold';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'bloodflow_mask_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PixelValAxes = uiaxes(app.GridLayout);
            title(app.PixelValAxes, 'Pixel Value Histogram')
            xlabel(app.PixelValAxes, 'pixel value')
            ylabel(app.PixelValAxes, 'log(frequency)')
            app.SizeAxes = uiaxes(app.GridLayout);
            title(app.SizeAxes, 'Feature Size Histogram')
            xlabel(app.SizeAxes, 'feature size')
            ylabel(app.SizeAxes, 'frequency')
            app.AutomaticThresholdPanel = uipanel(app.GridLayout);
            app.AutomaticThresholdPanel.Title = 'Automatic Threshold';
            app.GridLayout2 = uigridlayout(app.AutomaticThresholdPanel);
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @autoThresh, true);
            app.ApplyButton.Text = 'Apply';
            app.ThresholdMethodLabel = uilabel(app.GridLayout2);
            app.ThresholdMethodLabel.Text = 'Threshold Method:';
            app.ThresholdMethodDropDown = uidropdown(app.GridLayout2);
            app.ThresholdMethodDropDown.Items = {'Manual', 'Otsu''s Method', 'Adaptive'};
            app.ThresholdMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @chngThreshMethod, true);
            app.ThresholdMethodDropDown.Value = 'Manual';
            app.SensitivityEditFieldLabel = uilabel(app.GridLayout2);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity:';
            app.SensitivityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SensitivityEditField.Limits = [0 1];
            app.SensitivityEditField.Enable = 'off';
            app.SensitivityEditField.Value = 0.5;
            app.MultiSliceCheckBox = uicheckbox(app.GridLayout);
            app.MultiSliceCheckBox.ValueChangedFcn = createCallbackFcn(app, @chngAllSlices, true);
            app.MultiSliceCheckBox.Text = 'Apply to all slices';
            app.ThresholdLabel = uilabel(app.GridLayout);
            app.ThresholdLabel.Text = 'Threshold:';
            app.ThresholdEditField = uieditfield(app.GridLayout, 'numeric');
            app.ThresholdEditField.Limits = [0 100];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @chngThreshold, true);
            app.MinimumFeatureSizeEditFieldLabel = uilabel(app.GridLayout);
            app.MinimumFeatureSizeEditFieldLabel.Text = 'Minimum Feature Size:';
            app.FeatureSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.FeatureSizeEditField.Limits = [0 Inf];
            app.FeatureSizeEditField.ValueChangedFcn = createCallbackFcn(app, @chngSize, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mask_threshold_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end