classdef blood_vessel_mask_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        LoadStackMenu           matlab.ui.container.Menu
        ExportAESPatternMenu    matlab.ui.container.Menu
        ImageMenu               matlab.ui.container.Menu
        WindowLevelMenu         matlab.ui.container.Menu
        BidirectionalShiftMenu  matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        StackSelectionPanel     matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        ParityLabel             matlab.ui.control.Label
        FrameParityButton       matlab.ui.control.StateButton
        SliceSpinner            matlab.ui.control.Spinner
        SliceLabel              matlab.ui.control.Label
        ChannelSpinner          matlab.ui.control.Spinner
        ChannelLabel            matlab.ui.control.Label
        MaskGenerationPanel     matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        TotalOccLabel           matlab.ui.control.Label
        TotalOccLabel_2         matlab.ui.control.Label
        ChannelOccLabel         matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        SliceOccLabel           matlab.ui.control.Label
        Label_1                 matlab.ui.control.Label
        ThreshOccLabel          matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        ThresholdButton         matlab.ui.control.Button
        BorderSpinner           matlab.ui.control.Spinner
        BorderSpinnerLabel      matlab.ui.control.Label
        ImageAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % file variables
        testFile
        testPath
        refFile
        refPath

        % stack structure variables
        bidirectional
        bidiShift
        frameParity
        parityFlag
        channels
        mainChannel
        channel
        isVolume
        slices
        slice
        numPx

        % display variables
        imhandle
        refImages
        dispImage
        shiftImage
        contrImage
        alpha = 0.25;
        baseMask
        borderMask
        fullMask
        excludedMask
        BCwndw
        occThresh
        occTot

        % mask variables
        maskGenerator
        generateMaskFlag
        thresholds
        minFeatures
        borders
        chngAllSlices
        baseMasks
        fullMasks
        excludedMasks
        htot
        sizes

        % helper apps
        interruptDlg
        channelDlg
        BCDlg
        bidiShiftDlg
        thresholdDlg
    end

    properties (Access = public)
        % matlab version
        v
    end
    
    methods (Access = public)
        %% functions for updating image
        function updateImage(app)
            app.borderMask = app.fullMask .* (1 - app.baseMask - app.excludedMask);

            app.dispImage(:,:,1) = app.contrImage .* (1 - app.fullMask) + app.fullMask .* (app.alpha + app.contrImage * (1-app.alpha));
            app.dispImage(:,:,2) = app.contrImage .* (1 - app.excludedMask - app.borderMask) + (app.borderMask + app.excludedMask) .* (app.alpha + app.contrImage * (1-app.alpha));
            app.dispImage(:,:,3) = app.contrImage;
            app.imhandle.CData = app.dispImage;
        end

        function selectRefImage(app)
            if (app.bidirectional)
                if (app.frameParity)
                    app.shiftImage = circshift(app.refImages(:,:,app.slice,2*app.channel-1),app.bidiShift,1);
                    tempImage = app.shiftImage;
                else
                    app.shiftImage = app.refImages(:,:,app.slice,2*app.channel);
                    tempImage = circshift(app.refImages(:,:,app.slice,2*app.channel-1),app.bidiShift,1);
                end
            else
                app.shiftImage = app.refImages(:,:,app.slice,app.channel);
                tempImage = app.shiftImage;
            end

            if (app.parityFlag)
                app.parityFlag = false;
            else
                app.generateMaskFlag = true;
                tempMinFeature = app.minFeatures(app.slice,app.channel);
                tempThresh = app.thresholds(app.slice,app.channel);
                app.maskGenerator.setMinFeature(tempMinFeature);
                app.maskGenerator.setThreshold(tempThresh);
                app.maskGenerator.setBorder(app.borders(app.slice,app.channel));
                app.maskGenerator.image = tempImage;
                app.baseMask = app.baseMasks(:,:,app.slice,app.channel);
                app.excludedMask = app.excludedMasks(:,:,app.slice,app.channel);
                app.fullMask = app.fullMasks(:,:,app.slice,app.channel);
                app.maskGenerator.baseMask = app.baseMask;
                app.maskGenerator.fullMask = app.fullMask;
                app.maskGenerator.excludedMask = app.excludedMask;
                app.BorderSpinner.Value = app.borders(app.slice,app.channel);
                app.dispOcc();
                
                if (~isempty(app.thresholdDlg))
                    app.thresholdDlg.reinit(tempImage,tempThresh,app.sizes{app.slice,app.channel},tempMinFeature);
                end
            end

            app.chngImgBnds(app.BCwndw(app.channel,:));

            if (~isempty(app.BCDlg))
                app.BCDlg.reinit(app.shiftImage,app.BCwndw(app.channel,:));
            end
        end

        function dispOcc(app)
            app.SliceOccLabel.Text = strcat(num2str(round(1000*app.occTot(app.slice,app.channel))/10),'%');
            app.ThreshOccLabel.Text = strcat(num2str(round(1000*app.occThresh(app.slice,app.channel))/10),'%');
            app.ChannelOccLabel.Text = strcat(num2str(round(1000*mean(app.occTot(:,app.channel)))/10),'%');
            app.TotalOccLabel.Text = strcat(num2str(round(1000*mean(app.occTot,'all'))/10),'%');
        end

        function calcOcc(app,sl,ch)
            app.occTot(sl,ch) = sum(app.fullMasks(:,:,sl,ch),'all')/app.numPx;
            app.occThresh(sl,ch) = sum(app.baseMasks(:,:,sl,ch),'all')/app.numPx;
        end

        %% helper app callbacks
        % channel_helper_app callback
        function loadRefClbk(app,params,frameLimit)
            delete(app.channelDlg);
            app.channelDlg = [];
            if (isempty(params))
                close(app.interruptDlg);
                app.interruptDlg = [];
                return;
            end

            % older versions of matlab use a 16 bit int to track the
            % directory in the tiff library
            if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                disp('Matlab versions before 2023 cannot read large Tiff stacks. Limiting frames averaged')
                temp = 64000/(params.channels*params.slices*(1+params.bidirectional));
                if (frameLimit<1)
                    frameLimit = temp;
                else
                    frameLimit = min(frameLimit,temp);
                end
            end

            app.interruptDlg.Title = "Opening file...";
            try
                stackManager = StackTracker(params);
                tempImages = stackManager.buildProjection(fullfile(app.testPath,app.testFile),frameLimit,app.interruptDlg);
            catch exception
                close(app.interruptDlg);
                app.interruptDlg = [];
                rethrow(exception);
            end

            % update variables for stack structure
            app.refFile = app.testFile;
            app.refPath = app.testPath;
            app.bidirectional = params.bidirectional;
            app.channels = params.channels;
            app.slices = params.slices;
            app.mainChannel = params.mainChannel;
            
            % update ui elements for changing slice/channel
            app.frameParity = true;
            app.FrameParityButton.Enable = app.bidirectional;
            app.FrameParityButton.Text = "Odd";
            app.FrameParityButton.Value = true;
            app.channel = 1;
            app.ChannelSpinner.Enable = app.channels > 1;
            app.ChannelSpinner.Value = 1;
            app.ChannelSpinner.Limits = [1,app.channels];
            app.slice = 1;
            app.SliceSpinner.Enable = app.slices > 1;
            app.SliceSpinner.Value = 1;
            app.SliceSpinner.Limits = [1,app.slices];

            app.isVolume = length(size(tempImages))>3;
            app.generateMaskFlag = false;
            app.chngAllSlices = false;
            if (app.isVolume)
                app.refImages = zeros(size(tempImages));
                for sl=1:app.slices
                    for ch=1:size(tempImages,4)
                        tempIm = tempImages(:,:,sl,ch);
                        maxVal = max(tempIm,[],'all');
                        minVal = min(tempIm,[],'all');
                        app.refImages(:,:,sl,ch) = (tempIm-minVal)/(maxVal-minVal);
                    end
                end
            else
                app.refImages = zeros(size(tempImages,1),size(tempImages,2),1,size(tempImages,3));
                for ch=1:size(tempImages,3)
                    tempIm = tempImages(:,:,ch);
                    maxVal = max(tempIm,[],'all');
                    minVal = min(tempIm,[],'all');
                    app.refImages(:,:,1,ch) = (tempIm-minVal)/(maxVal-minVal);
                end
                if (app.bidirectional)
                    app.htot = stackManager.htot;
                end
                app.slices = 1;
            end
            app.shiftImage = app.refImages(:,:,1,1);
            app.thresholds = ones(app.slices,app.channels);
            app.minFeatures = zeros(app.slices,app.channels);
            app.borders = zeros(app.slices,app.channels);
            app.sizes = cell(app.slices,app.channels);
            app.occTot = zeros(app.slices,app.channels);
            app.occThresh = zeros(app.slices,app.channels);
            app.parityFlag = false;
            app.baseMasks = zeros(size(app.shiftImage,1),size(app.shiftImage,2),app.slices,app.channels);
            app.fullMasks = zeros(size(app.baseMasks));
            app.excludedMasks = zeros(size(app.baseMasks));
            app.baseMask = 0*app.shiftImage;
            app.excludedMask = 0*app.shiftImage;
            app.fullMask = 0*app.shiftImage;
            app.maskGenerator = MaskGenerator(1,0,0,app.shiftImage);
            app.contrImage = app.shiftImage;
            app.bidiShift = 0;
            app.numPx = numel(app.shiftImage);

            app.BCwndw = [zeros(app.channels,1),ones(app.channels,1)];
            app.dispImage = zeros(size(app.refImages,1),size(app.refImages,2),3);
            app.imhandle = image(app.ImageAxes, app.dispImage);
            set(app.ImageAxes,'xlim',[0,size(app.refImages,2)]);
            set(app.ImageAxes,'ylim',[0,size(app.refImages,1)]);
            app.ImageAxes.Title.String = ['file: ', app.refFile];

            if (app.bidirectional)
                app.autoYShift();
            else
                app.updateImage();
            end

            app.ExportAESPatternMenu.Enable = true;
            app.WindowLevelMenu.Enable = true;
            app.BidirectionalShiftMenu.Enable = app.bidirectional;
            app.ThresholdButton.Enable = true;
            app.BorderSpinner.Enable = true;
            app.ExportAESPatternMenu.Enable = true;
            close(app.interruptDlg);
            app.interruptDlg = [];
        end

        % bidirectional_shift_helper_app callbacks
        function chngYShift(app, shift)
            app.bidiShift = shift;
            if (app.frameParity)
                app.shiftImage = circshift(squeeze(app.refImages(:,:,app.slice,app.channel)),app.bidiShift,1);
                app.chngImgBnds(app.BCwndw(app.channel,:));
            end
        end

        function shift = autoYShift(app)
            if (app.bidirectional)
                shift = 0;
                for sl=1:app.slices
                    temp = xcorr2((app.refImages(:,:,sl,(2*app.mainChannel-1))-mean(app.refImages(:,:,sl,(2*app.mainChannel-1)),'all')),(app.refImages(:,:,sl,(2*app.mainChannel))-mean(app.refImages(:,:,sl,(2*app.mainChannel)),'all')));
                    temp = temp(:,size(app.shiftImage,2));
                    [~,ind] = max(temp);
                    shift = shift + size(app.shiftImage,1)-ind;
                end
                shift = shift/app.slices;
                app.bidiShift = shift;
                if (app.frameParity)
                    app.selectRefImage();
                end
            end
        end

        function closeShift(app)
            if (~isempty(app.bidiShiftDlg))
                delete(app.bidiShiftDlg);
                app.bidiShiftDlg = [];
            end
        end

        % BC_helper_app callbacks
        function chngImgBnds(app,bounds)
            app.BCwndw(app.channel,:) = [bounds(1), bounds(2)];
            tempMaskLow = app.shiftImage > bounds(1);
            tempMaskHigh = app.shiftImage > bounds(2);
            app.contrImage = tempMaskHigh + (tempMaskLow .* (1 - tempMaskHigh)) .* (app.shiftImage - bounds(1)) / (bounds(2) - bounds(1));
            app.updateImage();
        end

        function closeBC(app)
           if (~isempty(app.BCDlg))
               delete(app.BCDlg);
               app.BCDlg = [];
           end
        end

        % mask_threshold_helper_app callbacks
        function chngThresholdFast(app,threshold)
            app.baseMask = app.maskGenerator.image > threshold;
            app.excludedMask = 0*app.shiftImage;
            app.fullMask = app.baseMask;
            app.updateImage();
        end

        function chngThreshold(app,threshold)
            app.UIFigure.Pointer = 'watch';
            if (~isempty(app.thresholdDlg))
                app.thresholdDlg.UIFigure.Pointer = 'watch';
            end
            drawnow;
            app.maskGenerator.setThreshold(threshold);
            if (app.isVolume && app.chngAllSlices)
                app.thresholds(:,app.channel) = threshold*ones(app.slices,1);
                for sl=circshift(1:app.slices,(app.slices-app.slice))
                    app.maskGenerator.setMinFeature(app.minFeatures(sl,app.channel));
                    if (app.bidirectional)
                        app.maskGenerator.image = circshift(app.refImages(:,:,sl,app.channel*2-1),app.bidiShift,1);
                    else
                        app.maskGenerator.image = app.refImages(:,:,sl,app.channel);
                    end
                    app.maskGenerator.buildBaseMask();
                    app.sizes{sl,app.channel} = app.maskGenerator.sizes;
                    app.maskGenerator.buildBorder();
                    app.baseMasks(:,:,sl,app.channel) = app.maskGenerator.baseMask;
                    app.fullMasks(:,:,sl,app.channel) = app.maskGenerator.fullMask;
                    app.excludedMasks(:,:,sl,app.channel) = app.maskGenerator.excludedMask;
                    app.calcOcc(sl,app.channel);
                end
            else
                app.thresholds(app.slice,app.channel) = threshold;
                app.maskGenerator.buildBaseMask();
                app.sizes{app.slice,app.channel} = app.maskGenerator.sizes;
                app.maskGenerator.buildBorder();
                app.baseMasks(:,:,app.slice,app.channel) = app.maskGenerator.baseMask;
                app.fullMasks(:,:,app.slice,app.channel) = app.maskGenerator.fullMask;
                app.excludedMasks(:,:,app.slice,app.channel) = app.maskGenerator.excludedMask;
                app.calcOcc(app.slice,app.channel);
            end
            app.generateMaskFlag = false;
            app.baseMask = app.baseMasks(:,:,app.slice,app.channel);
            app.fullMask = app.fullMasks(:,:,app.slice,app.channel);
            app.excludedMask = app.excludedMasks(:,:,app.slice,app.channel);
            app.dispOcc();
            app.updateImage();

            if (~isempty(app.thresholdDlg))
                app.thresholdDlg.reinitMinFeature(app.maskGenerator.sizes,app.minFeatures(app.slice,app.channel));
                app.thresholdDlg.UIFigure.Pointer = 'arrow';
            end
            app.UIFigure.Pointer = 'arrow';
        end

        function chngSizeFast(app,minFeature)
            app.maskGenerator.setMinFeature(minFeature);
            app.maskGenerator.quickFilter();
            app.baseMask = app.maskGenerator.baseMask;
            app.excludedMask = app.maskGenerator.excludedMask;
            app.fullMask = app.baseMask;
            app.updateImage();
        end

        function chngSize(app,minFeature)
            app.UIFigure.Pointer = 'watch';
            if (~isempty(app.thresholdDlg))
                app.thresholdDlg.UIFigure.Pointer = 'watch';
            end
            drawnow;
            app.maskGenerator.setMinFeature(minFeature);
            if (app.isVolume && app.chngAllSlices)
                app.minFeatures(:,app.channel) = minFeature*ones(app.slices,1);
                for sl=circshift(1:app.slices,(app.slices-app.slice))
                    app.maskGenerator.setThreshold(app.thresholds(sl,app.channel));
                    if (app.bidirectional)
                        app.maskGenerator.image = circshift(app.refImages(:,:,sl,app.channel*2-1),app.bidiShift,1);
                    else
                        app.maskGenerator.image = app.refImages(:,:,sl,app.channel);
                    end
                    app.maskGenerator.blobDet();
                    app.maskGneerator.quickFilter();
                    app.maskGenerator.buildBorder();
                    app.sizes{sl,app.channel} = app.maskGenerator.sizes;
                    app.baseMasks(:,:,sl,app.channel) = app.maskGenerator.baseMask;
                    app.fullMasks(:,:,sl,app.channel) = app.maskGenerator.fullMask;
                    app.excludedMasks(:,:,sl,app.channel) = app.maskGenerator.excludedMask;
                    app.calcOcc(sl,app.channel);
                end
            else
                app.minFeatures(app.slice,app.channel) = minFeature;
                if (app.generateMaskFlag)
                    app.maskGenerator.blobDet();
                    app.maskGenerator.quickFilter();
                    app.sizes{app.slice,app.channel} = app.maskGenerator.sizes;
                else
                    app.maskGenerator.quickFilter();
                end
                app.maskGenerator.buildBorder();
                app.baseMasks(:,:,app.slice,app.channel) = app.maskGenerator.baseMask;
                app.fullMasks(:,:,app.slice,app.channel) = app.maskGenerator.fullMask;
                app.excludedMasks(:,:,app.slice,app.channel) = app.maskGenerator.excludedMask;
                app.calcOcc(app.slice,app.channel);
            end
            app.generateMaskFlag = false;
            app.baseMask = app.baseMasks(:,:,app.slice,app.channel);
            app.excludedMask = app.excludedMasks(:,:,app.slice,app.channel);
            app.fullMask = app.fullMasks(:,:,app.slice,app.channel);
            app.dispOcc();
            app.updateImage();

            app.UIFigure.Pointer = 'arrow';
            if (~isempty(app.thresholdDlg))
                app.thresholdDlg.UIFigure.Pointer = 'arrow';
            end
        end

        function setChngAllSlices(app,chngAllSlices)
            app.chngAllSlices = chngAllSlices;
        end

        function closeThreshold(app)
            if (~isempty(app.thresholdDlg))
                delete(app.thresholdDlg);
                app.thresholdDlg = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Title.Interpreter = 'none';
            disableDefaultInteractivity(app.ImageAxes);
            daspect(app.ImageAxes,[1,1,1]);
            temp = ver('Matlab');
            temp = split(temp.Version,'.');
            app.v = [str2double(temp{1}),str2double(temp{2})];
        end

        % Menu selected function: LoadStackMenu
        function LoadRef(app, event)
            app.closeBC();
            app.closeShift();
            app.closeThreshold();
            
            [tempFile, tempPath] = uigetfile('.tif');
            figure(app.UIFigure);
            if ~isequal(tempFile,0)
                app.testFile = tempFile;
                app.testPath = tempPath;
                app.interruptDlg = uiprogressdlg(app.UIFigure,"Title","Waiting for channel info...");
                app.interruptDlg.Cancelable = "off";
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.channelDlg = helper_apps.channel_helper_app(app);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (~isempty(app.channelDlg))
                delete(app.channelDlg);
            end

            if (~isempty(app.bidiShiftDlg))
                delete(app.bidiShiftDlg);
            end

            if (~isempty(app.BCDlg))
                delete(app.BCDlg);
            end

            if (~isempty(app.thresholdDlg))
                delete(app.thresholdDlg);
            end

            delete(app);
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            app.channel = app.ChannelSpinner.Value;
            app.selectRefImage();
        end

        % Value changed function: FrameParityButton
        function chngFrameParity(app, event)
            app.frameParity = app.FrameParityButton.Value;
            if (app.frameParity)
                app.FrameParityButton.Text = "Odd";
            else
                app.FrameParityButton.Text = "Even";
            end
            app.parityFlag = true;
            app.selectRefImage();
        end

        % Value changed function: SliceSpinner
        function chngSlice(app, event)
            app.slice = app.SliceSpinner.Value;
            app.selectRefImage();
        end

        % Menu selected function: BidirectionalShiftMenu
        function opnBidiShift(app, event)
            if (isempty(app.bidiShiftDlg))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.bidiShiftDlg = helper_apps.bidirectional_shift_helper_app(app,app.bidiShift);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Menu selected function: WindowLevelMenu
        function opnBC(app, event)
            if (isempty(app.BCDlg))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.BCDlg = helper_apps.BC_helper_app(app,app.shiftImage,app.BCwndw(app.channel,:));
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Button pushed function: ThresholdButton
        function opnThreshold(app, event)
            if (isempty(app.thresholdDlg))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (app.generateMaskFlag)
                    app.generateMaskFlag = false;
                    app.maskGenerator.blobDet();
                    app.sizes{app.slice,app.channel} = app.maskGenerator.sizes;
                end
                app.thresholdDlg = helper_apps.mask_threshold_helper_app(app,app.maskGenerator.image,app.thresholds(app.slice,app.channel),app.maskGenerator.sizes,app.minFeatures(app.slice,app.channel),app.isVolume*(1+app.chngAllSlices));
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: BorderSpinner
        function chngBorder(app, event)
            border = app.BorderSpinner.Value;
            app.maskGenerator.setBorder(border);
            if (app.chngAllSlices)
                app.borders(:,app.channel) = border*ones(app.slices,1);
                for sl=circshift(1:app.slices,(app.slices-app.slice))
                    app.maskGenerator.baseMask = app.baseMasks(:,:,sl,app.channel);
                    app.maskGenerator.buildBorder();
                    app.fullMasks(:,:,sl,app.channel) = app.maskGenerator.fullMask;
                    app.calcOcc(sl,app.channel);
                end
                app.fullMask = app.fullMasks(:,:,app.slice,app.channel);
            else
                app.borders(app.slice,app.channel) = border;
                app.maskGenerator.buildBorder();
                app.fullMask = app.maskGenerator.fullMask;
                app.fullMasks(:,:,app.slice,app.channel) = app.fullMask;
                app.calcOcc(app.slice,app.channel);
            end
            app.dispOcc();
            app.updateImage();
        end

        % Menu selected function: ExportAESPatternMenu
        function exportPattern(app, event)
            pathStr = strcat(app.refPath,filesep,'*.tif');
            [outFile,outPath] = uiputfile(pathStr,'AES Mask',strcat(app.refFile,'_mask.tif'));
            drawnow;
            figure(app.UIFigure);

            if (isequal(outFile,0) || isequal(outPath,0))
                return;
            end

            basename = split(outFile,'.');
            basename = basename{1};
            h=size(app.shiftImage,1);
            w=size(app.shiftImage,2);
            if (app.isVolume)
                if (app.bidirectional)
                    for ch=1:app.channels
                        app.htot = 2*app.slices*h;
                        tempMask = zeros(app.htot,w);
                        for sl=1:app.slices
                            tempMask(((sl-1)*h+1):(sl*h),:) = circshift(app.fullMasks(:,:,sl,ch),-app.bidiShift,1);
                            tempMask((app.htot-sl*h+1):(app.htot-(sl-1)*h),:) = flipud(app.fullMasks(:,:,sl,ch));
                        end

                        if (app.channels==1)
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'.tif')),'Compression','none');
                        else
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        end
                    end
                else
                    for ch=1:app.channels
                        tempMask = zeros(app.slices*h,w);
                        for sl=1:app.slices
                            tempMask(((sl-1)*h+1):(sl*h),:) = app.fullMasks(:,:,sl,ch);
                        end

                        if (app.channels==1)
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'.tif')),'Compression','none');
                        else
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        end
                    end
                end
            else
                if (app.bidirectional)
                    for ch=1:app.channels
                        tempMask = zeros(app.htot,w);
                        tempMask(1:h,:) = circshift(app.fullMasks(:,:,ch),-app.bidiShift,1);
                        tempMask((app.htot-h+1):app.htot,:) = flipud(app.fullMasks(:,:,ch));
                        if (app.channels==1)
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'.tif')),'Compression','none');
                        else
                            imwrite((tempMask+0),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        end
                    end
                else
                    if (app.channels==1)
                        imwrite((app.fullMasks(:,:,1)+0),fullfile(outPath,strcat(basename,'.tif')),'Compression','none');
                    else
                        for ch=1:app.channels
                            imwrite((app.fullMasks(:,:,1)+0),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Bloodflow Mask App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+helper_apps', '+images', 'bloodflow_mask_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadStackMenu = uimenu(app.FileMenu);
            app.LoadStackMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadRef, true);
            app.LoadStackMenu.Text = 'Load Stack';
            app.ExportAESPatternMenu = uimenu(app.FileMenu);
            app.ExportAESPatternMenu.MenuSelectedFcn = createCallbackFcn(app, @exportPattern, true);
            app.ExportAESPatternMenu.Enable = 'off';
            app.ExportAESPatternMenu.Text = 'Export AES Pattern';
            app.ImageMenu = uimenu(app.UIFigure);
            app.ImageMenu.Text = 'Image';
            app.WindowLevelMenu = uimenu(app.ImageMenu);
            app.WindowLevelMenu.MenuSelectedFcn = createCallbackFcn(app, @opnBC, true);
            app.WindowLevelMenu.Enable = 'off';
            app.WindowLevelMenu.Text = 'Window/Level';
            app.BidirectionalShiftMenu = uimenu(app.ImageMenu);
            app.BidirectionalShiftMenu.MenuSelectedFcn = createCallbackFcn(app, @opnBidiShift, true);
            app.BidirectionalShiftMenu.Enable = 'off';
            app.BidirectionalShiftMenu.Text = 'Bidirectional Shift';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageAxes = uiaxes(app.GridLayout);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            zlabel(app.ImageAxes, 'Z')
            app.MaskGenerationPanel = uipanel(app.GridLayout);
            app.MaskGenerationPanel.Title = 'Mask Generation';
            app.GridLayout4 = uigridlayout(app.MaskGenerationPanel);
            app.BorderSpinnerLabel = uilabel(app.GridLayout4);
            app.BorderSpinnerLabel.Text = 'Border:';
            app.BorderSpinner = uispinner(app.GridLayout4);
            app.BorderSpinner.Limits = [0 Inf];
            app.BorderSpinner.RoundFractionalValues = 'on';
            app.BorderSpinner.ValueChangedFcn = createCallbackFcn(app, @chngBorder, true);
            app.BorderSpinner.Enable = 'off';
            app.ThresholdButton = uibutton(app.GridLayout4, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @opnThreshold, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Text = 'Set Threshold';
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = 'Threshold Occ.:';
            app.ThreshOccLabel = uilabel(app.GridLayout4);
            app.ThreshOccLabel.Text = '0%';
            app.Label_1 = uilabel(app.GridLayout4);
            app.Label_1.Text = 'Slice Occ.:';
            app.SliceOccLabel = uilabel(app.GridLayout4);
            app.SliceOccLabel.Text = '0%';
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = 'Channel Occ.:';
            app.ChannelOccLabel = uilabel(app.GridLayout4);
            app.ChannelOccLabel.Text = '0%';
            app.TotalOccLabel_2 = uilabel(app.GridLayout4);
            app.TotalOccLabel_2.Text = 'Total Occ.:';
            app.TotalOccLabel = uilabel(app.GridLayout4);
            app.TotalOccLabel.Text = '0%';
            app.StackSelectionPanel = uipanel(app.GridLayout);
            app.StackSelectionPanel.Title = 'Stack Selection';
            app.GridLayout3 = uigridlayout(app.StackSelectionPanel);
            app.ChannelLabel = uilabel(app.GridLayout3);
            app.ChannelLabel.Text = 'Channel:';
            app.ChannelSpinner = uispinner(app.GridLayout3);
            app.ChannelSpinner.Limits = [1 Inf];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Enable = 'off';
            app.ChannelSpinner.Value = 1;
            app.SliceLabel = uilabel(app.GridLayout3);
            app.SliceLabel.Text = 'Slice:';
            app.SliceSpinner = uispinner(app.GridLayout3);
            app.SliceSpinner.Limits = [1 Inf];
            app.SliceSpinner.RoundFractionalValues = 'on';
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @chngSlice, true);
            app.SliceSpinner.Enable = 'off';
            app.SliceSpinner.Value = 1;
            app.FrameParityButton = uibutton(app.GridLayout3, 'state');
            app.FrameParityButton.ValueChangedFcn = createCallbackFcn(app, @chngFrameParity, true);
            app.FrameParityButton.Enable = 'off';
            app.FrameParityButton.Text = 'Odd';
            app.FrameParityButton.Value = true;
            app.ParityLabel = uilabel(app.GridLayout3);
            app.ParityLabel.Text = 'Parity:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blood_vessel_mask_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end