classdef fov_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        OutputTransmissionTab           matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        OutputLabel                     matlab.ui.control.Label
        OutputClearButton               matlab.ui.control.Button
        OutputLoadButton                matlab.ui.control.Button
        OutputYTAxes                    matlab.ui.control.UIAxes
        OutputXTAxes                    matlab.ui.control.UIAxes
        InputTransmissionTab            matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        InputLabel                      matlab.ui.control.Label
        InputClearButton                matlab.ui.control.Button
        InputLoadButton                 matlab.ui.control.Button
        InputYTAxes                     matlab.ui.control.UIAxes
        InputXTAxes                     matlab.ui.control.UIAxes
        MPOrderEditField                matlab.ui.control.NumericEditField
        MultiphotonOrderEditFieldLabel  matlab.ui.control.Label
        ChannelSpinner                  matlab.ui.control.Spinner
        ChannelSpinnerLabel             matlab.ui.control.Label
    end

    % fov transmission loaded as .tif and normalized to 1
    % input is transmission applied when imaging to produce current image
    % output is transmission to apply to future mask

    properties (Access = private)
        callingApp
        powerMod
        channel
        inputXPlot
        inputYPlot
        outputXPlot
        outputYPlot
    end
    
    methods (Access = public)
        
        function updateMpOrder(app,val)
            app.MPOrderEditField.Value = val;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, powerMod)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.powerMod = powerMod;
            app.ChannelSpinner.Enable = (app.powerMod.channels>1) && app.powerMod.sepChannels;
            if (app.ChannelSpinner.Enable)
                app.ChannelSpinner.Limits = [1, app.powerMod.channels];
            end
            imDim = app.powerMod.imDim;
            app.inputXPlot = plot(app.InputXTAxes,1:imDim(2),ones(1,imDim(2)),'k');
            app.inputYPlot = plot(app.InputYTAxes,1:imDim(1),ones(1,imDim(1)),'k');
            app.outputXPlot = plot(app.OutputXTAxes,1:imDim(2),ones(1,imDim(2)),'k');
            app.outputYPlot = plot(app.OutputYTAxes,1:imDim(1),ones(1,imDim(1)),'k');
            app.InputXTAxes.XLim = [1,imDim(2)];
            app.InputXTAxes.YLim = [0,1];
            app.InputYTAxes.XLim = [1,imDim(1)];
            app.InputYTAxes.YLim = [0,1];
            app.OutputXTAxes.XLim = [1,imDim(2)];
            app.OutputXTAxes.YLim = [0,1];
            app.OutputYTAxes.XLim = [1,imDim(1)];
            app.OutputYTAxes.YLim = [0,1];

            app.chngChannel([]);
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            app.channel = app.ChannelSpinner.Value;
            trans = app.powerMod.fovTrans(:,:,app.channel);
            name = app.powerMod.fovTransNames{app.channel};
            app.OutputClearButton.Enable = ~isempty(name);
            app.outputXPlot.YData = mean(trans,1);
            app.outputYPlot.YData = mean(trans,2)';
            if (isempty(name))
                app.OutputLabel.Text = 'None';
            else
                app.OutputLabel.Text = name;
            end

            trans = app.powerMod.fovPreTrans(:,:,app.channel);
            name = app.powerMod.fovPreTransNames{app.channel};
            app.InputClearButton.Enable = ~isempty(name);
            app.inputXPlot.YData = mean(trans,1);
            app.inputYPlot.YData = mean(trans,2)';
            if (isempty(name))
                app.InputLabel.Text = 'None';
            else
                app.InputLabel.Text = name;
            end
        end

        % Button pushed function: OutputLoadButton
        function loadOutput(app, event)
            app.UIFigure.Visible = 'off';
            [fovFile, fovPath] = uigetfile('*.tif','Select Output Transmission Image',app.callingApp.path);
            app.UIFigure.Visible = 'on';
            drawnow;
            if ((app.callingApp.v(1)>9)||((app.callingApp.v(1)==9)&&(app.callingApp.v(2)>=12)))
                focus(app.callingApp.UIFigure);
                focus(app.UIFigure);
            else
                figure(app.callingApp.UIFigure);
                figure(app.UIFigure);
            end

            if (isequal(fovFile,0))
                return;
            end

            try
                trans = double(imread(fullfile(fovPath,fovFile),1));
                app.powerMod.setFOVTrans(app.channel,trans,fovFile);
                app.callingApp.path = fovPath;
            catch
                errordlg('Unable to read file.');
                return;
            end

            app.chngChannel([]);
            app.callingApp.updatePower();
        end

        % Button pushed function: OutputClearButton
        function clearOutput(app, event)
            app.powerMod.setFOVTrans(app.channel,[],[]);
            app.chngChannel([]);
            app.callingApp.updatePower();
        end

        % Button pushed function: InputLoadButton
        function loadInput(app, event)
            app.UIFigure.Visible = 'off';
            [fovFile, fovPath] = uigetfile('*.tif','Select Input Transmission Image',app.callingApp.path);
            app.UIFigure.Visible = 'on';
            drawnow;
            if ((app.callingApp.v(1)>9)||((app.callingApp.v(1)==9)&&(app.callingApp.v(2)>=12)))
                focus(app.callingApp.UIFigure);
                focus(app.UIFigure);
            else
                figure(app.callingApp.UIFigure);
                figure(app.UIFigure);
            end

            if (isequal(fovFile,0))
                return;
            end

            try
                trans = double(imread(fullfile(fovPath,fovFile),1));
                app.powerMod.setFOVPreTrans(app.channel,trans,fovFile);
                app.callingApp.path = fovPath;
            catch
                errordlg('Unable to read file.');
                return;
            end

            app.chngChannel([]);
            app.callingApp.updatePower();
        end

        % Button pushed function: InputClearButton
        function clearInput(app, event)
            app.powerMod.setFOVPreTrans(app.channel,[],[]);
            app.chngChannel([]);
            app.callingApp.updatePower();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeFOVPower();
            delete(app);
        end

        % Value changed function: MPOrderEditField
        function chngMPOrder(app, event)
            app.powerMod.chngMpOrder(app.MPOrderEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FOV Transmission';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ChannelSpinnerLabel = uilabel(app.GridLayout);
            app.ChannelSpinnerLabel.Text = 'Channel:';
            app.ChannelSpinner = uispinner(app.GridLayout);
            app.ChannelSpinner.Limits = [1 Inf];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Value = 1;
            app.MultiphotonOrderEditFieldLabel = uilabel(app.GridLayout);
            app.MultiphotonOrderEditFieldLabel.Text = 'Multiphoton Order:';
            app.MPOrderEditField = uieditfield(app.GridLayout, 'numeric');
            app.MPOrderEditField.Limits = [1 Inf];
            app.MPOrderEditField.RoundFractionalValues = 'on';
            app.MPOrderEditField.ValueChangedFcn = createCallbackFcn(app, @chngMPOrder, true);
            app.MPOrderEditField.Value = 2;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.OutputTransmissionTab = uitab(app.TabGroup);
            app.OutputTransmissionTab.Title = 'Output Transmission';
            app.GridLayout2 = uigridlayout(app.OutputTransmissionTab);
            app.OutputXTAxes = uiaxes(app.GridLayout2);
            title(app.OutputXTAxes, 'Output Transmission')
            xlabel(app.OutputXTAxes, 'X')
            ylabel(app.OutputXTAxes, 'T')
            zlabel(app.OutputXTAxes, 'Z')
            app.OutputYTAxes = uiaxes(app.GridLayout2);
            xlabel(app.OutputYTAxes, 'Y')
            ylabel(app.OutputYTAxes, 'T')
            zlabel(app.OutputYTAxes, 'Z')
            app.OutputLoadButton = uibutton(app.GridLayout2, 'push');
            app.OutputLoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadOutput, true);
            app.OutputLoadButton.Text = 'Load';
            app.OutputClearButton = uibutton(app.GridLayout2, 'push');
            app.OutputClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearOutput, true);
            app.OutputClearButton.Enable = 'off';
            app.OutputClearButton.Text = 'Clear';
            app.OutputLabel = uilabel(app.GridLayout2);
            app.OutputLabel.Text = 'None';
            app.InputTransmissionTab = uitab(app.TabGroup);
            app.InputTransmissionTab.Title = 'Input Transmission';
            app.GridLayout3 = uigridlayout(app.InputTransmissionTab);
            app.InputXTAxes = uiaxes(app.GridLayout3);
            title(app.InputXTAxes, 'Input Transmission')
            xlabel(app.InputXTAxes, 'X')
            ylabel(app.InputXTAxes, 'T')
            zlabel(app.InputXTAxes, 'Z')
            app.InputYTAxes = uiaxes(app.GridLayout3);
            xlabel(app.InputYTAxes, 'Y')
            ylabel(app.InputYTAxes, 'T')
            zlabel(app.InputYTAxes, 'Z')
            app.InputLoadButton = uibutton(app.GridLayout3, 'push');
            app.InputLoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadInput, true);
            app.InputLoadButton.Text = 'Load';
            app.InputClearButton = uibutton(app.GridLayout3, 'push');
            app.InputClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearInput, true);
            app.InputClearButton.Enable = 'off';
            app.InputClearButton.Text = 'Clear';
            app.InputLabel = uilabel(app.GridLayout3);
            app.InputLabel.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fov_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end