classdef roi_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ROISpinner                      matlab.ui.control.Spinner
        ROISpinnerLabel                 matlab.ui.control.Label
        TransmissionEditField           matlab.ui.control.NumericEditField
        TransmissionLabel               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        IndividualROITab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        AdjustedSignalLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SignalLabel                     matlab.ui.control.Label
        Label_1                         matlab.ui.control.Label
        ROIAxes                         matlab.ui.control.UIAxes
        SignalComparisonTab             matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ClearButton                     matlab.ui.control.Button
        EqualizeSignalButton            matlab.ui.control.Button
        AdjustedSignalAxes              matlab.ui.control.UIAxes
        SignalAxes                      matlab.ui.control.UIAxes
        TransmissionTab                 matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        TransAxes                       matlab.ui.control.UIAxes
        PercentileEditField             matlab.ui.control.NumericEditField
        MinPxPercentileLabel            matlab.ui.control.Label
        MPOrderEditField                matlab.ui.control.NumericEditField
        MultiphotonOrderEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp
        powerMod
        shapeMaskHandler
        
        slice
        channel
        sigPlot
        adjSigPlot
        transPlot
        imhandle
        dispImage
        imDim

        op = 0.5;
    end
    
    methods (Access = public)
        function chngROI(app)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            sl = app.shapeMaskHandler.slice;
            ch = app.shapeMaskHandler.channel;
            ind = app.shapeMaskHandler.currentROIInd;
            if ((ch~=app.channel) || (sl~=app.slice))
                app.channel = ch;
                app.slice = sl;
                app.chngSlCh();
            else
                app.updatePlots();
            end

            if (app.shapeMaskHandler.currentROIType>0)
                app.ROISpinner.Value = ind;
                app.drawROI(ind);
                p = app.powerMod.roiRefPower{app.slice,app.channel}(ind);
                app.SignalLabel.Text = num2str(p);
                app.AdjustedSignalLabel.Text = num2str(p.*(app.powerMod.roiTrans{app.slice,app.channel}(ind).^app.powerMod.mpOrder));
                app.TransmissionEditField.Enable = true;
                app.TransmissionEditField.Value = app.powerMod.roiTrans{app.slice,app.channel}(ind);
            else
                app.ROISpinner.Value = 0;
                app.dispImage = 0*app.dispImage;
                app.imhandle.CData = app.dispImage;
                app.SignalLabel.Text = 'NA';
                app.AdjustedSignalLabel.Text = 'NA';
                app.TransmissionEditField.Enable = false;
            end
            app.UIFigure.Pointer = 'arrow';
        end

        function chngNumROIs(app)
            app.channel = 0;
            app.slice = 0;
            app.chngROI();
        end

        function drawROI(app,ind)
            mask = app.shapeMaskHandler.getRoiMask(app.slice,app.channel,ind);
            activePx = mask .* ((app.powerMod.refImages(:,:,app.slice,app.channel)./(app.powerMod.fovPreTrans(:,:,app.channel).^app.powerMod.mpOrder)) >= app.powerMod.roiThreshPxVal{app.slice,app.channel}(ind));
            app.dispImage(:,:,1) = mask .* (app.powerMod.refImages(:,:,app.slice,app.channel)-app.powerMod.roiLowPxVal{app.slice,app.channel}(ind))/(app.powerMod.roiHighPxVal{app.slice,app.channel}(ind)-app.powerMod.roiLowPxVal{app.slice,app.channel}(ind));
            app.dispImage(:,:,2) = app.dispImage(:,:,1);
            app.dispImage(:,:,3) = app.dispImage(:,:,1) + app.op*(1-mask);
            app.dispImage(:,:,1) = app.dispImage(:,:,1) .* (1 - activePx) + activePx .* (app.op + app.dispImage(:,:,1));
            app.imhandle.CData = app.dispImage;

            bd1 = 1;
            proj = sum(mask,1);
            while((proj(bd1)==0)&&(bd1<app.imDim(2)))
                bd1 = bd1 + 1;
            end
            bd2 = app.imDim(2);
            while((proj(bd2)==0)&&(bd2>bd1))
                bd2 = bd2 - 1;
            end
            app.ROIAxes.XLim = [bd1,bd2];

            bd1 = 1;
            proj = sum(mask,2);
            while((proj(bd1)==0)&&(bd1<app.imDim(1)))
                bd1 = bd1 + 1;
            end
            bd2 = app.imDim(1);
            while((proj(bd2)==0)&&(bd2>bd1))
                bd2 = bd2 - 1;
            end
            app.ROIAxes.YLim = [bd1,bd2];
        end

        function updatePlots(app)
            p = app.powerMod.roiRefPower{app.slice,app.channel};
            if (isempty(p))
                return;
            end
            t = app.powerMod.roiTrans{app.slice,app.channel};
            app.sigPlot.YData = p;
            app.transPlot.YData = t;
            app.adjSigPlot.YData = p .* (t .^ app.powerMod.mpOrder);
            app.setPlotYLim();
        end

        function setPlotYLim(app)
            maxVal = max(app.sigPlot.YData);
            if (maxVal <= 0)
                app.SignalAxes.YLim = [0,1];
                app.AdjustedSignalAxes.YLim = [0,1];
            else
                scale = 5*10^floor(log10(maxVal));
                ub = ceil(maxVal/scale)*scale;
                app.SignalAxes.YLim = [0,ub];
                app.AdjustedSignalAxes.YLim = [0,ub];
            end
        end

        function chngSlCh(app)
            cla(app.AdjustedSignalAxes);
            cla(app.SignalAxes);
            cla(app.TransAxes);
            p = app.powerMod.roiRefPower{app.slice,app.channel};
            app.ROISpinner.Enable = ~isempty(p);
            app.EqualizeSignalButton.Enable = ~isempty(p);
            if (~isempty(p))
                x = 1:length(p);
                app.sigPlot = scatter(app.SignalAxes,x,p,'filled');
                t = app.powerMod.roiTrans{app.slice,app.channel};
                app.transPlot = scatter(app.TransAxes,x,t,'filled');
                p = p .* (t .^ app.powerMod.mpOrder);
                app.adjSigPlot = scatter(app.AdjustedSignalAxes,x,p,'filled');
                app.ROISpinner.Limits = [0,length(p)];
                app.TransAxes.YLim = [0,1];
                app.setPlotYLim();
            end
        end

        function updateMpOrder(app,val)
            app.MPOrderEditField.Value = val;
            app.chngROI();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, powerMod, shapeMaskHandler)
            app.callingApp = callingApp;
            app.powerMod = powerMod;
            app.shapeMaskHandler = shapeMaskHandler;
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            box(app.ROIAxes,'on');
            box(app.SignalAxes,'on');
            box(app.AdjustedSignalAxes,'on');
            box(app.TransAxes,'on');
            disableDefaultInteractivity(app.ROIAxes);
            daspect(app.ROIAxes,[1,1,1]);
            app.imDim = app.powerMod.imDim;
            app.dispImage = zeros(app.imDim(1),app.imDim(2),3);
            app.imhandle = image(app.ROIAxes, app.dispImage);
            app.channel = 0;
            app.slice = 0;
            app.PercentileEditField.Value = app.powerMod.roiThresh*100;
            app.UIFigure.Pointer = 'watch';
            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeROIPower();
            delete(app);            
        end

        % Value changed function: ROISpinner
        function chngROIInd(app, event)
            ind = app.ROISpinner.Value;
            if (ind>0)
                app.shapeMaskHandler.chngROI(ind);
            else
                app.shapeMaskHandler.deselectROI();
                app.callingApp.updateDisplay();
            end
        end

        % Value changed function: PercentileEditField
        function chngPxPerc(app, event)
            app.channel = 0;
            app.slice = 0;
            app.powerMod.setROIThresh(app.PercentileEditField.Value/100);
        end

        % Value changed function: MPOrderEditField
        function chngMpOrder(app, event)
            app.powerMod.setMpOrder(app.MPOrderEditField.Value);
        end

        % Value changed function: TransmissionEditField
        function chngROITrans(app, event)
            app.powerMod.setROITrans(app.slice,app.channel,app.ROISpinner.Value,app.TransmissionEditField.Value);
        end

        % Button pushed function: EqualizeSignalButton
        function eqSig(app, event)
            app.powerMod.eqROISig(app.slice,app.channel);
            app.updatePlots();
            if (app.shapeMaskHandler.currentROIType>0)
                app.TransmissionEditField.Value = app.powerMod.roiTrans{app.slice,app.channel}(app.shapeMaskHandler.currentROIInd);
            end
        end

        % Button pushed function: ClearButton
        function clear(app, event)
            app.powerMod.eqROITrans(app.slice,app.channel);
            app.updatePlots();
            if (app.shapeMaskHandler.currentROIType>0)
                app.TransmissionEditField.Value = app.powerMod.roiTrans{app.slice,app.channel}(app.shapeMaskHandler.currentROIInd);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ROI Power';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MultiphotonOrderEditFieldLabel = uilabel(app.GridLayout);
            app.MultiphotonOrderEditFieldLabel.Text = 'Multiphoton Order:';
            app.MPOrderEditField = uieditfield(app.GridLayout, 'numeric');
            app.MPOrderEditField.Limits = [1 Inf];
            app.MPOrderEditField.ValueChangedFcn = createCallbackFcn(app, @chngMpOrder, true);
            app.MPOrderEditField.Value = 2;
            app.MinPxPercentileLabel = uilabel(app.GridLayout);
            app.MinPxPercentileLabel.Text = 'Min. Px. Percentile:';
            app.PercentileEditField = uieditfield(app.GridLayout, 'numeric');
            app.PercentileEditField.Limits = [0 100];
            app.PercentileEditField.ValueChangedFcn = createCallbackFcn(app, @chngPxPerc, true);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.IndividualROITab = uitab(app.TabGroup);
            app.IndividualROITab.Title = 'Individual ROI';
            app.GridLayout2 = uigridlayout(app.IndividualROITab);
            app.ROIAxes = uiaxes(app.GridLayout2);
            app.ROIAxes.XTick = [];
            app.ROIAxes.YTick = [];
            app.ROIAxes.ZTick = [];
            app.Label_1 = uilabel(app.GridLayout2);
            app.Label_1.Text = 'Signal:';
            app.SignalLabel = uilabel(app.GridLayout2);
            app.SignalLabel.Text = '0';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = 'Adjusted Signal:';
            app.AdjustedSignalLabel = uilabel(app.GridLayout2);
            app.AdjustedSignalLabel.Text = '0';
            app.SignalComparisonTab = uitab(app.TabGroup);
            app.SignalComparisonTab.Title = 'Signal Comparison';
            app.GridLayout3 = uigridlayout(app.SignalComparisonTab);
            app.SignalAxes = uiaxes(app.GridLayout3);
            title(app.SignalAxes, 'Current Signal')
            xlabel(app.SignalAxes, 'ROI')
            ylabel(app.SignalAxes, 'Signal')
            zlabel(app.SignalAxes, 'Z')
            app.AdjustedSignalAxes = uiaxes(app.GridLayout3);
            title(app.AdjustedSignalAxes, 'Adjusted Signal')
            xlabel(app.AdjustedSignalAxes, 'ROI')
            ylabel(app.AdjustedSignalAxes, 'Signal')
            zlabel(app.AdjustedSignalAxes, 'Z')
            app.EqualizeSignalButton = uibutton(app.GridLayout3, 'push');
            app.EqualizeSignalButton.ButtonPushedFcn = createCallbackFcn(app, @eqSig, true);
            app.EqualizeSignalButton.Text = 'Equalize Signal';
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clear, true);
            app.ClearButton.Text = 'Clear';
            app.TransmissionTab = uitab(app.TabGroup);
            app.TransmissionTab.Title = 'Transmission';
            app.GridLayout4 = uigridlayout(app.TransmissionTab);
            app.TransAxes = uiaxes(app.GridLayout4);
            xlabel(app.TransAxes, 'ROI')
            ylabel(app.TransAxes, 'Transmission')
            zlabel(app.TransAxes, 'Z')
            app.TransmissionLabel = uilabel(app.GridLayout);
            app.TransmissionLabel.Text = 'Transmission:';
            app.TransmissionEditField = uieditfield(app.GridLayout, 'numeric');
            app.TransmissionEditField.Limits = [0 1];
            app.TransmissionEditField.ValueChangedFcn = createCallbackFcn(app, @chngROITrans, true);
            app.TransmissionEditField.Value = 1;
            app.ROISpinnerLabel = uilabel(app.GridLayout);
            app.ROISpinnerLabel.Text = 'ROI:';
            app.ROISpinner = uispinner(app.GridLayout);
            app.ROISpinner.Limits = [0 Inf];
            app.ROISpinner.RoundFractionalValues = 'on';
            app.ROISpinner.ValueChangedFcn = createCallbackFcn(app, @chngROIInd, true);
            app.ROISpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roi_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end