classdef slice_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SliceSignalTab                  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        MinPxPercentileEditField        matlab.ui.control.NumericEditField
        MinPxPercentileEditFieldLabel   matlab.ui.control.Label
        MaxPxPercentileEditField        matlab.ui.control.NumericEditField
        MaxPxPercentileEditFieldLabel   matlab.ui.control.Label
        PAxes                           matlab.ui.control.UIAxes
        InputTransmissionTab            matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        PrePowLabel                     matlab.ui.control.Label
        PrePowClearButton               matlab.ui.control.Button
        PrePowLoadButton                matlab.ui.control.Button
        PrePAxes                        matlab.ui.control.UIAxes
        OutputTransmissionTab           matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        ExportButton                    matlab.ui.control.Button
        FitButton                       matlab.ui.control.Button
        OrientationDropDown             matlab.ui.control.DropDown
        OrientationLabel                matlab.ui.control.Label
        SLEditField                     matlab.ui.control.NumericEditField
        NAEditFieldLabel                matlab.ui.control.Label
        DepthScalingDropDown            matlab.ui.control.DropDown
        DepthScalingDropDownLabel       matlab.ui.control.Label
        TAxes                           matlab.ui.control.UIAxes
        AdjustedSliceSignalTab          matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        APAxes                          matlab.ui.control.UIAxes
        EffectiveOccupancyTab           matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        OccAxes                         matlab.ui.control.UIAxes
        MPOrderEditField                matlab.ui.control.NumericEditField
        MultiphotonOrderEditFieldLabel  matlab.ui.control.Label
        ChannelSpinner                  matlab.ui.control.Spinner
        ChannelSpinnerLabel             matlab.ui.control.Label
        SliceSepEditField               matlab.ui.control.NumericEditField
        SliceSeparationLabel            matlab.ui.control.Label
    end

    % Note: Calibration file is 2 columns. First column is Voltage, Second
    % column is the associated power transmission
    
    properties (Access = private)
        callingApp
        powerMod

        channel
        channels

        tPlotHandle
        pPlotHandle
        ppPlotHandle
        apPlotHandle
        occPlotHandle        
        occ        
    end
    
    methods (Access = public)
        
        function updatePlots(app)
            app.tPlotHandle.YData = app.powerMod.getSliceTrans(app.channel);
            app.pPlotHandle.YData = app.powerMod.getSlicePower(app.channel);
            tempAP = ((app.powerMod.getSliceTrans(app.channel)./app.powerMod.getSlicePrePower(app.channel)).^app.powerMod.mpOrder).*app.powerMod.getSlicePower(app.channel);
            tempAP = tempAP / max(tempAP);
            app.apPlotHandle.YData = tempAP;
            tempOcc = app.powerMod.getSliceTrans(app.channel).*app.occ(:,app.channel);
            scaleFactor = max((app.occ(:,app.channel)>0).*app.powerMod.getSliceTrans(app.channel));
            if (scaleFactor>0)
                tempOcc = tempOcc/scaleFactor;
            end
            app.occPlotHandle.YData = 100*tempOcc;
            set(app.OccAxes.Title,'String',strcat('Scaled Occupancy (',num2str(round(mean(tempOcc)*1000)/10),'%)'));
        end

        function updateOcc(app,occ)
            app.occ = occ;
            tempOcc = app.powerMod.getSliceTrans(app.channel).*app.occ(:,app.channel);
            scaleFactor = max((app.occ(app.channel,:)>0).*app.powerMod.getSliceTrans(app.channel));
            if (scaleFactor>0)
                tempOcc = tempOcc/scaleFactor;
            end
            app.occPlotHandle.YData = 100*tempOcc;
            set(app.OccAxes.Title,'String',strcat('Scaled Occupancy (',num2str(round(mean(tempOcc)*1000)/10),'%)'));
        end

        function updateMpOrder(app,val)
            app.MPOrderEditField.Value = val;
            app.updatePlots();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, powerMod, occ)
            app.powerMod = powerMod;
            app.occ = occ;
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.channels = powerMod.channels;
            app.channel = 1;
            app.ChannelSpinner.Enable = powerMod.sepChannels;
            if (powerMod.sepChannels)
                app.ChannelSpinner.Limits = [1,app.channels];
            end
            app.DepthScalingDropDown.ItemsData = 1:3;
            app.OrientationDropDown.ItemsData = [1,-1];
            
            app.MPOrderEditField.Value = powerMod.mpOrder;
            app.SliceSepEditField.Value = powerMod.sliceSep;
            app.OrientationDropDown.Value = powerMod.orientation(app.channel);
            app.MinPxPercentileEditField.Value = powerMod.getSliceLowPx()*100;
            app.MaxPxPercentileEditField.Value = powerMod.getSliceHighPx()*100;

            tempZ = app.powerMod.z;
            slices = length(tempZ);
            app.tPlotHandle = scatter(app.TAxes,tempZ,ones(1,slices),'filled');
            set(app.TAxes,'xlim',[0,(tempZ(end))]);
            set(app.TAxes,'ylim',[0,1]);            

            app.pPlotHandle = scatter(app.PAxes,tempZ,ones(1,slices),'filled');
            set(app.PAxes,'xlim',[0,(tempZ(end))]);
            set(app.PAxes,'ylim',[0,1]);

            app.ppPlotHandle = scatter(app.PrePAxes,tempZ,ones(1,slices),'filled');
            set(app.PrePAxes,'xlim',[0,(tempZ(end))]);
            set(app.PrePAxes,'ylim',[0,1]);

            app.apPlotHandle = scatter(app.APAxes,tempZ,ones(1,slices),'filled');
            set(app.APAxes,'xlim',[0,(tempZ(end))]);
            set(app.APAxes,'ylim',[0,1]);

            app.occPlotHandle = scatter(app.OccAxes,1:slices,ones(1,slices),'filled');
            set(app.OccAxes,'xlim',[1,slices]);
            set(app.OccAxes,'ylim',[0,100]);

            app.chngChannel([]);
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            app.channel = app.ChannelSpinner.Value;
            app.DepthScalingDropDown.Value = app.powerMod.curveTypes(app.channel);
            tempName = app.powerMod.slicePreTransNames{app.channel};
            if (isempty(tempName))
                app.PrePowLabel.Text = 'None';
                app.PrePowClearButton.Enable = false;
            else
                app.PrePowLabel.Text = tempName;
                app.PrePowClearButton.Enable = true;
            end
            app.ppPlotHandle.YData = app.powerMod.getSlicePrePower(app.channel);

            tempType = app.powerMod.curveTypes(app.channel);
            app.SLEditField.Enable = (tempType == app.powerMod.EXP);
            app.OrientationDropDown.Enable = (tempType == app.powerMod.EXP);
            app.FitButton.Enable = (tempType == app.powerMod.EXP);
            app.SLEditField.Value = app.powerMod.attenLen(app.channel);
            app.OrientationDropDown.Value = app.powerMod.orientation(app.channel);
            app.DepthScalingDropDown.Value = tempType;
            app.updatePlots();
        end

        % Value changed function: DepthScalingDropDown
        function chngType(app, event)
            if (isempty(event))
                return;
            end

            tempType = app.DepthScalingDropDown.Value;
            app.powerMod.setType(app.channel,tempType);
            app.SLEditField.Enable = (tempType == app.powerMod.EXP);
            app.OrientationDropDown.Enable = (tempType == app.powerMod.EXP);
            app.FitButton.Enable = (tempType == app.powerMod.EXP);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: SliceSepEditField
        function chngSliceSep(app, event)
            val = app.SliceSepEditField.Value;
            if (val==0)
                app.SliceSepEditField.Value = event.PreviousValue;
                return;
            end

            app.powerMod.setSliceSep(val);
            set(app.TAxes,'xlim',[0,(app.powerMod.z(end))]);
            app.tPlotHandle.XData = app.powerMod.z;
            set(app.PAxes,'xlim',[0,(app.powerMod.z(end))]);
            app.pPlotHandle.XData = app.powerMod.z;
            set(app.PrePAxes,'xlim',[0,(app.powerMod.z(end))]);
            app.ppPlotHandle.XData = app.powerMod.z;
            set(app.APAxes,'xlim',[0,(app.powerMod.z(end))]);
            app.apPlotHandle.XData = app.powerMod.z;
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: MPOrderEditField
        function chngMpOrder(app, event)
            app.powerMod.setMpOrder(app.MPOrderEditField.Value);
            app.callingApp.updatePower();
        end

        % Value changed function: SLEditField
        function chngSl(app, event)
            val = app.SLEditField.Value;
            if (val==0)
                app.SLEditField.Value = event.PreviousValue;
                return;
            end

            app.powerMod.setSL(app.channel,val);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: OrientationDropDown
        function chngOrientation(app, event)
            app.powerMod.setOrientation(app.channel,app.OrientationDropDown.Value);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: MaxPxPercentileEditField
        function chngMaxPx(app, event)
            value = app.MaxPxPercentileEditField.Value;
            if (value <= app.MinPxPercentileEditField.Value)
                app.MaxPxPercentileEditField.Value = event.PreviousValue;
                return;
            end

            app.powerMod.setSliceHighPx(value/100);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: MinPxPercentileEditField
        function chngMinPx(app, event)
            value = app.MinPxPercentileEditField.Value;
            if (value >= app.MaxPxPercentileEditField.Value)
                app.MinPxPercentileEditField.Value = event.PreviousValue;
                return;
            end

            app.powerMod.setSliceLowPx(value/100);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeSlicePower();
            delete(app)
        end

        % Button pushed function: FitButton
        function fitExp(app, event)
            app.powerMod.fitExp(app.channel);
            app.OrientationDropDown.Value = app.powerMod.orientation(app.channel);
            app.SLEditField.Value = app.powerMod.attenLen(app.channel);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Button pushed function: PrePowLoadButton
        function loadPrePow(app, event)
            app.UIFigure.Visible = 'off';
            [tempFile, tempPath] = uigetfile({'*.xlsx;*.csv;*.txt;*.dat','Data Files (*.xlsx,*.csv,*.txt,*.dat'},'Select Imaging Power Curve',app.callingApp.path);
            app.UIFigure.Visible = 'on';
            drawnow;

            if ((app.callingApp.v(1)>9)||((app.callingApp.v(1)==9)&&(app.callingApp.v(2)>=12)))
                focus(app.callingApp.UIFigure);
                focus(app.UIFigure);
            else
                figure(app.callingApp.UIFigure);
                figure(app.UIFigure);
            end

            if (isequal(tempFile,0))
                return;
            end

            try
                tempCurve = readmatrix(fullfile(tempPath,tempFile));
                success = app.powerMod.setSlicePreTrans(app.channel,tempCurve,tempFile);
                if (~success)
                    errordlg('File must be N by 1 column of slice imaging powers.','Formatting Error');
                    return;
                end
                app.updatePlots();
                app.ppPlotHandle.YData = app.powerMod.getSlicePrePower(app.channel);
                app.PrePowLabel.Text = tempFile;
                app.PrePowClearButton.Enable = true;
                app.callingApp.updatePower();
                app.callingApp.path = tempPath;
            catch
                errordlg('Unable to read file.');
                return;
            end
        end

        % Button pushed function: PrePowClearButton
        function clearPrePow(app, event)
            app.powerMod.setSlicePrePower(app.channel,[],[]);
            app.ppPlotHandle.YData = app.powerMod.getSlicePrePower(app.channel);
            app.updatePlots();
            app.PrePowLabel.Text = 'None';
            app.PrePowClearButton.Enable = false;
            app.callingApp.updatePower();
        end

        % Button pushed function: ExportButton
        function ExportTrans(app, event)
            app.UIFigure.Visible = 'off';
            [outFile,outPath] = uiputfile({'*.csv';'*.txt',;'*.dat'},'Slice Transmission Curve',fullfile(app.callingApp.path,'slice_transmission.csv'));
            app.UIFigure.Visible = 'on';
            if ((app.callingApp.v(1)>9)||((app.callingApp.v(1)==9)&&(app.callingApp.v(2)>=12)))
                focus(app.callingApp.UIFigure);
                focus(app.UIFigure);
            else
                figure(app.callingApp.UIFigure);
                figure(app.UIFigure);
            end
            drawnow;

            if (isequal(outFile,0))
                return;
            end

            basename = split(outFile,'.');
            if (length(basename)>1)
                extension = basename{end};
            else
                extension = 'csv';
            end
            basename = basename{1};
            if (app.ChannelSpinner.Enable)
                for ch=1:app.channels
                    writematrix(app.powerMod.getSliceTrans(ch),fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.',extension)));
                end
            else
                writematrix(app.powerMod.getSliceTrans(1),fullfile(outPath,strcat(basename,'.',extension)));
            end
            app.callingApp.path = outPath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Slice Power';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SliceSeparationLabel = uilabel(app.GridLayout);
            app.SliceSeparationLabel.Text = 'Slice Separation:';
            app.SliceSepEditField = uieditfield(app.GridLayout, 'numeric');
            app.SliceSepEditField.Limits = [0 Inf];
            app.SliceSepEditField.ValueChangedFcn = createCallbackFcn(app, @chngSliceSep, true);
            app.SliceSepEditField.Value = 1;
            app.ChannelSpinnerLabel = uilabel(app.GridLayout);
            app.ChannelSpinnerLabel.Text = 'Channel:';
            app.ChannelSpinner = uispinner(app.GridLayout);
            app.ChannelSpinner.Limits = [1 Inf];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Value = 1;
            app.MultiphotonOrderEditFieldLabel = uilabel(app.GridLayout);
            app.MultiphotonOrderEditFieldLabel.Text = 'Multiphoton Order:';
            app.MPOrderEditField = uieditfield(app.GridLayout, 'numeric');
            app.MPOrderEditField.Limits = [1 Inf];
            app.MPOrderEditField.RoundFractionalValues = 'on';
            app.MPOrderEditField.ValueChangedFcn = createCallbackFcn(app, @chngMpOrder, true);
            app.MPOrderEditField.Value = 2;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SliceSignalTab = uitab(app.TabGroup);
            app.SliceSignalTab.Title = 'Slice Signal';
            app.GridLayout5 = uigridlayout(app.SliceSignalTab);
            app.PAxes = uiaxes(app.GridLayout5);
            title(app.PAxes, 'Slice Signal')
            xlabel(app.PAxes, 'Depth')
            ylabel(app.PAxes, 'Signal')
            zlabel(app.PAxes, 'Z')
            app.MaxPxPercentileEditFieldLabel = uilabel(app.GridLayout5);
            app.MaxPxPercentileEditFieldLabel.Text = 'Max Px. Percentile:';
            app.MaxPxPercentileEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MaxPxPercentileEditField.Limits = [0 100];
            app.MaxPxPercentileEditField.ValueChangedFcn = createCallbackFcn(app, @chngMaxPx, true);
            app.MaxPxPercentileEditField.Value = 100;
            app.MinPxPercentileEditFieldLabel = uilabel(app.GridLayout5);
            app.MinPxPercentileEditFieldLabel.Text = 'Min Px. Percentile:';
            app.MinPxPercentileEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MinPxPercentileEditField.Limits = [0 100];
            app.MinPxPercentileEditField.ValueChangedFcn = createCallbackFcn(app, @chngMinPx, true);
            app.InputTransmissionTab = uitab(app.TabGroup);
            app.InputTransmissionTab.Title = 'Input Transmission';
            app.GridLayout9 = uigridlayout(app.InputTransmissionTab);
            app.PrePAxes = uiaxes(app.GridLayout9);
            title(app.PrePAxes, 'Initial Imaging Power')
            xlabel(app.PrePAxes, 'Depth')
            ylabel(app.PrePAxes, 'Normalization')
            zlabel(app.PrePAxes, 'Z')
            app.PrePowLoadButton = uibutton(app.GridLayout9, 'push');
            app.PrePowLoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadPrePow, true);
            app.PrePowLoadButton.Text = 'Load';
            app.PrePowClearButton = uibutton(app.GridLayout9, 'push');
            app.PrePowClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearPrePow, true);
            app.PrePowClearButton.Enable = 'off';
            app.PrePowClearButton.Text = 'Clear';
            app.PrePowLabel = uilabel(app.GridLayout9);
            app.PrePowLabel.Text = 'None';
            app.OutputTransmissionTab = uitab(app.TabGroup);
            app.OutputTransmissionTab.Title = 'Output Transmission';
            app.GridLayout6 = uigridlayout(app.OutputTransmissionTab);
            app.TAxes = uiaxes(app.GridLayout6);
            title(app.TAxes, 'Slice Transmission')
            xlabel(app.TAxes, 'Depth')
            ylabel(app.TAxes, 'Transmission')
            zlabel(app.TAxes, 'Z')
            app.DepthScalingDropDownLabel = uilabel(app.GridLayout6);
            app.DepthScalingDropDownLabel.Text = 'Depth Scaling:';
            app.DepthScalingDropDown = uidropdown(app.GridLayout6);
            app.DepthScalingDropDown.Items = {'Uniform', 'Exponential', 'Inverse Power'};
            app.DepthScalingDropDown.ValueChangedFcn = createCallbackFcn(app, @chngType, true);
            app.DepthScalingDropDown.Value = 'Uniform';
            app.NAEditFieldLabel = uilabel(app.GridLayout6);
            app.NAEditFieldLabel.Text = 'Scattering Length:';
            app.SLEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SLEditField.Limits = [0 Inf];
            app.SLEditField.ValueChangedFcn = createCallbackFcn(app, @chngSl, true);
            app.SLEditField.Enable = 'off';
            app.SLEditField.Value = 1;
            app.OrientationLabel = uilabel(app.GridLayout6);
            app.OrientationLabel.Text = 'Orientation:';
            app.OrientationDropDown = uidropdown(app.GridLayout6);
            app.OrientationDropDown.Items = {'Ascending', 'Descending'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @chngOrientation, true);
            app.OrientationDropDown.Enable = 'off';
            app.OrientationDropDown.Value = 'Ascending';
            app.FitButton = uibutton(app.GridLayout6, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @fitExp, true);
            app.FitButton.Enable = 'off';
            app.FitButton.Text = 'Fit';
            app.ExportButton = uibutton(app.GridLayout6, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTrans, true);
            app.ExportButton.Text = 'Export';
            app.AdjustedSliceSignalTab = uitab(app.TabGroup);
            app.AdjustedSliceSignalTab.Title = 'Adjusted Slice Signal';
            app.GridLayout7 = uigridlayout(app.AdjustedSliceSignalTab);
            app.APAxes = uiaxes(app.GridLayout7);
            title(app.APAxes, 'Adjusted Slice Signal')
            xlabel(app.APAxes, 'Depth')
            ylabel(app.APAxes, 'Signal')
            zlabel(app.APAxes, 'Z')
            app.EffectiveOccupancyTab = uitab(app.TabGroup);
            app.EffectiveOccupancyTab.Title = 'Effective Occupancy';
            app.GridLayout4 = uigridlayout(app.EffectiveOccupancyTab);
            app.OccAxes = uiaxes(app.GridLayout4);
            title(app.OccAxes, 'Scaled Occupancy (100%)')
            xlabel(app.OccAxes, 'Slice')
            ylabel(app.OccAxes, 'Occ. (%)')
            zlabel(app.OccAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slice_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end