classdef transmission_cal_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        MinTransEditField     matlab.ui.control.NumericEditField
        MinTransmissionLabel  matlab.ui.control.Label
        FNameLabel            matlab.ui.control.Label
        ClearButton           matlab.ui.control.Button
        ChannelSpinner        matlab.ui.control.Spinner
        ChannelSpinnerLabel   matlab.ui.control.Label
        LoadButton            matlab.ui.control.Button
        CalAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp
        powerMod

        channel
        channels
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, powerMod)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';

            app.callingApp = callingApp;
            app.powerMod = powerMod;
            app.channels = powerMod.channels;
            app.channel = 1;
            app.ChannelSpinner.Enable = powerMod.sepChannels;
            if (powerMod.sepChannels)
                app.ChannelSpinner.Limits = [1,app.channels];
            end

            app.MinTransEditField.Value = app.powerMod.minTrans;
            app.chngChannel([]);
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            app.channel = app.ChannelSpinner.Value;
            cla(app.CalAxes);
            map = app.powerMod.getCal(app.channel);
            hold(app.CalAxes,'on');
            if (isempty(map))
                plot(app.CalAxes,[0,1],[0,1]);
                set(app.CalAxes,'xlim',[0,1]);
                app.ClearButton.Enable = false;
                app.FNameLabel.Text = 'None';
            else
                plot(app.CalAxes,map(:,1),map(:,2));
                set(app.CalAxes,'xlim',[min(map(:,1)),max(map(:,1))]);
                app.ClearButton.Enable = true;
                app.FNameLabel.Text = app.powerMod.calNames{app.channel};
            end
            hold(app.CalAxes,'off');
        end

        % Button pushed function: ClearButton
        function clearMap(app, event)
            app.powerMod.setCal(app.channel,[],[]);
            app.chngChannel([]);
        end

        % Button pushed function: LoadButton
        function LoadMap(app, event)
            app.UIFigure.Visible = 'off';
            [calFile, calPath] = uigetfile({'*.xlsx;*.csv;*.txt;*.dat','Data Files (*.xlsx,*.csv,*.txt,*.dat'},'Select Calibration Curve',app.callingApp.path);
            app.UIFigure.Visible = 'on';
            drawnow;
            if ((app.callingApp.v(1)>9)||((app.callingApp.v(1)==9)&&(app.callingApp.v(2)>=12)))
                focus(app.callingApp.UIFigure);
                focus(app.UIFigure);
            else
                figure(app.callingApp.UIFigure);
                figure(app.UIFigure);
            end

            if (isequal(calFile,0))
                return;
            end

            try
                tempCurve = readmatrix(fullfile(calPath,calFile));
                if (~(length(size(tempCurve))==2) || (size(tempCurve,2)~=2) || (sum(isnan(tempCurve),'all')~=0))
                    errordlg('Cal curve must be N by 2 matrix.','Formatting Error');
                    return;
                end
                tempCurve = real(tempCurve);
                app.powerMod.setCal(app.channel,tempCurve,calFile);
                app.ClearButton.Enable = true;
                app.chngChannel([]);
                app.callingApp.path = calPath;
            catch
                errordlg('Unable to read file.');
                return;
            end
        end

        % Value changed function: MinTransEditField
        function chngMinTrans(app, event)
            app.powerMod.setMinTrans(app.MinTransEditField.Value);
            app.MinTransEditField.Value = app.powerMod.minTrans;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeTransCal();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Transmission Calibration';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CalAxes = uiaxes(app.GridLayout);
            title(app.CalAxes, 'Voltage Mapping')
            xlabel(app.CalAxes, 'Voltage')
            ylabel(app.CalAxes, 'Transmission')
            zlabel(app.CalAxes, 'Z')
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMap, true);
            app.LoadButton.Text = 'Load Map';
            app.ChannelSpinnerLabel = uilabel(app.GridLayout);
            app.ChannelSpinnerLabel.Text = 'Channel:';
            app.ChannelSpinner = uispinner(app.GridLayout);
            app.ChannelSpinner.Limits = [1 Inf];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Value = 1;
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearMap, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.FNameLabel = uilabel(app.GridLayout);
            app.FNameLabel.Text = 'None';
            app.MinTransmissionLabel = uilabel(app.GridLayout);
            app.MinTransmissionLabel.Text = 'Min. Transmission:';
            app.MinTransEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinTransEditField.Limits = [0 1];
            app.MinTransEditField.ValueChangedFcn = createCallbackFcn(app, @chngMinTrans, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transmission_cal_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end