classdef BC_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        RngSpinner           matlab.ui.control.Spinner
        RangeSpinnerLabel    matlab.ui.control.Label
        CntrSpinner          matlab.ui.control.Spinner
        CenterSpinnerLabel   matlab.ui.control.Label
        MaxSpinner           matlab.ui.control.Spinner
        MaximumSpinnerLabel  matlab.ui.control.Label
        MinSpinner           matlab.ui.control.Spinner
        MinimumSpinnerLabel  matlab.ui.control.Label
        histogramPlot        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp % Description
        image
        imhandle
        histhandle
        histCentRect
        histLeftRect
        histRightRect
        histMax
        baseNobWidth = 3;
        baseNobHeight = 25;
        nobWidth
        nobHeight

        highVal
        lowVal
        maxVal
        minVal
    end
    
    methods (Access = public)
        
        function reinit(app,image,window)
            app.image = image;
            app.highVal = window(2);
            app.lowVal = window(1);

            [counts,bins] = hist(image(:),50);
            counts = log10(counts+(counts==0));
            app.histhandle = bar(app.histogramPlot,bins,counts);

            app.histMax = max(counts);
            app.maxVal = bins(end);
            app.minVal = bins(1);
            app.maxVal = max([app.maxVal,app.highVal]);
            app.minVal = min([app.minVal,app.lowVal]);
            rangeVal = app.maxVal - app.minVal;
            if (rangeVal == 0)
                rangeVal = 1;
            end
            app.maxVal = app.maxVal + 0.25 * rangeVal;
            app.minVal = app.minVal - 0.25 * rangeVal;
            app.histogramPlot.XLim = [app.minVal,app.maxVal];
            app.histogramPlot.YLim = [0,app.histMax];

            app.nobHeight = app.histMax * app.baseNobHeight / 100;
            app.nobWidth = rangeVal * app.baseNobWidth / 100;
            app.histCentRect = images.roi.Rectangle(app.histogramPlot);
            app.histCentRect.Deletable = 0;
            app.histCentRect.DrawingArea = [app.minVal,0,rangeVal*1.5,app.histMax];
            app.histCentRect.InteractionsAllowed = "translate";
            app.histCentRect.MarkerSize = 1;
            addlistener(app.histCentRect,'ROIMoved',@app.centRectMv);
            addlistener(app.histCentRect,'MovingROI',@app.centRectMv);

            app.histLeftRect = images.roi.Rectangle(app.histogramPlot);
            app.histLeftRect.Deletable = 0;
            app.histLeftRect.DrawingArea = [app.minVal-app.nobWidth/2,app.histMax/2 - app.nobHeight / 2,1.5*rangeVal+app.nobWidth/2,app.nobHeight];
            app.histLeftRect.InteractionsAllowed = "translate";
            addlistener(app.histLeftRect,'ROIMoved',@app.leftRectMv);
            addlistener(app.histLeftRect,'MovingROI',@app.leftRectMv);
            app.histLeftRect.MarkerSize = 1;
            app.histLeftRect.FaceAlpha = 0.5;

            app.histRightRect = images.roi.Rectangle(app.histogramPlot);
            app.histRightRect.Deletable = 0;
            app.histRightRect.DrawingArea = [app.minVal-app.nobWidth/2,app.histMax / 2 - app.nobHeight / 2,rangeVal*1.5+app.nobWidth/2,app.nobHeight];
            app.histRightRect.InteractionsAllowed = "translate";
            addlistener(app.histRightRect,'ROIMoved',@app.rightRectMv);
            addlistener(app.histRightRect,'MovingROI',@app.rightRectMv);
            app.histRightRect.MarkerSize = 1;
            app.histRightRect.FaceAlpha = 0.5;

            app.MinSpinner.Limits = [app.minVal,app.maxVal];
            app.MaxSpinner.Limits = [app.minVal,app.maxVal];

            app.updateRects();
            app.updateFields();
        end

        function leftRectMv(app,src,evt)
            if (app.histRightRect.Position(1) < app.histLeftRect.Position(1))
                tempPos = app.histLeftRect.Position;
                tempPos(1) = app.histRightRect.Position(1) - app.nobWidth - 0.1;
                app.histLeftRect.Position = tempPos;
            end
            app.lowVal = app.histLeftRect.Position(1) + app.nobWidth/2;
            app.updateRects();
            app.updateFields();         
            app.updateImage();

        end

        function rightRectMv(app,src,evt)
            if (app.histRightRect.Position(1) < app.histLeftRect.Position(1))
                tempPos = app.histRightRect.Position;
                tempPos(1) = app.histLeftRect.Position(1) + app.nobWidth+0.1;
                app.histRightRect.Position = tempPos;
            end
            app.highVal = app.histRightRect.Position(1) + app.nobWidth/2;
            app.updateRects();
            app.updateFields();
            app.updateImage();
        end

        function centRectMv(app,src,evt)
            app.lowVal = app.histCentRect.Position(1);
            app.highVal = app.histCentRect.Position(1) + app.histCentRect.Position(3);
            app.updateRects();
            app.updateFields();
            app.updateImage();
        end

        function updateRects(app)
            app.histCentRect.Position = [app.lowVal,0,(app.highVal-app.lowVal),app.histMax];
            app.histLeftRect.Position = [app.lowVal-app.nobWidth/2,(app.histMax - app.nobHeight)/2,app.nobWidth,app.nobHeight];
            app.histRightRect.Position = [app.highVal-app.nobWidth/2,(app.histMax - app.nobHeight) / 2,app.nobWidth,app.nobHeight];
        end

        function updateFields(app)
            app.MinSpinner.Value = app.lowVal;
            app.MaxSpinner.Value = app.highVal;
            app.CntrSpinner.Value = (app.highVal + app.lowVal)/2;
            app.RngSpinner.Value = app.highVal - app.lowVal;
        end

        function updateImage(app)
            app.callingApp.chngImgBnds([app.lowVal,app.highVal]);
        end

        function minimize(app)
            app.UIFigure.WindowState = "minimized";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, image, window)
            app.UIFigure.Visible = 'off';
            app.callingApp = callingApp;
            app.UIFigure.WindowState = 'normal';
            disableDefaultInteractivity(app.histogramPlot);
            app.reinit(image,window);
            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: MaxSpinner, MinSpinner
        function chngHiLo(app, event)
            if(app.MinSpinner.Value > app.MaxSpinner.Value)
                if(event.Source == app.MinSpinner)
                    app.MinSpinner.Value = event.PreviousValue;
                else
                    app.MaxSpinner.Value = event.PreviousValue;
                end
            end

            app.lowVal = app.MinSpinner.Value;
            app.highVal = app.MaxSpinner.Value;
            app.updateRects();
            app.updateFields();            
            app.updateImage();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeBC();
            delete(app);
        end

        % Value changed function: CntrSpinner, RngSpinner
        function chngCntrRng(app, event)
            cntrVal = app.CntrSpinner.Value;
            rngVal = app.RngSpinner.Value;
            if (((cntrVal + rngVal / 2) > 100) || ((cntrVal - rngVal / 2) < 0))
                if (event.Source == app.RngSpinner)
                    app.RngSpinner.Value = event.PreviousValue;
                else
                    app.CntrSpinner.Value = event.PreviousValue;
                end
            end
            
            app.lowVal = app.CntrSpinner.Value - app.RngSpinner.Value / 2;
            app.highVal = app.CntrSpinner.Value + app.RngSpinner.Value / 2;
            app.updateRects();
            app.updateFields();
            app.updateImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Window/Level';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+power_modulation', 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.histogramPlot = uiaxes(app.GridLayout);
            title(app.histogramPlot, 'histogram')
            xlabel(app.histogramPlot, 'pixel value')
            ylabel(app.histogramPlot, 'log(frequency)')
            zlabel(app.histogramPlot, 'Z')
            app.histogramPlot.XLim = [0 100];
            app.histogramPlot.YLim = [0 1];
            app.MinimumSpinnerLabel = uilabel(app.GridLayout);
            app.MinimumSpinnerLabel.Text = 'Minimum';
            app.MinSpinner = uispinner(app.GridLayout);
            app.MinSpinner.Limits = [0 100];
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @chngHiLo, true);
            app.MaximumSpinnerLabel = uilabel(app.GridLayout);
            app.MaximumSpinnerLabel.Text = 'Maximum';
            app.MaxSpinner = uispinner(app.GridLayout);
            app.MaxSpinner.Limits = [0 100];
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @chngHiLo, true);
            app.CenterSpinnerLabel = uilabel(app.GridLayout);
            app.CenterSpinnerLabel.Text = 'Center';
            app.CntrSpinner = uispinner(app.GridLayout);
            app.CntrSpinner.ValueChangedFcn = createCallbackFcn(app, @chngCntrRng, true);
            app.RangeSpinnerLabel = uilabel(app.GridLayout);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RngSpinner = uispinner(app.GridLayout);
            app.RngSpinner.ValueChangedFcn = createCallbackFcn(app, @chngCntrRng, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BC_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end