classdef bidirectional_shift_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        AutoButton            matlab.ui.control.Button
        VerticalShiftSpinner  matlab.ui.control.Spinner
        VerticalShiftLabel    matlab.ui.control.Label
    end

    %%%%%%%%%%%%
    % Simple helper window to set the shift between even and off frames
    % when processing slow axis bidirectional data.
    %%%%%%%%%%%%

    properties (Access = public)
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, shift)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.VerticalShiftSpinner.Value = shift;
            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: VerticalShiftSpinner
        function chngShift(app, event)
            app.callingApp.chngYShift(app.VerticalShiftSpinner.Value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeShift();
            delete(app);
        end

        % Button pushed function: AutoButton
        function autoYShift(app, event)
            % tell main app to auto-detect shift between frames and update
            % ui
            app.UIFigure.Pointer = 'watch';
            drawnow;
            yShift = app.callingApp.autoYShift();
            app.VerticalShiftSpinner.Value = yShift;
            app.UIFigure.Pointer = 'arrow';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Bidirectional Shift';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+power_modulation', 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.VerticalShiftLabel = uilabel(app.GridLayout);
            app.VerticalShiftLabel.Text = 'Vertical Shift:';
            app.VerticalShiftSpinner = uispinner(app.GridLayout);
            app.VerticalShiftSpinner.ValueChangedFcn = createCallbackFcn(app, @chngShift, true);
            app.AutoButton = uibutton(app.GridLayout, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @autoYShift, true);
            app.AutoButton.Text = 'Auto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bidirectional_shift_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end