classdef channel_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TruncateVideoCheckBox      matlab.ui.control.CheckBox
        ZBidirectionalCheckBox     matlab.ui.control.CheckBox
        SlowZCheckBox              matlab.ui.control.CheckBox
        MainChannelEditField       matlab.ui.control.NumericEditField
        MainChannelEditFieldLabel  matlab.ui.control.Label
        SlicesEditField            matlab.ui.control.NumericEditField
        SlicesEditFieldLabel       matlab.ui.control.Label
        VolumeCheckBox             matlab.ui.control.CheckBox
        SeperateChannelCheckBox    matlab.ui.control.CheckBox
        YBidirectionalCheckBox     matlab.ui.control.CheckBox
        LimitFramesCheckBox        matlab.ui.control.CheckBox
        FrameLimitEditField        matlab.ui.control.NumericEditField
        FrameLimitEditFieldLabel   matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        OkayButton                 matlab.ui.control.Button
        ChannelsEditField          matlab.ui.control.NumericEditField
        ChannelsLabel              matlab.ui.control.Label
    end

    properties (Access = private)
        callingApp
    end
    
    methods (Access = private)
        
        function enableBoxes(app)
            app.SlowZCheckBox.Enable = app.VolumeCheckBox.Value;
            app.YBidirectionalCheckBox.Enable = ~app.SlowZCheckBox.Value;
            app.ZBidirectionalCheckBox.Enable = (~app.YBidirectionalCheckBox.Value) && (~app.SlowZCheckBox.Value) && app.VolumeCheckBox.Value;
            app.TruncateVideoCheckBox.Enable = app.LimitFramesCheckBox.Value && (~app.SlowZCheckBox.Value);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.chnlClbk([],0);
            delete(app);
        end

        % Value changed function: ChannelsEditField
        function chngNumChannels(app, event)
            value = app.ChannelsEditField.Value;
            app.MainChannelEditField.Limits = [1,value];
            if (value == 1)
                app.SeperateChannelCheckBox.Value = false;
                app.SeperateChannelCheckBox.Enable = false;
                app.MainChannelEditField.Value = 1;
                app.MainChannelEditField.Enable = false;
            else
                app.SeperateChannelCheckBox.Enable = true;
                if (~app.SeperateChannelCheckBox.Value)
                    app.MainChannelEditField.Enable = true;
                end
            end
        end

        % Button pushed function: OkayButton
        function okayClbk(app, event)
            params.channels = app.ChannelsEditField.Value;
            params.splitChannels = app.SeperateChannelCheckBox.Value;
            params.volume = app.VolumeCheckBox.Value * (app.SlicesEditField.Value > 1);
            params.mainChannel = app.MainChannelEditField.Value;
            if (params.volume)
                params.slices = app.SlicesEditField.Value;
            else
                params.slices = 1;
            end

            params.yBidirectional = app.YBidirectionalCheckBox.Value && app.YBidirectionalCheckBox.Enable;
            params.zBidirectional = (app.ZBidirectionalCheckBox.Value && app.ZBidirectionalCheckBox.Enable) || (params.volume && params.yBidirectional);
            params.bidirectional = params.yBidirectional || params.zBidirectional;
            params.bidiShift = 0;
            
            limit = 0;
            if (app.LimitFramesCheckBox.Value)
                limit = app.FrameLimitEditField.Value;
            end

            truncateVid = app.TruncateVideoCheckBox.Enable && app.TruncateVideoCheckBox.Value;

            params.slowZ = (app.SlowZCheckBox.Value && app.SlowZCheckBox.Enable);

            app.callingApp.chnlClbk(params,limit,truncateVid);
            delete(app);
        end

        % Button pushed function: CancelButton
        function cnclClbk(app, event)
            app.callingApp.chnlClbk([],0);
            delete(app);
        end

        % Value changed function: LimitFramesCheckBox
        function limitFramesClbk(app, event)
            app.FrameLimitEditField.Enable = app.LimitFramesCheckBox.Value;
            app.enableBoxes();
        end

        % Value changed function: VolumeCheckBox
        function setVolume(app, event)
            app.SlicesEditField.Enable = app.VolumeCheckBox.Value;
            if (app.VolumeCheckBox.Value)
                if (app.YBidirectionalCheckBox.Value)
                    app.ZBidirectionalCheckBox.Value = true;
                end
            else
                app.SlowZCheckBox.Value = false;
                app.ZBidirectionalCheckBox.Value = false;
            end
            app.enableBoxes();
        end

        % Value changed function: SeperateChannelCheckBox
        function splitChannels(app, event)
            app.MainChannelEditField.Enable = ~app.SeperateChannelCheckBox.Value && (app.ChannelsEditField.Value > 1);
        end

        % Value changed function: SlowZCheckBox
        function setSlowZ(app, event)
            if (app.SlowZCheckBox.Value)
                app.YBidirectionalCheckBox.Value = false;
                app.ZBidirectionalCheckBox.Value = false;
            end
            app.enableBoxes();
        end

        % Value changed function: YBidirectionalCheckBox
        function setYBidi(app, event)
            if(app.YBidirectionalCheckBox.Value)
                app.ZBidirectionalCheckBox.Value = true;
            end
            app.enableBoxes();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Stack Settings';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+power_modulation', 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ChannelsLabel = uilabel(app.GridLayout);
            app.ChannelsLabel.Text = 'Channels:';
            app.ChannelsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChannelsEditField.Limits = [1 Inf];
            app.ChannelsEditField.RoundFractionalValues = 'on';
            app.ChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @chngNumChannels, true);
            app.ChannelsEditField.Value = 1;
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okayClbk, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cnclClbk, true);
            app.CancelButton.Text = 'Cancel';
            app.FrameLimitEditFieldLabel = uilabel(app.GridLayout);
            app.FrameLimitEditFieldLabel.Text = 'Frame Limit:';
            app.FrameLimitEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameLimitEditField.Limits = [1 Inf];
            app.FrameLimitEditField.Enable = 'off';
            app.FrameLimitEditField.Value = 500;
            app.LimitFramesCheckBox = uicheckbox(app.GridLayout);
            app.LimitFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @limitFramesClbk, true);
            app.LimitFramesCheckBox.Text = 'Limit Frames';
            app.YBidirectionalCheckBox = uicheckbox(app.GridLayout);
            app.YBidirectionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @setYBidi, true);
            app.YBidirectionalCheckBox.Text = 'Y Bidirectional';
            app.SeperateChannelCheckBox = uicheckbox(app.GridLayout);
            app.SeperateChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @splitChannels, true);
            app.SeperateChannelCheckBox.Enable = 'off';
            app.SeperateChannelCheckBox.Text = 'Split Channels';
            app.SeperateChannelCheckBox.Value = true;
            app.VolumeCheckBox = uicheckbox(app.GridLayout);
            app.VolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @setVolume, true);
            app.VolumeCheckBox.Text = 'Volume';
            app.SlicesEditFieldLabel = uilabel(app.GridLayout);
            app.SlicesEditFieldLabel.Text = 'Slices';
            app.SlicesEditField = uieditfield(app.GridLayout, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.RoundFractionalValues = 'on';
            app.SlicesEditField.Enable = 'off';
            app.SlicesEditField.Value = 1;
            app.MainChannelEditFieldLabel = uilabel(app.GridLayout);
            app.MainChannelEditFieldLabel.Text = 'Main Channel:';
            app.MainChannelEditField = uieditfield(app.GridLayout, 'numeric');
            app.MainChannelEditField.Limits = [1 Inf];
            app.MainChannelEditField.Enable = 'off';
            app.MainChannelEditField.Value = 1;
            app.SlowZCheckBox = uicheckbox(app.GridLayout);
            app.SlowZCheckBox.ValueChangedFcn = createCallbackFcn(app, @setSlowZ, true);
            app.SlowZCheckBox.Enable = 'off';
            app.SlowZCheckBox.Text = 'Slow Z';
            app.ZBidirectionalCheckBox = uicheckbox(app.GridLayout);
            app.ZBidirectionalCheckBox.Enable = 'off';
            app.ZBidirectionalCheckBox.Text = 'Z Bidirectional';
            app.TruncateVideoCheckBox = uicheckbox(app.GridLayout);
            app.TruncateVideoCheckBox.Enable = 'off';
            app.TruncateVideoCheckBox.Text = 'Truncate Video';
            app.TruncateVideoCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end