classdef crosstalk_demixer_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ResetButton               matlab.ui.control.Button
        AutoButton                matlab.ui.control.Button
        Axis2Panel                matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        AngleEditField2           matlab.ui.control.NumericEditField
        AngledegEditField_2Label  matlab.ui.control.Label
        ChannelDropDown2          matlab.ui.control.DropDown
        ChannelDropDown_2Label    matlab.ui.control.Label
        Axis1Panel                matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        AngleEditField1           matlab.ui.control.NumericEditField
        AngledegEditFieldLabel    matlab.ui.control.Label
        ChannelDropDown1          matlab.ui.control.DropDown
        ChannelLabel              matlab.ui.control.Label
        pixelPlotAxes             matlab.ui.control.UIAxes
    end

    % crosstalk matrix calculation method from:
    % "An estimate of the crosstalk matrix in four-dye fluorescence-based
    % DNA sequencing", Lei Li, Tarence Speed. Electrophoresis 1999
    
    properties (Access = private)
        callingApp
        
        pix_vals
        channels
        W
        threshold = 0.1;

        ax1
        ax2
        angle1
        angle2
        nob1
        nob2
        line1
        line2
        bound1
        bound2
        x1
        y1
        x2
        y2
    end
    
    methods (Access = private)
        
        function updateAngleBars(app)
            app.x1 = app.bound1*app.bound2/sqrt(app.bound2^2+app.bound1^2*tan(app.angle1)^2);
            app.y1 = app.bound2*sqrt(1-app.x1^2/app.bound1^2);
            app.x2 = app.bound1*app.bound2/sqrt(app.bound2^2+app.bound1^2*tan(pi/2-app.angle2)^2);
            app.y2 = app.bound2*sqrt(1-app.x2^2/app.bound1^2);

            app.line1.Position = [0, 0; 4*app.x1, 4*app.y1];
            app.line2.Position = [0, 0; 4*app.x2, 4*app.y2];
        end

        function updateNobs(app)
            app.nob1.Position = [app.x1,app.y1];
            app.nob2.Position = [app.x2,app.y2];
        end

        function updateFields(app)
            app.AngleEditField1.Value = app.angle1*180/pi;
            app.AngleEditField2.Value = app.angle2*180/pi;
        end

        function updateImage(app)
            app.W(app.ax2,app.ax1) = tan(app.angle1);
            app.W(app.ax1,app.ax2) = tan(app.angle2);
            app.callingApp.setDmx(app.W);
        end

        function updateImageFast(app)
            app.W(app.ax2,app.ax1) = tan(app.angle1);
            app.W(app.ax1,app.ax2) = tan(app.angle2);
            app.callingApp.setDmxFast(app.W);
        end

        function nob1Mv(app,~,~)
            coord = app.nob1.Position;
            app.angle1 = atan(coord(2)/coord(1));
            if (app.angle1 < 0)
                app.angle1 = 0;
                app.updateAngleBars();
                app.updateNobs();
            elseif (app.angle1 > (pi/2-app.angle2))
                app.angle1 = pi/2-app.angle2;
                app.updateAngleBars();
                app.updateNobs();
            else
                app.updateAngleBars();
            end
            app.updateImageFast()
        end

        function nob2Mv(app,~,~)
            coord = app.nob2.Position;
            app.angle2 = atan(coord(1)/coord(2));
            if (app.angle2 < 0)
                app.angle2 = 0;
                app.updateAngleBars();
                app.updateNobs();
            elseif (app.angle2 > (pi/2-app.angle1))
                app.angle2 = pi/2-app.angle1;
                app.updateAngleBars();
                app.updateNobs();
            else
                app.updateAngleBars();
            end
            app.updateImageFast();
        end

        function nob1Chng(app,~,~)
            coord = app.nob1.Position;
            app.angle1 = atan(coord(2)/coord(1));
            if (app.angle1 < 0)
                app.angle1 = 0;
            elseif (app.angle1 > (pi/2-app.angle2))
                app.angle1 = pi/2-app.angle2;
            end

            app.updateAngleBars();
            app.updateNobs();
            app.updateImage();
        end

        function nob2Chng(app,~,~)
            coord = app.nob2.Position;
            app.angle2 = atan(coord(1)/coord(2));
            if (app.angle2 < 0)
                app.angle2 = 0;
            elseif (app.angle2 > (pi/2-app.angle1))
                app.angle2 = pi/2-app.angle1;
            end

            app.updateAngleBars();
            app.updateNobs();
            app.updateImage();
        end

        function buildGraph(app)
            thresh1 = app.threshold*max(app.pix_vals(:,app.ax1));
            thresh2 = app.threshold*max(app.pix_vals(:,app.ax2));
            valid = (app.pix_vals(:,app.ax1)>thresh1) & (app.pix_vals(:,app.ax2)>thresh2);
            scatter(app.pixelPlotAxes,app.pix_vals(valid,app.ax1),app.pix_vals(valid,app.ax2),2,'k','filled');

            app.line1 = images.roi.Line(app.pixelPlotAxes);
            app.line1.InteractionsAllowed = "none";
            app.line1.MarkerSize = 0.1;
            app.line1.Color = 'r';

            app.line2 = images.roi.Line(app.pixelPlotAxes);
            app.line2.InteractionsAllowed = "none";
            app.line2.MarkerSize = 0.1;
            app.line2.Color = 'r';

            app.nob1 = images.roi.Point(app.pixelPlotAxes);
            app.nob2 = images.roi.Point(app.pixelPlotAxes);
            app.bound1 = max(app.pix_vals(:,app.ax1))/2;
            app.bound2 = max(app.pix_vals(:,app.ax2))/2;
            axis(app.pixelPlotAxes,[0, 2*app.bound1, 0, 2*app.bound2]);
            app.nob1.DrawingArea = [0, 0, 2*app.bound1, 2*app.bound2];
            app.nob2.DrawingArea = [0, 0, 2*app.bound1, 2*app.bound2];
            app.nob1.Color = 'r';
            app.nob2.Color = 'r';
            app.angle1 = atan(app.W(app.ax2,app.ax1));
            app.angle2 = atan(app.W(app.ax1,app.ax2));
            addlistener(app.nob1,'ROIMoved',@app.nob1Chng);
            addlistener(app.nob1,'MovingROI',@app.nob1Mv);
            addlistener(app.nob2,'ROIMoved',@app.nob2Chng);
            addlistener(app.nob2,'MovingROI',@app.nob2Mv);

            app.updateAngleBars();
            app.updateNobs();
            app.updateFields();
        end
    end

    methods (Access = public)
        function reinit(app,ims)
            for ch=1:app.channels
                app.pix_vals(:,ch) = reshape(ims(:,:,ch),[],1);
            end
            app.buildGraph();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, ims, W)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end
            app.channels = size(ims,3);
            app.pix_vals = zeros(numel(ims(:,:,1)),app.channels);
            for ch=1:app.channels
                app.pix_vals(:,ch) = reshape(ims(:,:,ch),[],1);
            end
            app.W = W;

            channelopts = cell(1,app.channels);
            for ch=1:app.channels
                channelopts{ch} = ['Channel ',num2str(ch)];
            end
            app.ax1 = 1;
            app.ax2 = 2;
            app.ChannelDropDown1.Items = channelopts;
            app.ChannelDropDown1.ItemsData = 1:app.channels;
            app.ChannelDropDown1.Value = app.ax1;
            app.ChannelDropDown2.Items = channelopts;
            app.ChannelDropDown2.ItemsData = 1:app.channels;
            app.ChannelDropDown2.Value = app.ax2;

            app.buildGraph();

            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: AutoButton
        function AutoCalc(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            pairs = nchoosek(1:app.channels,2);
            nPairs = size(pairs,1);
            app.W = eye(app.channels);
            for ii=1:nPairs
                thresh1 = app.threshold * max(app.pix_vals(:,pairs(ii,1)));
                thresh2 = app.threshold * max(app.pix_vals(:,pairs(ii,2)));
                valid = (app.pix_vals(:,pairs(ii,1))>thresh1) & (app.pix_vals(:,pairs(ii,2))>thresh2);
                pix_angles = atan(app.pix_vals(valid,pairs(ii,2))./app.pix_vals(valid,pairs(ii,1)));
                ang1 = min(pix_angles);
                ang2 = pi/2 - max(pix_angles);
                app.W(pairs(ii,2),pairs(ii,1)) = tan(ang1);
                app.W(pairs(ii,1),pairs(ii,2)) = tan(ang2);
            end

            app.angle1 = atan(app.W(app.ax2,app.ax1));
            app.angle2 = atan(app.W(app.ax1,app.ax2));
            app.updateFields();
            app.updateAngleBars();
            app.updateNobs();
            app.updateImage();
            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: ResetButton
        function Reset(app, event)
            app.W = eye(app.channels);
            app.angle1 = 0;
            app.angle2 = 0;
            app.updateFields();
            app.updateAngleBars();
            app.updateNobs();
            app.updateImage();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeDmx();
            delete(app);
        end

        % Value changed function: AngleEditField1
        function chngAngle1(app, event)
            app.angle1 = app.AngleEditField1.Value*pi/180;
            if (app.angle1 > (pi/2-app.angle2))
                app.angle1 = app.angle2;
                app.AngleEditField1.Value = app.angle1;
            end

            app.updateAngleBars();
            app.updateNobs();
            app.updateImage();
        end

        % Value changed function: AngleEditField2
        function chngAngle2(app, event)
            app.angle2 = app.AngleEditField2.Value*pi/180;
            if (app.angle2 > (pi/2-app.angle1))
                app.angle2 = app.angle1;
                app.AngleEditField2.Value = app.angle2;
            end

            app.updateAngleBars();
            app.updateNobs();
            app.updateImage()
        end

        % Value changed function: ChannelDropDown1
        function chngAx1(app, event)
            value = app.ChannelDropDown1.Value;
            if (app.ax2 == value)
                app.ax2 = app.ax1;
                app.ChannelDropDown2.Value = app.ax2;
            end
            app.ax1 = value;
            app.buildGraph();
        end

        % Value changed function: ChannelDropDown2
        function chngAx2(app, event)
            value = app.ChannelDropDown2.Value;
            if (app.ax1 == value)
                app.ax1 = app.ax2;
                app.ChannelDropDown2.Value = app.ax1;
            end
            app.ax2 = value;
            app.buildGraph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Crosstalk Demixing';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+power_modulation', 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.pixelPlotAxes = uiaxes(app.GridLayout);
            title(app.pixelPlotAxes, 'Title')
            xlabel(app.pixelPlotAxes, 'X')
            ylabel(app.pixelPlotAxes, 'Y')
            zlabel(app.pixelPlotAxes, 'Z')
            app.Axis1Panel = uipanel(app.GridLayout);
            app.Axis1Panel.Title = 'Axis 1';
            app.GridLayout2 = uigridlayout(app.Axis1Panel);
            app.ChannelLabel = uilabel(app.GridLayout2);
            app.ChannelLabel.Text = 'Channel:';
            app.ChannelDropDown1 = uidropdown(app.GridLayout2);
            app.ChannelDropDown1.Items = {};
            app.ChannelDropDown1.ValueChangedFcn = createCallbackFcn(app, @chngAx1, true);
            app.ChannelDropDown1.Value = {};
            app.AngledegEditFieldLabel = uilabel(app.GridLayout2);
            app.AngledegEditFieldLabel.Text = 'Angle (deg):';
            app.AngleEditField1 = uieditfield(app.GridLayout2, 'numeric');
            app.AngleEditField1.Limits = [0 90];
            app.AngleEditField1.ValueChangedFcn = createCallbackFcn(app, @chngAngle1, true);
            app.Axis2Panel = uipanel(app.GridLayout);
            app.Axis2Panel.Title = 'Axis 2';
            app.GridLayout3 = uigridlayout(app.Axis2Panel);
            app.ChannelDropDown_2Label = uilabel(app.GridLayout3);
            app.ChannelDropDown_2Label.Text = 'Channel:';
            app.ChannelDropDown2 = uidropdown(app.GridLayout3);
            app.ChannelDropDown2.Items = {};
            app.ChannelDropDown2.ValueChangedFcn = createCallbackFcn(app, @chngAx2, true);
            app.ChannelDropDown2.Value = {};
            app.AngledegEditField_2Label = uilabel(app.GridLayout3);
            app.AngledegEditField_2Label.Text = 'Angle (deg):';
            app.AngleEditField2 = uieditfield(app.GridLayout3, 'numeric');
            app.AngleEditField2.Limits = [0 90];
            app.AngleEditField2.ValueChangedFcn = createCallbackFcn(app, @chngAngle2, true);
            app.AutoButton = uibutton(app.GridLayout, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoCalc, true);
            app.AutoButton.Text = 'Auto';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crosstalk_demixer_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end