classdef mask_output_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        FOVShapesCheckBox               matlab.ui.control.CheckBox
        BidirectionalShiftSpinner       matlab.ui.control.Spinner
        BidirectionalShiftSpinnerLabel  matlab.ui.control.Label
        YBidirectionalCheckBox          matlab.ui.control.CheckBox
        ZBidirectionalCheckBox          matlab.ui.control.CheckBox
        AnalogueModulationCheckBox      matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        OkayButton                      matlab.ui.control.Button
        ReverseSliceOrderCheckBox       matlab.ui.control.CheckBox
        IncludeCombinedCheckBox         matlab.ui.control.CheckBox
        YDisplacementSpinner            matlab.ui.control.Spinner
        YDisplacementSpinnerLabel       matlab.ui.control.Label
        XDisplacementSpinner            matlab.ui.control.Spinner
        XDisplacementSpinnerLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingApp
        params
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, params)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.params = params;
            app.IncludeCombinedCheckBox.Enable = params.splitChannels && (params.channels > 1);
            app.ReverseSliceOrderCheckBox.Enable = params.volume;
            app.ZBidirectionalCheckBox.Enable = params.volume && (~app.params.yBidirectional);
            app.ZBidirectionalCheckBox.Value = params.zBidirectional;
            app.YBidirectionalCheckBox.Value = params.yBidirectional;
            app.BidirectionalShiftSpinner.Enable = params.yBidirectional;
            app.BidirectionalShiftSpinner.Value = params.bidiShift;
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            app.params.combine = app.IncludeCombinedCheckBox.Value;
            app.params.reverse = app.ReverseSliceOrderCheckBox.Value;
            app.params.xDisp = app.XDisplacementSpinner.Value;
            app.params.yDisp = app.YDisplacementSpinner.Value;
            app.params.maskPower = app.AnalogueModulationCheckBox.Value;
            app.params.yBidirectional = app.YBidirectionalCheckBox.Value;
            app.params.zBidirectional = app.ZBidirectionalCheckBox.Value;
            app.params.bidirectional = app.params.yBidirectional || app.params.zBidirectional;
            app.params.bidiShift = app.BidirectionalShiftSpinner.Value;
            app.params.fovShapes = app.FOVShapesCheckBox.Value && app.FOVShapesCheckBox.Enable;
            app.callingApp.outputClbk(app.params);
        end

        % Callback function: CancelButton, UIFigure
        function cancel(app, event)
            app.callingApp.closeOutput();
        end

        % Value changed function: YBidirectionalCheckBox
        function setYBidi(app, event)
            app.BidirectionalShiftSpinner.Enable = app.YBidirectionalCheckBox.Value;
            app.ZBidirectionalCheckBox.Enable = (~app.YBidirectionalCheckBox.Value) && app.params.volume;
            if (app.YBidirectionalCheckBox.Value && app.params.volume)
                app.ZBidirectionalCheckBox.Value = true;
            end
        end

        % Value changed function: AnalogueModulationCheckBox
        function chngPowMod(app, event)
            app.FOVShapesCheckBox.Enable = app.AnalogueModulationCheckBox.Value && app.params.fovShapes;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'AES Mask Properties';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+power_modulation', 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @cancel, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.XDisplacementSpinnerLabel = uilabel(app.GridLayout);
            app.XDisplacementSpinnerLabel.Text = 'X Displacement:';
            app.XDisplacementSpinner = uispinner(app.GridLayout);
            app.XDisplacementSpinner.RoundFractionalValues = 'on';
            app.YDisplacementSpinnerLabel = uilabel(app.GridLayout);
            app.YDisplacementSpinnerLabel.Text = 'Y Displacement:';
            app.YDisplacementSpinner = uispinner(app.GridLayout);
            app.IncludeCombinedCheckBox = uicheckbox(app.GridLayout);
            app.IncludeCombinedCheckBox.Text = 'Include Combined';
            app.ReverseSliceOrderCheckBox = uicheckbox(app.GridLayout);
            app.ReverseSliceOrderCheckBox.Text = 'Reverse Slice Order';
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.AnalogueModulationCheckBox = uicheckbox(app.GridLayout);
            app.AnalogueModulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @chngPowMod, true);
            app.AnalogueModulationCheckBox.Text = 'Analogue Modulation';
            app.ZBidirectionalCheckBox = uicheckbox(app.GridLayout);
            app.ZBidirectionalCheckBox.Text = 'Z Bidirectional';
            app.YBidirectionalCheckBox = uicheckbox(app.GridLayout);
            app.YBidirectionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @setYBidi, true);
            app.YBidirectionalCheckBox.Text = 'Y Bidirectional';
            app.BidirectionalShiftSpinnerLabel = uilabel(app.GridLayout);
            app.BidirectionalShiftSpinnerLabel.Text = 'Bidirectional Shift:';
            app.BidirectionalShiftSpinner = uispinner(app.GridLayout);
            app.BidirectionalShiftSpinner.RoundFractionalValues = 'on';
            app.FOVShapesCheckBox = uicheckbox(app.GridLayout);
            app.FOVShapesCheckBox.Enable = 'off';
            app.FOVShapesCheckBox.Text = 'FOV Gradiant Shapes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mask_output_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end