classdef power_modulation_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SliceSignalTab                  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        MinPxPercentileEditField        matlab.ui.control.NumericEditField
        MinPxPercentileEditFieldLabel   matlab.ui.control.Label
        MaxPxPercentileEditField        matlab.ui.control.NumericEditField
        MaxPxPercentileEditFieldLabel   matlab.ui.control.Label
        PAxes                           matlab.ui.control.UIAxes
        InputPowerTab                   matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        PrePowLabel                     matlab.ui.control.Label
        PrePowClearButton               matlab.ui.control.Button
        PrePowLoadButton                matlab.ui.control.Button
        PrePAxes                        matlab.ui.control.UIAxes
        TransmissionTab                 matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        AutoButton                      matlab.ui.control.Button
        OrientationDropDown             matlab.ui.control.DropDown
        OrientationLabel                matlab.ui.control.Label
        SLEditField                     matlab.ui.control.NumericEditField
        NAEditFieldLabel                matlab.ui.control.Label
        CurveTypeDropDown               matlab.ui.control.DropDown
        CurveTypeDropDownLabel          matlab.ui.control.Label
        TAxes                           matlab.ui.control.UIAxes
        AdjustedSliceSignalTab          matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        APAxes                          matlab.ui.control.UIAxes
        VoltageTab                      matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        VCalLabel                       matlab.ui.control.Label
        VCalClearButton                 matlab.ui.control.Button
        VCalLoadButton                  matlab.ui.control.Button
        ExportVoltageButton             matlab.ui.control.Button
        vAxes                           matlab.ui.control.UIAxes
        EffectiveOccupancyTab           matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        OccAxes                         matlab.ui.control.UIAxes
        MPOrderEditField                matlab.ui.control.NumericEditField
        MultiphotonOrderEditFieldLabel  matlab.ui.control.Label
        ChannelSpinner                  matlab.ui.control.Spinner
        ChannelSpinnerLabel             matlab.ui.control.Label
        SliceSepEditField               matlab.ui.control.NumericEditField
        SliceSeparationLabel            matlab.ui.control.Label
    end

    % Note: Calibration file is 2 columns. First column is Voltage, Second
    % column is the associated power transmission
    
    properties (Access = private)
        callingApp
        powerMod

        channel
        channels
        sepChannels

        tPlotHandle
        pPlotHandle
        ppPlotHandle
        apPlotHandle
        occPlotHandle
        vPlotHandle
        
        occ
        
        vCal
        vCalNames
    end
    
    methods (Access = public)
        
        function updatePlots(app)
            app.tPlotHandle.YData = app.powerMod.getTrans(app.channel);
            app.pPlotHandle.YData = app.powerMod.getPower(app.channel);
            tempAP = ((app.powerMod.getTrans(app.channel)./app.powerMod.getPrePower(app.channel)).^app.powerMod.mpOrder).*app.powerMod.getPower(app.channel);
            tempAP = tempAP / max(tempAP);
            app.apPlotHandle.YData = tempAP;
            tempOcc = app.powerMod.getTrans(app.channel).*app.occ(app.channel,:);
            scaleFactor = max((app.occ(app.channel,:)>0).*app.powerMod.getTrans(app.channel));
            if (scaleFactor>0)
                tempOcc = tempOcc/scaleFactor;
            end
            app.occPlotHandle.YData = 100*tempOcc;
            set(app.OccAxes.Title,'String',strcat('Scaled Occupancy (',num2str(round(mean(tempOcc)*1000)/10),'%)'));

            app.vPlotHandle.XData = app.powerMod.getV(app.channel);
            app.vPlotHandle.YData = app.powerMod.getTrans(app.channel);
        end

        function updateOcc(app,occ)
            app.occ = occ';
            tempOcc = app.powerMod.getTrans(app.channel).*app.occ(app.channel,:);
            scaleFactor = max((app.occ(app.channel,:)>0).*app.powerMod.getTrans(app.channel));
            if (scaleFactor>0)
                tempOcc = tempOcc/scaleFactor;
            end
            app.occPlotHandle.YData = 100*tempOcc;
            set(app.OccAxes.Title,'String',strcat('Scaled Occupancy (',num2str(round(mean(tempOcc)*1000)/10),'%)'));
        end

        function updateVMap(app)
            cla(app.vAxes);
            map = app.powerMod.getCal(app.channel);
            hold(app.vAxes,'on');
            if (isempty(map))
                plot(app.vAxes,[0,1],[0,1]);
                set(app.vAxes,'xlim',[0,1]);
                app.VCalClearButton.Enable = false;
                app.VCalLabel.Text = "None";
            else
                plot(app.vAxes,map(:,1),map(:,2));
                set(app.vAxes,'xlim',[min(map(:,1)),max(map(:,1))]);
                app.VCalClearButton.Enable = true;
                app.VCalLabel.Text = app.powerMod.calNames{app.channel};
            end

            app.vPlotHandle = scatter(app.vAxes,app.powerMod.getV(app.channel),app.powerMod.getTrans(app.channel),'filled');
            hold(app.vAxes,'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, powerMod, occ)
            app.powerMod = powerMod;
            app.occ = occ';
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end
            app.channels = powerMod.channels;
            app.channel = 1;
            app.ChannelSpinner.Enable = powerMod.sepChannels;
            if (powerMod.sepChannels)
                app.ChannelSpinner.Limits = [1,app.channels];
            end
            app.CurveTypeDropDown.ItemsData = 1:3;
            app.OrientationDropDown.ItemsData = [1,-1];
            
            app.MPOrderEditField.Value = powerMod.mpOrder;
            app.SliceSepEditField.Value = powerMod.sliceSep;
            app.OrientationDropDown.Value = powerMod.orientation(app.channel);
            app.MinPxPercentileEditField.Value = powerMod.getLowPx()*100;
            app.MaxPxPercentileEditField.Value = powerMod.getHighPx()*100;

            tempZ = app.powerMod.z;
            slices = length(tempZ);
            app.tPlotHandle = scatter(app.TAxes,tempZ,ones(1,slices),'filled');
            set(app.TAxes,'xlim',[0,(tempZ(end))]);
            set(app.TAxes,'ylim',[0,1]);            

            app.pPlotHandle = scatter(app.PAxes,tempZ,ones(1,slices),'filled');
            set(app.PAxes,'xlim',[0,(tempZ(end))]);
            set(app.PAxes,'ylim',[0,1]);

            app.ppPlotHandle = scatter(app.PrePAxes,tempZ,ones(1,slices),'filled');
            set(app.PrePAxes,'xlim',[0,(tempZ(end))]);
            set(app.PrePAxes,'ylim',[0,1]);

            app.apPlotHandle = scatter(app.APAxes,tempZ,ones(1,slices),'filled');
            set(app.APAxes,'xlim',[0,(tempZ(end))]);
            set(app.APAxes,'ylim',[0,1]);

            app.occPlotHandle = scatter(app.OccAxes,1:slices,ones(1,slices),'filled');
            set(app.OccAxes,'xlim',[1,slices]);
            set(app.OccAxes,'ylim',[0,100]);

            app.chngChannel([]);
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: VCalLoadButton
        function loadVCal(app, event)
            app.UIFigure.Visible = 'off';
            [calFile, calPath] = uigetfile({'*.xlsx;*.csv;*.txt;*.dat','Data Files (*.xlsx,*.csv,*.txt,*.dat'},'Select Calibration Curve');
            app.UIFigure.Visible = 'on';
            drawnow;
            if ((app.callingApp.v(1)>9)||((app.callingApp.v(1)==9)&&(app.callingApp.v(2)>=12)))
                focus(app.callingApp.UIFigure);
                focus(app.UIFigure);
            else
                figure(app.callingApp.UIFigure);
                figure(app.UIFigure);
            end

            if (isequal(calFile,0))
                return;
            end

            try
                tempCurve = readmatrix(fullfile(calPath,calFile));
                if (~(length(size(tempCurve))==2) || (size(tempCurve,2)~=2) || (sum(isnan(tempCurve),'all')~=0))
                    errordlg('Cal curve must be N by 2 matrix.','Formatting Error');
                    return;
                end
                tempCurve = real(tempCurve);
                app.powerMod.setCal(app.channel,tempCurve,calFile);
                app.VCalClearButton.Enable = true;
                app.updateVMap();
            catch
                errordlg('Unable to read file.');
                return;
            end
        end

        % Button pushed function: VCalClearButton
        function clearVCal(app, event)
            app.powerMod.setCal(app.channel,[],[]);
            app.VCalClearButton.Enable = false;
            app.updateVMap();
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            app.channel = app.ChannelSpinner.Value;
            app.CurveTypeDropDown.Value = app.powerMod.curveTypes(app.channel);
            tempName = app.powerMod.calNames{app.channel};
            if (isempty(tempName))
                app.VCalLabel.Text = 'None';
                app.VCalClearButton.Enable = false;
            else
                app.VCalLabel.Text = tempName;
                app.VCalClearButton.Enable = true;
            end

            tempName = app.powerMod.prePowerNames{app.channel};
            if (isempty(tempName))
                app.PrePowLabel.Text = 'None';
                app.PrePowClearButton.Enable = false;
            else
                app.PrePowLabel.Text = tempName;
                app.PrePowClearButton.Enable = true;
            end
            app.ppPlotHandle.YData = app.powerMod.getPrePower(app.channel);

            tempType = app.powerMod.curveTypes(app.channel);
            app.SLEditField.Enable = (tempType == app.powerMod.EXP);
            app.OrientationDropDown.Enable = (tempType == app.powerMod.EXP);
            app.AutoButton.Enable = (tempType == app.powerMod.EXP);
            app.SLEditField.Value = app.powerMod.attenLen(app.channel);
            app.OrientationDropDown.Value = app.powerMod.orientation(app.channel);
            app.CurveTypeDropDown.Value = tempType;

            app.updateVMap();
            app.updatePlots();
        end

        % Value changed function: CurveTypeDropDown
        function chngType(app, event)
            if (isempty(event))
                return;
            end

            tempType = app.CurveTypeDropDown.Value;
            app.powerMod.setType(app.channel,tempType);
            app.SLEditField.Enable = (tempType == app.powerMod.EXP);
            app.OrientationDropDown.Enable = (tempType == app.powerMod.EXP);
            app.AutoButton.Enable = (tempType == app.powerMod.EXP);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: SliceSepEditField
        function chngSliceSep(app, event)
            val = app.SliceSepEditField.Value;
            if (val==0)
                app.SliceSepEditField.Value = event.PreviousValue;
                return;
            end

            app.powerMod.setSliceSep(val);
            set(app.TAxes,'xlim',[0,(app.powerMod.z(end))]);
            app.tPlotHandle.XData = app.powerMod.z;
            set(app.PAxes,'xlim',[0,(app.powerMod.z(end))]);
            app.pPlotHandle.XData = app.powerMod.z;
            set(app.PrePAxes,'xlim',[0,(app.powerMod.z(end))]);
            app.ppPlotHandle.XData = app.powerMod.z;
            set(app.APAxes,'xlim',[0,(app.powerMod.z(end))]);
            app.apPlotHandle.XData = app.powerMod.z;
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: MPOrderEditField
        function chngMpOrder(app, event)
            app.powerMod.setMpOrder(app.MPOrderEditField.Value);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: SLEditField
        function chngSl(app, event)
            val = app.SLEditField.Value;
            if (val==0)
                app.SLEditField.Value = event.PreviousValue;
                return;
            end

            app.powerMod.setSL(app.channel,val);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: OrientationDropDown
        function chngOrientation(app, event)
            app.powerMod.setOrientation(app.channel,app.OrientationDropDown.Value);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: MaxPxPercentileEditField
        function chngMaxPx(app, event)
            value = app.MaxPxPercentileEditField.Value;
            if (value <= app.MinPxPercentileEditField.Value)
                app.MaxPxPercentileEditField.Value = event.PreviousValue;
                return;
            end

            app.powerMod.setHighPx(value/100);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Value changed function: MinPxPercentileEditField
        function chngMinPx(app, event)
            value = app.MinPxPercentileEditField.Value;
            if (value >= app.MaxPxPercentileEditField.Value)
                app.MinPxPercentileEditField.Value = event.PreviousValue;
                return;
            end

            app.powerMod.setLowPx(value/100);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closePowerMod();
            delete(app)
        end

        % Button pushed function: AutoButton
        function fitExp(app, event)
            app.powerMod.fitExp(app.channel);
            app.OrientationDropDown.Value = app.powerMod.orientation(app.channel);
            app.SLEditField.Value = app.powerMod.attenLen(app.channel);
            app.updatePlots();
            app.callingApp.updatePower();
        end

        % Button pushed function: ExportVoltageButton
        function exportV(app, event)
            app.UIFigure.Visible = 'off';
            [outFile,outPath] = uiputfile({'*.csv';'*.txt';'*.dat'},'Slice Voltage Curve','slice_voltage.csv');
            app.UIFigure.Visible = 'on';
            drawnow;
            if ((app.callingApp.v(1)>9)||((app.callingApp.v(1)==9)&&(app.callingApp.v(2)>=12)))
                focus(app.callingApp.UIFigure);
                focus(app.UIFigure);
            else
                figure(app.callingApp.UIFigure);
                figure(app.UIFigure);
            end

            if (isequal(outFile,0))
                return;
            end

            basename = split(outFile,'.');
            if (length(basename)>1)
                extension = basename{end};
            else
                extension = '.csv';                
            end
            basename = basename{1};
            if (app.ChannelSpinner.Enable)
                for ch=1:app.channels
                    writematrix(app.powerMod.getV(ch)',fullfile(outPath,strcat(basename,'_ch',num2str(ch),'.',extension)));
                end
            else
                writematrix(app.powerMod.getV(1)',fullfile(outPath,strcat(basename,'.',extension)));
            end
        end

        % Button pushed function: PrePowLoadButton
        function loadPrePow(app, event)
            app.UIFigure.Visible = 'off';
            [tempFile, tempPath] = uigetfile({'*.xlsx;*.csv;*.txt;*.dat','Data Files (*.xlsx,*.csv,*.txt,*.dat'},'Select Imaging Power Curve');
            app.UIFigure.Visible = 'on';
            drawnow;

            if ((app.callingApp.v(1)>9)||((app.callingApp.v(1)==9)&&(app.callingApp.v(2)>=12)))
                focus(app.callingApp.UIFigure);
                focus(app.UIFigure);
            else
                figure(app.callingApp.UIFigure);
                figure(app.UIFigure);
            end

            if (isequal(tempFile,0))
                return;
            end

            try
                tempCurve = readmatrix(fullfile(tempPath,tempFile));
                success = app.powerMod.setPrePower(app.channel,tempCurve,tempFile);
                if (~success)
                    errordlg('File must be N by 1 column of slice imaging powers.','Formatting Error');
                    return;
                end
                app.updatePlots();
                app.ppPlotHandle.YData = app.powerMod.getPrePower(app.channel);
                app.PrePowLabel.Text = tempFile;
                app.PrePowClearButton.Enable = true;
                app.callingApp.updatePower();
            catch
                errordlg('Unable to read file.');
                return;
            end
        end

        % Button pushed function: PrePowClearButton
        function clearPrePow(app, event)
            app.powerMod.setPrePower(app.channel,[],[]);
            app.ppPlotHandle.YData = app.powerMod.getPrePower(app.channel);
            app.updatePlots();
            app.PrePowLabel.Text = 'None';
            app.PrePowClearButton.Enable = false;
            app.callingApp.updatePower();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Power Modulation';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SliceSeparationLabel = uilabel(app.GridLayout);
            app.SliceSeparationLabel.Text = 'Slice Separation:';
            app.SliceSepEditField = uieditfield(app.GridLayout, 'numeric');
            app.SliceSepEditField.Limits = [0 Inf];
            app.SliceSepEditField.ValueChangedFcn = createCallbackFcn(app, @chngSliceSep, true);
            app.SliceSepEditField.Value = 1;
            app.ChannelSpinnerLabel = uilabel(app.GridLayout);
            app.ChannelSpinnerLabel.Text = 'Channel:';
            app.ChannelSpinner = uispinner(app.GridLayout);
            app.ChannelSpinner.Limits = [1 Inf];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Value = 1;
            app.MultiphotonOrderEditFieldLabel = uilabel(app.GridLayout);
            app.MultiphotonOrderEditFieldLabel.Text = 'Multiphoton Order:';
            app.MPOrderEditField = uieditfield(app.GridLayout, 'numeric');
            app.MPOrderEditField.Limits = [1 Inf];
            app.MPOrderEditField.RoundFractionalValues = 'on';
            app.MPOrderEditField.ValueChangedFcn = createCallbackFcn(app, @chngMpOrder, true);
            app.MPOrderEditField.Value = 2;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SliceSignalTab = uitab(app.TabGroup);
            app.SliceSignalTab.Title = 'Slice Signal';
            app.GridLayout5 = uigridlayout(app.SliceSignalTab);
            app.PAxes = uiaxes(app.GridLayout5);
            title(app.PAxes, 'Slice Signal')
            xlabel(app.PAxes, 'Depth')
            ylabel(app.PAxes, 'Signal')
            zlabel(app.PAxes, 'Z')
            app.MaxPxPercentileEditFieldLabel = uilabel(app.GridLayout5);
            app.MaxPxPercentileEditFieldLabel.Text = 'Max Px. Percentile:';
            app.MaxPxPercentileEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MaxPxPercentileEditField.Limits = [0 100];
            app.MaxPxPercentileEditField.ValueChangedFcn = createCallbackFcn(app, @chngMaxPx, true);
            app.MaxPxPercentileEditField.Value = 100;
            app.MinPxPercentileEditFieldLabel = uilabel(app.GridLayout5);
            app.MinPxPercentileEditFieldLabel.Text = 'Min Px. Percentile:';
            app.MinPxPercentileEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MinPxPercentileEditField.Limits = [0 100];
            app.MinPxPercentileEditField.ValueChangedFcn = createCallbackFcn(app, @chngMinPx, true);
            app.InputPowerTab = uitab(app.TabGroup);
            app.InputPowerTab.Title = 'Input Power';
            app.GridLayout9 = uigridlayout(app.InputPowerTab);
            app.PrePAxes = uiaxes(app.GridLayout9);
            title(app.PrePAxes, 'Initial Imaging Power')
            xlabel(app.PrePAxes, 'Depth')
            ylabel(app.PrePAxes, 'Normalization')
            zlabel(app.PrePAxes, 'Z')
            app.PrePowLoadButton = uibutton(app.GridLayout9, 'push');
            app.PrePowLoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadPrePow, true);
            app.PrePowLoadButton.Text = 'Load';
            app.PrePowClearButton = uibutton(app.GridLayout9, 'push');
            app.PrePowClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearPrePow, true);
            app.PrePowClearButton.Enable = 'off';
            app.PrePowClearButton.Text = 'Clear';
            app.PrePowLabel = uilabel(app.GridLayout9);
            app.PrePowLabel.Text = 'None';
            app.TransmissionTab = uitab(app.TabGroup);
            app.TransmissionTab.Title = 'Transmission';
            app.GridLayout6 = uigridlayout(app.TransmissionTab);
            app.TAxes = uiaxes(app.GridLayout6);
            title(app.TAxes, 'Slice Transmission')
            xlabel(app.TAxes, 'Depth')
            ylabel(app.TAxes, 'Transmission')
            zlabel(app.TAxes, 'Z')
            app.CurveTypeDropDownLabel = uilabel(app.GridLayout6);
            app.CurveTypeDropDownLabel.Text = 'Curve Type';
            app.CurveTypeDropDown = uidropdown(app.GridLayout6);
            app.CurveTypeDropDown.Items = {'Uniform', 'Exponential', 'Inverse Power'};
            app.CurveTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @chngType, true);
            app.CurveTypeDropDown.Value = 'Uniform';
            app.NAEditFieldLabel = uilabel(app.GridLayout6);
            app.NAEditFieldLabel.Text = 'Scattering Length:';
            app.SLEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SLEditField.Limits = [0 Inf];
            app.SLEditField.ValueChangedFcn = createCallbackFcn(app, @chngSl, true);
            app.SLEditField.Enable = 'off';
            app.SLEditField.Value = 1;
            app.OrientationLabel = uilabel(app.GridLayout6);
            app.OrientationLabel.Text = 'Orientation:';
            app.OrientationDropDown = uidropdown(app.GridLayout6);
            app.OrientationDropDown.Items = {'Ascending', 'Descending'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @chngOrientation, true);
            app.OrientationDropDown.Enable = 'off';
            app.OrientationDropDown.Value = 'Ascending';
            app.AutoButton = uibutton(app.GridLayout6, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @fitExp, true);
            app.AutoButton.Enable = 'off';
            app.AutoButton.Text = 'Auto';
            app.AdjustedSliceSignalTab = uitab(app.TabGroup);
            app.AdjustedSliceSignalTab.Title = 'Adjusted Slice Signal';
            app.GridLayout7 = uigridlayout(app.AdjustedSliceSignalTab);
            app.APAxes = uiaxes(app.GridLayout7);
            title(app.APAxes, 'Adjusted Slice Signal')
            xlabel(app.APAxes, 'Depth')
            ylabel(app.APAxes, 'Signal')
            zlabel(app.APAxes, 'Z')
            app.VoltageTab = uitab(app.TabGroup);
            app.VoltageTab.Title = 'Voltage';
            app.GridLayout8 = uigridlayout(app.VoltageTab);
            app.vAxes = uiaxes(app.GridLayout8);
            title(app.vAxes, 'Slice Voltage')
            xlabel(app.vAxes, 'Voltage')
            ylabel(app.vAxes, 'Transmission')
            zlabel(app.vAxes, 'Z')
            app.ExportVoltageButton = uibutton(app.GridLayout8, 'push');
            app.ExportVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @exportV, true);
            app.ExportVoltageButton.Text = 'Export Voltage';
            app.VCalLoadButton = uibutton(app.GridLayout8, 'push');
            app.VCalLoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadVCal, true);
            app.VCalLoadButton.Text = 'Load Voltage Map';
            app.VCalClearButton = uibutton(app.GridLayout8, 'push');
            app.VCalClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearVCal, true);
            app.VCalClearButton.Enable = 'off';
            app.VCalClearButton.Text = 'Clear';
            app.VCalLabel = uilabel(app.GridLayout8);
            app.VCalLabel.Text = 'None';
            app.EffectiveOccupancyTab = uitab(app.TabGroup);
            app.EffectiveOccupancyTab.Title = 'Effective Occupancy';
            app.GridLayout4 = uigridlayout(app.EffectiveOccupancyTab);
            app.OccAxes = uiaxes(app.GridLayout4);
            title(app.OccAxes, 'Scaled Occupancy (100%)')
            xlabel(app.OccAxes, 'Slice')
            ylabel(app.OccAxes, 'Occ. (%)')
            zlabel(app.OccAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = power_modulation_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end