classdef roi_duplicate_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        EndSliceEditField         matlab.ui.control.NumericEditField
        EndSliceEditFieldLabel    matlab.ui.control.Label
        StartSliceEditField       matlab.ui.control.NumericEditField
        StartSliceEditFieldLabel  matlab.ui.control.Label
        ChannelEditField          matlab.ui.control.NumericEditField
        ChannelEditFieldLabel     matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        OkayButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp
        slices
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, params)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.ChannelEditField.Enable = (params.splitChannels) && (params.channels > 1);
            if (app.ChannelEditField.Enable)
                app.ChannelEditField.Limits = [1,params.channels];
            end
            
            if (params.volume)
                app.slices = params.slices;
                app.StartSliceEditField.Limits = [1,params.slices];
                app.EndSliceEditField.Limits = [1,params.slices];
            end
            app.StartSliceEditField.Enable = params.volume;
            app.StartSliceEditField.Enable = params.volume;
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: StartSliceEditField
        function setStartSlice(app, event)
            value = app.StartSliceEditField.Value;
            if (value > app.EndSliceEditField.Value)
                app.EndSliceEditField.Value = value;
            end
            app.EndSliceEditField.Limits = [value,app.slices];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeDuplicate();
            delete(app);
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.callingApp.closeDuplicate();
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            app.callingApp.duplicateROIs(app.ChannelEditField.Value,app.StartSliceEditField.Value,app.EndSliceEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Duplicate Range';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+power_modulation', 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ChannelEditFieldLabel = uilabel(app.GridLayout);
            app.ChannelEditFieldLabel.Text = 'Channel:';
            app.ChannelEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChannelEditField.Limits = [1 Inf];
            app.ChannelEditField.RoundFractionalValues = 'on';
            app.ChannelEditField.Value = 1;
            app.StartSliceEditFieldLabel = uilabel(app.GridLayout);
            app.StartSliceEditFieldLabel.Text = 'Start Slice:';
            app.StartSliceEditField = uieditfield(app.GridLayout, 'numeric');
            app.StartSliceEditField.Limits = [1 Inf];
            app.StartSliceEditField.RoundFractionalValues = 'on';
            app.StartSliceEditField.ValueChangedFcn = createCallbackFcn(app, @setStartSlice, true);
            app.StartSliceEditField.Value = 1;
            app.EndSliceEditFieldLabel = uilabel(app.GridLayout);
            app.EndSliceEditFieldLabel.Text = 'End Slice:';
            app.EndSliceEditField = uieditfield(app.GridLayout, 'numeric');
            app.EndSliceEditField.Limits = [1 Inf];
            app.EndSliceEditField.RoundFractionalValues = 'on';
            app.EndSliceEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roi_duplicate_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end