classdef aes_image_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AESmaskappUIFigure             matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadImageMenu                  matlab.ui.container.Menu
        LoadROIsMenu                   matlab.ui.container.Menu
        SaveROIsMenu                   matlab.ui.container.Menu
        ExportAESMaskMenu              matlab.ui.container.Menu
        ExportROIMasksMenu             matlab.ui.container.Menu
        ImageMenu                      matlab.ui.container.Menu
        WindowLevelMenu                matlab.ui.container.Menu
        GridLayout3                    matlab.ui.container.GridLayout
        YShiftAutoButton               matlab.ui.control.Button
        YShiftSpinner                  matlab.ui.control.Spinner
        YShiftSpinnerLabel             matlab.ui.control.Label
        ChannelSpinner                 matlab.ui.control.Spinner
        ChannelSpinnerLabel            matlab.ui.control.Label
        TargetOccupancyEditField       matlab.ui.control.NumericEditField
        TargetOccupancyEditFieldLabel  matlab.ui.control.Label
        ROIControlsPanel               matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        DeleteButton                   matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        CircleTab                      matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        YcenterEditField               matlab.ui.control.NumericEditField
        YcenterEditFieldLabel          matlab.ui.control.Label
        XcenterEditField               matlab.ui.control.NumericEditField
        XcenterLabel                   matlab.ui.control.Label
        radiusEditField                matlab.ui.control.NumericEditField
        radiusLabel                    matlab.ui.control.Label
        EllipseTab                     matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        AngleEditField                 matlab.ui.control.NumericEditField
        AngleEditFieldLabel            matlab.ui.control.Label
        Semiaxis2EditField             matlab.ui.control.NumericEditField
        Semiaxis2Label                 matlab.ui.control.Label
        Semiaxis1EditField             matlab.ui.control.NumericEditField
        MajoraxisLabel                 matlab.ui.control.Label
        YcenterEditField_2             matlab.ui.control.NumericEditField
        YcenterEditField_2Label        matlab.ui.control.Label
        XcenterEditField_2             matlab.ui.control.NumericEditField
        XcenterEditField_2Label        matlab.ui.control.Label
        RectangleTab                   matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        YminEditField                  matlab.ui.control.NumericEditField
        YminEditFieldLabel             matlab.ui.control.Label
        XminEditField                  matlab.ui.control.NumericEditField
        XminEditFieldLabel             matlab.ui.control.Label
        RingTab                        matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        ThicknessEditField_2           matlab.ui.control.NumericEditField
        ThicknessEditField_2Label      matlab.ui.control.Label
        AngleEditField_2               matlab.ui.control.NumericEditField
        AngleEditField_2Label          matlab.ui.control.Label
        Semiaxis2EditField_2           matlab.ui.control.NumericEditField
        Semiaxis2EditField_2Label      matlab.ui.control.Label
        Semiaxis1EditField_2           matlab.ui.control.NumericEditField
        Semiaxis1EditField_2Label      matlab.ui.control.Label
        YcenterEditField_3             matlab.ui.control.NumericEditField
        YcenterEditField_3Label        matlab.ui.control.Label
        XcenterEditField_3             matlab.ui.control.NumericEditField
        XcenterEditField_3Label        matlab.ui.control.Label
        CurveTab                       matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        PointsEditField                matlab.ui.control.NumericEditField
        PointsEditFieldLabel           matlab.ui.control.Label
        ThicknessEditField             matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel        matlab.ui.control.Label
        PolygonTab                     matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        PolygonPointsEditField         matlab.ui.control.NumericEditField
        PointsEditField_2Label         matlab.ui.control.Label
        ImagePanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        imagePlot                      matlab.ui.control.UIAxes
        occPlot                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % related to file management
        file
        path
        chnlDlgBox
        bcDlgBox
        interruptDialog
        refSliceLimit = 1500

        % related to image and mask creation
        channel
        channels
        mainChannel
        sepChannels
        refImages
        dispImage
        shiftImage
        contrImage
        overMask
        windows
        imhandle
        xMap
        yMap
        op = 0.3
        roiMask
        roiOccHandle
        targetOcc
        targetLine

        % for y axis bidirectional images
        bidirectional
        evenRows
        yShift

        % roi elements
        circROIs
        rectROIs
        elpsROIs
        lineROIs
        ringROIs
        lineWidths
        ringWidths
        plgnROIs
        
        circROIsCh
        rectROIsCh
        elpsROIsCh
        lineROIsCh
        ringROIsCh
        lineWidthsCh
        ringWidthsCh
        plgnROIsCh

        currentROI
        roiSlct
        currentROIType
    end
    
    properties (Access = public)
        matlabDate
    end

    properties (SetAccess = immutable)
        CIRC = 1;
        RECT = 2;
        ELPS = 3;
        LINE = 4;
        RING = 5;
        PLGN = 6;
    end
    
    methods (Access = public)
        %% callback functions

        %%%% Circle Callbacks
        function circClck(app,src,evt)
            app.currentROI = src;
            app.updateCircleFields(src);
            app.TabGroup.SelectedTab = app.CircleTab;

            if (~(app.roiSlct && (app.currentROIType == app.CIRC)))
                app.roiSlct = true;
                app.currentROIType = app.CIRC;
                app.setActiveFields();
            end
        end

        function circDlt(app,src,evt)
            app.roiSlct = false;
            ch = app.channel;
            if (app.sepChannels)
                ch = 1;
            elseif (app.bidirectional)
                ch = ceil(app.channel/2);
            end

            for ii=1:length(app.circROIs)
                if eq(app.circROIs{ii},src)
                    app.circROIs(ii) = [];
                    break;
                end
            end
            app.circROIs = app.circROIs(~cellfun(@isempty,app.circROIs));
            app.updateProjection();
            app.setActiveFields();
        end
        
        function circMv(app,src,evt)
            app.updateCircleFields(src);
            app.updateProjection();
        end

        %%%% Rectangle Callbacks
        function rectClck(app,src,evt)
            app.currentROI = src;
            app.updateRectFields(src);
            app.TabGroup.SelectedTab = app.RectangleTab;

            if (~(app.roiSlct && (app.currentROIType == app.RECT)))
                app.roiSlct = true;
                app.currentROIType = app.RECT;
                app.setActiveFields();
            end
        end

        function rectDlt(app,src,evt)
            app.roiSlct = false;
            for ii=1:length(app.rectROIs)
                if eq(app.rectROIs{ii},src)
                    app.rectROIs(ii) = [];
                    break;
                end
            end
            app.rectROIs = app.rectROIs(~cellfun(@isempty,app.rectROIs));
            app.updateProjection();
            app.setActiveFields();
        end

        function rectMv(app,src,evt)
            app.updateRectFields(src);
            app.updateProjection();
        end

        %%%% Ellipse Callbacks
        function elpsClck(app,src,evt)
            app.currentROI = src;
            app.roiSlct = true;
            app.updateElpsFields(src);
            app.TabGroup.SelectedTab = app.EllipseTab;

            if (~(app.roiSlct && (app.currentROIType == app.ELPS)))
                app.roiSlct = true;
                app.currentROIType = app.ELPS;
                app.setActiveFields();
            end
        end

        function elpsDlt(app,src,evt)
            app.roiSlct = false;
            for ii=1:length(app.elpsROIs)
                if(eq(app.elpsROIs{ii},src))
                    app.elpsROIs(ii) = [];
                    break;
                end
            end
            app.elpsROIs = app.elpsROIs(~cellfun(@isempty,app.elpsROIs));
            app.updateProjection();
            app.setActiveFields();
        end

        function elpsMv(app,src,evt)
            app.updateElpsFields(src);
            app.updateProjection();
        end

        %%%% Poly Line Callbacks
        function lineClck(app,src,evt)
            app.currentROI = src;
            app.roiSlct = true;
            app.updateLineFields(src);
            app.TabGroup.SelectedTab = app.CurveTab;

            if (~(app.roiSlct && (app.currentROIType == app.LINE)))
                app.roiSlct = true;
                app.currentROIType = app.LINE;
                app.setActiveFields();
            end
        end

        function lineDlt(app,src,evt)
            app.roiSlct = false;
            for ii=1:length(app.lineROIs)
                if(eq(app.lineROIs{ii},src))
                    app.lineROIs(ii) = [];
                    break;
                end
            end
            filled = ~cellfun(@isempty,app.lineROIs);
            app.lineROIs = app.lineROIs(filled);
            app.lineWidths = app.lineWidths(filled);
            app.updateProjection();
            app.setActiveFields();
        end

        function lineMv(app,src,evt)
            app.updateLineFields(src);
            app.updateProjection();
        end

        %%%% Ring Callbacks
        function ringClck(app,src,evt)
            app.currentROI = src;
            app.roiSlct = true;
            app.updateRingFields(src);
            app.TabGroup.SelectedTab = app.RingTab;

            if (~(app.roiSlct && (app.currentROIType == app.RING)))
                app.roiSlct = true;
                app.currentROIType = app.RING;
                app.setActiveFields();
            end
        end

        function ringDlt(app,src,evt)
            app.roiSlct = false;
            for ii=1:length(app.ringROIs)
                if(eq(app.ringROIs{ii},src))
                    app.ringROIs(ii) = [];
                    break;
                end
            end
            filled = ~cellfun(@isempty,app.ringROIs);
            app.ringROIs = app.ringROIs(filled);
            app.ringWidths = app.ringWidths(filled);            
            app.updateProjection();
            app.setActiveFields();
        end

        function ringMv(app,src,evt)
            app.updateRingFields(src);
            app.updateProjection();
        end

        %%%% Polygon Callbacks
        function plgnClck(app,src,evt)
            app.currentROI = src;
            app.roiSlct = true;
            app.updatePlgnFields(src);
            app.TabGroup.SelectedTab = app.PolygonTab;

            if (~(app.roiSlct && (app.currentROIType == app.PLGN)))
                app.roiSlct = true;
                app.currentROIType = app.PLGN;
                app.setActiveFields();
            end
        end

        function plgnDlt(app,src,evt)
            app.roiSlct = false;
            for ii=1:length(app.plgnROIs)
                if(eq(app.plgnROIs{ii},src))
                    app.plgnROIs(ii) = [];
                    break;
                end
            end
            filled = ~cellfun(@isempty,app.plgnROIs);
            app.plgnROIs = app.plgnROIs(filled);
            app.updateProjection();
            app.setActiveFields();
        end

        function plgnMv(app,src,evt)
            app.updatePlgnFields(src);
            app.updateProjection();
        end

        %% update UI functions
        function updateCircleFields(app,src)
            coord = src.Center;
            app.radiusEditField.Value = src.Radius;
            app.XcenterEditField.Value = coord(1);
            app.YcenterEditField.Value = coord(2);
        end

        function updateRectFields(app,src)
            coord = src.Position;
            app.XminEditField.Value = coord(1);
            app.YminEditField.Value = coord(2);
            app.WidthEditField.Value = coord(3);
            app.HeightEditField.Value = coord(4);
        end

        function updateElpsFields(app,src)
            coord = src.Center;
            app.XcenterEditField_2.Value = coord(1);
            app.YcenterEditField_2.Value = coord(2);

            coord = src.SemiAxes;
            app.Semiaxis1EditField.Value = coord(1);
            app.Semiaxis2EditField.Value = coord(2);
            app.AngleEditField.Value = src.RotationAngle;
        end

        function updateLineFields(app,src)
            for ii = 1:length(app.lineROIs)
                if (eq(app.lineROIs{ii},src))
                    break;
                end
            end

            app.ThicknessEditField.Value = app.lineWidths(ii);
            coord = src.Position;
            app.PointsEditField.Value = length(coord(:,1));
        end

        function updateRingFields(app,src)
            for ii = 1:length(app.ringROIs)
                if (eq(app.ringROIs{ii},src))
                    break;
                end
            end

            coord = src.Center;
            app.XcenterEditField_3.Value = coord(1);
            app.YcenterEditField_3.Value = coord(2);

            coord = src.SemiAxes;
            app.Semiaxis1EditField_2.Value = coord(1);
            app.Semiaxis2EditField_2.Value = coord(2);
            app.AngleEditField_2.Value = src.RotationAngle;
            app.ThicknessEditField_2.Value = app.ringWidths(ii);
        end

        function updatePlgnFields(app,src)
            for ii = 1:length(app.plgnROIs)
                if (eq(app.plgnROIs{ii},src))
                    break;
                end
            end

            coord = src.Position;
            app.PolygonPointsEditField.Value = length(coord(:,1));
        end

        function setActiveFields(app)
            if (~app.roiSlct)
                app.activateCircFields('Off');
                app.activateRectFields('Off');
                app.activateElpsFields('Off');
                app.activateLineFields('Off');
                app.activateRingFields('Off');
                app.activatePlgnFields('Off');
            elseif (app.currentROIType == app.CIRC)
                app.activateCircFields('On');
                app.activateRectFields('Off');
                app.activateElpsFields('Off');
                app.activateLineFields('Off');
                app.activateRingFields('Off');
                app.activatePlgnFields('Off');
            elseif (app.currentROIType == app.RECT)
                app.activateCircFields('Off');
                app.activateRectFields('On');
                app.activateElpsFields('Off');
                app.activateLineFields('Off');
                app.activateRingFields('Off');
                app.activatePlgnFields('Off');
            elseif (app.currentROIType == app.ELPS)
                app.activateCircFields('Off');
                app.activateRectFields('Off');
                app.activateElpsFields('On');
                app.activateLineFields('Off');
                app.activateRingFields('Off');
                app.activatePlgnFields('Off');
            elseif (app.currentROIType == app.LINE)
                app.activateCircFields('Off');
                app.activateRectFields('Off');
                app.activateElpsFields('Off');
                app.activateLineFields('On');
                app.activateRingFields('Off');
                app.activatePlgnFields('Off');
            elseif (app.currentROIType == app.RING)
                app.activateCircFields('Off');
                app.activateRectFields('Off');
                app.activateElpsFields('Off');
                app.activateLineFields('Off');
                app.activateRingFields('On');
                app.activatePlgnFields('Off');
            elseif (app.currentROIType == app.PLGN)
                app.activateCircFields('Off');
                app.activateRectFields('Off');
                app.activateElpsFields('Off');
                app.activateLineFields('Off');
                app.activateRingFields('Off');
                app.activatePlgnFields('On');
            end
        end

        function activateCircFields(app,state)
            app.XcenterEditField.Editable = state;
            app.XcenterEditField.Enable = state;
            app.YcenterEditField.Editable = state;
            app.YcenterEditField.Enable = state;
            app.radiusEditField.Editable = state;
            app.radiusEditField.Enable = state;
        end

        function activateRectFields(app,state)
            app.HeightEditField.Editable = state;
            app.HeightEditField.Enable = state;
            app.WidthEditField.Editable = state;
            app.WidthEditField.Enable = state;
            app.YminEditField.Editable = state;
            app.YminEditField.Enable = state;
            app.XminEditField.Editable = state;
            app.XminEditField.Enable = state;
        end

        function activateElpsFields(app,state)
            app.XcenterEditField_2.Editable = state;
            app.XcenterEditField_2.Enable = state;
            app.YcenterEditField_2.Editable = state;
            app.YcenterEditField_2.Enable = state;
            app.Semiaxis2EditField.Editable = state;
            app.Semiaxis2EditField.Enable = state;
            app.Semiaxis1EditField.Editable = state;
            app.Semiaxis1EditField.Enable = state;
            app.AngleEditField.Editable = state;
            app.AngleEditField.Enable = state;
        end

        function activateLineFields(app,state)
            app.ThicknessEditField.Editable = state;
            app.ThicknessEditField.Enable = state;
            app.PointsEditField.Editable = state;
            app.PointsEditField.Enable = state;
        end

        function activateRingFields(app,state)
            app.XcenterEditField_3.Editable = state;
            app.XcenterEditField_3.Enable = state;
            app.YcenterEditField_3.Editable = state;
            app.YcenterEditField_3.Enable = state;
            app.Semiaxis1EditField_2.Editable = state;
            app.Semiaxis1EditField_2.Enable = state;
            app.Semiaxis2EditField_2.Editable = state;
            app.Semiaxis2EditField_2.Enable = state;
            app.AngleEditField_2.Editable = state;
            app.AngleEditField_2.Enable = state;
            app.ThicknessEditField_2.Editable = state;
            app.ThicknessEditField_2.Enable = state;
        end

        function activatePlgnFields(app,state)
            app.PolygonPointsEditField.Editable = state;
        end

        %% Other functions
        % builds mask that will be exported
        % also updates percent occupancy for each line
        % and draw polyline and ring roi's to screen
        function updateProjection(app)
            app.roiMask = zeros(size(app.refImages,1),size(app.refImages,2));
            for ii = 1:length(app.circROIs)
                tempMask = createMask(app.circROIs{ii});
                app.roiMask = app.roiMask + tempMask - app.roiMask.*tempMask;
            end
            
            for ii = 1:length(app.rectROIs)
                tempMask = createMask(app.rectROIs{ii});
                app.roiMask = app.roiMask + tempMask;
            end

            for ii = 1:length(app.elpsROIs)
                tempMask = createMask(app.elpsROIs{ii});
                app.roiMask = app.roiMask + tempMask;
            end

            for ii = 1:length(app.lineROIs)
                tempMask = createMask(app.lineROIs{ii});
                tempMask = imdilate(tempMask,strel('disk',ceil(app.lineWidths(ii)/2)));
                app.roiMask = app.roiMask + tempMask;
            end

            for ii = 1:length(app.ringROIs)
                ax = app.ringROIs{ii}.SemiAxes;
                cent = app.ringROIs{ii}.Center;
                x = (app.xMap-cent(1));
                y = (app.yMap-cent(2));
                theta = -pi/180*app.ringROIs{ii}.RotationAngle;
                tempMask = (1 < app.ellipseMask(x,y,(ax(1)-app.ringWidths(ii)/2),(ax(2)-app.ringWidths(ii)/2),theta))...
                    .* (1 > app.ellipseMask(x,y,(ax(1)+app.ringWidths(ii)/2),(ax(2)+app.ringWidths(ii)/2),theta));
                app.roiMask = app.roiMask + tempMask;
            end

            for ii = 1:length(app.plgnROIs)
                tempMask = createMask(app.plgnROIs{ii});
                app.roiMask = app.roiMask + tempMask;
            end

            app.roiMask = app.roiMask>0;
            roiOcc = sum(app.roiMask,2)/length(app.roiMask(1,:))*100;
            app.overMask = repmat(roiOcc>app.targetOcc,1,size(app.roiMask,2));
            app.dispImage(:,:,1) = app.contrImage .* (1 - 0.5 * app.overMask) + 0.5 * app.overMask;
            app.dispImage(:,:,2) = app.contrImage;
            app.dispImage(:,:,3) = app.contrImage .* (1 - app.op*app.roiMask) + app.op*app.roiMask;
            app.imhandle.CData = app.dispImage;

            app.roiOccHandle.XData = roiOcc;
            app.occPlot.Title.String = strcat("Occupancy: ",num2str(round(sum(sum(app.roiMask))/(size(app.refImages,1)*size(app.refImages,2))*100,1)),"%");
        end

        % helper function for drawing ring roi
        function d = ellipseMask(app,x,y,a,b,theta)
            d = (a^2*sin(theta)^2+b^2*cos(theta)^2)*x.^2+2*(b^2-a^2)*sin(theta)*cos(theta)*x.*y+(a^2*cos(theta)^2+b^2*sin(theta)^2)*y.^2;
            d = d/(a^2*b^2);
        end

        function shiftROIs(app,shift)
            for ii = 1:length(app.circROIs)
                app.shiftROI(app.circROIs{ii},shift);
            end

            for ii = 1:length(app.rectROIs)
                coord = app.rectROIs{ii}.Position;
                coord(1) = coord(1) + shift(1);
                coord(2) = coord(2) + shift(2);
                app.rectROIs{ii}.Position = coord;
            end

            for ii = 1:length(app.elpsROIs)
                app.shiftROI(app.elpsROIs{ii},shift);
            end

            for ii = 1:length(app.ringROIs)
                app.shiftROI(app.ringROIs{ii},shift);
            end

            for ii = 1:length(app.lineROIs)
                coord = app.lineROIs{ii}.Position;
                coord = coord + repmat(shift,[size(coord,1),1]);
                app.lineROIs{ii}.Position = coord;
            end

            for ii = 1:length(app.plgnROIs)
                coord = app.plgnROIs{ii}.Position;
                coord = coord + repmat(shift,[size(coord,1),1]);
                app.plgnROIs{ii}.Position = coord;
            end
        end

        function shiftROIsCh(app,ch,shift)
            for ii = 1:length(app.circROIsCh{ch})
                app.shiftROI(app.circROIsCh{ch}{ii},shift);
            end

            for ii = 1:length(app.rectROIsCh{ch})
                coord = app.rectROIsCh{ch}{ii}.Position;
                coord(1) = coord(1) + shift(1);
                coord(2) = coord(2) + shift(2);
                app.rectROIsCh{ch}{ii}.Position = coord;
            end

            for ii = 1:length(app.elpsROIsCh{ch})
                app.shiftROI(app.elpsROIsCh{ch}{ii},shift);
            end

            for ii = 1:length(app.ringROIsCh{ch})
                app.shiftROI(app.ringROIsCh{ch}{ii},shift);
            end

            for ii = 1:length(app.lineROIsCh{ch})
                coord = app.lineROIsCh{ch}{ii}.Position;
                coord = coord + repmat(shift,[size(coord,1),1]);
                app.lineROIsCh{ch}{ii}.Position = coord;
            end

            for ii = 1:length(app.plgnROIsCh{ch})
                coord = app.plgnROIsCh{ch}{ii}.Position;
                coord = coord + repmat(shift,[size(coord,1),1]);
                app.plgnROIsCh{ch}{ii}.Position = coord;
            end
        end

        function shiftROI(app,ROI,shift)
            coord = ROI.Center;
            coord = coord + shift;
            ROI.Center = coord;
        end

        function unloadROIs(app)
            if (app.sepChannels)
                for jj = 1:app.channels
                    for ii = 1:length(app.circROIsCh{jj})
                        delete(app.circROIsCh{jj}{ii});
                    end

                    for ii = 1:length(app.rectROIsCh{jj})
                        delete(app.rectROIsCh{jj}{ii});
                    end

                    for ii = 1:length(app.elpsROIsCh{jj})
                        delete(app.elpsROIsCh{jj}{ii});
                    end

                    for ii = 1:length(app.lineROIsCh{jj})
                        delete(app.lineROIsCh{jj}{ii});
                    end

                    for ii = 1:length(app.ringROIsCh{jj})
                        delete(app.ringROIsCh{jj}{ii});
                    end

                    for ii = 1:length(app.plgnROIsCh{jj})
                        delete(app.plgnROIsCh{jj}{ii});
                    end

                    app.lineWidthsCh{jj} = [];
                    app.ringWidthsCh{jj} = [];
                end
                app.lineWidths = [];
                app.ringWidths = [];
            else
                for ii=1:length(app.circROIs)
                    delete(app.circROIs{ii});
                end

                for ii=1:length(app.rectROIs)
                    delete(app.rectROIs{ii});
                end
    
                for ii=1:length(app.elpsROIs)
                    delete(app.elpsROIs{ii});
                end
    
                for ii=1:length(app.lineROIs)
                    delete(app.lineROIs{ii});
                end
    
                for ii=1:length(app.ringROIs)
                    delete(app.ringROIs{ii});
                end
    
                for ii = 1:length(app.plgnROIs)
                    delete(app.plgnROIs{ii});
                end
    
                app.lineWidths = [];
                app.ringWidths = [];
            end
        end

        function initROIs(app)
            if (app.channels < 1)
                app.channels = 1;
            end

            if (app.sepChannels)
                app.circROIsCh = cell(app.channels,1);
                app.rectROIsCh = cell(app.channels,1);
                app.elpsROIsCh = cell(app.channels,1);
                app.lineROIsCh = cell(app.channels,1);
                app.ringROIsCh = cell(app.channels,1);
                app.plgnROIsCh = cell(app.channels,1);
                app.lineWidthsCh = cell(app.channels,1);
                app.ringWidthsCh = cell(app.channels,1);
                for ii=1:app.channels
                    app.circROIsCh{ii} = cell(0);
                    app.rectROIsCh{ii} = cell(0);
                    app.elpsROIsCh{ii} = cell(0);
                    app.lineROIsCh{ii} = cell(0);
                    app.ringROIsCh{ii} = cell(0);
                    app.plgnROIsCh{ii} = cell(0);
                    app.lineWidthsCh{ii} = [];
                    app.ringWidthsCh{ii} = [];
                end
            else
                app.circROIs = cell(0);
                app.rectROIs = cell(0);
                app.elpsROIs = cell(0);
                app.lineROIs = cell(0);
                app.ringROIs = cell(0);
                app.plgnROIs = cell(0);
                app.lineWidths = [];
                app.ringWidths = [];
            end
        end

        function attachListeners(app)
            for ii=1:length(app.circROIs)
                app.circROIs{ii}.Parent = app.imagePlot;
                addlistener(app.circROIs{ii},'ROIClicked',@app.circClck);
                addlistener(app.circROIs{ii},'DeletingROI',@app.circDlt);
                addlistener(app.circROIs{ii},'ROIMoved',@app.circMv);
            end

            for ii=1:length(app.rectROIs)
                app.rectROIs{ii}.Parent = app.imagePlot;
                addlistener(app.rectROIs{ii},'ROIClicked',@app.rectClck);
                addlistener(app.rectROIs{ii},'DeletingROI',@app.rectDlt);
                addlistener(app.rectROIs{ii},'ROIMoved',@app.rectMv);
            end

            for ii=1:length(app.elpsROIs)
                app.elpsROIs{ii}.Parent = app.imagePlot;
                addlistener(app.elpsROIs{ii},'ROIClicked',@app.elpsClck);
                addlistener(app.elpsROIs{ii},'DeletingROI',@app.elpsDlt);
                addlistener(app.elpsROIs{ii},'ROIMoved',@app.elpsMv);
            end

            for ii=1:length(app.lineROIs)
                app.lineROIs{ii}.Parent = app.imagePlot;
                addlistener(app.lineROIs{ii},'ROIClicked',@app.lineClck);
                addlistener(app.lineROIs{ii},'DeletingROI',@app.lineDlt);
                addlistener(app.lineROIs{ii},'ROIMoved',@app.lineMv);
                addlistener(app.lineROIs{ii},'VertexAdded',@app.lineMv);
                addlistener(app.lineROIs{ii},'VertexDeleted',@app.lineMv);
            end

            for ii=1:length(app.ringROIs)
                app.ringROIs{ii}.Parent = app.imagePlot;
                addlistener(app.ringROIs{ii},'ROIClicked',@app.ringClck);
                addlistener(app.ringROIs{ii},'DeletingROI',@app.ringDlt);
                addlistener(app.ringROIs{ii},'ROIMoved',@app.ringMv);
            end

            for ii = 1:length(app.plgnROIs)
                app.plgnROIs{ii}.Parent = app.imagePlot;
                addlistener(app.plgnROIs{ii},'ROIClicked',@app.plgnClck);
                addlistener(app.plgnROIs{ii},'DeletingROI',@app.plgnDlt);
                addlistener(app.plgnROIs{ii},'ROIMoved',@app.plgnMv);
            end
        end

        function chnlClbk(app, params, limit)
            delete(app.chnlDlgBox);
            drawnow;
            figure(app.AESmaskappUIFigure);
            if (isempty(params))
                close(app.interruptDialog);
                app.interruptDialog = [];
                return;
            end

            app.interruptDialog.Title = "Opening file...";
            if (limit == 0)
                if (params.mtn)
                    limit = 300;
                else
                    limit = app.refSliceLimit;
                end
            end

            if (params.mtn)
                stackManager = MotionTracker(params);
            else
                stackManager = StackTracker(params);
            end

            app.interruptDialog.Title = "Loading image...";
            app.refImages = stackManager.buildProjection(fullfile(app.path,app.file),limit,app.interruptDialog);

            app.bidirectional = params.bidirectional;
            if (app.bidirectional)
                app.yShift = 0;
                app.YShiftSpinner.Value = 0;
                app.YShiftSpinner.Enable = true;
                app.YShiftSpinner.Editable = true;
                app.ChannelSpinner.Limits = [1,(params.channels+0.5)];
                app.YShiftAutoButton.Enable = true;
                app.evenRows = mod((stackManager.htot-2*stackManager.h),2)==0;
                app.ChannelSpinner.RoundFractionalValues = false;
                app.ChannelSpinner.Step = 0.5;
            else
                app.YShiftSpinner.Enable = false;
                app.YShiftSpinner.Editable = false;
                if (params.channels == 1)
                    app.ChannelSpinner.Limits = [1, 1.1];
                else
                    app.ChannelSpinner.Limits = [1,params.channels];
                end
                app.YShiftAutoButton.Enable = false;
                app.ChannelSpinner.Step = 1;
                app.ChannelSpinner.RoundFractionalValues = true;
            end

            for ii = 1:size(app.refImages,3)
                if (min(app.refImages(:,:,ii),[],'all') == max(app.refImages(:,:,ii),[],'all'))
                    app.refImages(:,:,ii) = 0 * app.refImages(:,:,ii);
                else
                    app.refImages(:,:,ii) = (app.refImages(:,:,ii) - min(app.refImages(:,:,ii),[],'all')) / (max(app.refImages(:,:,ii),[],'all') - min(app.refImages(:,:,ii),[],'all'));
                end
            end

            if ((params.channels > 1) || params.bidirectional)
                app.ChannelSpinner.Editable = true;
                app.ChannelSpinner.Enable = true;
            else
                app.ChannelSpinner.Editable = false;
                app.ChannelSpinner.Enable = false;
            end
            app.mainChannel = params.mainChannel;
            app.channel = (1+app.bidirectional)*params.mainChannel-app.bidirectional;
            app.channels = params.channels;
            app.ChannelSpinner.Value = 1;
                
            app.imagePlot.Title.String = strcat("file: ", app.file);
            app.shiftImage = app.refImages(:,:,(1+app.bidirectional)*params.mainChannel-app.bidirectional);
            app.dispImage = zeros(size(app.shiftImage,1),size(app.shiftImage,2),3);
            app.dispImage(:,:,1) = app.shiftImage;
            app.dispImage(:,:,2) = app.shiftImage;
            app.dispImage(:,:,3) = app.shiftImage;
            app.windows = [zeros(1,size(app.refImages,3)); ones(1,size(app.refImages,3))];
            app.contrImage = app.shiftImage;
            app.overMask = zeros(size(app.refImages,1),size(app.refImages,2));

            app.imhandle = image(app.imagePlot, app.dispImage);
            set(app.imagePlot,'xlim',[0,size(app.shiftImage,2)]);
            set(app.imagePlot,'ylim',[0,size(app.shiftImage,1)]);
            set(app.occPlot,'ylim',[0,size(app.shiftImage,1)]);
            app.roiOccHandle = plot(app.occPlot,zeros(1,size(app.shiftImage,1)),1:size(app.shiftImage,1));
            hold(app.occPlot,"on");
            app.targetLine = plot(app.occPlot,[app.targetOcc,app.targetOcc],[1,size(app.shiftImage,1)],'r');
            hold(app.occPlot,"off");
            app.xMap = repmat(1:size(app.shiftImage,2),size(app.shiftImage,1),1);
            app.yMap = repmat((1:size(app.shiftImage,1))',1,size(app.shiftImage,2));   
            app.unloadROIs();
            app.sepChannels = params.splitChannels;
            app.initROIs();
            app.updateProjection();

            if (app.bidirectional)
                app.autoYShift([]);
            end
            close(app.interruptDialog);
            app.interruptDialog = [];
        end

        function closeBC(app)
            app.bcDlgBox = [];
        end
            

        function chngImgBnds(app,bounds)
            if (app.bidirectional)
                ch = 2*floor((app.channel-1)/2);
                app.windows(:,(ch+1):(ch+2)) = [bounds(1), bounds(1); bounds(2), bounds(2)];
            else
                app.windows(:,app.channel) = [bounds(1);bounds(2)];
            end

            tempMaskLow = app.shiftImage > bounds(1);
            tempMaskHigh = app.shiftImage > bounds(2);
            app.contrImage = tempMaskHigh + (tempMaskLow .* (1 - tempMaskHigh)) .* (app.shiftImage - bounds(1)) / (bounds(2) - bounds(1));

            app.dispImage(:,:,1) = app.contrImage .* (1 - 0.5 * app.overMask) + 0.5 * app.overMask;
            app.dispImage(:,:,2) = app.contrImage;
            app.dispImage(:,:,3) = app.contrImage .* (1 - app.op*app.roiMask) + app.op*app.roiMask;
            app.imhandle.CData = app.dispImage;
        end

        function outMask = genMask(app,disp,fillLines)
            app.updateProjection();
            app.roiMask = app.roiMask > 0;
            if (app.bidirectional)
                if (app.evenRows)
                    outMask = zeros((2*size(app.roiMask,1)),size(app.roiMask,2));
                else
                    outMask = zeros((2*size(app.roiMask,1)+1),size(app.roiMask,2));
                end
                outMask(1:size(app.roiMask,1),:) = circshift(app.roiMask,-app.yShift,1);
                outMask((size(outMask,1)-size(app.roiMask,1)+1):size(outMask,1),:) = flipud(app.roiMask);
            else
                outMask = app.roiMask;
            end

            outMask = circshift(outMask,disp);
            
            if (fillLines)
                target_px = round(app.targetOcc * length(outMask(1,:))/100);
                line_ind_1 = 1:(length(outMask(1,:))-1);
                line_ind_2 = 2:length(outMask(1,:));
                line_ind_3 = 1:length(outMask(1,:));
        
                for ii = 1:length(outMask(:,1))
                    line = outMask(ii,:);
                    px = sum(outMask(ii,:));
                    feature_start = line_ind_2(line(2:end) > line(1:(end-1)));
                    if (line(1))
                        feature_start = [1 feature_start];
                    end
    
                    feature_end = line_ind_1(line(2:end) < line(1:(end-1)));
    
                    if (line(end))
                        feature_end = [feature_end length(line)];
                    end
    
                    diff = target_px - px;
    
                    if (diff < 0)
                        disp(strcat("oh no! over target on line ", num2str(ii)));
                    else
                        if (isempty(feature_start))
                            line(round(length(line)/2):(round(length(line)/2)+target_px-1)) = ones(1,target_px);
                            diff = 0;
                        elseif (length(feature_start) == 2)
                            gap = feature_start(2) - feature_end(1) - 1;
                            if (gap <= diff)
                                line((feature_end(1)+1):(feature_end(1)+gap)) = ones(1,gap);
                                diff = diff - gap;
                                feature_start = feature_start(1);
                                feature_end = feature_end(2);
                            end
                        end
                
                        while(diff > 0)
                            for jj = 1:length(feature_end)
                                if(feature_end(jj) < length(line))
                                    if (~line(feature_end(jj)+1))
                                        feature_end(jj) = feature_end(jj) + 1;
                                        line(feature_end(jj)) = 1;
                                        diff = diff - 1;
                                    elseif (feature_start(jj) > 1)
                                        if (~line(feature_start(jj)-1))
                                            feature_start(jj) = feature_start(jj) - 1;
                                            line(feature_start(jj)) = 1;
                                            diff = diff - 1;
                                        end
                                    end
                                else
                                    if (~line(feature_start(jj)-1))
                                        feature_start(jj) = feature_start(jj) - 1;
                                        line(feature_start(jj)) = 1;
                                        diff = diff - 1;
                                    end
                                end
    
                                if (diff == 0)
                                    break;
                                end
                            end
                        end
                    end
    
                    outMask(ii,:) = line;
                end
            end
        end

        function printMasks(app,outPath,basename)
            index = 0;
                for ii = 1:length(app.circROIs)
                    index = index + 1;
                    tempMask = createMask(app.circROIs{ii});
                    ff = fullfile(outPath,strcat(basename,'_',num2str(index),'.tif'));
                    imwrite((tempMask+0),ff, 'Compression','none');
                end
            
                for ii = 1:length(app.rectROIs)
                    index = index + 1;
                    tempMask = createMask(app.rectROIs{ii});
                    ff = fullfile(outPath,strcat(basename,'_',num2str(index),'.tif'));
                    imwrite((tempMask+0),ff, 'Compression','none');
                end

                for ii = 1:length(app.elpsROIs)
                    index = index + 1;
                    tempMask = createMask(app.elpsROIs{ii});
                    ff = fullfile(outPath,strcat(basename,'_',num2str(index),'.tif'));
                    imwrite((tempMask+0),ff, 'Compression','none');
                end

                for ii = 1:length(app.lineROIs)
                    index = index + 1;
                    tempMask = createMask(app.lineROIs{ii});
                    tempMask = imdilate(tempMask,strel('disk',ceil(app.lineWidths(ii)/2)));
                    ff = fullfile(outPath,strcat(basename,'_',num2str(index),'.tif'));
                    imwrite((tempMask+0),ff, 'Compression','none');
                end

                for ii = 1:length(app.ringROIs)
                    index = index + 1;
                    ax = app.ringROIs{ii}.SemiAxes;
                    cent = app.ringROIs{ii}.Center;
                    x = (app.xMap-cent(1));
                    y = (app.yMap-cent(2));
                    theta = -pi/180*app.ringROIs{ii}.RotationAngle;
                    tempMask = (1 < app.ellipseMask(x,y,(ax(1)-app.ringWidths(ii)/2),(ax(2)-app.ringWidths(ii)/2),theta))...
                        .* (1 > app.ellipseMask(x,y,(ax(1)+app.ringWidths(ii)/2),(ax(2)+app.ringWidths(ii)/2),theta));
                    ff = fullfile(outPath,strcat(basename,'_',num2str(index),'.tif'));
                    imwrite((tempMask+0),ff, 'Compression','none');
                end

                for ii = 1:length(app.plgnROIs)
                    index = index + 1;
                    tempMask = createMask(app.plgnROIs{ii});
                    ff = fullfile(outPath,strcat(basename,'_',num2str(index),'.tif'));
                    imwrite((tempMask+0),ff,'Compression','none');
                end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupTasks(app)
            app.AESmaskappUIFigure.Visible = 'off';
            box(app.occPlot,'on');
            box(app.imagePlot,'on');
            disableDefaultInteractivity(app.imagePlot);
            app.imagePlot.Title.Interpreter = 'none';
            movegui(app.AESmaskappUIFigure,'center');
            app.AESmaskappUIFigure.Visible = 'on';
            app.initROIs();
            app.targetOcc = app.TargetOccupancyEditField.Value;
            daspect(app.imagePlot,[1,1,1]);
            [v,app.matlabDate] = version;
        end

        % Menu selected function: LoadImageMenu
        function loadImage(app, event)
            [tempFile, tempPath] = uigetfile('.tif');
            drawnow;
            figure(app.AESmaskappUIFigure);
            if ~isequal(tempFile,0)
                app.file = tempFile;
                app.path = tempPath;
                app.interruptDialog = uiprogressdlg(app.AESmaskappUIFigure,"Title","Waiting for channel info...");
                app.chnlDlgBox = channel_helper_app(app);
            end
        end

        % Button pushed function: AddButton
        function addROI(app, event)
            if(isempty(app.refImages))
                msgbox('Need to load image before making rois','error');
            else
                if(eq(app.TabGroup.SelectedTab,app.CircleTab))
                    tempROI = drawcircle(app.imagePlot);
                    if (~isempty(tempROI.Center))
                        tempROI.DrawingArea = "unlimited";
                        tempROI.FaceAlpha = 0;
                        app.circROIs = [app.circROIs, {tempROI}];
                        addlistener(tempROI,'ROIClicked',@app.circClck);
                        addlistener(tempROI,'DeletingROI',@app.circDlt);
                        addlistener(tempROI,'ROIMoved',@app.circMv);
                        app.currentROI = tempROI;
                        app.roiSlct = true;
                        app.updateCircleFields(tempROI);
                        app.updateProjection();
                        app.currentROIType = app.CIRC;
                        app.setActiveFields();
                    end
                elseif (eq(app.TabGroup.SelectedTab,app.RectangleTab))
                    tempROI = drawrectangle(app.imagePlot);
                    if (~isempty(tempROI.Position))
                        tempROI.DrawingArea = "unlimited";
                        tempROI.FaceAlpha = 0;
                        app.rectROIs = [app.rectROIs, {tempROI}];
                        addlistener(tempROI,'ROIClicked',@app.rectClck);
                        addlistener(tempROI,'DeletingROI',@app.rectDlt);
                        addlistener(tempROI,'ROIMoved',@app.rectMv);
                        app.currentROI = tempROI;
                        app.roiSlct = true;
                        app.updateRectFields(tempROI);
                        app.updateProjection();
                        app.currentROIType = app.RECT;
                        app.setActiveFields();
                    end
                elseif (eq(app.TabGroup.SelectedTab,app.EllipseTab))
                    tempROI = drawellipse(app.imagePlot);
                    if (~isempty(tempROI.Center))
                        tempROI.DrawingArea = "unlimited";
                        tempROI.FaceAlpha = 0;
                        app.elpsROIs = [app.elpsROIs, {tempROI}];
                        addlistener(tempROI,'ROIClicked',@app.elpsClck);
                        addlistener(tempROI,'DeletingROI',@app.elpsDlt);
                        addlistener(tempROI,'ROIMoved',@app.elpsMv);
                        app.currentROI = tempROI;
                        app.roiSlct = true;
                        app.updateElpsFields(tempROI);
                        app.updateProjection();
                        app.currentROIType = app.ELPS;
                        app.setActiveFields();
                    end
                elseif(eq(app.TabGroup.SelectedTab,app.CurveTab))
                    tempROI = drawpolyline(app.imagePlot);
                    if (~isempty(tempROI.Position))
                        tempROI.DrawingArea = "unlimited";
                        app.lineROIs = [app.lineROIs, {tempROI}];
                        tempROI.LineWidth = 1;
                        app.lineWidths = [app.lineWidths, 3];
                        addlistener(tempROI,'ROIClicked',@app.lineClck);
                        addlistener(tempROI,'DeletingROI',@app.lineDlt);
                        addlistener(tempROI,'ROIMoved',@app.lineMv);
                        addlistener(tempROI,'VertexAdded',@app.lineMv);
                        addlistener(tempROI,'VertexDeleted',@app.lineMv);
                        app.currentROI = tempROI;
                        app.roiSlct = true;
                        app.updateLineFields(tempROI);
                        app.updateProjection();
                        app.currentROIType = app.LINE;
                        app.setActiveFields();
                    end
                elseif(eq(app.TabGroup.SelectedTab,app.RingTab))
                    tempROI = drawellipse(app.imagePlot);
                    if(~isempty(tempROI.Center))
                        tempROI.DrawingArea = "unlimited";
                        tempROI.FaceAlpha = 0;
                        tempROI.LineWidth = 1;
                        app.ringROIs = [app.ringROIs, {tempROI}];
                        app.ringWidths = [app.ringWidths, 3];
                        addlistener(tempROI,'ROIClicked',@app.ringClck);
                        addlistener(tempROI,'DeletingROI',@app.ringDlt);
                        addlistener(tempROI,'ROIMoved',@app.ringMv);
                        app.currentROI = tempROI;
                        app.roiSlct = true;
                        app.updateRingFields(tempROI);
                        app.updateProjection();
                        app.currentROIType = app.RING;
                        app.setActiveFields();
                    end
                elseif(eq(app.TabGroup.SelectedTab,app.PolygonTab))
                    tempROI = drawpolygon(app.imagePlot);
                    if(~isempty(tempROI.Position))
                        tempROI.DrawingArea = "unlimited";
                        tempROI.FaceAlpha = 0;
                        tempROI.LineWidth = 1;
                        app.plgnROIs = [app.plgnROIs, {tempROI}];
                        addlistener(tempROI,'ROIClicked',@app.plgnClck);
                        addlistener(tempROI,'DeletingROI',@app.plgnDlt);
                        addlistener(tempROI,'ROIMoved',@app.plgnMv);
                        addlistener(tempROI,'VertexAdded',@app.plgnMv);
                        addlistener(tempROI,'VertexDeleted',@app.plgnMv);
                        app.currentROI = tempROI;
                        app.roiSlct = true;
                        app.updatePlgnFields(tempROI);
                        app.updateProjection();
                        app.currentROIType = app.PLGN;
                        app.setActiveFields();
                    end
                end
            end
        end

        % Button pushed function: DeleteButton
        function DelButton(app, event)
            if (app.roiSlct)
                if (app.currentROIType == app.CIRC)
                    app.circDlt(app.currentROI,1);
                elseif (app.currentROIType == app.RECT)
                    app.rectDlt(app.currentROI,1);
                elseif (app.currentROIType == app.ELPS)
                    app.elpsDlt(app.currentROI,1);
                elseif (app.currentROIType == app.LINE)
                    app.lineDlt(app.currentROI,1);
                elseif (app.currentROIType == app.RING)
                    app.ringDlt(app.currentROI,1);
                end
                delete(app.currentROI);
            end
        end

        % Value changed function: radiusEditField
        function chngCircRadius(app, event)
            if (app.roiSlct)
                app.currentROI.Radius = app.radiusEditField.Value;
                app.updateProjection();
            end
        end

        % Value changed function: XcenterEditField
        function chngCircX(app, event)
            if (app.roiSlct)
                coord = app.currentROI.Center;
                coord(1) = app.XcenterEditField.Value;
                app.currentROI.Center = coord;
                app.updateProjection();
            end
        end

        % Value changed function: YcenterEditField
        function chngCircY(app, event)
            if (app.roiSlct)
                coord = app.currentROI.Center;
                coord(2) = app.YcenterEditField.Value;
                app.currentROI.Center = coord;
                app.updateProjection();
            end
        end

        % Menu selected function: ExportAESMaskMenu
        function exportPattern(app, event)
            [file,outPath,indx] = uiputfile('.tif','ROI Mask','mask.tif');
            drawnow;
            figure(app.AESmaskappUIFigure);
            if (~(isequal(file,0)||isequal(outPath,0)))
                valid_output = false;
                opts.Interpreter = 'tex';
                opts.WindowStyle = 'non-modal';
                
                while(~valid_output)
                    userInput = inputdlg({'x (px)','y (px)'},'Mask Displacement',[1,30],{'0','0'});
                    if(isempty(userInput))
                        return;
                    end

                    xDisp = round(str2num(userInput{1}));
                    yDisp = round(str2num(userInput{2}));

                    if (abs(xDisp)>=size(app.roiMask,2) || abs(yDisp) >= size(app.roiMask,1))
                        waitfor(errordlg(strcat('X disp \in (',num2str(-size(app.roiMask,2)),',',num2str(size(app.roiMask,2)),'). Y disp \in (',num2str(-size(app.roiMask,1)),',',num2str(size(app.roiMask,1)),').'),'Bad input',opts));
                    else
                        valid_output = true;
                    end
                end

                userInput = questdlg("Fill in line occupancy?");
                if (strcmp(userInput,'Yes'))
                    fillLines = true;
                elseif(strcmp(userInput,'No'))
                    fillLines = false;
                else
                    return;
                end

                if (app.sepChannels)
                    userInput = questdlg("Include mask with combined channels?");
                    if (strcmp(userInput,'Yes'))
                        combine = true;
                        if (app.bidirectional)
                            totMask = zeros((2*size(app.shiftImage,1)+~app.evenRows),size(app.shiftImage,2));
                        else
                            totMask = zeros(size(app.shiftImage));
                        end
                    elseif (strcmp(userInput,'No'))
                        combine = false;
                    else
                        return;
                    end
                    oldChannel = app.ChannelSpinner.Value;
                    baseName = split(file,'.');
                    baseName = baseName{1};
                    for ii=1:app.channels
                        app.ChannelSpinner.Value = ii;
                        app.chngChannel([]);
                        outMask = app.genMask([yDisp,xDisp],fillLines);
                        imwrite((outMask+0),fullfile(outPath,strcat(baseName,'_',num2str(ii),'.tif')),'Compression','none');
                        if (combine)
                            totMask = totMask + outMask;
                        end
                    end
                    if (combine)
                        imwrite((totMask>0),fullfile(outPath,strcat(baseName,'_combinedCh.tif')),'Compression','none');
                    end
                    app.ChannelSpinner.Value = oldChannel;
                    app.chngChannel([]);
                else
                    outMask = app.genMask([yDisp,xDisp],fillLines);
                    imwrite((outMask+0),fullfile(outPath,file),'Compression','none');
                end
            end
        end

        % Value changed function: XminEditField
        function chngRectX(app, event)
            if (app.roiSlct)
                coord = app.currentROI.Position;
                coord(1) = app.XminEditField.Value;
                app.currentROI.Position = coord;
                app.updateProjection();
            end
        end

        % Value changed function: YminEditField
        function chngRectY(app, event)
            if (app.roiSlct)
                coord = app.currentROI.Position;
                coord(2) = app.YminEditField.Value;
                app.currentROI.Position = coord;
                app.updateProjection();
            end
        end

        % Value changed function: HeightEditField
        function chngRectHeight(app, event)
            if (app.roiSlct)
                coord = app.currentROI.Position;
                coord(4) = app.HeightEditField.Value;
                app.currentROI.Position = coord;
                app.updateProjection();
            end
        end

        % Value changed function: WidthEditField
        function chngRectWidth(app, event)
            if (app.roiSlct)
                coord = app.currentROI.Position;
                coord(3) = app.WidthEditField.Value;
                app.currentROI.Position = coord;
                app.updateProjection();
            end
        end

        % Value changed function: XcenterEditField_2, XcenterEditField_3
        function chngElpsX(app, event)
            if (app.roiSlct)
                coord = app.currentROI.Center;
                coord(1) = event.Source.Value;
                app.currentROI.Center = coord;
                app.updateProjection();
            end
        end

        % Value changed function: YcenterEditField_2, YcenterEditField_3
        function chngElpsY(app, event)
            if (app.roiSlct)
                coord = app.currentROI.Center;
                coord(2) = event.Source.Value;
                app.currentROI.Center = coord;
                app.updateProjection();
            end
        end

        % Value changed function: Semiaxis1EditField, Semiaxis1EditField_2
        function chngElpsAx1(app, event)
            if (app.roiSlct)
                coord = app.currentROI.SemiAxes;
                coord(1) = event.Source.Value;
                app.currentROI.SemiAxes = coord;
                app.updateProjection();
            end
        end

        % Value changed function: Semiaxis2EditField, Semiaxis2EditField_2
        function chngElpsAx2(app, event)
            if (app.roiSlct)
                coord = app.currentROI.SemiAxes;
                coord(2) = event.Source.Value;
                app.currentROI.SemiAxes = coord;
                app.updateProjection();
            end
        end

        % Value changed function: AngleEditField, AngleEditField_2
        function chngElpsAng(app, event)
            if (app.roiSlct)
                app.currentROI.RotationAngle = event.Source.Value;
                app.updateProjection();
            end
        end

        % Menu selected function: SaveROIsMenu
        function saveROIs(app, event)
            [file,path,indx] = uiputfile('.mat','ROI Pattern','roi.mat');
            drawnow;
            figure(app.AESmaskappUIFigure);
            if (~(isequal(file,0)||isequal(path,0)))
                if (app.sepChannels)
                    tempCh = ceil(app.channel/(1+app.bidirectional));

                    % make sure multi channel superstructure is synced to
                    % current channel ROIs
                    app.circROIsCh{tempCh} = app.circROIs;
                    app.rectROIsCh{tempCh} = app.rectROIs;
                    app.elpsROIsCh{tempCh} = app.elpsROIs;
                    app.lineROIsCh{tempCh} = app.lineROIs;
                    app.ringROIsCh{tempCh} = app.ringROIs;
                    app.plgnROIsCh{tempCh} = app.plgnROIs;
                    app.lineWidthsCh{tempCh} = app.lineWidths;
                    app.ringWidthsCh{tempCh} = app.ringWidths;

                    for ii=1:app.channels
                        if (ii~=tempCh)
                            app.shiftROIsCh(ii,-3*size(app.shiftImage));
                        end
                    end
                    sepCh = app.sepChannels;
                    circ = app.circROIsCh;
                    rect = app.rectROIsCh;
                    elps = app.elpsROIsCh;
                    line = app.lineROIsCh;
                    ring = app.ringROIsCh;
                    plgn = app.plgnROIsCh;
                    lws = app.lineWidthsCh;
                    rws = app.ringWidthsCh;
                    save(fullfile(path,file),"sepCh","circ","rect","elps","line","ring","plgn","lws","rws");
                    for ii=1:app.channels
                        if (ii~=tempCh)
                            app.shiftROIsCh(ii,3*size(app.shiftImage));
                        end
                    end
                else
                    circ = app.circROIs;
                    rect = app.rectROIs;
                    elps = app.elpsROIs;
                    line = app.lineROIs;
                    ring = app.ringROIs;
                    plgn = app.plgnROIs;
                    lws = app.lineWidths;
                    rws = app.ringWidths;
                    save(fullfile(path,file),"circ","rect","elps","line","ring","plgn","lws","rws");
                end
            end
        end

        % Menu selected function: LoadROIsMenu
        function loadROIs(app, event)
            if (isempty(app.dispImage))
                msgbox('Need to load image first','error');
                return;
            end

            % queary file from user
            [tempFile, tempPath] = uigetfile('.mat');
            drawnow;
            figure(app.AESmaskappUIFigure);
            % check if file exists
            if ~isequal(tempFile,0)
                temp=load(fullfile(tempPath,tempFile),"sepCh","circ","rect","elps","line","ring","lws","rws","plgn");
                % check if file contains proper variables
                if (isfield(temp,"circ") && isfield(temp,"rect") && isfield(temp,"elps") && isfield(temp,"line") ...
                        && isfield(temp,"ring") && isfield(temp, "lws") && isfield(temp,"rws"))
                    % delete existing rois (still atached to image axis
                    % even if you overwrite variable otherwise)
                    app.unloadROIs();
                    
                    % copy variable to app and attach listeners
                    tempCh = ceil(app.channel / (app.bidirectional + 1));
                    if (isfield(temp,"sepCh") && temp.sepCh)
                        if (app.sepChannels)
                            newLen = length(app.circROIsCh);
                            if (app.channels > newLen)
                                app.circROIsCh(1:newLen) = temp.circ;
                                app.rectROIsCh(1:newLen) = temp.rect;
                                app.elpsROIsCh(1:newLen) = temp.elps;
                                app.lineROIsCh(1:newLen) = temp.line;
                                app.ringROIsCh(1:newLen) = temp.ring;
                                app.plgnROIsCh(1:newLen) = temp.plgn;
                                app.lineWidthsCh(1:newLen) = temp.lws;
                                app.ringWidthsCh(1:newLen) = temp.rws;
                            elseif (app.channels < newLen)
                                app.circROIsCh = temp.circ(1:newLen);
                                app.rectROIsCh = temp.circ(1:newLen);
                                app.elpsROIsCh = temp.elps(1:newLen);
                                app.lineROIsCh = temp.line(1:newLen);
                                app.ringROIsCh = temp.ring(1:newLen);
                                app.plgnROIsCh = temp.plgn(1:newLen);
                                app.lineWidthsCh = temp.lws(1:newLen);
                                app.ringWidthsCh = temp.rws(1:newLen);
                            else
                                app.circROIsCh = temp.circ;
                                app.rectROIsCh = temp.rect;
                                app.elpsROIsCh = temp.elps;
                                app.lineROIsCh = temp.line;
                                app.ringROIsCh = temp.ring;
                                app.plgnROIsCh = temp.plgn;
                                app.lineWidthsCh = temp.lws;
                                app.ringWidthsCh = temp.rws;
                            end

                            for ii = 1:app.channels
                                app.circROIs = app.circROIsCh{ii};
                                app.rectROIs = app.rectROIsCh{ii};
                                app.elpsROIs = app.elpsROIsCh{ii};
                                app.lineROIs = app.lineROIsCh{ii};
                                app.ringROIs = app.ringROIsCh{ii};
                                app.plgnROIs = app.plgnROIsCh{ii};
                                app.lineWidths = app.lineWidthsCh{ii};
                                app.ringWidths = app.ringWidthsCh{ii};
                                app.attachListeners()
                                if (ii ~= tempCh)
                                    app.shiftROIs(3*size(app.shiftImage));
                                end
                            end
                            app.circROIs = app.circROIsCh{tempCh};
                            app.rectROIs = app.rectROIsCh{tempCh};
                            app.elpsROIs = app.elpsROIsCh{tempCh};
                            app.lineROIs = app.lineROIsCh{tempCh};
                            app.ringROIs = app.ringROIsCh{tempCh};
                            app.plgnROIs = app.plgnROIsCh{tempCh};
                            app.lineWidths = app.lineWidthsCh{tempCh};
                            app.ringWidths = app.ringWidthsCh{tempCh};
                        else
                            app.circROIs = temp.circ{1};
                            app.rectROIs = temp.rect{1};
                            app.elpsROIs = temp.elps{1};
                            app.lineROIs = temp.line{1};
                            app.ringROIs = temp.ring{1};
                            app.lineWidths = temp.lws{1};
                            app.ringWidths = temp.rws{1};
                            app.plgnROIs = temp.plgn{1};
                            app.attachListeners();
                        end
                    else
                        app.circROIs = temp.circ;
                        app.rectROIs = temp.rect;
                        app.elpsROIs = temp.elps;
                        app.lineROIs = temp.line;
                        app.ringROIs = temp.ring;
                        app.lineWidths = temp.lws;
                        app.ringWidths = temp.rws;

                        if (isfield(temp,"plgn"))
                            app.plgnROIs = temp.plgn;
                        end

                        if (app.sepChannels)
                            app.circROIsCh{tempCh} = app.circROIs;
                            app.rectROIsCh{tempCh} = app.rectROIs;
                            app.elpsROIsCh{tempCh} = app.elpsROIs;
                            app.lineROIsCh{tempCh} = app.lineROIs;
                            app.ringROIsCh{tempCh} = app.ringROIs;
                            app.lineWidthsCh{tempCh} = app.lineWidths;
                            app.ringWidthsCh{tempCh} = app.ringWidths;
                            app.plgnROIsCh{tempCh} = app.plgnROIs;
                        end

                        app.attachListeners();
                    end

                    % finish up
                    app.roiSlct = false;
                    app.setActiveFields();
                    app.updateProjection();
                else
                    msgbox('File missing information','error');
                end
            end
        end

        % Value changed function: ThicknessEditField
        function chngLineThck(app, event)
            if (app.roiSlct)
                for ii = 1:length(app.lineROIs)
                    if (eq(app.lineROIs{ii},app.currentROI))
                        break;
                    end
                end
                app.lineWidths(ii) = app.ThicknessEditField.Value;
                app.updateProjection();
            end
        end

        % Value changed function: PointsEditField
        function chngLinePts(app, event)
            target = app.PointsEditField.Value;
            coord = app.currentROI.Position;
            diff = target - length(coord(:,1));

            if (diff < 0)
                coord = coord(1:target,:);
                app.currentROI.Position = coord;
            elseif (diff > 0)
                for ii = 1:diff
                    pt = coord(end,:)-50;
                    if (pt(1) < 1)
                        pt(1) = 1;
                    elseif (pt(1) > size(app.refImages,2))
                        pt(1) = size(app.refImages,2);
                    end

                    if(pt(2) < 1)
                        pt(2) = 1;
                    elseif (pt(2) > size(app.refImages,1))
                        pt(2) = size(app.refImages,1);
                    end
                    coord = [coord; pt];
                end

                app.currentROI.Position = coord;
            end
            app.updateProjection();
        end

        % Value changed function: ThicknessEditField_2
        function chngRingThck(app, event)
            if (app.roiSlct)
                for ii = 1:length(app.ringROIs)
                    if (eq(app.ringROIs{ii},app.currentROI))
                        break;
                    end
                end
                app.ringWidths(ii) = event.Source.Value;
                app.updateProjection();
            end
        end

        % Close request function: AESmaskappUIFigure
        function AESmaskappUIFigureCloseRequest(app, event)
            delete(app.chnlDlgBox);
            delete(app.bcDlgBox);
            delete(app);
        end

        % Menu selected function: WindowLevelMenu
        function opnBC(app, event)
            if (isempty(app.bcDlgBox) && ~isempty(app.refImages))
                app.bcDlgBox = BC_helper_app(app,app.shiftImage,app.windows(:,app.channel));
            end
        end

        % Value changed function: TargetOccupancyEditField
        function change_target_occ(app, event)
            app.targetOcc = app.TargetOccupancyEditField.Value;
            app.targetLine.XData = [app.targetOcc, app.targetOcc];
            app.updateProjection();
        end

        % Menu selected function: ExportROIMasksMenu
        function exportROIPatterns(app, event)
            [tempFile,tempPath,indx] = uiputfile('*.*','ROI Mask Basename','roi');
            drawnow;
            figure(app.AESmaskappUIFigure);

            if ~isequal(tempFile,0)
                basename = split(tempFile,'.');
                basename = basename{1};

                if (app.sepChannels)
                    oldChannel = app.ChannelSpinner.Value;
                    for ii = 1:app.channels
                        folderTitle = strcat(basename,"_ch",num2str(ii));
                        [status, msg] = mkdir(tempPath,folderTitle);
                        if (~status)
                            opts.WindowStyle = 'non-modal';
                            waitfor(errordlg(msg,'Folder Write Issue',opts));
                            return;
                        end
                        chPath = fullfile(tempPath,folderTitle);

                        app.ChannelSpinner.Value = ii;
                        app.chngChannel([]);
                        app.printMasks(chPath,basename);
                    end
                    app.ChannelSpinner.Value = oldChannel;
                    app.chngChannel([]);
                else
                    app.printMasks(tempPath,basename);
                end
            end
        end

        % Value changed function: PolygonPointsEditField
        function chngPlgnPts(app, event)
            target = app.PolygonPointsEditField.Value;
            coord = app.currentROI.Position;
            diff = target - length(coord(:,1));

            if (diff < 0)
                coord = coord(1:target,:);
                app.currentROI.Position = coord;
            elseif(diff > 0)
                for ii = 1:diff
                    pt = coord(end,:) - 50;
                    if (pt(1) < 1)
                        pt(1) = 1;
                    elseif (pt(1) > size(app.refImages,2))
                        pt(1) = size(app.refImages,2);
                    end

                    if (pt(2) < 1)
                        pt(2) = 1;
                    elseif (pt(2) > size(app.refImages,1))
                        pt(2) = size(app.refImages,1);
                    end
                    coord = [coord; pt];
                end

                app.currentROI.Position = coord;
            end
            app.updateProjection();
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            oldChannel = app.channel;
            app.channel = app.ChannelSpinner.Value;
            if (app.bidirectional)
                app.channel = round(2*app.ChannelSpinner.Value)-1;
                if (app.channel < 1)
                    app.channel = 1;
                elseif (app.channel > (2*app.channels))
                    app.channel = 2*app.channels;
                end
                app.ChannelSpinner.Value = (app.channel+1)/2;

                if (mod(app.channel,2)==1)
                    app.shiftImage = circshift(app.refImages(:,:,app.channel),app.yShift,1);
                else
                    app.shiftImage = app.refImages(:,:,app.channel);
                end
            else
                app.shiftImage = app.refImages(:,:,app.channel);
            end

            if (app.sepChannels)
                chngROI = false;
                if (app.bidirectional)
                    chIndex = ceil(app.channel/2);
                    oldChIndex = ceil(oldChannel/2);
                    if (oldChIndex~=chIndex)
                        chngROI = true;
                    end
                else
                    chngROI = true;
                    chIndex = app.channel;
                    oldChIndex = oldChannel;
                end
                
                if (chngROI)
                    app.shiftROIs(3*size(app.shiftImage));
                    app.circROIsCh{oldChIndex} = app.circROIs;
                    app.rectROIsCh{oldChIndex} = app.rectROIs;
                    app.elpsROIsCh{oldChIndex} = app.elpsROIs;
                    app.lineROIsCh{oldChIndex} = app.lineROIs;
                    app.ringROIsCh{oldChIndex} = app.ringROIs;
                    app.plgnROIsCh{oldChIndex} = app.plgnROIs;
                    app.lineWidthsCh{oldChIndex} = app.lineWidths;
                    app.ringWidthsCh{oldChIndex} = app.ringWidths;
                    
                    app.circROIs = app.circROIsCh{chIndex};
                    app.rectROIs = app.rectROIsCh{chIndex};
                    app.elpsROIs = app.elpsROIsCh{chIndex};
                    app.lineROIs = app.lineROIsCh{chIndex};
                    app.ringROIs = app.ringROIsCh{chIndex};
                    app.plgnROIs = app.plgnROIsCh{chIndex};
                    app.lineWidths = app.lineWidthsCh{chIndex};
                    app.ringWidths = app.ringWidthsCh{chIndex};
                    
                    app.shiftROIs(-3*size(app.shiftImage));
                end

                app.updateProjection();
            end

            app.chngImgBnds(app.windows(:,app.channel));
            
            if (~isempty(app.bcDlgBox))
                app.bcDlgBox.reInit(app.shiftImage,app.windows(:,app.channel));
            end
        end

        % Value changed function: YShiftSpinner
        function chngShift(app, event)
            app.yShift = app.YShiftSpinner.Value;
            if (mod(app.channel,2)==1)
                app.shiftImage = circshift(app.refImages(:,:,app.channel),app.yShift,1);
                app.chngImgBnds(app.windows(:,app.channel));
            end
        end

        % Button pushed function: YShiftAutoButton
        function autoYShift(app, event)
            if (app.bidirectional)
                temp = xcorr2((app.refImages(:,:,(2*app.mainChannel-1))-mean(app.refImages(:,:,(2*app.mainChannel-1)),'all')),(app.refImages(:,:,(2*app.mainChannel))-mean(app.refImages(:,:,(2*app.mainChannel)),'all')));
                temp = temp(:,size(app.shiftImage,2));
                [t, ind] = max(temp);
                app.yShift = size(app.shiftImage,1)-ind;
                app.YShiftSpinner.Value = app.yShift;
                if (mod(app.channel,2)==1)
                    app.shiftImage = circshift(app.refImages(:,:,app.channel),app.yShift,1);
                    app.chngImgBnds(app.windows(:,app.channel));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AESmaskappUIFigure = uifigure('Visible', 'off');
            app.AESmaskappUIFigure.Name = 'AES mask app';
            app.AESmaskappUIFigure.Icon = fullfile(pathToMLAPP, 'thumbnail_mask_app.png');
            app.AESmaskappUIFigure.CloseRequestFcn = createCallbackFcn(app, @AESmaskappUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.AESmaskappUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @loadImage, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.LoadROIsMenu = uimenu(app.FileMenu);
            app.LoadROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @loadROIs, true);
            app.LoadROIsMenu.Text = 'Load ROIs';
            app.SaveROIsMenu = uimenu(app.FileMenu);
            app.SaveROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @saveROIs, true);
            app.SaveROIsMenu.Text = 'Save ROIs';
            app.ExportAESMaskMenu = uimenu(app.FileMenu);
            app.ExportAESMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @exportPattern, true);
            app.ExportAESMaskMenu.Text = 'Export AES Mask';
            app.ExportROIMasksMenu = uimenu(app.FileMenu);
            app.ExportROIMasksMenu.MenuSelectedFcn = createCallbackFcn(app, @exportROIPatterns, true);
            app.ExportROIMasksMenu.Text = 'Export ROI Masks';
            app.ImageMenu = uimenu(app.AESmaskappUIFigure);
            app.ImageMenu.Text = 'Image';
            app.WindowLevelMenu = uimenu(app.ImageMenu);
            app.WindowLevelMenu.MenuSelectedFcn = createCallbackFcn(app, @opnBC, true);
            app.WindowLevelMenu.Text = 'Window/Level';
            app.GridLayout3 = uigridlayout(app.AESmaskappUIFigure);
            app.ImagePanel = uipanel(app.GridLayout3);
            app.ImagePanel.Title = 'Image';
            app.GridLayout2 = uigridlayout(app.ImagePanel);
            app.occPlot = uiaxes(app.GridLayout2);
            title(app.occPlot, 'Occupancy')
            xlabel(app.occPlot, '%')
            ylabel(app.occPlot, 'Row')
            zlabel(app.occPlot, 'Z')
            app.occPlot.YDir = 'reverse';
            app.imagePlot = uiaxes(app.GridLayout2);
            title(app.imagePlot, 'No File Selected')
            xlabel(app.imagePlot, 'X')
            ylabel(app.imagePlot, 'Y')
            zlabel(app.imagePlot, 'Z')
            app.ROIControlsPanel = uipanel(app.GridLayout3);
            app.ROIControlsPanel.Title = 'ROI Controls';
            app.GridLayout6 = uigridlayout(app.ROIControlsPanel);
            app.TabGroup = uitabgroup(app.GridLayout6);
            app.CircleTab = uitab(app.TabGroup);
            app.CircleTab.Title = 'Circle';
            app.GridLayout4 = uigridlayout(app.CircleTab);
            app.radiusLabel = uilabel(app.GridLayout4);
            app.radiusLabel.Text = 'radius:';
            app.radiusEditField = uieditfield(app.GridLayout4, 'numeric');
            app.radiusEditField.ValueChangedFcn = createCallbackFcn(app, @chngCircRadius, true);
            app.radiusEditField.Editable = 'off';
            app.radiusEditField.Enable = 'off';
            app.XcenterLabel = uilabel(app.GridLayout4);
            app.XcenterLabel.Text = 'X center:';
            app.XcenterEditField = uieditfield(app.GridLayout4, 'numeric');
            app.XcenterEditField.ValueChangedFcn = createCallbackFcn(app, @chngCircX, true);
            app.XcenterEditField.Editable = 'off';
            app.XcenterEditField.Enable = 'off';
            app.YcenterEditFieldLabel = uilabel(app.GridLayout4);
            app.YcenterEditFieldLabel.Text = 'Y center:';
            app.YcenterEditField = uieditfield(app.GridLayout4, 'numeric');
            app.YcenterEditField.ValueChangedFcn = createCallbackFcn(app, @chngCircY, true);
            app.YcenterEditField.Editable = 'off';
            app.YcenterEditField.Enable = 'off';
            app.EllipseTab = uitab(app.TabGroup);
            app.EllipseTab.Title = 'Ellipse';
            app.GridLayout7 = uigridlayout(app.EllipseTab);
            app.XcenterEditField_2Label = uilabel(app.GridLayout7);
            app.XcenterEditField_2Label.Text = 'X center:';
            app.XcenterEditField_2 = uieditfield(app.GridLayout7, 'numeric');
            app.XcenterEditField_2.ValueChangedFcn = createCallbackFcn(app, @chngElpsX, true);
            app.XcenterEditField_2.Editable = 'off';
            app.XcenterEditField_2.Enable = 'off';
            app.YcenterEditField_2Label = uilabel(app.GridLayout7);
            app.YcenterEditField_2Label.Text = 'Y center:';
            app.YcenterEditField_2 = uieditfield(app.GridLayout7, 'numeric');
            app.YcenterEditField_2.ValueChangedFcn = createCallbackFcn(app, @chngElpsY, true);
            app.YcenterEditField_2.Editable = 'off';
            app.YcenterEditField_2.Enable = 'off';
            app.MajoraxisLabel = uilabel(app.GridLayout7);
            app.MajoraxisLabel.Text = 'Semi axis 1:';
            app.Semiaxis1EditField = uieditfield(app.GridLayout7, 'numeric');
            app.Semiaxis1EditField.ValueChangedFcn = createCallbackFcn(app, @chngElpsAx1, true);
            app.Semiaxis1EditField.Editable = 'off';
            app.Semiaxis1EditField.Enable = 'off';
            app.Semiaxis2Label = uilabel(app.GridLayout7);
            app.Semiaxis2Label.Text = 'Semi axis 2:';
            app.Semiaxis2EditField = uieditfield(app.GridLayout7, 'numeric');
            app.Semiaxis2EditField.ValueChangedFcn = createCallbackFcn(app, @chngElpsAx2, true);
            app.Semiaxis2EditField.Editable = 'off';
            app.Semiaxis2EditField.Enable = 'off';
            app.AngleEditFieldLabel = uilabel(app.GridLayout7);
            app.AngleEditFieldLabel.Text = 'Angle:';
            app.AngleEditField = uieditfield(app.GridLayout7, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @chngElpsAng, true);
            app.AngleEditField.Editable = 'off';
            app.AngleEditField.Enable = 'off';
            app.RectangleTab = uitab(app.TabGroup);
            app.RectangleTab.Title = 'Rectangle';
            app.GridLayout5 = uigridlayout(app.RectangleTab);
            app.XminEditFieldLabel = uilabel(app.GridLayout5);
            app.XminEditFieldLabel.Text = 'X min:';
            app.XminEditField = uieditfield(app.GridLayout5, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @chngRectX, true);
            app.XminEditField.Editable = 'off';
            app.XminEditField.Enable = 'off';
            app.YminEditFieldLabel = uilabel(app.GridLayout5);
            app.YminEditFieldLabel.Text = 'Y min:';
            app.YminEditField = uieditfield(app.GridLayout5, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @chngRectY, true);
            app.YminEditField.Editable = 'off';
            app.YminEditField.Enable = 'off';
            app.WidthEditFieldLabel = uilabel(app.GridLayout5);
            app.WidthEditFieldLabel.Text = 'Width:';
            app.WidthEditField = uieditfield(app.GridLayout5, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @chngRectWidth, true);
            app.WidthEditField.Editable = 'off';
            app.WidthEditField.Enable = 'off';
            app.HeightEditFieldLabel = uilabel(app.GridLayout5);
            app.HeightEditFieldLabel.Text = 'Height:';
            app.HeightEditField = uieditfield(app.GridLayout5, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @chngRectHeight, true);
            app.HeightEditField.Editable = 'off';
            app.HeightEditField.Enable = 'off';
            app.RingTab = uitab(app.TabGroup);
            app.RingTab.Title = 'Ring';
            app.GridLayout9 = uigridlayout(app.RingTab);
            app.XcenterEditField_3Label = uilabel(app.GridLayout9);
            app.XcenterEditField_3Label.Text = 'X center:';
            app.XcenterEditField_3 = uieditfield(app.GridLayout9, 'numeric');
            app.XcenterEditField_3.ValueChangedFcn = createCallbackFcn(app, @chngElpsX, true);
            app.XcenterEditField_3.Editable = 'off';
            app.XcenterEditField_3.Enable = 'off';
            app.YcenterEditField_3Label = uilabel(app.GridLayout9);
            app.YcenterEditField_3Label.Text = 'Y center:';
            app.YcenterEditField_3 = uieditfield(app.GridLayout9, 'numeric');
            app.YcenterEditField_3.ValueChangedFcn = createCallbackFcn(app, @chngElpsY, true);
            app.YcenterEditField_3.Editable = 'off';
            app.YcenterEditField_3.Enable = 'off';
            app.Semiaxis1EditField_2Label = uilabel(app.GridLayout9);
            app.Semiaxis1EditField_2Label.Text = 'Semi axis 1:';
            app.Semiaxis1EditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.Semiaxis1EditField_2.ValueChangedFcn = createCallbackFcn(app, @chngElpsAx1, true);
            app.Semiaxis1EditField_2.Editable = 'off';
            app.Semiaxis1EditField_2.Enable = 'off';
            app.Semiaxis2EditField_2Label = uilabel(app.GridLayout9);
            app.Semiaxis2EditField_2Label.Text = 'Semi axis 2:';
            app.Semiaxis2EditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.Semiaxis2EditField_2.ValueChangedFcn = createCallbackFcn(app, @chngElpsAx2, true);
            app.Semiaxis2EditField_2.Editable = 'off';
            app.Semiaxis2EditField_2.Enable = 'off';
            app.AngleEditField_2Label = uilabel(app.GridLayout9);
            app.AngleEditField_2Label.Text = 'Angle:';
            app.AngleEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.AngleEditField_2.ValueChangedFcn = createCallbackFcn(app, @chngElpsAng, true);
            app.AngleEditField_2.Editable = 'off';
            app.AngleEditField_2.Enable = 'off';
            app.ThicknessEditField_2Label = uilabel(app.GridLayout9);
            app.ThicknessEditField_2Label.Text = 'Thickness:';
            app.ThicknessEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.ThicknessEditField_2.ValueChangedFcn = createCallbackFcn(app, @chngRingThck, true);
            app.ThicknessEditField_2.Editable = 'off';
            app.ThicknessEditField_2.Enable = 'off';
            app.CurveTab = uitab(app.TabGroup);
            app.CurveTab.Title = 'Curve';
            app.GridLayout8 = uigridlayout(app.CurveTab);
            app.ThicknessEditFieldLabel = uilabel(app.GridLayout8);
            app.ThicknessEditFieldLabel.Text = 'Thickness:';
            app.ThicknessEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ThicknessEditField.Limits = [1 Inf];
            app.ThicknessEditField.RoundFractionalValues = 'on';
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @chngLineThck, true);
            app.ThicknessEditField.Editable = 'off';
            app.ThicknessEditField.Enable = 'off';
            app.ThicknessEditField.Value = 1;
            app.PointsEditFieldLabel = uilabel(app.GridLayout8);
            app.PointsEditFieldLabel.Text = 'Points:';
            app.PointsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.PointsEditField.Limits = [1 Inf];
            app.PointsEditField.RoundFractionalValues = 'on';
            app.PointsEditField.ValueChangedFcn = createCallbackFcn(app, @chngLinePts, true);
            app.PointsEditField.Editable = 'off';
            app.PointsEditField.Enable = 'off';
            app.PointsEditField.Value = 1;
            app.PolygonTab = uitab(app.TabGroup);
            app.PolygonTab.Title = 'Polygon';
            app.GridLayout10 = uigridlayout(app.PolygonTab);
            app.PointsEditField_2Label = uilabel(app.GridLayout10);
            app.PointsEditField_2Label.Text = 'Points:';
            app.PolygonPointsEditField = uieditfield(app.GridLayout10, 'numeric');
            app.PolygonPointsEditField.Limits = [1 Inf];
            app.PolygonPointsEditField.RoundFractionalValues = 'on';
            app.PolygonPointsEditField.ValueChangedFcn = createCallbackFcn(app, @chngPlgnPts, true);
            app.PolygonPointsEditField.Value = 1;
            app.AddButton = uibutton(app.GridLayout6, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @addROI, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.GridLayout6, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DelButton, true);
            app.DeleteButton.Text = 'Delete';
            app.TargetOccupancyEditFieldLabel = uilabel(app.GridLayout3);
            app.TargetOccupancyEditFieldLabel.Text = 'Target Occupancy';
            app.TargetOccupancyEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TargetOccupancyEditField.Limits = [0 100];
            app.TargetOccupancyEditField.ValueChangedFcn = createCallbackFcn(app, @change_target_occ, true);
            app.TargetOccupancyEditField.Value = 3.3;
            app.ChannelSpinnerLabel = uilabel(app.GridLayout3);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.GridLayout3);
            app.ChannelSpinner.Limits = [1 1.1];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Editable = 'off';
            app.ChannelSpinner.Enable = 'off';
            app.ChannelSpinner.Value = 1;
            app.YShiftSpinnerLabel = uilabel(app.GridLayout3);
            app.YShiftSpinnerLabel.Text = 'Y Shift';
            app.YShiftSpinner = uispinner(app.GridLayout3);
            app.YShiftSpinner.RoundFractionalValues = 'on';
            app.YShiftSpinner.ValueChangedFcn = createCallbackFcn(app, @chngShift, true);
            app.YShiftSpinner.Editable = 'off';
            app.YShiftSpinner.Enable = 'off';
            app.YShiftAutoButton = uibutton(app.GridLayout3, 'push');
            app.YShiftAutoButton.ButtonPushedFcn = createCallbackFcn(app, @autoYShift, true);
            app.YShiftAutoButton.Enable = 'off';
            app.YShiftAutoButton.Text = 'Auto';
            % Show the figure after all components are created
            app.AESmaskappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aes_image_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AESmaskappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupTasks)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AESmaskappUIFigure)
        end
    end
end