classdef aes_video_mask_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadImageMenu                matlab.ui.container.Menu
        LoadROIsMenu                 matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SaveAsMenu                   matlab.ui.container.Menu
        ExportAESMaskMenu            matlab.ui.container.Menu
        ExportROIMasksMenu           matlab.ui.container.Menu
        ImageMenu                    matlab.ui.container.Menu
        WindowLevelMenu              matlab.ui.container.Menu
        BidirectionalShiftMenu       matlab.ui.container.Menu
        DemixCrosstalkMenu           matlab.ui.container.Menu
        PlaybackOptionsMenu          matlab.ui.container.Menu
        ModulationMenu               matlab.ui.container.Menu
        SliceModulationMenu          matlab.ui.container.Menu
        ROIModulationMenu            matlab.ui.container.Menu
        FieldUniformityMenu          matlab.ui.container.Menu
        TransmissionCalibrationMenu  matlab.ui.container.Menu
        GridLayout3                  matlab.ui.container.GridLayout
        ROIPanel                     matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        ROIDeleteButton              matlab.ui.control.Button
        ThresholdMaskPanel           matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        MaxHoleSpinner               matlab.ui.control.Spinner
        MaxHoleSpinnerLabel          matlab.ui.control.Label
        ThresholdButton              matlab.ui.control.Button
        BlockSliceOverlapCheckBox    matlab.ui.control.CheckBox
        ApplytoAllSlicesCheckBox     matlab.ui.control.CheckBox
        BorderSpinner                matlab.ui.control.Spinner
        BorderSpinnerLabel           matlab.ui.control.Label
        ShapesPanel                  matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        DuplicateButton              matlab.ui.control.Button
        ROIField6                    matlab.ui.control.NumericEditField
        ROILabel6                    matlab.ui.control.Label
        ROIField5                    matlab.ui.control.NumericEditField
        ROILabel5                    matlab.ui.control.Label
        ROIField4                    matlab.ui.control.NumericEditField
        ROILabel4                    matlab.ui.control.Label
        ROIField3                    matlab.ui.control.NumericEditField
        ROILabel3                    matlab.ui.control.Label
        ROIField2                    matlab.ui.control.NumericEditField
        ROILabel2                    matlab.ui.control.Label
        ROIField1                    matlab.ui.control.NumericEditField
        ROILabel1                    matlab.ui.control.Label
        ROIShapeDropDown             matlab.ui.control.DropDown
        ROIShapeDropDownLabel        matlab.ui.control.Label
        ROIAddButton                 matlab.ui.control.Button
        ImagePanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout11                 matlab.ui.container.GridLayout
        FrameLabel                   matlab.ui.control.Label
        PlayButton                   matlab.ui.control.StateButton
        StopButton                   matlab.ui.control.Button
        FrameForwardButton           matlab.ui.control.Button
        FrameBackwardButton          matlab.ui.control.Button
        ScrubberAxes                 matlab.ui.control.UIAxes
        StackNavigationPanel         matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        FrameParityButton            matlab.ui.control.StateButton
        FrameParityLabel             matlab.ui.control.Label
        ChannelSpinner               matlab.ui.control.Spinner
        ChannelSpinnerLabel          matlab.ui.control.Label
        SliceSpinner                 matlab.ui.control.Spinner
        SliceSpinnerLabel            matlab.ui.control.Label
        OccupancyPanel               matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        OccTotLabel                  matlab.ui.control.Label
        TotalLabel                   matlab.ui.control.Label
        OccChLabel                   matlab.ui.control.Label
        ChannelLabel                 matlab.ui.control.Label
        OccLabel                     matlab.ui.control.Label
        ImageLabel                   matlab.ui.control.Label
        imagePlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % related to file management
        tempFile
        tempPath
        roiFile
        roiPath

        % stack structure variables
        vidParams
        channel
        mainChannel
        channels
        slice
        slices
        sepChannels
        isVolume

        % image variables
        refImages
        dmxImages
        dispImage
        shiftImage
        contrImage
        BCwndw
        imhandle
        op = 0.4;
        op2 = 0.55;
        parityFlag
        W
        H
        dmxBuff
        borderMask
        numPx
        minVals

        % for y axis bidirectional images
        bidirectional
        yBidirectional
        zBidirectional
        frameParity
        yShift
        autoYShiftVal
        htot
        h

        % roi elements
        shapeMaskHandler
        threshMaskHandler
        powerMod
        cntrlPressed
        occ

        % video variable
        tin
        ff
        tiffCounter
        tiffRefreshCount = 500;
        bcCounter
        bcRefreshCount
        slowZ
        frames
        frameInd
        currentFrames
        vidImage
        fps=30
        frmBfr
        frmBfrSz
        frmBfrInd
        frmBfrBidi
        vidPar
        playing
        avg
        frmDmx
        frmDmxVec
        usingProj
        tmr

        frameBar
        hitBar
        playIcon
        pauseIcon
        stopIcon
        stopActiveIcon
        scrubberColorActive = [0.3,0.3,0.3];
        scrubberColorInactive = [0.7,0.7,0.7];

        ROIFields
        ROILabels
        numActiveFields

        chnlDlgBox
        bcDlgBox
        bidiShiftDlgBox
        thresholdDlgBox
        dmxDlgBox
        playbackDlgBox
        slicePowerDlgBox
        roiPowerDlgBox
        fovPowerDlgBox
        transCalDlgBox
        duplicateDlgBox
        outputDlgBox
        interruptDialog
    end
    
    properties (GetAccess = public, SetAccess = private)
        v
        file
    end

    properties (Access = public)
        % tracks location of last accessed file
        path
    end
    
    methods (Access = public)
        %% functions used by shape roi handler
        function setROILabels(app,labelStrs,roiType)
            if (roiType > 0)
                app.ROIShapeDropDown.Value = roiType;
            end

            app.numActiveFields = length(labelStrs);
            for ii=1:app.numActiveFields
                app.ROILabels{ii}.Text = labelStrs{ii};
            end

            if (length(app.ROILabels)>length(app.numActiveFields))
                for ii=(app.numActiveFields+1):length(app.ROILabels)
                    app.ROILabels{ii}.Text = 'NA:';
                    app.ROIFields{ii}.Value = 0;
                    app.ROIFields{ii}.Enable = false;
                end
            end
        end

        function enableROIFields(app,enable)
            app.DuplicateButton.Enable = enable;
            if (enable)
                for ii=1:app.numActiveFields
                    app.ROIFields{ii}.Enable = true;
                end
            else
                for ii=1:app.numActiveFields
                    app.ROIFields{ii}.Enable = false;
                    app.ROIFields{ii}.Value = 0;
                end
            end
            app.ROIDeleteButton.Enable = enable;
        end

        function setROIFields(app,vals)
            for ii=1:length(vals)
                app.ROIFields{ii}.Value = vals(ii);
            end
        end

        %% main function for setting image (t-projection)
        function selectRefImage(app)
            if (app.yBidirectional && app.frameParity)
                app.shiftImage = circshift(app.dmxImages(:,:,app.slice,2*app.channel-1),app.yShift,1);
            else
                app.shiftImage = app.dmxImages(:,:,app.slice,(1+app.bidirectional)*app.channel-app.frameParity);
            end
            app.shiftImage = app.powerMod.getImFactor(app.slice,app.channel).*app.shiftImage;

            if (app.parityFlag)
                app.parityFlag = false;
            else
                if (~isempty(app.thresholdDlgBox))
                    app.thresholdDlgBox.reinit();
                end
            end
            app.chngImgBnds(app.BCwndw(app.channel,:));

            if (~isempty(app.bcDlgBox))
                app.bcDlgBox.reinit(app.shiftImage,app.BCwndw(app.channel,:));
            end
        end

        %% Main Function for updating display with mask overlay
        function updateDisplay(app)
            app.borderMask = app.threshMaskHandler.fullMask - app.threshMaskHandler.baseMask + app.threshMaskHandler.excludedMask;
            app.dispImage(:,:,1) = app.contrImage .* (1 - app.threshMaskHandler.fullMask) + app.threshMaskHandler.fullMask .* (app.op + app.contrImage * (1-app.op));
            app.dispImage(:,:,2) = app.contrImage .* (1 - app.borderMask) + app.borderMask .* (app.op + app.contrImage * (1-app.op));
            app.dispImage(:,:,3) = app.contrImage .* (1 - app.shapeMaskHandler.getMask()) + app.shapeMaskHandler.getMask() .* (app.op + app.contrImage * (1-app.op));
            app.dispImage(:,:,3) = app.dispImage(:,:,3) .* (1-app.op2*app.threshMaskHandler.selectedMask) + app.threshMaskHandler.selectedMask .* (app.op2 + app.dispImage(:,:,3) * (1-app.op2));
            app.dispImage(:,:,2) = app.dispImage(:,:,2) .* (1-app.op2*app.shapeMaskHandler.getSelectedMask()) + app.shapeMaskHandler.getSelectedMask() .* (app.op2 + app.dispImage(:,:,2) * (1-app.op2));
            app.imhandle.CData = app.dispImage;
        end

        % handles hit detection for threshold blobs when clicking on image
        function ImgClk(app,~,evt)
            coord = ceil(evt.IntersectionPoint);
            x = coord(1);
            y = coord(2);
            app.shapeMaskHandler.deselectROI();
            app.enableROIFields(false);
            app.ROIDeleteButton.Enable = app.threshMaskHandler.imgClk(x,y,app.cntrlPressed);
            app.updateDisplay();
        end

        function deselectThresh(app)
            app.threshMaskHandler.deselectROI();
        end

        %% functions for updating/displaying mask occupancy
        function updateOccLabels(app)
            if (app.sepChannels)
                app.OccLabel.Text = strcat(num2str(round(1000*app.occ(app.slice,app.channel))/10),"%");
                app.OccChLabel.Text = strcat(num2str(round(1000*mean(app.occ(:,app.channel)))/10),"%");
                app.OccTotLabel.Text = strcat(num2str(round(1000*mean(app.occ,'all'))/10),"%");
            else
                app.OccLabel.Text = strcat(num2str(round(1000*app.occ(app.slice,1))/10),"%");
                app.OccChLabel.Text = strcat(num2str(round(1000*mean(app.occ(:,1)))/10),"%");
                app.OccTotLabel.Text = app.OccChLabel.Text;
            end

            if (~isempty(app.slicePowerDlgBox))
                 app.slicePowerDlgBox.updateOcc(app.occ);
            end
        end

        function calcOcc(app,sl,ch)
            app.occ(sl,ch) = sum(((app.threshMaskHandler.getFullMask(sl,ch)+app.shapeMaskHandler.getChMask(sl,ch))>0),'all')/app.numPx;
        end

        function updateOcc(app)
            if (app.sepChannels)
                app.calcOcc(app.slice,app.channel);
            else
                app.calcOcc(app.slice,1);
            end
            app.updateOccLabels();
        end

        function updateChOcc(app)
            if (app.sepChannels)
                for sl=1:app.slices
                    app.calcOcc(sl,app.channel);
                end
            else
                for sl=1:app.slices
                    app.calcOcc(sl,1);
                end
            end
            app.updateOccLabels();
        end

        function updateTotOcc(app)
            if (app.sepChannels)
                for sl=1:app.slices
                    for ch=1:app.channels
                        app.calcOcc(sl,ch);
                    end
                end
            else
                for sl=1:app.slices
                    app.calcOcc(sl,1);
                end
            end
            app.updateOccLabels();
        end

        %% functions for video playback
        % read in frame and push to display
        function loadFrame(app)
            app.tiffCounter = app.tiffCounter+1;
            if (app.tiffCounter == app.tiffRefreshCount)
                app.tiffCounter = 1;
                tempDirIn = app.tin.currentDirectory();
                close(app.tin);
                clear app.tin;
                app.tin = Tiff(app.ff,'r');
                app.tin.setDirectory(tempDirIn);
            end

            app.vidPar = mod(app.frameInd,2)==1;
            if (~app.playing || (app.frameInd==1))
                app.setDir();
                app.readFrame(false);
                
                if (app.avg)
                    app.frmBfrInd = 1;
                    for ch=1:app.channels
                        app.frmBfr(:,:,:,ch) = repmat(app.currentFrames(:,:,ch)/app.frmBfrSz,1,1,app.frmBfrSz);
                    end
                end
            else
                if (app.isVolume && ~app.slowZ)
                    app.setDir();
                elseif (app.slowZ || ~app.bidirectional || app.vidPar)
                    app.tin.nextDirectory();
                end

                if (app.avg)
                    for ch=1:app.channels
                        app.currentFrames(:,:,ch) = app.currentFrames(:,:,ch) - app.frmBfr(:,:,app.frmBfrInd,ch);
                    end
                    app.readFrame(true);
                    for ch=1:app.channels
                        app.currentFrames(:,:,ch) = app.currentFrames(:,:,ch) + app.frmBfr(:,:,app.frmBfrInd,ch);
                    end
                    app.frmBfrInd = mod(app.frmBfrInd,app.frmBfrSz)+1;
                else
                    app.readFrame(false);
                end
            end

            if (app.frmDmx)
                app.vidImage = sum(app.frmDmxVec.*app.currentFrames,3);
            else
                app.vidImage = app.currentFrames(:,:,app.channel);
            end

            app.vidImage = app.vidImage .* app.powerMod.getImFactor(app.slice,app.channel);
            tempMaskLow = app.vidImage > app.BCwndw(app.channel,1);
            tempMaskHigh = app.vidImage > app.BCwndw(app.channel,2);
            app.contrImage = tempMaskHigh + (tempMaskLow .* (1 - tempMaskHigh)) .* (app.vidImage - app.BCwndw(app.channel,1)) / (app.BCwndw(app.channel,2) - app.BCwndw(app.channel,1));
            app.updateDisplay();
        end

        % helper to set directory in tiff feed to match current frame
        function setDir(app)
            if (app.isVolume)
                if (app.slowZ)
                    app.tin.setDirectory((app.slice-1)*app.frames*app.channels+(app.frameInd-1)*app.channels+1);
                else
                    if (app.zBidirectional && (~app.vidPar))
                        app.tin.setDirectory(app.frameInd*app.slices*app.channels-app.slice*app.channels+1);
                    else
                        app.tin.setDirectory((app.frameInd-1)*app.slices*app.channels+(app.slice-1)*app.channels+1);
                    end
                end
            else
                if (app.yBidirectional)
                    app.tin.setDirectory((ceil(app.frameInd/2)-1)*app.channels+1);
                else
                    app.tin.setDirectory((app.frameInd-1)*app.channels+1);
                end
            end
        end

        % helper to read next frame from tiff feed
        function readFrame(app,toBuffer)
            if (toBuffer)
                if (app.isVolume && ~app.slowZ)
                    if (app.yBidirectional)
                        if (app.vidPar)
                            app.frmBfr(:,:,app.frmBfrInd,1) = circshift((double(app.tin.read())-app.minVals(1)),app.yShift,1)/app.frmBfrSz;
                            for ch=2:app.channels
                                app.tin.nextDirectory();
                                app.frmBfr(:,:,app.frmBfrInd,ch) = circshift((double(app.tin.read())-app.minVals(ch)),app.yShift,1)/app.frmBfrSz;
                            end
                        else
                            app.frmBfr(:,:,app.frmBfrInd,1) = flipud((double(app.tin.read()))-app.minVals(1))/app.frmBfrSz;
                            for ch=2:app.channels
                                app.tin.nextDirectory();
                                app.frmBfr(:,:,app.frmBfrInd,ch) = flipud((double(app.tin.read()))-app.minVals(ch))/app.frmBfrSz;
                            end
                        end
                    else
                        app.frmBfr(:,:,app.frmBfrInd,1) = (double(app.tin.read())-app.minVals(1))/app.frmBfrSz;
                        for ch=2:app.channels
                            app.tin.nextDirectory();
                            app.frmBfr(:,:,app.frmBfrInd,ch) = (double(app.tin.read())-app.minVals(ch))/app.frmBfrSz;
                        end
                    end
                else
                    if (app.yBidirectional)
                        if (app.vidPar || ~app.playing)
                            app.frmBfrBidi(:,:,1) = (double(app.tin.read())-app.minVals(1));
                            for ch=2:app.channels
                                app.tin.nextDirectory();
                                app.frmBfrBidi(:,:,ch) = (double(app.tin.read())-app.minVals(ch));
                            end
                        end

                        if (app.vidPar)
                            for ch=1:app.channels
                                app.frmBfr(:,:,app.frmBfrInd,ch) = circshift(app.frmBfrBidi(1:app.h,:,ch),app.yShift,1)/app.frmBfrSz;
                            end
                        else
                            for ch=1:app.channels
                                app.frmBfr(:,:,app.frmBfrInd,ch) = flipud(app.frmBfrBidi((app.htot-app.h+1):app.htot,:,ch))/app.frmBfrSz;
                            end
                        end
                    else
                        app.frmBfr(:,:,app.frmBfrInd,1) = (double(app.tin.read())-app.minVals(1))/app.frmBfrSz;
                        for ch=2:app.channels
                            app.tin.nextDirectory();
                            app.frmBfr(:,:,app.frmBfrInd,ch) = (double(app.tin.read())-app.minVals(ch))/app.frmBfrSz;
                        end
                    end
                end
            else
                if (app.isVolume && ~app.slowZ)
                    if (app.yBidirectional)
                        if (app.vidPar)
                            app.currentFrames(:,:,1) = circshift((double(app.tin.read())-app.minVals(1)),app.yShift,1);
                            for ch=2:app.channels
                                app.tin.nextDirectory();
                                app.currentFrames(:,:,ch) = circshift((double(app.tin.read())-app.minVals(ch)),app.yShift,1);
                            end
                        else
                            app.currentFrames(:,:,1) = flipud((double(app.tin.read())-app.minVals(1)));
                            for ch=2:app.channels
                                app.tin.nextDirectory();
                                app.currentFrames(:,:,ch) = flipud((double(app.tin.read())-app.minVals(ch)));
                            end
                        end
                    else
                        app.currentFrames(:,:,1) = (double(app.tin.read())-app.minVals(1));
                        for ch=2:app.channels
                            app.tin.nextDirectory();
                            app.currentFrames(:,:,ch) = (double(app.tin.read())-app.minVals(ch));
                        end
                    end
                else
                    if (app.yBidirectional)
                        if (app.vidPar || ~app.playing)
                            app.frmBfrBidi(:,:,1) = (double(app.tin.read())-app.minVals(1));
                            for ch=2:app.channels
                                app.tin.nextDirectory();
                                app.frmBfrBidi(:,:,ch) = (double(app.tin.read())-app.minVals(ch));
                            end
                        end

                        if (app.vidPar)
                            for ch=1:app.channels
                                app.currentFrames(:,:,ch) = circshift(app.frmBfrBidi(1:app.h,:,ch),app.yShift,1);
                            end
                        else
                            for ch=1:app.channels
                                app.currentFrames(:,:,ch) = flipud(app.frmBfrBidi((app.htot-app.h+1):app.htot,:,ch));
                            end
                        end
                    else
                        app.currentFrames(:,:,1) = (double(app.tin.read())-app.minVals(1));
                        for ch=2:app.channels
                            app.tin.nextDirectory();
                            app.currentFrames(:,:,ch) = (double(app.tin.read())-app.minVals(ch));
                        end
                    end
                end
            end
        end

        % update scrubber position and frame label
        function setBar(app)
            app.FrameLabel.Text = strcat(num2str(app.frameInd),"/",num2str(app.frames));
            app.frameBar.Position = [0,0,app.frameInd/app.frames,1];
        end

        % switch between projection and video on primary display
        function enableProj(app,enable)
            if (app.usingProj==enable)
                return;
            end

            app.usingProj = enable;
            app.FrameParityButton.Enable = enable && app.bidirectional;
            app.FrameLabel.Enable = ~enable;
            if (app.usingProj)
                app.StopButton.Icon = app.stopActiveIcon;
                app.frameBar.FaceColor = app.scrubberColorInactive;
            else
                app.StopButton.Icon = app.stopIcon;
                app.frameBar.FaceColor = app.scrubberColorActive;
            end

            app.bcCounter = 1;
            if (~enable)
                app.closeDmx();
                app.closeShift();
                if (~isempty(app.bcDlgBox))
                    app.bcDlgBox.reinit(app.vidImage,app.BCwndw(app.channel,:));
                end
            end
        end

        % enable video player buttons
        function enablePlayer(app,enable)
            app.FrameBackwardButton.Enable = enable;
            app.FrameForwardButton.Enable = enable;
            app.PlayButton.Enable = enable;
            app.StopButton.Enable = enable;
            if (~enable)
                app.FrameLabel.Enable = false;
                app.frameBar.FaceColor = app.scrubberColorInactive;
            end
        end

        % advance frame (timer call for playing video)
        function playNext(app,~,~)
            if (app.frameInd < app.frames)
                app.frameInd = app.frameInd+1;
            else
                app.frameInd = 1;
            end
            app.loadFrame();
            app.setBar();
            app.bcCounter = app.bcCounter+1;
            if (mod(app.bcCounter,app.bcRefreshCount)==0)
                app.bcCounter = 1;
                if (~isempty(app.bcDlgBox))
                    app.bcDlgBox.reinit(app.vidImage,app.BCwndw(app.channel,:));
                end
            end
        end

        % start video playback
        function startVid(app)
            if (~app.playing)
                app.PlayButton.Value = true;
                app.PlayButton.Icon = app.pauseIcon;
                app.playing = true;
                start(app.tmr);
            end
        end

        % stop video playback
        function stopVid(app)
            if (app.playing)
                stop(app.tmr);
                app.playing = false;
                app.PlayButton.Value = false;
                app.PlayButton.Icon = app.playIcon;
            end
        end

        % helper functions
        function loadFailHelper(app,msg)
            app.UIFigure.Pointer = 'arrow';
            drawnow;
            errordlg(msg,'File Error');
        end

        function closeAllHelpers(app)
            app.closeBC();
            app.closeShift();
            app.closeDmx();
            app.closeThreshold();
            app.closePlayback();
            app.closeSlicePower();
            app.closeFOVPower();
            app.closeROIPower();
            app.closeTransCal();
            app.closeDuplicate();
            app.closeChnlDlg();
            app.closeOutput();
        end

        function saveSession(app,file,path)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            fullName = fullfile(path,file);
            app.shapeMaskHandler.saveROIs(fullName);
            app.threshMaskHandler.saveROIs(fullName);
            app.powerMod.save(fullName);

            vidParams = app.vidParams;
            save(fullName,'vidParams','-append');

            playbackParams.fps = app.fps;
            playbackParams.frmBfrSz = app.frmBfrSz;
            playbackParams.avg = app.avg;
            save(fullName,'playbackParams','-append');

            WL = app.BCwndw;
            save(fullName,'WL','-append');
            
            dMix=app.W;
            save(fullName,'dMix','-append');

            absPath = fileparts(mfilename('fullpath'));
            mlappMetaDataReader = mlapp.internal.MLAPPMetadataReader(fullfile(absPath,"aes_video_mask_app"));
            fileInfo = mlappMetaDataReader.readMLAPPMetadata();
            version = str2double(fileInfo.Version);
            save(fullName,'version','-append');
            
            app.roiFile = file;
            app.roiPath = path;
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end

        %% Helper App callback functions
        % main method for loading a new image
        function chnlClbk(app, params, limit, truncate)
            app.closeChnlDlg();
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            
            if (isempty(params))
                close(app.interruptDialog);
                app.interruptDialog = [];
                return;
            end

            app.interruptDialog.Title = "Opening file...";
            if (params.slowZ)
                tempParams = params;
                tempParams.volume = false;
                tempParams.slices = 1;
                tempStackManager = StackTracker(tempParams);
            else
                tempStackManager = StackTracker(params);
            end

            if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                disp('Matlab versions before 2023 cannot read large Tiff stacks. Limiting frames averaged');
                countLim = 64000;
                if (truncate)
                    countLim = min(countLim,limit);
                end
                tempStackManager.frameLimit = countLim;
            elseif (truncate && (limit>0) && (~params.slowZ))
                tempStackManager.frameLimit = limit;
            end

            app.interruptDialog.Indeterminate = "on";
            app.interruptDialog.Title = "Counting Frames";
            tempStackManager.open(fullfile(app.tempPath,app.tempFile));
            app.frames = tempStackManager.getNumFrames();
            app.interruptDialog.Indeterminate = "off";
            app.interruptDialog.Title = "Loading image...";
            app.slowZ = params.slowZ;
            % need to handle slow z scan differently
            if (app.slowZ)
                app.frames = floor(app.frames/params.slices);
                tempStackManager.readFrame();
                tempFrame = tempStackManager.getFrame();
                tempImages = zeros(size(tempFrame,1),size(tempFrame,2),params.slices,(params.channels*(1+params.bidirectional)));
                if (limit < 1)
                    limit = app.frames;
                else
                    limit = min(limit,app.frames);
                end
                notify_count = round(app.frames*params.slices/20);
                for sl=1:params.slices
                    tempStackManager.setIndex(app.frames*(sl-1)+1);
                    for fr=1:limit
                        prog_val = fr+(sl-1)*app.frames;
                        if (mod(prog_val,notify_count)==0)
                            app.interruptDialog.Value = prog_val / (app.frames*params.slices);
                            app.interruptDialog.Message = ['Frame (',num2str(prog_val),'/',num2str(app.frames*params.slices),')'];
                        end
                        tempStackManager.readFrame();
                        tempFrame = tempStackManager.getFrame();
                        for ch=1:params.channels
                            tempImages(:,:,sl,ch) = tempImages(:,:,sl,ch) + double(tempFrame(:,:,ch))/limit;
                        end
                    end
                end
                tempStackManager.close();
            else
                try
                    tempImages = tempStackManager.buildProjection(fullfile(app.tempPath,app.tempFile),limit,app.interruptDialog);
                catch exception
                    close(app.interruptDialog);
                    app.interruptDialog = [];
                    rethrow(exception);
                end
            end

            % update some app level parameters describing video
            app.file = app.tempFile;
            app.path = app.tempPath;
            app.bidirectional = params.bidirectional;
            app.yBidirectional = params.yBidirectional;
            app.zBidirectional = params.zBidirectional;
            app.channels = params.channels;
            app.slices = params.slices;
            app.sepChannels = params.splitChannels;
            if (~app.sepChannels)
                app.mainChannel = params.mainChannel;
            end

            % update ui elements for changing slice/channel
            app.frameParity = app.bidirectional;
            app.FrameParityButton.Enable = app.bidirectional;
            app.FrameParityButton.Text = "Odd";
            app.FrameParityButton.Value = true;
            app.channel = 1;
            app.ChannelSpinner.Enable = app.channels > 1;
            app.ChannelSpinner.Value = 1;
            if (app.channels>1)
                app.ChannelSpinner.Limits = [1,app.channels];
            else
                app.ChannelSpinner.Limits = [1,1.1];
            end
            app.slice = 1;
            app.SliceSpinner.Enable = app.slices > 1;
            app.SliceSpinner.Value = 1;
            if (app.slices>1)
                app.SliceSpinner.Limits = [1,app.slices];
            else
                app.SliceSpinner.Limits = [1,1.1];
            end

            % setup reference images
            app.isVolume = params.volume;
            app.BCwndw = zeros(app.channels,2);
            if (app.isVolume)
                app.refImages = tempImages;
                for sl=1:app.slices
                    for ch=1:app.channels
                        maxVal = max(app.refImages(:,:,sl,(1+app.bidirectional)*ch-app.bidirectional),[],'all');
                        minVal = min(app.refImages(:,:,sl,(1+app.bidirectional)*ch-app.bidirectional),[],'all');
                        if (sl==1)
                            app.BCwndw(ch,1) = minVal;
                            app.BCwndw(ch,2) = maxVal;
                        else
                            if (app.BCwndw(ch,1)>minVal)
                                app.BCwndw(ch,1) = minVal;
                            end
    
                            if (app.BCwndw(ch,2)<maxVal)
                                app.BCwndw(ch,2) = maxVal;
                            end
                        end
                    end
                end
            else
                app.refImages = zeros(size(tempImages,1),size(tempImages,2),1,size(tempImages,3));
                for ch=1:app.channels
                    tempIm = tempImages(:,:,(1+app.bidirectional)*ch-app.bidirectional);
                    app.BCwndw(ch,2) = max(tempIm,[],'all');
                    app.BCwndw(ch,1) = min(tempIm,[],'all');
                    app.refImages(:,:,1,(1+app.bidirectional)*ch-app.bidirectional) = tempIm;
                    if (app.bidirectional)
                        app.refImages(:,:,1,2*ch) = tempImages(:,:,2*ch);
                    end
                end
                
                app.slices = 1;
            end
            params.dim = [size(app.refImages,1),size(app.refImages,2)];
            app.minVals = app.BCwndw(:,1);
            app.BCwndw(:,1) = zeros(app.channels,1);
            app.BCwndw(:,2) = app.BCwndw(:,2) - app.minVals;
            for ch=1:app.channels
                if (app.bidirectional)
                    app.refImages(:,:,:,2*ch) = app.refImages(:,:,:,2*ch) - app.minVals(ch);
                    app.refImages(:,:,:,2*ch-1) = app.refImages(:,:,:,2*ch-1) - app.minVals(ch);
                else
                    app.refImages(:,:,:,ch) = app.refImages(:,:,:,ch) - app.minVals(ch);
                end
            end

            % calculate the autoY shift
            if (app.yBidirectional)
                app.autoYShiftVal = 0;
                wght = 0;
                for ch=1:app.channels
                    for sl=1:app.slices
                        tempIm1 = app.refImages(:,:,sl,2*ch-1) - mean(app.refImages(:,:,sl,(2*ch-1)),'all');
                        tempIm2 = app.refImages(:,:,sl,(2*ch)) - mean(app.refImages(:,:,sl,(2*ch)),'all');
                        scaleVal1 = range(tempIm1,'all');
                        scaleVal2 = range(tempIm2,'all');
                        if (scaleVal1>0)
                            tempIm1 = tempIm1 / scaleVal1;
                        end

                        if (scaleVal2>0)
                            tempIm2 = tempIm2 / scaleVal2;
                        end
                        
                        countLim = xcorr2(tempIm1,tempIm2);
                        countLim = countLim(:,size(app.refImages,2));
                        [tempWgt,ind] = max(countLim);
                        app.autoYShiftVal = app.autoYShiftVal + tempWgt * (size(app.refImages,1)-ind);
                        wght = wght + tempWgt;
                    end
                end
                if (wght==0)
                    app.autoYShiftVal = 0;
                else
                    app.autoYShiftVal = round(app.autoYShiftVal/wght);
                end
                app.yShift = app.autoYShiftVal;
                params.bidiShift = app.yShift;
            else
                app.autoYShiftVal = 0;
                app.yShift=0;
            end

            % variables for linear demixing
            app.W = eye(app.channels);
            app.H = app.W;
            app.dmxImages = app.refImages;
            app.dmxBuff = zeros(size(app.refImages,1),size(app.refImages,2),app.channels);
            app.frmDmx = false;
            
            % start new shape mask handler
            if (~isempty(app.shapeMaskHandler))
                app.shapeMaskHandler.delete();
                app.shapeMaskHandler = [];
            end
            app.htot = tempStackManager.htot;
            app.h = size(app.refImages,1);
            app.shapeMaskHandler = MaskListenerHandler(app,app.imagePlot,params);

            % start new threshold mask handler
            if (~isempty(app.threshMaskHandler))
                app.threshMaskHandler = [];
            end
            app.threshMaskHandler = ThresholdMaskHandler(app,params,app.refImages);

            if (~isempty(app.powerMod))
                app.powerMod = [];
            end
            app.powerMod = PowerModulator(params,app.refImages,app.shapeMaskHandler,app.threshMaskHandler);

            app.occ = zeros(app.slices,app.channels);
            app.OccLabel.Text = "0%";
            app.OccChLabel.Text = "0%";
            app.OccTotLabel.Text = "0%";
            app.numPx = size(app.refImages,1)*size(app.refImages,2);
            
            % reset image and occupancy plots to match current image
            % dimensions
            app.dispImage = zeros(size(app.refImages,1),size(app.refImages,2),3);
            app.imagePlot.Title.String = strcat("file: ", app.file);
            app.imhandle = image(app.imagePlot, app.dispImage,'ButtonDownFcn',@app.ImgClk);
            set(app.imagePlot,'xlim',[0,size(app.refImages,2)]);
            set(app.imagePlot,'ylim',[0,size(app.refImages,1)]);

            % handle video setup
            isVid = app.frames > 1;
            app.enablePlayer(isVid);
            app.usingProj = ~isVid;
            if (isVid)    
                if (~isempty(app.tin))
                    app.tin.close();
                    app.tin = [];
                end
                app.ff = fullfile(app.tempPath,app.tempFile);
                app.tin = Tiff(app.ff,'r');
                app.currentFrames = zeros(size(app.refImages,1),size(app.refImages,2),app.channels);
                app.tiffCounter = 1;
                app.bcCounter = 1;
                app.bcRefreshCount = app.fps*2;
                app.avg = false;
                app.frmBfrSz = 10;
                app.frameInd = 1;
                if (app.yBidirectional && ~app.isVolume)
                    app.frmBfrBidi = zeros(app.htot,size(app.refImages,2),app.channels);
                end
                app.loadFrame();
                app.enableProj(true);
                app.setBar();
            end
            app.vidParams = params;
            app.roiFile = [];
            app.roiPath = [];

            % enable ui elements
            app.LoadROIsMenu.Enable = true;
            app.SaveMenu.Enable = false;
            app.SaveAsMenu.Enable = true;
            app.ExportAESMaskMenu.Enable = true;
            app.ExportROIMasksMenu.Enable = true;
            app.WindowLevelMenu.Enable = true;
            app.BidirectionalShiftMenu.Enable = app.yBidirectional;
            app.ROIShapeDropDown.Enable = true;
            app.ROIDeleteButton.Enable = false;
            app.ROIAddButton.Enable = true;
            app.DemixCrosstalkMenu.Enable = app.channels>1;
            app.ThresholdButton.Enable = true;
            app.BlockSliceOverlapCheckBox.Enable = true;
            app.BlockSliceOverlapCheckBox.Value = false;
            app.ApplytoAllSlicesCheckBox.Enable = true;
            app.ApplytoAllSlicesCheckBox.Value = false;
            app.BorderSpinner.Enable = true;
            app.BorderSpinner.Value = 0;
            app.MaxHoleSpinner.Enable = true;
            app.MaxHoleSpinner.Value = 1;
            app.PlaybackOptionsMenu.Enable = isVid;
            app.playing = false;
            app.SliceModulationMenu.Enable = app.isVolume;
            app.ROIModulationMenu.Enable = true;
            app.FieldUniformityMenu.Enable = true;
            app.TransmissionCalibrationMenu.Enable = true;

            % finish up
            app.parityFlag = false;
            app.selectRefImage();
            close(app.interruptDialog);
            app.interruptDialog = [];
        end

        function closeChnlDlg(app)
            if (~isempty(app.chnlDlgBox))
                delete(app.chnlDlgBox);
                app.chnlDlgBox = [];
            end
        end

        function outputClbk(app,params)
            app.closeOutput();
            if (isempty(params))
                return;
            end
            
            app.UIFigure.Pointer = 'watch';
            drawnow;
            outFile = params.outFile;
            combine = params.combine;
            xDisp = params.xDisp;
            yDisp = params.yDisp;
            reverse = params.reverse;
            basename = split(outFile,'.');
            basename = basename{1};
            w = size(app.refImages,2);
            modP = params.maskPower;
            if (app.isVolume)
                app.htot = app.h*app.slices;
                if (params.bidirectional)
                    app.htot = 2*app.htot;
                end

                combineMask = zeros(app.htot,w);
                for ch=1:(app.channels*app.sepChannels+1-app.sepChannels)
                    tempMask = zeros(app.htot,w);
                    for sl=1:app.slices
                        if (modP)
                            tempImage = app.powerMod.buildAESMask(sl,ch,params.fovShapes);
                        else
                            tempImage = (app.threshMaskHandler.getFullMask(sl,ch) + app.shapeMaskHandler.getChMask(sl,ch))>0;
                        end

                        if (reverse)
                            outSl = app.slices-sl+1;
                        else
                            outSl = sl;
                        end
                        
                        if (params.yBidirectional)
                            tempMask(((outSl-1)*app.h+1):(outSl*app.h),:) = circshift(tempImage,[yDisp-params.bidiShift,xDisp]);
                            tempMask((app.htot-outSl*app.h+1):(app.htot-(outSl-1)*app.h),:) = circshift(flipud(tempImage),[yDisp,xDisp]);
                        else
                            tempMask(((outSl-1)*app.h+1):(outSl*app.h),:) = circshift(tempImage,[yDisp,xDisp]);
                            if (params.zBidirectional)
                                tempMask((app.htot-outSl*app.h+1):(app.htot-(outSl-1)*app.h),:) = tempMask(((outSl-1)*app.h+1):(outSl*app.h),:);
                            end
                        end
                    end

                    combineMask = combineMask + tempMask;
                    if (modP)
                        tempMask = uint16(2^16*tempMask);
                    else
                        tempMask = tempMask+0;
                    end

                    if (app.sepChannels && (app.channels > 1))
                        imwrite(tempMask,fullfile(app.path,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                    else
                        imwrite(tempMask,fullfile(app.path,strcat(basename,'.tif')),'Compression','none');
                    end
                end
            else
                if (params.yBidirectional)
                    if (~app.yBidirectional)
                        app.htot = 2*app.h;
                    end

                    combineMask = zeros(app.htot,w);
                    for ch=1:(app.channels*app.sepChannels+1-app.sepChannels)
                        tempMask = zeros(app.htot,w);
                        if (modP)
                            tempImage = app.powerMod.buildAESMask(1,ch,params.fovShapes);
                        else
                            tempImage = (app.threshMaskHandler.getFullMask(1,ch) + app.shapeMaskHandler.getChMask(1,ch))>0;
                        end
                        tempMask(1:app.h,:) = circshift(tempImage,[yDisp-params.bidiShift,xDisp]);
                        tempMask((app.htot-app.h+1):app.htot,:) = circshift(flipud(tempImage),[yDisp,xDisp]);
                        combineMask = combineMask + tempMask;
                        
                        if (modP)
                            tempMask = uint16(2^16*tempMask);
                        else
                            tempMask = tempMask+0;
                        end

                        if (app.sepChannels && (app.channels>1))
                            imwrite((tempMask+0),fullfile(app.path,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        else
                            imwrite((tempMask+0),fullfile(app.path,strcat(basename,'.tif')),'Compression','none');
                        end
                    end
                else
                    combineMask = zeros(size(app.refImages,1),size(app.refImages,2));
                    for ch=1:(app.channels*app.sepChannels+1-app.sepChannels)
                        if (modP)
                            tempMask = app.powerMod.buildAESMask(1,ch,params.fovShapes);
                        else
                            tempMask = (app.threshMaskHandler.getFullMask(1,ch) + app.shapeMaskHandler.getChMask(1,ch))>0;
                        end
                        tempMask = circshift(tempMask,[yDisp,xDisp]);
                        combineMask = combineMask + tempMask;
                        if (modP)
                            tempMask = uint16(2^16*tempMask);
                        else
                            tempMask = tempMask+0;
                        end

                        if (app.sepChannels && (app.channels > 1))
                            imwrite((tempMask+0),fullfile(app.path,strcat(basename,'_ch',num2str(ch),'.tif')),'Compression','none');
                        else
                            imwrite((tempMask+0),fullfile(app.path,strcat(basename,'.tif')),'Compression','none');
                        end
                    end
                end
            end

            if (combine)
                imwrite(((combineMask>0)+0),fullfile(app.path,strcat(basename,'_combined.tif')),'Compression','none');
            end
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end

        function closeOutput(app)
            if (~isempty(app.outputDlgBox))
                delete(app.outputDlgBox);
                app.outputDlgBox = [];
            end
        end

        % Window level helper app callbacks
        function closeBC(app)
            if (~isempty(app.bcDlgBox))
                delete(app.bcDlgBox);
                app.bcDlgBox = [];
            end
        end
            
        function chngImgBnds(app,bounds)
            app.BCwndw(app.channel,:) = bounds;
            if (app.usingProj)
                tempMaskLow = app.shiftImage > bounds(1);
                tempMaskHigh = app.shiftImage > bounds(2);
                app.contrImage = tempMaskHigh + (tempMaskLow .* (1 - tempMaskHigh)) .* (app.shiftImage - bounds(1)) / (bounds(2) - bounds(1));
            else
                tempMaskLow = app.vidImage > bounds(1);
                tempMaskHigh = app.vidImage > bounds(2);
                app.contrImage = tempMaskHigh + (tempMaskLow .* (1 - tempMaskHigh)) .* (app.vidImage - bounds(1)) / (bounds(2) - bounds(1));
            end
            app.updateDisplay();
        end

        % Bidirectional shift helper app callbacks
        function closeShift(app)
            if (~isempty(app.bidiShiftDlgBox))
                delete(app.bidiShiftDlgBox);
                app.bidiShiftDlgBox = [];
            end
        end

        function shift = autoYShift(app)
            if (app.yBidirectional)
                shift = app.autoYShiftVal;
                app.yShift = shift;
                app.selectRefImage();
            end
        end

        function chngYShift(app, shift)
            app.yShift = shift;
            app.selectRefImage();
        end

        % linear demixing helper app callbacks
        function closeDmx(app)
            if (~isempty(app.dmxDlgBox))
                delete(app.dmxDlgBox);
                app.dmxDlgBox = [];
            end
        end

        function setDmxFast(app,W)
            tempH = real(inv(W));
            for ch=1:app.channels
                app.dmxBuff(:,:,ch) = app.refImages(:,:,app.slice,ch*(1+app.bidirectional)-app.frameParity);
            end
            vec = reshape(tempH(app.channel,:),1,1,app.channels);
            app.dmxImages(:,:,app.slice,app.channel*(1+app.bidirectional)-app.frameParity) = sum(vec.*app.dmxBuff,3);
            app.parityFlag = true;
            app.selectRefImage();
        end

        function setDmx(app,W)
            app.W = W;
            app.H = real(inv(W));
            for sl=1:app.slices
                for par=0:(0+app.bidirectional)
                    for ch=1:app.channels
                        app.dmxBuff(:,:,ch) = app.refImages(:,:,sl,ch*(1+app.bidirectional)-par);
                    end

                    for ch=1:app.channels
                        vec = reshape(app.H(ch,:),1,1,app.channels);
                        app.dmxImages(:,:,sl,ch*(1+app.bidirectional)-par) = sum(vec.*app.dmxBuff,3);
                    end
                end
            end

            app.frmDmx = (prod(app.H(app.channel,:) == (1:app.channels==app.channel))==0);
            if (app.frmDmx)
                app.frmDmxVec = reshape(app.H(app.channel,:),1,1,app.channels);
            end
            app.powerMod.setImages(app.dmxImages);
            app.selectRefImage();

            if (~isempty(app.slicePowerDlgBox))
                app.slicePowerDlgBox.updatePlots();
            end
        end

        % threshold mask helper functions
        function closeThreshold(app)
            if (~isempty(app.thresholdDlgBox))
                delete(app.thresholdDlgBox);
                app.thresholdDlgBox = [];
            end
        end

        function closePlayback(app)
            if (~isempty(app.playbackDlgBox))
                delete(app.playbackDlgBox);
                app.playbackDlgBox = [];
            end
        end

        % change framerate rolling average for video playback
        function playbackClbk(app,fps,avg,bfrSz)
            if (fps~=app.fps)
                app.tmr = [];
                app.fps = fps;
                app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.fps)/1000),'TasksToExecute',Inf);
                app.bcRefreshCount = 2*app.fps;
            end
            
            app.avg = avg;
            if (avg)
                app.frmBfrSz = bfrSz;
                app.frmBfrInd = 1;
                app.frmBfr = zeros(size(app.refImages,1),size(app.refImages,2),app.frmBfrSz,app.channels);
                if (~isempty(app.vidImage))
                    for ch=1:app.channels
                        app.frmBfr(:,:,:,ch) = repmat(app.currentFrames(:,:,ch)/app.frmBfrSz,1,1,app.frmBfrSz);
                    end
                end
            end
        end

        function closeSlicePower(app)
            if (~isempty(app.slicePowerDlgBox))
                delete(app.slicePowerDlgBox);
                app.slicePowerDlgBox = [];
                app.powerMod.setSliceUI([]);
            end
        end

        function closeFOVPower(app)
            if (~isempty(app.fovPowerDlgBox))
                delete(app.fovPowerDlgBox);
                app.fovPowerDlgBox = [];
                app.powerMod.setFovUI([]);
            end
        end

        function closeROIPower(app)
            if (~isempty(app.roiPowerDlgBox))
                delete(app.roiPowerDlgBox);
                app.roiPowerDlgBox = [];
                app.powerMod.setRoiUI([]);
            end
        end

        function closeTransCal(app)
            if (~isempty(app.transCalDlgBox))
                delete(app.transCalDlgBox);
                app.transCalDlgBox = [];
            end
        end

        function updatePower(app)
            if (app.usingProj)
                app.selectRefImage();
            else
                flag = app.playing;
                if (app.playing)
                    app.stopVid();
                end
                app.loadFrame();
                if (flag)
                    app.startVid();
                end
            end
        end

        function closeDuplicate(app)
            if (~isempty(app.duplicateDlgBox))
                delete(app.duplicateDlgBox);
                app.duplicateDlgBox = [];
            end
        end

        function duplicateROIs(app,ch,sl1,sl2)
            app.closeDuplicate();
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.shapeMaskHandler.duplicateMask(ch,sl1,sl2);
            app.updateTotOcc();
            app.updateOccLabels();
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupTasks(app)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            box(app.imagePlot,'on');
            disableDefaultInteractivity(app.imagePlot);
            app.imagePlot.Title.Interpreter = 'none';
            movegui(app.UIFigure,'center');
            daspect(app.imagePlot,[1,1,1]);
            temp = ver('Matlab'); %#ok<VERMATLAB>
            temp = split(temp.Version,'.');
            app.v = [str2double(temp{1}),str2double(temp{2})];

            app.ROIFields = cell(6,1);
            app.ROILabels = cell(6,1);
            app.ROIFields{1} = app.ROIField1;
            app.ROIFields{2} = app.ROIField2;
            app.ROIFields{3} = app.ROIField3;
            app.ROIFields{4} = app.ROIField4;
            app.ROIFields{5} = app.ROIField5;
            app.ROIFields{6} = app.ROIField6;
            app.ROILabels{1} = app.ROILabel1;
            app.ROILabels{2} = app.ROILabel2;
            app.ROILabels{3} = app.ROILabel3;
            app.ROILabels{4} = app.ROILabel4;
            app.ROILabels{5} = app.ROILabel5;
            app.ROILabels{6} = app.ROILabel6;
            app.ROIShapeDropDown.ItemsData = 1:6;
            app.UIFigure.Visible = 'on';
            app.cntrlPressed = false;

            app.frameBar = rectangle(app.ScrubberAxes,'Position',[0,0,0.5,1]);
            app.frameBar.FaceColor = app.scrubberColorInactive;
            app.hitBar = rectangle(app.ScrubberAxes,'Position',[0,0,1,1]);
            app.frameBar.HitTest = "off";
            app.hitBar.HitTest = "off";
            app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.fps)/1000),'TasksToExecute',Inf);
            app.playing = false;

            absPath = fileparts(mfilename('fullpath'));
            app.playIcon = imread(fullfile(absPath,'+helper_apps/images/play.png'));
            app.pauseIcon = imread(fullfile(absPath,'+helper_apps/images/pause.png'));
            app.stopIcon = imread(fullfile(absPath,'+helper_apps/images/stop.png'));
            app.stopActiveIcon = imread(fullfile(absPath,'+helper_apps/images/stop_active.png'));
        end

        % Menu selected function: LoadImageMenu
        function loadImage(app, event)
            app.closeAllHelpers();
            app.stopVid();

            if (~isempty(app.shapeMaskHandler))
                userInput = questdlg("All unsaved masks will be deleted. Continue?");
                if (~strcmp(userInput,'Yes'))
                    return;
                end
            end
            
            if (isempty(app.path))
                [app.tempFile, app.tempPath] = uigetfile('*.tif','Load Reference Stack'); 
            else
                [app.tempFile, app.tempPath] = uigetfile('*.tif','Load Reference Stack',app.path);
            end
            
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            if (isequal(app.tempFile,0))
                return;
            end

            app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Waiting for channel info...");
            app.interruptDialog.Cancelable = "off";
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.chnlDlgBox = helper_apps.channel_helper_app(app);
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end

        % Button pushed function: ROIAddButton
        function addROI(app, event)
            app.threshMaskHandler.deselectROI();
            app.shapeMaskHandler.addROI(app.ROIShapeDropDown.Value);
        end

        % Button pushed function: ROIDeleteButton
        function deleteROI(app, event)
            app.shapeMaskHandler.deleteCurrentROI();
            app.threshMaskHandler.deleteROI();
        end

        % Menu selected function: ExportAESMaskMenu
        function exportPattern(app, event)
            app.closeAllHelpers();
            app.stopVid();

            filt = '*.tif';
            defName = split(app.file,'.');
            defName = defName{1};
            defName = fullfile(app.path,strcat(defName,'_mask.tif'));
            [outFile,outPath] = uiputfile(filt,'AES Mask',defName);
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end

            if ((isequal(outFile,0)||isequal(outPath,0)))
                return
            end

            app.UIFigure.Pointer = 'watch';
            drawnow;
            params = app.vidParams;
            params.outFile = outFile;
            app.path = outPath;
            if (app.yBidirectional)
                params.bidiShift = app.yShift;
            end

            params.fovShapes = false;
            names = app.powerMod.fovTransNames;
            for ch=1:(app.channels*app.sepChannels+1-app.sepChannels)
                if (~isempty(names{ch}))
                    params.fovShapes = true;
                    break;
                end
            end

            if (isempty(app.outputDlgBox))
                app.outputDlgBox = helper_apps.mask_output_helper_app(app,params);
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.outputDlgBox.UIFigure);
            else
                figure(app.outputDlgBox.UIFigure);
            end
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end

        % Menu selected function: SaveAsMenu
        function saveAsROIs(app, event)
            filt = '*.mat';
            if (isempty(app.roiFile))
                defName = fullfile(app.path,'roi.mat');
            else
                defName = fullfile(app.roiPath,app.roiFile);
            end
            
            [outFile,outPath] = uiputfile(filt,'ROIs',defName);
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            drawnow;
            if (isequal(outFile,0)||isequal(outPath,0))
                return;
            end

            app.saveSession(outFile,outPath);
            app.SaveMenu.Enable = true;
            app.path = outPath;
        end

        % Menu selected function: LoadROIsMenu
        function loadROIs(app, event)
            app.closeAllHelpers();
            
            % queary file from user
            [tFile, tPath] = uigetfile('*.mat','Load ROI File',app.path);
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end

            if (isequal(tFile,0) || isequal(tPath,0))
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow;
            fullName = fullfile(tPath,tFile);
            
            try
                tempMaskHandler = MaskListenerHandler(app,app.imagePlot,app.vidParams);
                tempMaskHandler.powerMod = Placeholder();
                data = load(fullName);
                success = tempMaskHandler.loadROIs(data);
                if (~success)
                    tempMaskHandler.delete();
                    app.UIFigure.Pointer = 'arrow';
                    drawnow;
                    errordlg('Unable to read file','File Error');
                end
            catch exception
                app.UIFigure.Pointer = 'arrow';
                drawnow;
                errordlg('Unable to read file','File Error');
                tempMaskHandler.delete();
                rethrow(exception);
            end
            
            if (success)
                app.shapeMaskHandler.delete();
                app.shapeMaskHandler = tempMaskHandler;
                app.shapeMaskHandler.setChannel(app.channel);
                app.shapeMaskHandler.setSlice(app.slice);
            else
                return;
            end

            if (isfield(data,'playbackParams'))
                params = data.playbackParams;
                app.playbackClbk(params.fps,params.avg,params.frmBfrSz)
            end

            if (isfield(data,'vidParams'))
                params = data.vidParams;
                success = (params.channels == app.channels) && (params.splitChannels == app.sepChannels) && (app.isVolume == params.volume);
                success = success && (params.dim(1) == size(app.refImages,1)) && (params.dim(2) == size(app.refImages,2));
                if (success)
                    if (app.isVolume)
                        success = app.slices == params.slices;
                    end
        
                    if (~app.sepChannels)
                        success = success && (~isempty(params.mainChannel));
                    end

                    if (success)
                        if (isfield(data,'dMix'))
                            tempW = data.dMix;
                            if (prod(size(tempW)==size(app.W)))
                                app.setDmx(tempW);
                            end
                        end

                        delete(app.powerMod);
                        app.powerMod = PowerModulator(app.vidParams,app.refImages,app.shapeMaskHandler,app.threshMaskHandler);
                        app.powerMod.load(data);
                        app.threshMaskHandler.loadROIs(data);

                        app.BorderSpinner.Value = app.threshMaskHandler.getBorder();
                        app.MaxHoleSpinner.Value = app.threshMaskHandler.getMaxHole();
                        app.ApplytoAllSlicesCheckBox.Value = app.threshMaskHandler.getChngAllSlices();
                        app.BlockSliceOverlapCheckBox.Value = app.threshMaskHandler.getBlockOverlap();

                        app.mainChannel = params.mainChannel;
                        if (app.bidirectional && params.bidirectional)
                            app.yShift = params.bidiShift;
                        end
                        
                        app.BCwndw = data.WL;                        
                    end
                end
            end

            app.roiPath = tPath;
            app.roiFile = tFile;
            app.updateTotOcc();
            app.updateOccLabels();
            app.updateDisplay();
            app.SaveMenu.Enable = true;
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (~isempty(app.chnlDlgBox))
                delete(app.chnlDlgBox);
            end
            app.closeAllHelpers();
            app.stopVid();

            if (~isempty(app.tin))
                app.tin.close();
                app.tin = [];
            end

            if (~isempty(app.tmr))
                delete(app.tmr);
                app.tmr = [];
            end

            if (~isempty(app.shapeMaskHandler))
                app.shapeMaskHandler.delete();
            end

            delete(app);
        end

        % Menu selected function: WindowLevelMenu
        function opnBC(app, event)
            if (isempty(app.bcDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (app.usingProj)
                    app.bcDlgBox = helper_apps.BC_helper_app(app,app.shiftImage,app.BCwndw(app.channel,:));
                else
                    app.bcDlgBox = helper_apps.BC_helper_app(app,app.vidImage,app.BCwndw(app.channel,:));
                end
                app.UIFigure.Pointer = 'arrow';
                drawnow;
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.bcDlgBox.UIFigure);
            else
                figure(app.bcDlgBox.UIFigure);
            end
        end

        % Menu selected function: ExportROIMasksMenu
        function exportROIPatterns(app, event)
            app.closeAllHelpers();
            app.stopVid();

            [outFile,outPath] = uiputfile('*.tif','ROI Masks',fullfile(app.path,'roi.tif'));
            drawnow;
            if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.UIFigure);
            else
                figure(app.UIFigure);
            end
            if ((isequal(outFile,0)||isequal(outPath,0)))
                return
            end

            basename = split(outFile,'.');
            basename = basename{1};
            app.UIFigure.Pointer = 'watch';
            drawnow;
            status = app.shapeMaskHandler.printROIMasks(basename,outPath);
            if (status)
                app.path = outPath;
                tempInd = app.shapeMaskHandler.numRois;
                app.threshMaskHandler.printROIMasks(basename,outPath,tempInd,0,(app.yBidirectional*app.yShift));
            else
                disp('Something went wrong.');
            end
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end

        % Value changed function: ChannelSpinner
        function chngChannel(app, event)
            app.channel = app.ChannelSpinner.Value;
            app.shapeMaskHandler.setChannel(app.channel);
            app.threshMaskHandler.setChannel(app.channel);
            app.BorderSpinner.Value = app.threshMaskHandler.getBorder();
            app.updateOccLabels();

            app.frmDmx = (prod(app.H(app.channel,:) == (1:app.channels==app.channel))==0);
            if (app.frmDmx)
                app.frmDmxVec = reshape(app.H(app.channel,:),1,1,app.channels);
            end

            if (app.usingProj)
                app.selectRefImage();
            else
                flag = app.playing;
                app.stopVid();
                app.loadFrame();

                if (~isempty(app.bcDlgBox))
                    app.bcDlgBox.reinit(app.vidImage,app.BCwndw(app.channel,:));
                    app.bcCounter = 1;
                end

                if (flag)
                    app.startVid();
                end
            end

            if (~isempty(app.thresholdDlgBox))
                app.thresholdDlgBox.reinit();
            end
        end

        % Value changed function: ROIField1, ROIField2, ROIField3, 
        % ...and 3 other components
        function ROIFieldChng(app, event)
            src = event.Source;
            ind=0;
            for ii=1:length(app.ROIFields)
                if (app.ROIFields{ii}==src)
                    ind=ii;
                    break;
                end
            end
            
            if (ind~=0)
                val = src.Value;
                val = app.shapeMaskHandler.chngParam(val,ind);
                src.Value = val;
            end
        end

        % Value changed function: FrameParityButton
        function chngFrmParity(app, event)
            app.frameParity = app.FrameParityButton.Value;
            if (app.frameParity)
                app.FrameParityButton.Text = 'Odd';
            else
                app.FrameParityButton.Text = 'Even';
            end
            app.parityFlag = true;
            app.selectRefImage();
            app.BorderSpinner.Value = app.threshMaskHandler.getBorder();
        end

        % Value changed function: SliceSpinner
        function chngSlice(app, event)
            app.slice = app.SliceSpinner.Value;
            app.shapeMaskHandler.setSlice(app.slice);
            app.threshMaskHandler.setSlice(app.slice);
            app.BorderSpinner.Value = app.threshMaskHandler.getBorder();
            app.updateOccLabels();

            if (app.usingProj)
                app.selectRefImage();
                if (~isempty(app.dmxDlgBox))
                    for ch=1:app.channels
                        app.dmxBuff(:,:,ch) = app.refImages(:,:,app.slice,(1+app.bidirectional)*ch-app.bidirectional);
                    end
                    app.dmxDlgBox.reinit(app.dmxBuff);
                end
            else
                flag = app.playing;
                app.stopVid();
                app.loadFrame();
                if (~isempty(app.bcDlgBox))
                    app.bcDlgBox.reinit(app.vidImage,app.BCwndw(app.channel,:));
                    app.bcCounter = 1;
                end

                if (flag)
                    app.startVid();
                end
            end

            if (~isempty(app.thresholdDlgBox))
                app.thresholdDlgBox.reinit();
            end
        end

        % Menu selected function: BidirectionalShiftMenu
        function opnBidiShift(app, event)
            if (isempty(app.bidiShiftDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (~app.usingProj)
                    app.showProj([]);
                end

                app.bidiShiftDlgBox = helper_apps.bidirectional_shift_helper_app(app,app.yShift);
                app.UIFigure.Pointer = 'arrow';
                drawnow;
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.bidiShiftDlgBox.UIFigure);
            else
                figure(app.bidiShiftDlgBox.UIFigure);
            end
        end

        % Value changed function: ROIShapeDropDown
        function chngROIType(app, event)
            value = app.ROIShapeDropDown.Value;
            app.setROILabels(app.shapeMaskHandler.ROIParamLabels{value},0);
            for ii=1:app.numActiveFields
                app.ROIFields{ii}.Value = 0;
                app.ROIFields{ii}.Enable = false;
            end
            app.ROIDeleteButton.Enable = false;
            app.DuplicateButton.Enable = false;
            app.shapeMaskHandler.deselectROI();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;

            if (strcmp(key,'delete'))
                if (app.ROIDeleteButton.Enable)
                    app.deleteROI([]);
                end
            end

            app.cntrlPressed = false;
            shiftPressed = false;
            for ii=1:length(modifier)
                if (strcmp(modifier{ii},'control'))
                    app.cntrlPressed = true;
                end

                if (strcmp(modifier{ii},'shift'))
                    shiftPressed = true;
                end
            end

            if (~app.cntrlPressed)
                if (strcmp(key,'space'))
                    if (app.PlayButton.Enable)
                        app.playPause([]);
                    end
                elseif (strcmp(key,'leftarrow'))
                    if (~isempty(app.tin))
                        flag = app.playing;
                        app.stopVid();
                        app.frameInd = max((app.frameInd-5*app.fps),1);
                        app.enableProj(false);
                        app.setBar();
                        app.loadFrame();
                        if (flag)
                            app.startVid();
                        end
                    end
                elseif (strcmp(key,'rightarrow'))
                    if (~isempty(app.tin))
                        flag = app.playing;
                        app.stopVid();
                        app.frameInd = min((app.frameInd+5*app.fps),app.frames);
                        app.enableProj(false);
                        app.setBar();
                        app.loadFrame();
                        if (flag)
                            app.startVid();
                        end
                    end
                end
                return;
            end

            if (shiftPressed)
                if (strcmp(key,'o'))
                    if (app.LoadROIsMenu.Enable)
                        app.loadROIs([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'c'))
                    if (app.WindowLevelMenu.Enable)
                        app.opnBC([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'p'))
                    if (app.ExportROIMasksMenu.Enable)
                        app.exportROIPatterns([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'s'))
                    if (app.SaveAsMenu.Enable)
                        app.saveAsROIs([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'leftarrow'))
                    if (app.ROIShapeDropDown.Enable)
                        app.ROIShapeDropDown.Value = mod((app.ROIShapeDropDown.Value-2),6)+1;
                        app.chngROIType([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'rightarrow'))
                    if (app.ROIShapeDropDown.Enable)
                        app.ROIShapeDropDown.Value = mod(app.ROIShapeDropDown.Value,6)+1;
                        app.chngROIType([]);
                        app.cntrlPressed = false;
                    end
                end
            else
                if (strcmp(key,'o'))
                    if (app.LoadImageMenu.Enable)
                        app.loadImage([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'b'))
                    if (app.BidirectionalShiftMenu.Enable)
                        app.opnBidiShift([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'s'))
                    if (app.SaveMenu.Enable)
                        app.saveROIs([]);
                        app.cntrlPressed = false;
                    elseif (app.SaveAsMenu.Enable)
                        app.saveAsROIs([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'p'))
                    if (app.ExportAESMaskMenu.Enable)
                        app.exportPattern([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'n'))
                    if (app.ROIAddButton.Enable)
                        app.addROI([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'d'))
                    if (app.DemixCrosstalkMenu.Enable)
                        app.opnDmx([]);
                        app.cntrlPressed = false;
                    end
                elseif (strcmp(key,'t'))
                    if (app.ThresholdButton.Enable)
                        app.opnThreshold();
                    end
                elseif (~isempty(app.shapeMaskHandler))
                    if (strcmp(key,'leftarrow'))
                            app.shapeMaskHandler.shiftMask([-1,0]);
                    elseif (strcmp(key,'rightarrow'))
                            app.shapeMaskHandler.shiftMask([1,0]);
                    elseif (strcmp(key,'uparrow'))
                            app.shapeMaskHandler.shiftMask([0,-1]);
                    elseif (strcmp(key,'downarrow'))
                            app.shapeMaskHandler.shiftMask([0,1]);
                    end
                end
            end
        end

        % Menu selected function: DemixCrosstalkMenu
        function opnDmx(app, event)
            if (isempty(app.dmxDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (~app.usingProj)
                    app.showProj([]);
                end
    
                for ch=1:app.channels
                    app.dmxBuff(:,:,ch) = app.refImages(:,:,app.slice,(1+app.bidirectional)*ch-app.bidirectional);
                end
                app.dmxDlgBox = helper_apps.crosstalk_demixer_helper_app(app,app.dmxBuff,app.W);
                app.UIFigure.Pointer = 'arrow';
                drawnow;
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.dmxDlgBox.UIFigure);
            else
                figure(app.dmxDlgBox.UIFigure);
            end
        end

        % Button pushed function: ThresholdButton
        function opnThreshold(app, event)
            if (isempty(app.thresholdDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.thresholdDlgBox = helper_apps.mask_threshold_helper_app(app,app.threshMaskHandler);
                app.UIFigure.Pointer = 'arrow';
                drawnow;
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.thresholdDlgBox.UIFigure);
            else
                figure(app.thresholdDlgBox.UIFigure);
            end
        end

        % Value changed function: BorderSpinner
        function chngBorder(app, event)
            app.threshMaskHandler.setBorder(app.BorderSpinner.Value);
            app.updateDisplay();
        end

        % Value changed function: ApplytoAllSlicesCheckBox
        function applyToAllSlices(app, event)
            app.threshMaskHandler.setChngAllSlices(app.ApplytoAllSlicesCheckBox.Value);
            app.updateDisplay();
        end

        % Value changed function: BlockSliceOverlapCheckBox
        function blockSliceOverlap(app, event)
            app.threshMaskHandler.setBlockOverlap(app.BlockSliceOverlapCheckBox.Value);
            app.updateDisplay();
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if (strcmp(key,'control'))
                app.cntrlPressed = false;
            end
        end

        % Value changed function: MaxHoleSpinner
        function chngMaxHole(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.threshMaskHandler.setMaxHole(app.MaxHoleSpinner.Value);
            app.updateDisplay();
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end

        % Button pushed function: StopButton
        function showProj(app, event)
            if (~app.usingProj)
                app.stopVid();
                app.enableProj(true);
                app.selectRefImage();
            end
        end

        % Value changed function: PlayButton
        function playPause(app, event)
            if (app.playing)
                app.stopVid();
            else
                app.loadFrame();
                app.startVid();
                if (app.usingProj)
                    app.enableProj(false);
                end
            end
        end

        % Button pushed function: FrameBackwardButton
        function prvFrame(app, event)
            if (app.frameInd>1)
                app.stopVid();
                app.frameInd = app.frameInd-1;
                app.enableProj(false);
                app.setBar();
                app.loadFrame();
            end
        end

        % Button pushed function: FrameForwardButton
        function nxtFrame(app, event)
            if (app.frameInd < app.frames)
                app.stopVid();
                app.frameInd = app.frameInd+1;
                app.enableProj(false);
                app.setBar();
                if (app.avg && (app.frmBfrSz==size(app.frmBfr,3)))
                    app.playing = true;
                    app.loadFrame();
                    app.playing = false;
                else
                    app.loadFrame();
                end
            end
        end

        % Menu selected function: PlaybackOptionsMenu
        function opnPlayback(app, event)
            app.stopVid();

            if (isempty(app.playbackDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.playbackDlgBox = helper_apps.playback_helper_app(app,app.fps,(app.frmBfrSz*app.avg));
                app.UIFigure.Pointer = 'arrow';
                drawnow;

                if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>9)))
                    if (~isempty(app.bcDlgBox))
                        app.bcDlgBox.minimize();
                    end
                end
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.playbackDlgBox.UIFigure);
            else
                figure(app.playbackDlgBox.UIFigure);
            end
        end

        % Button down function: ScrubberAxes
        function scrubberClbk(app, event)
            if (~isempty(app.tin))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (app.usingProj)
                    app.enableProj(false);
                    flag = false;
                else
                    flag = app.playing;
                    app.stopVid();
                end

                % update video time based on where scrubber is clicked and
                % update ui
                coord = app.ScrubberAxes.CurrentPoint;
                app.frameInd = round(coord(1)*app.frames);
                if (app.frameInd < 1)
                    app.frameInd = 1;
                end
                app.loadFrame();
                app.setBar();

                if (flag)
                    app.startVid();
                end
                app.UIFigure.Pointer = 'arrow';
                drawnow;
            end
        end

        % Menu selected function: SliceModulationMenu
        function opnSlicePower(app, event)
            if (isempty(app.slicePowerDlgBox))
                app.slicePowerDlgBox = helper_apps.power_modulation.slice_helper_app(app,app.powerMod,app.occ);
                app.powerMod.setSliceUI(app.slicePowerDlgBox);
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.slicePowerDlgBox.UIFigure);
            else
                figure(app.slicePowerDlgBox.UIFigure);
            end
        end

        % Button pushed function: DuplicateButton
        function opnDuplicate(app, event)
            if (isempty(app.duplicateDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                params.channels = app.channels;
                params.slices = app.slices;
                params.volume = app.isVolume;
                params.splitChannels = app.sepChannels;
                app.duplicateDlgBox = helper_apps.roi_duplicate_helper_app(app,params);
                app.UIFigure.Pointer = 'arrow';
                drawnow;
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.duplicateDlgBox.UIFigure);
            else
                figure(app.duplicateDlgBox.UIFigure);
            end
        end

        % Menu selected function: SaveMenu
        function saveROIs(app, event)
            if (~isempty(app.roiFile))
                app.saveSession(app.roiFile,app.roiPath);
            end
        end

        % Menu selected function: ROIModulationMenu
        function opnROIPower(app, event)
            if (isempty(app.roiPowerDlgBox))
                app.roiPowerDlgBox = helper_apps.power_modulation.roi_helper_app(app,app.powerMod,app.shapeMaskHandler);
                app.powerMod.setRoiUI(app.roiPowerDlgBox);
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.roiPowerDlgBox.UIFigure);
            else
                figure(app.roiPowerDlgBox.UIFigure);
            end
        end

        % Menu selected function: FieldUniformityMenu
        function opnFOVPower(app, event)
            if (isempty(app.fovPowerDlgBox))
                app.fovPowerDlgBox = helper_apps.power_modulation.fov_helper_app(app,app.powerMod);
                app.powerMod.setFovUI(app.fovPowerDlgBox);
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.fovPowerDlgBox.UIFigure);
            else
                figure(app.fovPowerDlgBox.UIFigure);
            end
        end

        % Menu selected function: TransmissionCalibrationMenu
        function opnTransCal(app, event)
            if (isempty(app.transCalDlgBox))
                app.transCalDlgBox = helper_apps.power_modulation.transmission_cal_helper_app(app,app.powerMod);
            elseif ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>=12)))
                focus(app.transCalDlgBox.UIFigure);
            else
                figure(app.transCalDlgBox.UIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'AES mask app';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+helper_apps', '+power_modulation', 'logo', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @loadImage, true);
            app.LoadImageMenu.Tooltip = {'(Cntl+O)'};
            app.LoadImageMenu.Text = 'Load Image';
            app.LoadROIsMenu = uimenu(app.FileMenu);
            app.LoadROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @loadROIs, true);
            app.LoadROIsMenu.Tooltip = {'(Cntl+Shift+O)'};
            app.LoadROIsMenu.Enable = 'off';
            app.LoadROIsMenu.Text = 'Load ROIs';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @saveROIs, true);
            app.SaveMenu.Tooltip = {'(Cntl+S)'};
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @saveAsROIs, true);
            app.SaveAsMenu.Tooltip = {'(Cntl+Shift+S)'};
            app.SaveAsMenu.Enable = 'off';
            app.SaveAsMenu.Text = 'Save As';
            app.ExportAESMaskMenu = uimenu(app.FileMenu);
            app.ExportAESMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @exportPattern, true);
            app.ExportAESMaskMenu.Tooltip = {'(Cntl+P)'};
            app.ExportAESMaskMenu.Enable = 'off';
            app.ExportAESMaskMenu.Text = 'Export AES Mask';
            app.ExportROIMasksMenu = uimenu(app.FileMenu);
            app.ExportROIMasksMenu.MenuSelectedFcn = createCallbackFcn(app, @exportROIPatterns, true);
            app.ExportROIMasksMenu.Tooltip = {'(Cntl+Shift+P)'};
            app.ExportROIMasksMenu.Enable = 'off';
            app.ExportROIMasksMenu.Text = 'Export ROI Masks';
            app.ImageMenu = uimenu(app.UIFigure);
            app.ImageMenu.Text = 'Image';
            app.WindowLevelMenu = uimenu(app.ImageMenu);
            app.WindowLevelMenu.MenuSelectedFcn = createCallbackFcn(app, @opnBC, true);
            app.WindowLevelMenu.Tooltip = {'(Cntl+Shift+C)'};
            app.WindowLevelMenu.Enable = 'off';
            app.WindowLevelMenu.Text = 'Window/Level';
            app.BidirectionalShiftMenu = uimenu(app.ImageMenu);
            app.BidirectionalShiftMenu.MenuSelectedFcn = createCallbackFcn(app, @opnBidiShift, true);
            app.BidirectionalShiftMenu.Tooltip = {'(Cntl+B)'};
            app.BidirectionalShiftMenu.Enable = 'off';
            app.BidirectionalShiftMenu.Text = 'Bidirectional Shift';
            app.DemixCrosstalkMenu = uimenu(app.ImageMenu);
            app.DemixCrosstalkMenu.MenuSelectedFcn = createCallbackFcn(app, @opnDmx, true);
            app.DemixCrosstalkMenu.Tooltip = {'(Cntl+D)'};
            app.DemixCrosstalkMenu.Enable = 'off';
            app.DemixCrosstalkMenu.Text = 'Demix Crosstalk';
            app.PlaybackOptionsMenu = uimenu(app.ImageMenu);
            app.PlaybackOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @opnPlayback, true);
            app.PlaybackOptionsMenu.Enable = 'off';
            app.PlaybackOptionsMenu.Text = 'Playback Options';
            app.ModulationMenu = uimenu(app.UIFigure);
            app.ModulationMenu.Text = 'Modulation';
            app.SliceModulationMenu = uimenu(app.ModulationMenu);
            app.SliceModulationMenu.MenuSelectedFcn = createCallbackFcn(app, @opnSlicePower, true);
            app.SliceModulationMenu.Enable = 'off';
            app.SliceModulationMenu.Text = 'Volume Modulation';
            app.ROIModulationMenu = uimenu(app.ModulationMenu);
            app.ROIModulationMenu.MenuSelectedFcn = createCallbackFcn(app, @opnROIPower, true);
            app.ROIModulationMenu.Enable = 'off';
            app.ROIModulationMenu.Text = 'ROI Modulation';
            app.FieldUniformityMenu = uimenu(app.ModulationMenu);
            app.FieldUniformityMenu.MenuSelectedFcn = createCallbackFcn(app, @opnFOVPower, true);
            app.FieldUniformityMenu.Enable = 'off';
            app.FieldUniformityMenu.Text = 'Field Uniformity';
            app.TransmissionCalibrationMenu = uimenu(app.ModulationMenu);
            app.TransmissionCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @opnTransCal, true);
            app.TransmissionCalibrationMenu.Enable = 'off';
            app.TransmissionCalibrationMenu.Text = 'Transmission Calibration';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.ImagePanel = uipanel(app.GridLayout3);
            app.ImagePanel.Title = 'Image';
            app.GridLayout2 = uigridlayout(app.ImagePanel);
            app.imagePlot = uiaxes(app.GridLayout2);
            title(app.imagePlot, 'No File Selected')
            xlabel(app.imagePlot, 'X')
            ylabel(app.imagePlot, 'Y')
            zlabel(app.imagePlot, 'Z')
            app.OccupancyPanel = uipanel(app.GridLayout2);
            app.OccupancyPanel.Title = 'Occupancy';
            app.GridLayout8 = uigridlayout(app.OccupancyPanel);
            app.ImageLabel = uilabel(app.GridLayout8);
            app.ImageLabel.Text = 'Image:';
            app.OccLabel = uilabel(app.GridLayout8);
            app.OccLabel.Text = '0%';
            app.ChannelLabel = uilabel(app.GridLayout8);
            app.ChannelLabel.Text = 'Channel:';
            app.OccChLabel = uilabel(app.GridLayout8);
            app.OccChLabel.Text = '0%';
            app.TotalLabel = uilabel(app.GridLayout8);
            app.TotalLabel.Text = 'Total:';
            app.OccTotLabel = uilabel(app.GridLayout8);
            app.OccTotLabel.Text = '0%';
            app.StackNavigationPanel = uipanel(app.GridLayout2);
            app.StackNavigationPanel.Title = 'Stack Navigation';
            app.GridLayout7 = uigridlayout(app.StackNavigationPanel);
            app.SliceSpinnerLabel = uilabel(app.GridLayout7);
            app.SliceSpinnerLabel.Text = 'Slice:';
            app.SliceSpinner = uispinner(app.GridLayout7);
            app.SliceSpinner.Limits = [1 1.1];
            app.SliceSpinner.RoundFractionalValues = 'on';
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @chngSlice, true);
            app.SliceSpinner.Enable = 'off';
            app.SliceSpinner.Value = 1;
            app.ChannelSpinnerLabel = uilabel(app.GridLayout7);
            app.ChannelSpinnerLabel.Text = 'Channel:';
            app.ChannelSpinner = uispinner(app.GridLayout7);
            app.ChannelSpinner.Limits = [1 1.1];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelSpinner.Enable = 'off';
            app.ChannelSpinner.Value = 1;
            app.FrameParityLabel = uilabel(app.GridLayout7);
            app.FrameParityLabel.Text = 'Frame Parity:';
            app.FrameParityButton = uibutton(app.GridLayout7, 'state');
            app.FrameParityButton.ValueChangedFcn = createCallbackFcn(app, @chngFrmParity, true);
            app.FrameParityButton.Enable = 'off';
            app.FrameParityButton.Text = 'Odd';
            app.FrameParityButton.Value = true;
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.ScrubberAxes = uiaxes(app.GridLayout11);
            app.ScrubberAxes.XTick = [];
            app.ScrubberAxes.YTick = [];
            app.ScrubberAxes.ButtonDownFcn = createCallbackFcn(app, @scrubberClbk, true);
            app.FrameBackwardButton = uibutton(app.GridLayout11, 'push');
            app.FrameBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @prvFrame, true);
            app.FrameBackwardButton.Icon = fullfile(pathToMLAPP, '+helper_apps', 'images', 'framebackward.png');
            app.FrameBackwardButton.Enable = 'off';
            app.FrameBackwardButton.Text = '';
            app.FrameForwardButton = uibutton(app.GridLayout11, 'push');
            app.FrameForwardButton.ButtonPushedFcn = createCallbackFcn(app, @nxtFrame, true);
            app.FrameForwardButton.Icon = fullfile(pathToMLAPP, '+helper_apps', 'images', 'frameforward.png');
            app.FrameForwardButton.Enable = 'off';
            app.FrameForwardButton.Text = '';
            app.StopButton = uibutton(app.GridLayout11, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @showProj, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, '+helper_apps', 'images', 'stop.png');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '';
            app.PlayButton = uibutton(app.GridLayout11, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @playPause, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Icon = fullfile(pathToMLAPP, '+helper_apps', 'images', 'play.png');
            app.PlayButton.Text = '';
            app.FrameLabel = uilabel(app.GridLayout11);
            app.FrameLabel.Enable = 'off';
            app.FrameLabel.Text = '0/0';
            app.ROIPanel = uipanel(app.GridLayout3);
            app.ROIPanel.Title = 'ROI';
            app.GridLayout9 = uigridlayout(app.ROIPanel);
            app.ShapesPanel = uipanel(app.GridLayout9);
            app.ShapesPanel.Title = 'Shapes';
            app.GridLayout6 = uigridlayout(app.ShapesPanel);
            app.ROIAddButton = uibutton(app.GridLayout6, 'push');
            app.ROIAddButton.ButtonPushedFcn = createCallbackFcn(app, @addROI, true);
            app.ROIAddButton.Enable = 'off';
            app.ROIAddButton.Tooltip = {'(Cntl+N)'};
            app.ROIAddButton.Text = 'Add';
            app.ROIShapeDropDownLabel = uilabel(app.GridLayout6);
            app.ROIShapeDropDownLabel.Text = 'ROI Shape';
            app.ROIShapeDropDown = uidropdown(app.GridLayout6);
            app.ROIShapeDropDown.Items = {'Rectangle', 'Circle', 'Ellipse', 'Ring', 'Poly Line', 'Polygon'};
            app.ROIShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @chngROIType, true);
            app.ROIShapeDropDown.Enable = 'off';
            app.ROIShapeDropDown.Tooltip = {'(Cntl+left/right)'};
            app.ROIShapeDropDown.Value = 'Rectangle';
            app.ROILabel1 = uilabel(app.GridLayout6);
            app.ROILabel1.Text = 'NA:';
            app.ROIField1 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField1.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField1.Enable = 'off';
            app.ROILabel2 = uilabel(app.GridLayout6);
            app.ROILabel2.Text = 'NA:';
            app.ROIField2 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField2.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField2.Enable = 'off';
            app.ROILabel3 = uilabel(app.GridLayout6);
            app.ROILabel3.Text = 'NA:';
            app.ROIField3 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField3.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField3.Enable = 'off';
            app.ROILabel4 = uilabel(app.GridLayout6);
            app.ROILabel4.Text = 'NA:';
            app.ROIField4 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField4.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField4.Enable = 'off';
            app.ROILabel5 = uilabel(app.GridLayout6);
            app.ROILabel5.Text = 'NA:';
            app.ROIField5 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField5.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField5.Enable = 'off';
            app.ROILabel6 = uilabel(app.GridLayout6);
            app.ROILabel6.Text = 'NA:';
            app.ROIField6 = uieditfield(app.GridLayout6, 'numeric');
            app.ROIField6.ValueChangedFcn = createCallbackFcn(app, @ROIFieldChng, true);
            app.ROIField6.Enable = 'off';
            app.DuplicateButton = uibutton(app.GridLayout6, 'push');
            app.DuplicateButton.ButtonPushedFcn = createCallbackFcn(app, @opnDuplicate, true);
            app.DuplicateButton.Enable = 'off';
            app.DuplicateButton.Text = 'Duplicate';
            app.ThresholdMaskPanel = uipanel(app.GridLayout9);
            app.ThresholdMaskPanel.Title = 'Threshold Mask';
            app.GridLayout10 = uigridlayout(app.ThresholdMaskPanel);
            app.BorderSpinnerLabel = uilabel(app.GridLayout10);
            app.BorderSpinnerLabel.Text = 'Border:';
            app.BorderSpinner = uispinner(app.GridLayout10);
            app.BorderSpinner.Limits = [0 Inf];
            app.BorderSpinner.RoundFractionalValues = 'on';
            app.BorderSpinner.ValueChangedFcn = createCallbackFcn(app, @chngBorder, true);
            app.BorderSpinner.Enable = 'off';
            app.ApplytoAllSlicesCheckBox = uicheckbox(app.GridLayout10);
            app.ApplytoAllSlicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @applyToAllSlices, true);
            app.ApplytoAllSlicesCheckBox.Enable = 'off';
            app.ApplytoAllSlicesCheckBox.Text = 'Apply to All Slices';
            app.BlockSliceOverlapCheckBox = uicheckbox(app.GridLayout10);
            app.BlockSliceOverlapCheckBox.ValueChangedFcn = createCallbackFcn(app, @blockSliceOverlap, true);
            app.BlockSliceOverlapCheckBox.Enable = 'off';
            app.BlockSliceOverlapCheckBox.Text = 'Block Slice Overlap';
            app.ThresholdButton = uibutton(app.GridLayout10, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @opnThreshold, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Tooltip = {'(Cntl+T)'};
            app.ThresholdButton.Text = 'Threshold';
            app.MaxHoleSpinnerLabel = uilabel(app.GridLayout10);
            app.MaxHoleSpinnerLabel.Text = 'Max Hole:';
            app.MaxHoleSpinner = uispinner(app.GridLayout10);
            app.MaxHoleSpinner.Limits = [1 Inf];
            app.MaxHoleSpinner.RoundFractionalValues = 'on';
            app.MaxHoleSpinner.ValueChangedFcn = createCallbackFcn(app, @chngMaxHole, true);
            app.MaxHoleSpinner.Enable = 'off';
            app.MaxHoleSpinner.Value = 1;
            app.ROIDeleteButton = uibutton(app.GridLayout9, 'push');
            app.ROIDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteROI, true);
            app.ROIDeleteButton.Enable = 'off';
            app.ROIDeleteButton.Tooltip = {'(Del)'};
            app.ROIDeleteButton.Text = 'Delete ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aes_video_mask_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupTasks)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end