classdef channel_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReferenceSettingsUIFigure     matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        InitialWindowEditField        matlab.ui.control.NumericEditField
        InitialWindowEditFieldLabel   matlab.ui.control.Label
        MotionWindowEditField         matlab.ui.control.NumericEditField
        TemporalSmoothingWindowLabel  matlab.ui.control.Label
        MotionCheckBox                matlab.ui.control.CheckBox
        SeperateChannelCheckBox       matlab.ui.control.CheckBox
        BidirectionalCheckBox         matlab.ui.control.CheckBox
        LimitFramesCheckBox           matlab.ui.control.CheckBox
        FrameLimitEditField           matlab.ui.control.NumericEditField
        FrameLimitEditFieldLabel      matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        OkayButton                    matlab.ui.control.Button
        MainChannelEditField          matlab.ui.control.NumericEditField
        MainChannelLabel              matlab.ui.control.Label
        ChannelsEditField             matlab.ui.control.NumericEditField
        ChannelsLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.ReferenceSettingsUIFigure.Visible = 'off';
            app.callingApp = callingApp;
            movegui(app.ReferenceSettingsUIFigure,'center');
            app.ReferenceSettingsUIFigure.Visible = 'on';

            if (callingApp.matlabDate>datetime('2022-05-01'))
                focus(app.ChannelsEditField);
            end

            if (callingApp.matlabDate>datetime('2022-01-01'))
                app.ReferenceSettingsUIFigure.WindowStyle = 'alwaysontop';
            end
        end

        % Close request function: ReferenceSettingsUIFigure
        function ReferenceSettingsUIFigureCloseRequest(app, event)
            app.callingApp.chnlClbk([],0);
            delete(app);
        end

        % Value changed function: ChannelsEditField
        function chngNumChannels(app, event)
            value = app.ChannelsEditField.Value;
            if (value == 1)
                app.MainChannelEditField.Limits = [1, 1.1];
                app.SeperateChannelCheckBox.Value = false;
                app.SeperateChannelCheckBox.Enable = false;
            else
                app.MainChannelEditField.Limits = [1, value];
                app.SeperateChannelCheckBox.Enable = true;
            end
        end

        % Button pushed function: OkayButton
        function okayClbk(app, event)
            params.channels = app.ChannelsEditField.Value;
            params.mainChannel = app.MainChannelEditField.Value;
            params.mtn = app.MotionCheckBox.Value;
            params.mtnWndw = app.MotionWindowEditField.Value;
            params.mtnIntlWndw = app.InitialWindowEditField.Value;
            params.splitChannels = app.SeperateChannelCheckBox.Value;
            params.bidirectional = app.BidirectionalCheckBox.Value;
            limit = 0;
            if (app.LimitFramesCheckBox.Value)
                limit = app.FrameLimitEditField.Value;
            end

            app.callingApp.chnlClbk(params,limit);
            delete(app);
        end

        % Button pushed function: CancelButton
        function cnclClbk(app, event)
            app.callingApp.chnlClbk([],0);
            delete(app);
        end

        % Value changed function: LimitFramesCheckBox
        function limitFramesClbk(app, event)
            app.FrameLimitEditField.Enable = app.LimitFramesCheckBox.Value;
        end

        % Value changed function: MotionCheckBox
        function correctMtn(app, event)
            value = app.MotionCheckBox.Value;
            app.MotionWindowEditField.Enable = value;
            app.InitialWindowEditField.Enable = value;
            if (app.MotionCheckBox.Value)
                app.MotionWindowEditField.Enable = true;
            else
                app.MotionWindowEditField.Enable = false;
            end
        end

        % Value changed function: MotionWindowEditField
        function mtnWndwValueChanged(app, event)
            value = app.MotionWindowEditField.Value;
            if (mod(value,2)==0)
                app.MotionWindowEditField.Value = value+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ReferenceSettingsUIFigure = uifigure('Visible', 'off');
            app.ReferenceSettingsUIFigure.Name = 'Reference Settings';
            app.ReferenceSettingsUIFigure.Icon = fullfile(pathToMLAPP, 'thumbnail_mask_app.png');
            app.ReferenceSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ReferenceSettingsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ReferenceSettingsUIFigure);
            app.ChannelsLabel = uilabel(app.GridLayout);
            app.ChannelsLabel.Text = 'Channels:';
            app.ChannelsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChannelsEditField.Limits = [1 Inf];
            app.ChannelsEditField.RoundFractionalValues = 'on';
            app.ChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @chngNumChannels, true);
            app.ChannelsEditField.Value = 1;
            app.MainChannelLabel = uilabel(app.GridLayout);
            app.MainChannelLabel.Text = 'Main Channel:';
            app.MainChannelEditField = uieditfield(app.GridLayout, 'numeric');
            app.MainChannelEditField.Limits = [1 1.1];
            app.MainChannelEditField.RoundFractionalValues = 'on';
            app.MainChannelEditField.Value = 1;
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okayClbk, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cnclClbk, true);
            app.CancelButton.Text = 'Cancel';
            app.FrameLimitEditFieldLabel = uilabel(app.GridLayout);
            app.FrameLimitEditFieldLabel.Text = 'Frame Limit:';
            app.FrameLimitEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameLimitEditField.Limits = [1 Inf];
            app.FrameLimitEditField.Enable = 'off';
            app.FrameLimitEditField.Value = 200;
            app.LimitFramesCheckBox = uicheckbox(app.GridLayout);
            app.LimitFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @limitFramesClbk, true);
            app.LimitFramesCheckBox.Text = 'Limit Frames';
            app.BidirectionalCheckBox = uicheckbox(app.GridLayout);
            app.BidirectionalCheckBox.Text = 'Bidirectional';
            app.SeperateChannelCheckBox = uicheckbox(app.GridLayout);
            app.SeperateChannelCheckBox.Enable = 'off';
            app.SeperateChannelCheckBox.Text = 'Split Channels';
            app.MotionCheckBox = uicheckbox(app.GridLayout);
            app.MotionCheckBox.ValueChangedFcn = createCallbackFcn(app, @correctMtn, true);
            app.MotionCheckBox.Text = 'Correct Motion';
            app.TemporalSmoothingWindowLabel = uilabel(app.GridLayout);
            app.TemporalSmoothingWindowLabel.Text = 'Temporal Smoothing Window:';
            app.MotionWindowEditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionWindowEditField.Limits = [1 Inf];
            app.MotionWindowEditField.RoundFractionalValues = 'on';
            app.MotionWindowEditField.ValueChangedFcn = createCallbackFcn(app, @mtnWndwValueChanged, true);
            app.MotionWindowEditField.Enable = 'off';
            app.MotionWindowEditField.Value = 11;
            app.InitialWindowEditFieldLabel = uilabel(app.GridLayout);
            app.InitialWindowEditFieldLabel.Text = 'Inital Window';
            app.InitialWindowEditField = uieditfield(app.GridLayout, 'numeric');
            app.InitialWindowEditField.Limits = [1 Inf];
            app.InitialWindowEditField.Enable = 'off';
            app.InitialWindowEditField.Value = 200;
            % Show the figure after all components are created
            app.ReferenceSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReferenceSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReferenceSettingsUIFigure)
        end
    end
end