classdef auto_roi_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        EditMaskPanel               matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        BrushTypeLabel              matlab.ui.control.Label
        BrushSizeSpinner            matlab.ui.control.Spinner
        BrushSizeSpinnerLabel       matlab.ui.control.Label
        BrushTypeDropDown           matlab.ui.control.DropDown
        MaskTypeDropDown            matlab.ui.control.DropDown
        ThresholdsPanel             matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        AesCheckBox                 matlab.ui.control.CheckBox
        ExpCheckBox                 matlab.ui.control.CheckBox
        AesEditField                matlab.ui.control.NumericEditField
        AesMasksEditFieldLabel      matlab.ui.control.Label
        ExpEditField                matlab.ui.control.NumericEditField
        ExposureMaskEditFieldLabel  matlab.ui.control.Label
        HistogramAxes               matlab.ui.control.UIAxes
        CancelButton                matlab.ui.control.Button
        OkayButton                  matlab.ui.control.Button
        ImageAxes                   matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%%%%
    % helper window for selecting intensity ranges to autogen aes rois and 
    % exp mask
    %%%%%%%%%%%%%%%%%%

    properties (Access = private)
        callingApp
        refImage
        contrImage
        histhandle
        imhandle
        dispImage

        expRect
        aesRect
        expLine
        aesLine
        histMax
        nobWidth = 2;
        nobHeight
        defaultNobHeight = 25;
        opacity = 0.4;
        opacity2 = 0.8;

        expThreshold
        aesThreshold
        expMask
        aesMask
        expEnabled
        aesEnabled

        % variables related to paintbrush
        mouseDown = false;
        mouseInField = false;
        brushMask
        brushSize
        brushType
        brushBase
        brush
        rMap
        xOffset
        yOffset

        NONE = 0;
        ADD = 1;
        SUBTRACT = 2;
        EXPOSURE = 1;
        AES = 2;
    end
    
    methods (Access = private)
        
        % listener for when exp mask threshold nob is dragged
        function  expRectMv(app,src,evt)
            % validate values
            if (app.aesRect.Position(1) < app.expRect.Position(1))
                tempPos = app.expRect.Position;
                tempPos(1) = app.aesRect.Position(1);
                app.expRect.Position = tempPos;
            end
            app.expThreshold = app.expRect.Position(1) + app.nobWidth/2;
            app.expMask = app.refImage > (app.expThreshold/100);

            % update ui
            app.updateRects();
            app.updateFields();
            app.updateImage();
        end

        % listener for when aes mask threshold nob is dragged
        function aesRectMv(app,src,evt)
            % validate values
            if (app.ExpCheckBox.Value && (app.aesRect.Position(1) < app.expRect.Position(1)))
                tempPos = app.aesRect.Position;
                tempPos(1) = app.expRect.Position(1);
                app.aesRect.Position = tempPos;
            end
            app.aesThreshold = app.aesRect.Position(1) + app.nobWidth/2;
            app.aesMask = app.refImage > (app.aesThreshold/100);

            % update ui
            app.updateRects();
            app.updateFields();
            app.updateImage();
        end

        % update ui elements in histogram to match model
        function updateRects(app)
            app.expLine.Position = [app.expThreshold, 2*app.histMax; app.expThreshold, 0];
            app.aesLine.Position = [app.aesThreshold, 2*app.histMax; app.aesThreshold, 0];
            app.expRect.Position = [app.expThreshold-app.nobWidth/2,(app.histMax - app.nobHeight)/2,app.nobWidth,app.nobHeight];
            app.aesRect.Position = [app.aesThreshold-app.nobWidth/2,(app.histMax - app.nobHeight) / 2,app.nobWidth,app.nobHeight];
        end

        % update number fields to match model
        function updateFields(app)
            if (app.ExpCheckBox.Value)
                app.ExpEditField.Value = app.expThreshold;
            end

            if (app.AesCheckBox.Value)
                app.AesEditField.Value = app.aesThreshold;
            end
        end

        function enableBrush(app)
            if ((app.MaskTypeDropDown.Value == app.EXPOSURE) && (~app.ExpCheckBox.Value))
                app.MaskTypeDropDown.Value = app.NONE;
            end

            if ((app.MaskTypeDropDown.Value == app.AES) && (~app.AesCheckBox.Value))
                app.MaskTypeDropDown.Value = app.NONE;
            end

            app.brushMask = app.MaskTypeDropDown.Value;
            app.MaskTypeDropDown.Enable = app.AesCheckBox.Value || app.ExpCheckBox.Value;
            enable = (app.brushMask ~= app.NONE);
            app.BrushTypeDropDown.Enable = enable;
            app.BrushSizeSpinner.Enable = enable;
        end

        function drawBrush(app, coord)
            app.brush = circshift(app.brushBase,(coord-[1,1]));
            if (coord(1)<app.brushSize)
                app.brush((size(app.refImage,1)+coord(1)-app.brushSize+1):size(app.refImage,1),:) = 0;
            elseif ((coord(1)+app.brushSize-1)>size(app.refImage,1))
                app.brush(1:(app.brushSize+coord(1)-size(app.refImage,1)-1),:) = 0;
            end

            if (coord(2)<app.brushSize)
                app.brush(:,(size(app.refImage,2)+coord(2)-app.brushSize+1):size(app.refImage,2)) = 0;
            elseif ((coord(2)+app.brushSize-1)>size(app.refImage,2))
                app.brush(:,1:(app.brushSize+coord(2)-size(app.refImage,2)-1)) = 0;
            end

            app.brush = (app.brush > 0);
            if (app.mouseDown)
                if (app.brushType == app.ADD)
                    if (app.brushMask == app.EXPOSURE)
                        app.expMask = app.expMask .* (~app.brush) + app.brush;
                    elseif (app.brushMask == app.AES)
                        app.aesMask = app.aesMask .* (~app.brush) + app.brush;
                    end
                elseif (app.brushType == app.SUBTRACT)
                    if (app.brushMask == app.EXPOSURE)
                        app.expMask = app.expMask .* (~app.brush);
                    elseif (app.brushMask == app.AES)
                        app.aesMask = app.aesMask .* (~app.brush);
                    end
                end
            end

            app.updateImage();
        end

        % update image to highlight pixels below thresholds
        function updateImage(app)
            app.dispImage(:,:,1) = app.contrImage + app.aesEnabled*app.opacity*(app.aesMask);
            app.dispImage(:,:,2) = app.contrImage + app.expEnabled*app.opacity*(app.expMask - app.aesEnabled*app.expMask .* app.aesMask);
            if (app.mouseInField)
                if (app.mouseDown)
                    if (app.brushMask == app.EXPOSURE)
                        app.dispImage(:,:,2) = app.dispImage(:,:,2) .* (~app.brush) + app.opacity2*app.brush;
                    elseif (app.brushMask == app.AES)
                        app.dispImage(:,:,1) = app.dispImage(:,:,1) .* (~app.brush) + app.opacity2*app.brush;
                    end
                elseif (app.brushMask ~= app.NONE)
                    app.dispImage(:,:,1) = app.dispImage(:,:,1) .* (~app.brush) + app.opacity2*app.brush;
                    app.dispImage(:,:,2) = app.dispImage(:,:,2) .* (~app.brush) + app.opacity2*app.brush;
                end
            end
            app.imhandle.CData = app.dispImage;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, refImage, window)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.refImage = refImage;
            tempMaskLow = app.refImage > window(1);
            tempMaskHigh = app.refImage > window(2);
            
            % setup axis showing image
            app.contrImage = tempMaskHigh + (tempMaskLow .* (1 - tempMaskHigh)) .* (app.refImage - window(1)) / (window(2) - window(1));
            imMx = max(app.refImage,[],'all');
            imMn = min(app.refImage,[],'all');
            app.refImage = (app.refImage-imMn) / (imMx-imMn);
            daspect(app.ImageAxes,[1,1,1]);
            app.imhandle = image(app.ImageAxes, app.refImage);
            set(app.ImageAxes,'xlim',[0,size(app.refImage,2)]);
            set(app.ImageAxes,'ylim',[0,size(app.refImage,1)]);
            app.dispImage = zeros(size(app.refImage,1),size(app.refImage,2),3);
            app.dispImage(:,:,3) = app.contrImage;

            % build histogram
            [counts,bins] = imhist(app.refImage);
            counts = log10(counts+(counts==0));
            app.histhandle = bar(app.HistogramAxes,bins*100,counts);
            disableDefaultInteractivity(app.HistogramAxes);
            app.histMax = max(counts);
            app.nobHeight = app.histMax * app.defaultNobHeight / 100;
            set(app.HistogramAxes,'xlim',[0,100]);
            set(app.HistogramAxes,'ylim',[0,app.histMax]);
               
            % build elements for setting exp mask threshold in histogram
            app.expLine = images.roi.Line(app.HistogramAxes);
            app.expLine.InteractionsAllowed = "none";
            app.expLine.MarkerSize = 0.1;
            app.expLine.Color = 'g';
            app.expRect = images.roi.Rectangle(app.HistogramAxes);
            app.expRect.Deletable = 0;
            app.expRect.DrawingArea = [-app.nobWidth/2,app.histMax/2 - app.nobHeight / 2,100+app.nobWidth/2,app.nobHeight];
            app.expRect.InteractionsAllowed = "translate";
            addlistener(app.expRect,'ROIMoved',@app.expRectMv);
            addlistener(app.expRect,'MovingROI',@app.expRectMv);
            app.expRect.MarkerSize = 1;
            app.expRect.FaceAlpha = 0.5;
            app.expRect.Color = 'g';

            % build elements for setting aes mask threshold in histogram
            app.aesLine = images.roi.Line(app.HistogramAxes);
            app.aesLine.InteractionsAllowed = "none";
            app.aesLine.MarkerSize = 0.1;
            app.aesLine.Color = 'r';
            app.aesRect = images.roi.Rectangle(app.HistogramAxes);
            app.aesRect.Deletable = 0;
            app.aesRect.DrawingArea = [-app.nobWidth/2,app.histMax/2 - app.nobHeight / 2,100+app.nobWidth/2,app.nobHeight];
            app.aesRect.InteractionsAllowed = "translate";
            addlistener(app.aesRect,'ROIMoved',@app.aesRectMv);
            addlistener(app.aesRect,'MovingROI',@app.aesRectMv);
            app.aesRect.MarkerSize = 1;
            app.aesRect.FaceAlpha = 0.5;
            app.aesRect.Color = 'r';

            % initialize variables for mask brush
            xInd = (-ceil((size(app.refImage,2)-1)/2)):floor((size(app.refImage,2)-1)/2);
            xInd = repmat(xInd,size(app.refImage,1),1);
            yInd = (-ceil((size(app.refImage,1)-1)/2)):floor((size(app.refImage,1)-1)/2);
            yInd = repmat(yInd',1,size(app.refImage,2));
            app.rMap = sqrt(xInd.^2+yInd.^2);
            app.yOffset = ceil((size(app.refImage,1)-1)/2);
            app.xOffset = ceil((size(app.refImage,2)-1)/2);

            % set initial values and update ui
            app.MaskTypeDropDown.ItemsData = [0, 1, 2];
            app.BrushTypeDropDown.ItemsData = [1, 2];
            app.expThreshold = 10;
            app.aesThreshold = 20;
            app.expEnabled = true;
            app.aesEnabled = true;
            app.expMask = app.refImage > (app.expThreshold/100);
            app.aesMask = app.refImage > (app.aesThreshold/100);
            app.chngBrushSize([]);
            app.chngBrushType([]);
            app.chngBrushMask([]);
            app.mouseDown = false;
            app.updateRects();
            app.updateFields();
            app.updateImage();
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: ExpEditField
        function ExpChanged(app, event)
            % validate model
            value = app.ExpEditField.Value;
            if (value > app.aesThreshold)
                app.expThreshold = app.aesThreshold;
                app.ExpEditField.Value = app.aesThreshold;
            else
                app.expThreshold = value;
            end
            app.expMask = app.refImage > (app.expThreshold/100);

            % update ui
            app.updateRects();
            app.updateImage();
        end

        % Value changed function: AesEditField
        function AesChanged(app, event)
            % validate model
            value = app.AesEditField.Value;
            if (value < app.expThreshold)
                app.aesThreshold = app.expThreshold;
                app.AesEditField.Value = app.expThreshold;
            else
                app.aesThreshold = value;
            end
            app.aesMask = app.refImage > (app.aesThreshold/100);

            % update ui
            app.updateRects();
            app.updateImage();
        end

        % Value changed function: ExpCheckBox
        function ExpEnable(app, event)
            % update model and move elements outside of visible range if
            % needed
            app.expEnabled = app.ExpCheckBox.Value;
            app.ExpEditField.Enable = app.expEnabled;
            app.expRect.Visible = app.expEnabled;
            app.expLine.Visible = app.expEnabled;
            app.enableBrush();
            app.updateImage();
        end

        % Value changed function: AesCheckBox
        function AesEnable(app, event)
            % update model and move elements outside of visible range if
            % needed
            app.aesEnabled = app.AesCheckBox.Value;
            app.AesEditField.Enable = app.aesEnabled;
            app.aesRect.Visible = app.aesEnabled;
            app.aesLine.Visible = app.aesEnabled;
            app.enableBrush();
            app.updateImage();
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            app.callingApp.closeRoiDtct();
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeRoiDtct();
            delete(app);
        end

        % Button pushed function: OkayButton
        function Okay(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            if (~app.aesEnabled)
                app.aesMask = [];
            end

            if (~app.expEnabled)
                app.expMask = [];
            end

            app.callingApp.detectROIs(app.expMask,app.aesMask);
            delete(app);
        end

        % Window button down function: UIFigure
        function mousePress(app, event)
            coord = app.ImageAxes.CurrentPoint;
            coord = ceil([coord(1,2),coord(1,1)]);
            app.mouseDown = app.mouseInField;
            if (app.mouseInField)
                app.drawBrush(coord);
            end
        end

        % Window button motion function: UIFigure
        function mouseMove(app, event)
            coord = app.ImageAxes.CurrentPoint;
            coord = ceil([coord(1,2),coord(1,1)]);
            temp = app.mouseInField;
            app.mouseInField = (coord(1)>0) && (coord(2)>0) && (coord(1)<=size(app.refImage,1)) && (coord(2)<=size(app.refImage,2));
            if (app.mouseInField)
                app.drawBrush(coord);
            elseif (temp)
                app.updateImage();
            end
        end

        % Window button up function: UIFigure
        function mouseRelease(app, event)
            app.mouseDown = false;
        end

        % Value changed function: BrushSizeSpinner
        function chngBrushSize(app, event)
            app.brushSize = app.BrushSizeSpinner.Value;
            if (app.brushSize == 1)
                app.brushBase = zeros(size(app.refImage,1),size(app.refImage,2));
                app.brushBase(1,1) = 1;
                app.brushBase = (app.brushBase > 0);
            elseif (app.brushSize == 2)
                app.brushBase = zeros(size(app.refImage,1),size(app.refImage,2));
                app.brushBase(1:2,1:2) = [1 1; 1 1];
            else
                app.brushBase = (app.rMap < (app.brushSize-1));
                app.brushBase = circshift(app.brushBase,[-app.yOffset,-app.xOffset]);
            end
        end

        % Value changed function: BrushTypeDropDown
        function chngBrushType(app, event)
            app.brushType = app.BrushTypeDropDown.Value;
        end

        % Value changed function: MaskTypeDropDown
        function chngBrushMask(app, event)
            app.brushMask = app.MaskTypeDropDown.Value;
            app.enableBrush();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Mask Generation Thresholds';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @mousePress, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @mouseRelease, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @mouseMove, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageAxes = uiaxes(app.GridLayout);
            title(app.ImageAxes, 'Reference Image')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            zlabel(app.ImageAxes, 'Z')
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @Okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ThresholdsPanel = uipanel(app.GridLayout3);
            app.ThresholdsPanel.Title = 'Thresholds';
            app.GridLayout2 = uigridlayout(app.ThresholdsPanel);
            app.HistogramAxes = uiaxes(app.GridLayout2);
            title(app.HistogramAxes, 'Histogram')
            xlabel(app.HistogramAxes, 'pixel value')
            ylabel(app.HistogramAxes, 'log(frequency)')
            app.ExposureMaskEditFieldLabel = uilabel(app.GridLayout2);
            app.ExposureMaskEditFieldLabel.Text = 'Exposure Mask';
            app.ExpEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ExpEditField.Limits = [0 100];
            app.ExpEditField.ValueChangedFcn = createCallbackFcn(app, @ExpChanged, true);
            app.AesMasksEditFieldLabel = uilabel(app.GridLayout2);
            app.AesMasksEditFieldLabel.Text = 'AES Masks';
            app.AesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AesEditField.Limits = [0 100];
            app.AesEditField.ValueChangedFcn = createCallbackFcn(app, @AesChanged, true);
            app.ExpCheckBox = uicheckbox(app.GridLayout2);
            app.ExpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExpEnable, true);
            app.ExpCheckBox.Text = 'Enable';
            app.ExpCheckBox.Value = true;
            app.AesCheckBox = uicheckbox(app.GridLayout2);
            app.AesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AesEnable, true);
            app.AesCheckBox.Text = 'Enable';
            app.AesCheckBox.Value = true;
            app.EditMaskPanel = uipanel(app.GridLayout3);
            app.EditMaskPanel.Title = 'Edit Mask';
            app.GridLayout4 = uigridlayout(app.EditMaskPanel);
            app.MaskTypeDropDown = uidropdown(app.GridLayout4);
            app.MaskTypeDropDown.Items = {'None', 'Exposure Mask', 'AES Mask'};
            app.MaskTypeDropDown.ItemsData = {'0', '1', '2'};
            app.MaskTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @chngBrushMask, true);
            app.MaskTypeDropDown.Value = '0';
            app.BrushTypeDropDown = uidropdown(app.GridLayout4);
            app.BrushTypeDropDown.Items = {'Add', 'Subtract'};
            app.BrushTypeDropDown.ItemsData = {'1', '2'};
            app.BrushTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @chngBrushType, true);
            app.BrushTypeDropDown.Value = '1';
            app.BrushSizeSpinnerLabel = uilabel(app.GridLayout4);
            app.BrushSizeSpinnerLabel.Text = 'Brush Size:';
            app.BrushSizeSpinner = uispinner(app.GridLayout4);
            app.BrushSizeSpinner.Limits = [1 Inf];
            app.BrushSizeSpinner.RoundFractionalValues = 'on';
            app.BrushSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @chngBrushSize, true);
            app.BrushSizeSpinner.Value = 1;
            app.BrushTypeLabel = uilabel(app.GridLayout4);
            app.BrushTypeLabel.Text = 'Brush Type:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auto_roi_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end