classdef channel_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReferenceSettingsUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        SplitChannelsCheckBox      matlab.ui.control.CheckBox
        BidirectionalCheckBox      matlab.ui.control.CheckBox
        LimitFramesCheckBox        matlab.ui.control.CheckBox
        FrameLimitEditField        matlab.ui.control.NumericEditField
        FrameLimitEditFieldLabel   matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        OkayButton                 matlab.ui.control.Button
        MainChannelEditField       matlab.ui.control.NumericEditField
        MainChannelLabel           matlab.ui.control.Label
        ChannelsEditField          matlab.ui.control.NumericEditField
        ChannelsLabel              matlab.ui.control.Label
    end

    %%%%%%%%%%%%%
    % helper window used to set additional details not present in file metadata 
    %%%%%%%%%%%%%
    
    properties (Access = private)
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            % trying to make sure figure pops up in center. Might change
            % later
            app.ReferenceSettingsUIFigure.Visible = 'off';
            app.callingApp = callingApp;
            movegui(app.ReferenceSettingsUIFigure,'center');
            app.ReferenceSettingsUIFigure.Visible = 'on';
            
            if (callingApp.matlabDate>datetime('2022-05-01'))
                focus(app.ChannelsEditField);
            end
        end

        % Close request function: ReferenceSettingsUIFigure
        function ReferenceSettingsUIFigureCloseRequest(app, event)
            app.callingApp.chnlClbk(0,0,0,0,0);
            delete(app);
        end

        % Value changed function: ChannelsEditField
        function chngNumChannels(app, event)
            % make sure main channel is within range of possible channels
            value = app.ChannelsEditField.Value;
            if (value == 1)
                app.MainChannelEditField.Limits = [1, 1.1];
                app.SplitChannelsCheckBox.Value = false;
                app.SplitChannelsCheckBox.Enable = false;
            else
                app.MainChannelEditField.Limits = [1, value];
                app.SplitChannelsCheckBox.Enable = true;
            end
        end

        % Button pushed function: OkayButton
        function okayClbk(app, event)
            interruptDialog2 = uiprogressdlg(app.ReferenceSettingsUIFigure);
            limit = 0;
            if (app.LimitFramesCheckBox.Value)
                limit = app.FrameLimitEditField.Value;
            end
            app.callingApp.chnlClbk(app.ChannelsEditField.Value,app.MainChannelEditField.Value,limit,app.BidirectionalCheckBox.Value,app.SplitChannelsCheckBox.Value);
            close(interruptDialog2);
            delete(app);
        end

        % Button pushed function: CancelButton
        function cnclClbk(app, event)
            app.callingApp.chnlClbk(0,0,0,0,false);
            delete(app);
        end

        % Value changed function: LimitFramesCheckBox
        function limitFramesClbk(app, event)
            value = app.LimitFramesCheckBox.Value;
            if (value)
                app.FrameLimitEditField.Enable = true;
            else
                app.FrameLimitEditField.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ReferenceSettingsUIFigure = uifigure('Visible', 'off');
            app.ReferenceSettingsUIFigure.Name = 'Reference Settings';
            app.ReferenceSettingsUIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.ReferenceSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ReferenceSettingsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ReferenceSettingsUIFigure);
            app.ChannelsLabel = uilabel(app.GridLayout);
            app.ChannelsLabel.Text = 'Channels:';
            app.ChannelsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChannelsEditField.Limits = [1 Inf];
            app.ChannelsEditField.RoundFractionalValues = 'on';
            app.ChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @chngNumChannels, true);
            app.ChannelsEditField.Value = 1;
            app.MainChannelLabel = uilabel(app.GridLayout);
            app.MainChannelLabel.Text = 'Main Channel:';
            app.MainChannelEditField = uieditfield(app.GridLayout, 'numeric');
            app.MainChannelEditField.Limits = [1 1.1];
            app.MainChannelEditField.RoundFractionalValues = 'on';
            app.MainChannelEditField.Value = 1;
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okayClbk, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cnclClbk, true);
            app.CancelButton.Text = 'Cancel';
            app.FrameLimitEditFieldLabel = uilabel(app.GridLayout);
            app.FrameLimitEditFieldLabel.Text = 'Frame Limit';
            app.FrameLimitEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameLimitEditField.Limits = [1 Inf];
            app.FrameLimitEditField.Enable = 'off';
            app.FrameLimitEditField.Value = 400;
            app.LimitFramesCheckBox = uicheckbox(app.GridLayout);
            app.LimitFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @limitFramesClbk, true);
            app.LimitFramesCheckBox.Text = 'Limit Frames';
            app.BidirectionalCheckBox = uicheckbox(app.GridLayout);
            app.BidirectionalCheckBox.Text = 'Bidirectional';
            app.SplitChannelsCheckBox = uicheckbox(app.GridLayout);
            app.SplitChannelsCheckBox.Enable = 'off';
            app.SplitChannelsCheckBox.Text = 'Split Channels';
            % Show the figure after all components are created
            app.ReferenceSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReferenceSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReferenceSettingsUIFigure)
        end
    end
end