classdef playback_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        OkayButton                 matlab.ui.control.Button
        FrameRateEditField         matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel    matlab.ui.control.Label
        FrameWindowEditField       matlab.ui.control.NumericEditField
        FrameWindowEditFieldLabel  matlab.ui.control.Label
        RollingAverageCheckBox     matlab.ui.control.CheckBox
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Helper app for changing video playback settings
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, fr, avg)
            app.callingApp = callingApp;
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            parentPos = callingApp.UIFigure.Position;
            pos = app.UIFigure.Position;
            movegui(app.UIFigure,[round(parentPos(1)+parentPos(3)/2-pos(3)/2),round(parentPos(2)+parentPos(4)/2)-pos(4)/2]);
            
            app.FrameRateEditField.Value = fr;
            if (avg==0)
                app.RollingAverageCheckBox.Value = false;
                app.FrameWindowEditField.Enable = false;
            else
                app.RollingAverageCheckBox.Value = true;
                app.FrameWindowEditField.Value = avg;
            end
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.WindowStyle = "modal";
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: RollingAverageCheckBox
        function rollingAvg(app, event)
            app.FrameWindowEditField.Enable = app.RollingAverageCheckBox.Value;
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.callingApp.closePlayback();
            delete(app);
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            app.callingApp.playbackClbk(app.FrameRateEditField.Value,app.RollingAverageCheckBox.Value,app.FrameWindowEditField.Value);
            app.callingApp.closePlayback();
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closePlayback();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Playback Settings';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RollingAverageCheckBox = uicheckbox(app.GridLayout);
            app.RollingAverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @rollingAvg, true);
            app.RollingAverageCheckBox.Text = 'Rolling Average';
            app.FrameWindowEditFieldLabel = uilabel(app.GridLayout);
            app.FrameWindowEditFieldLabel.Text = 'Frame Window';
            app.FrameWindowEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameWindowEditField.Limits = [2 Inf];
            app.FrameWindowEditField.RoundFractionalValues = 'on';
            app.FrameWindowEditField.Value = 2;
            app.FrameRateEditFieldLabel = uilabel(app.GridLayout);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameRateEditField.Limits = [1 Inf];
            app.FrameRateEditField.Value = 15;
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = playback_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end