classdef preview_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        MotionPanel                 matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        FilterAccelerationCheckBox  matlab.ui.control.CheckBox
        MaxAccEditField             matlab.ui.control.NumericEditField
        MaxAccLabel                 matlab.ui.control.Label
        MtnValLabel                 matlab.ui.control.Label
        MtnLabel                    matlab.ui.control.Label
        PlotMetricDropDown          matlab.ui.control.DropDown
        MtnPlotAxes                 matlab.ui.control.UIAxes
        ShowSampleROIsCheckBox      matlab.ui.control.CheckBox
        ShowAESROIsCheckBox         matlab.ui.control.CheckBox
        FrameTimeLabel              matlab.ui.control.Label
        ReferenceTimeLabel          matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        SliceDropDown               matlab.ui.control.DropDown
        SettingsButton              matlab.ui.control.Button
        ChannelDropDown             matlab.ui.control.DropDown
        FrameLabel                  matlab.ui.control.Label
        PlayButton                  matlab.ui.control.StateButton
        FrameFwdButton              matlab.ui.control.Button
        FrameBackButton             matlab.ui.control.Button
        ProgressAxes                matlab.ui.control.UIAxes
        MotionFrameAxes             matlab.ui.control.UIAxes
        OriginalFrameAxes           matlab.ui.control.UIAxes
        ComparisonFrameAxes         matlab.ui.control.UIAxes
        ReferenceFrameAxes          matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%
    % helper app used to display preview frames of motion correction and
    % segmentation
    %%%%%%%%%%%%%

    properties (Access = private)
        % main app
        callingApp
        params
        
        % visual data
        mtnVidRef
        orgVidRef
        refImg % reference image for motion correction
        cmpVid % what the computer sees when to xcorr
        mtnVid % the motion corrected video
        orgVid % the original video
        masks % sample masks to overlay over videos
        maskOp = 0.4; % mask opacity in viewer
        smplMasks
        aesMasks
        mtnDiffMask
        orgDiffMasks
        orgSumMasks
        showSmpl
        showAes

        mtn
        pos
        vel
        acc        
        inBoundsPos
        inBoundsAcc
        checkAcc
        maxAcc

        % handles for visual elements
        imhandleRef
        imhandleCmp
        imhandleMtn
        imhandleOrg
        dispImgCmp
        dispImgMtn
        dispImgOrg
        hitBar % scrubber
        frameBar
        playIcon
        pauseIcon
        accBar
        framePnt
        mtnAbsPlotHandle
        mtnXPlotHandle
        mtnYPlotHandle
        plotType

        % timer variables
        tmr
        playing
        framerate = 15;
        avg = false;
        rollingAvg = 0;

        % variables for tracking place in video
        index
        frames
        channel
        slice
        mainChannel
        channels
        slices

        PlaybackDlgBox

        POSITION = 1;
        VELOCITY = 2;
        ACCELERATION = 3;
    end

    properties (Access = public)
        deathFlag % calling up reads to know if user exited during startup
    end
    
    methods (Access = public)
        function closePlayback(app)
            if (~isempty(app.PlaybackDlgBox))
                app.PlaybackDlgBox = [];
            end
        end

        function playbackClbk(app, framerate, avg, rollingAvg)
            app.UIFigure.Pointer = 'watch';
            if (~isempty(app.PlaybackDlgBox))
                app.PlaybackDlgBox.UIFigure.Pointer = 'watch';
            end
            drawnow;

            if (framerate ~= app.framerate)
                app.tmr = [];
                app.framerate = framerate;
                app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.framerate)/1000),'TasksToExecute',Inf);
            end

            if (avg)
                if (~app.avg || (app.rollingAvg ~= rollingAvg))
                    app.avg = true;
                    app.rollingAvg = rollingAvg;
                    kernel = ones(1,1,1,1,rollingAvg)/rollingAvg;
                    app.mtnVid = convn(app.mtnVidRef,kernel,'same');
                    app.orgVid = convn(app.orgVidRef,kernel,'same');
                    app.loadFrame();
                end
            elseif (~avg && app.avg)
                app.avg = false;
                app.rollingAvg = rollingAvg;
                app.mtnVid = app.mtnVidRef;
                app.orgVid = app.orgVidRef;
                app.loadFrame();
            end
            app.UIFigure.Pointer = 'arrow';
            if (~isempty(app.PlaybackDlgBox))
                app.PlaybackDlgBox.UIFigure.Pointer = 'arrow';
            end
        end
    end

    methods (Access = private)
        % play videos
        function startVid(app)
            if (~app.playing)
                start(app.tmr);
                app.playing = true;
                app.PlayButton.Value = true;
                app.PlayButton.Icon = app.pauseIcon;
            end
        end

        % stop playing videos
        function stopVid(app)
            if (app.playing)
                stop(app.tmr);
                app.playing = false;
                app.PlayButton.Value = false;
                app.PlayButton.Icon = app.playIcon;
            end
        end

        % set next frame and call ui update functions
        function playNext(app,~,~)
            if (app.index < app.frames)
                app.index = app.index+1;
            else
                app.index = 1;
            end
                app.loadFrame();
                app.setScrubber();
        end

        % update scrubber
        function setScrubber(app)
            app.FrameLabel.Text = strcat(num2str(app.index),"/",num2str(app.frames));
            app.frameBar.Position = [0,0,app.index/app.frames,1];
            if (app.mtn)
                switch(app.plotType)
                    case app.POSITION
                        app.framePnt.Position = [app.index,app.pos(app.index,3)];
                        app.MtnValLabel.Text = num2str(app.pos(app.index,3));
                    case app.VELOCITY
                        app.framePnt.Position = [app.index,app.vel(app.index,3)];
                        app.MtnValLabel.Text = num2str(app.vel(app.index,3));
                    case app.ACCELERATION
                        app.framePnt.Position = [app.index,app.acc(app.index,3)];
                        app.MtnValLabel.Text = num2str(app.acc(app.index,3));
                end
            end
        end

        % update ui showing current frame of videos
        function loadFrame(app)
            if (app.showSmpl)
                if (app.showAes)
                    app.mtnDiffMask = (circshift(app.aesMasks(:,:,app.slice,app.channel),app.pos(app.index,1:2))-app.smplMasks(:,:,app.slice,app.channel))>0;
                    app.dispImgOrg(:,:,1) = app.orgVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.orgDiffMasks(:,:,app.slice,app.channel)) + app.maskOp*app.orgDiffMasks(:,:,app.slice,app.channel);
                    app.dispImgOrg(:,:,2) = app.orgVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.orgSumMasks(:,:,app.slice,app.channel)) + app.maskOp*app.orgSumMasks(:,:,app.slice,app.channel);
                else
                    app.mtnDiffMask = 0*app.mtnDiffMask;
                    app.dispImgOrg(:,:,1) = app.orgVid(:,:,app.slice,app.channel,app.index);
                    app.dispImgOrg(:,:,2) = app.orgVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.smplMasks(:,:,app.slice,app.channel)) + app.maskOp*app.smplMasks(:,:,app.slice,app.channel);
                end

                if (~app.inBoundsPos(app.slice,app.channel,app.index)||(app.checkAcc&&~app.inBoundsAcc(app.index)))
                    app.dispImgMtn(:,:,2) = app.mtnVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.mtnDiffMask) + app.maskOp*app.mtnDiffMask;
                    app.mtnDiffMask = app.mtnDiffMask + app.smplMasks(:,:,app.slice,app.channel);
                    app.dispImgMtn(:,:,1) = app.mtnVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.mtnDiffMask) + app.maskOp*app.mtnDiffMask;
                    app.dispImgMtn(:,:,3) = app.mtnVid(:,:,app.slice,app.channel,app.index);
                else
                    app.dispImgMtn(:,:,1) = app.mtnVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.mtnDiffMask) + app.maskOp*app.mtnDiffMask;
                    app.mtnDiffMask = app.mtnDiffMask + app.smplMasks(:,:,app.slice,app.channel);
                    app.dispImgMtn(:,:,2) = app.mtnVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.mtnDiffMask) + app.maskOp*app.mtnDiffMask;
                    app.dispImgMtn(:,:,3) = app.mtnVid(:,:,app.slice,app.channel,app.index);
                end
            else
                if (app.showAes)
                    app.mtnDiffMask = circshift(app.aesMasks(:,:,app.slice,app.channel),app.pos(app.index,1:2));
                    app.dispImgOrg(:,:,1) = app.orgVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.aesMasks(:,:,app.slice,app.channel)) + app.maskOp*app.aesMasks(:,:,app.slice,app.channel);
                    app.dispImgOrg(:,:,2) = app.dispImgOrg(:,:,1);
                else
                    app.mtnDiffMask = 0*app.mtnDiffMask;
                    app.dispImgOrg(:,:,1) = app.orgVid(:,:,app.slice,app.channel,app.index);
                    app.dispImgOrg(:,:,2) = app.orgVid(:,:,app.slice,app.channel,app.index);
                end

                app.dispImgMtn(:,:,1) = app.mtnVid(:,:,app.slice,app.channel,app.index) .* (1-app.maskOp*app.mtnDiffMask) + app.maskOp*app.mtnDiffMask;
                app.dispImgMtn(:,:,2) = app.dispImgMtn(:,:,1);
                app.dispImgMtn(:,:,3) = app.mtnVid(:,:,app.slice,app.channel,app.index);
            end

            app.dispImgOrg(:,:,3) = app.orgVid(:,:,app.slice,app.channel,app.index);
            for ii=1:3
                app.dispImgCmp(:,:,ii) = app.cmpVid(:,:,app.slice,app.index);
            end

            app.imhandleCmp.CData = app.dispImgCmp;
            app.imhandleMtn.CData = app.dispImgMtn;
            app.imhandleOrg.CData = app.dispImgOrg;
        end

        function mvMaxAccBar(app,~,~)
            coord = app.accBar.Position;
            app.maxAcc = coord(1,2);
            app.MaxAccEditField.Value = app.maxAcc;
            app.inBoundsAcc = app.maxAcc > app.acc(:,3);
            if (~app.playing)
                app.loadFrame();
            end
        end

        function updateAccBar(app)
            app.accBar.Position = [-10,app.maxAcc;app.frames+10,app.maxAcc];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, params, refVidFile, vidFile, startFrame, endFrame, loadDlg1, loadDlg2)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.params = params;

            if (~isempty(app.params.reslice))
                app.slices = params.slicesOut;
                app.channels = params.channelsOut;
            else
                app.slices = params.slices;
                app.channels = params.channels;
            end

            % build reference frame
            if (params.volume)
                maxTiffCount = app.slices*app.channels*max((endFrame+params.mtnWndw),params.mtnIntlWndw);
            else
                maxTiffCount = app.channels*max((endFrame+params.mtnWndw),params.mtnIntlWndw)/(1+params.yBidirectional);
            end

            refTime = 0;
            app.mtn = params.mtn;
            if (app.mtn)
                stackManager = MotionTracker(params);
                if ((app.callingApp.v(1) < 9) || ((app.callingApp.v(1)==9) && (app.callingApp.v(2)<14)))
                    stackManager.frameLimit = min(64000,maxTiffCount);
                else
                    stackManager.frameLimit = maxTiffCount;
                end
                loadDlg1.Title = 'Building Reference...';
                loadDlg1.Indeterminate = 'on';
                loadDlg2.Title = 'Building Reference...';
                loadDlg2.Indeterminate = 'on';
                tic;
                stackManager.setup(refVidFile);
                refTime = toc;
                app.refImg = double(stackManager.getReference());
                maxVal = max(app.refImg,[],'all');
                minVal = min(app.refImg,[],'all');
                app.refImg = (app.refImg-minVal)/(maxVal-minVal);
            else
                stackManager = StackTracker(params);
                if ((app.callingApp.v(1) < 9) || ((app.callingApp.v(1)==9) && (app.callingApp.v(2)<14)))
                    stackManager.frameLimit = min(64000,maxTiffCount);
                else
                    stackManager.frameLimit = maxTiffCount;
                end
                stackManager.open(refVidFile);
                stackManager.readFrame();
                app.refImg = double(0*stackManager.getImage(1,1));                
                app.refImg = repmat(app.refImg,1,1,app.slices);
                stackManager.close();
            end

            % prepare masks for overlay
            app.aesMasks = zeros(size(app.refImg,1),size(app.refImg,2),app.slices,app.channels);
            if (~isempty(params.roiAes))
                for sl=1:app.slices
                    for ch=1:app.channels
                        if (~isempty(params.roiAes{sl,ch}))
                            app.aesMasks(:,:,sl,ch) = sum(params.roiAes{sl,ch},3)>0;
                        end
                    end
                end
            end

            app.smplMasks = zeros(size(app.aesMasks));
            if (~isempty(params.roiSmpl))
                for sl=1:app.slices
                    for ch=1:app.channels
                        if (~isempty(params.roiSmpl{sl,ch}))
                            app.smplMasks(:,:,sl,ch) = sum(params.roiSmpl{sl,ch},3)>0;
                        end
                    end
                end
            end
            app.mtnDiffMask = zeros(size(app.aesMasks,1),size(app.aesMasks,2));
            app.orgDiffMasks = ((app.aesMasks-app.smplMasks)>0)+0;
            app.orgSumMasks = ((app.aesMasks+app.smplMasks)>0)+0;

            if (app.mtn)
                overlapMask = zeros((2*size(app.refImg,1)-1),(2*size(app.refImg,2)-1),app.slices,app.channels);
                for sl=1:app.slices
                    for ch=1:app.channels
                        numPx = sum(app.smplMasks(:,:,sl,ch),'all');
                        if (numPx>0)
                            overlapMask(:,:,sl,ch) = xcorr2(app.aesMasks(:,:,sl,ch),app.smplMasks(:,:,sl,ch))/numPx;
                        else
                            overlapMask(:,:,sl,ch) = ones(size(overlapMask,1),size(overlapMask,2));
                        end
                    end
                end
                overlapMask = overlapMask >= 1;
                tempInBounds = zeros(1,app.frames);
            end

            % open stack manager and setup matrices to read data into
            stackManager.open(vidFile);
            app.frames = stackManager.getNumFrames();
            endFrame = min(endFrame,app.frames);
            startFrame = min(startFrame,(endFrame-1));
            app.frames = endFrame-startFrame+1;
            app.cmpVid = zeros(size(app.refImg,1),size(app.refImg,2),app.slices,app.frames);
            app.orgVid = zeros(size(app.refImg,1),size(app.refImg,2),app.slices,app.channels,app.frames);
            app.mtnVid = zeros(size(app.orgVid));
            app.pos = zeros(app.frames,3);
            app.inBoundsPos = ones(app.slices,app.channels,app.frames)>0;

            % setup progress dialog
            loadDlg1.Title = 'Processing Frames';
            loadDlg1.Indeterminate = 'off';    
            loadDlg1.Value = 0;
            loadDlg1.Cancelable = true;
            loadDlg2.Title = 'Processing Frames';
            loadDlg2.Indeterminate = 'off';    
            loadDlg2.Value = 0;
            loadDlg2.Cancelable = true;
            app.deathFlag = false;
            updateThreshold = min(round(app.frames*0.05),200);
            count = 0;
            frameTime = 0;
            % loop through passes if doing multiple passes
            for pass = 1:params.passes
                stackManager.setIndex(startFrame);
                lastPass = (pass == params.passes);
                if (params.passes > 1)
                    dlgstr = ['Pass (',num2str(pass),'/',num2str(params.passes),') Frame ('];
                else
                    dlgstr = 'Frame (';
                end
                loadDlg1.Message = [dlgstr,'0/',num2str(app.frames),')'];
                loadDlg2.Message = [dlgstr,'0/',num2str(app.frames),')'];
                % loop through frames
                for fr = 1:app.frames
                    count = count + 1;
                    % update progress bar
                    if (mod(count,updateThreshold)==0)
                        if (loadDlg1.CancelRequested || loadDlg2.CancelRequested)
                            loadDlg1.Title = 'Closing...';
                            loadDlg2.Title = 'Closing...';
                            stackManager.close();
                            app.deathFlag = true;
                            return;
                        end
                        tempMsg = [dlgstr,num2str(fr),'/',num2str(app.frames),')'];
                        tempVal = ((pass-1)+fr/app.frames)/params.passes;
                        loadDlg1.Message = tempMsg;
                        loadDlg1.Value = tempVal;
                        loadDlg2.Message = tempMsg;
                        loadDlg2.Value = tempVal;
                    end

                    % read frame
                    tic;
                    stackManager.readFrame();
                    
                    % motion correct frame and store displaced image
                    if (params.mtn)
                        stackManager.calculateDisp();
                        frameTime = frameTime + toc;
                    else
                        frameTime = frameTime + toc;
                    end

                    if (lastPass)
                        for sl=1:app.slices
                            for ch=1:app.channels
                                tempTiff = double(stackManager.getImage(sl,ch));
                                maxVal = max(tempTiff,[],'all');
                                minVal = min(tempTiff,[],'all');
                                app.orgVid(:,:,sl,ch,fr) = (tempTiff-minVal)/(maxVal-minVal);
                            end
                        end

                        if (app.mtn)
                            [xDisp,yDisp] = stackManager.getDisp();
                            app.pos(fr,1) = yDisp;
                            app.pos(fr,2) = xDisp;
                            tempTiffs = stackManager.getCompImage();
                            for sl=1:app.slices
                                maxVal = max(tempTiffs(:,:,sl),[],'all');
                                minVal = min(tempTiffs(:,:,sl),[],'all');
                                app.cmpVid(:,:,sl,fr) = (tempTiffs(:,:,sl)-minVal)/(maxVal-minVal);
                                for ch=1:app.channels
                                    app.inBoundsPos(sl,ch,fr) = overlapMask((size(app.refImg,1)-app.pos(fr,1)),(size(app.refImg,2)-app.pos(fr,2)),sl,ch);
                                    app.mtnVid(:,:,sl,ch,fr) = circshift(app.orgVid(:,:,sl,ch,fr),app.pos(fr,1:2));
                                end
                            end
                            tempInBounds(fr) = prod(app.inBoundsPos(:,:,fr),'all');
                        end
                    end
                end
            end
            stackManager.close();
            frameTime = frameTime/app.frames;

            loadDlg1.Title = 'Finishing Setup';
            loadDlg1.Message = '';
            loadDlg1.Value = 0;
            loadDlg1.Indeterminate = 'on';
            loadDlg1.Cancelable = 'off';
            loadDlg2.Title = 'Finishing Setup';
            loadDlg2.Message = '';
            loadDlg2.Value = 0;
            loadDlg2.Indeterminate = 'on';
            loadDlg2.Cancelable = 'off';

            % setup UI elements
            if (~app.mtn)
                app.mtnVid = app.orgVid;
            end
            app.orgVidRef = app.orgVid;
            app.mtnVidRef = app.mtnVid;
            app.maxAcc = params.maxAcc;
            app.checkAcc = app.params.checkAcc;
            app.showSmpl = app.ShowSampleROIsCheckBox.Value;
            app.showAes = app.ShowAESROIsCheckBox.Value;
            
            % build scrubber and make clickable
            app.frameBar = rectangle(app.ProgressAxes,'Position',[0,0,0.5,1]);
            app.frameBar.FaceColor = [0.3,0.3,0.3];
            app.hitBar = rectangle(app.ProgressAxes,'Position',[0,0,1,1]);
            app.frameBar.HitTest = "off";
            app.hitBar.HitTest = "off";
            app.ProgressAxes.Toolbar.Visible = "off";

            % misc startup settings
            app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.framerate)/1000),'TasksToExecute',Inf);
            absPath = fileparts(mfilename('fullpath'));
            app.playIcon = imread(fullfile(absPath,'+images/play.png'));
            app.pauseIcon = imread(fullfile(absPath,'+images/pause.png'));
            daspect(app.ComparisonFrameAxes,[1,1,1]);
            daspect(app.MotionFrameAxes,[1,1,1]);
            daspect(app.ReferenceFrameAxes,[1,1,1]);
            daspect(app.OriginalFrameAxes,[1,1,1]);
            refDispImg = repmat(app.refImg(:,:,1),1,1,3);
            app.dispImgCmp = refDispImg;
            app.dispImgMtn = refDispImg;
            app.dispImgOrg = refDispImg;
            app.imhandleRef = image(app.ReferenceFrameAxes, refDispImg);
            app.imhandleCmp = image(app.ComparisonFrameAxes, refDispImg);
            app.imhandleMtn = image(app.MotionFrameAxes, refDispImg);
            app.imhandleOrg = image(app.OriginalFrameAxes, refDispImg);
            set(app.ComparisonFrameAxes,'xlim',[0,size(app.refImg,2)]);
            set(app.ComparisonFrameAxes,'ylim',[0,size(app.refImg,1)]);
            set(app.MotionFrameAxes,'xlim',[0,size(app.refImg,2)]);
            set(app.MotionFrameAxes,'ylim',[0,size(app.refImg,1)]);
            set(app.ReferenceFrameAxes,'xlim',[0,size(app.refImg,2)]);
            set(app.ReferenceFrameAxes,'ylim',[0,size(app.refImg,1)]);
            set(app.OriginalFrameAxes,'xlim',[0,size(app.refImg,2)]);
            set(app.OriginalFrameAxes,'ylim',[0,size(app.refImg,1)]);
            app.playing = false;
            app.index = 1;

            % setup slice selector
            slopts = cell(1,app.slices);
            for ii=1:app.slices
                slopts{ii} = ['Slice ',num2str(ii)];
            end
            app.slice = 1;
            app.SliceDropDown.Items = slopts;
            app.SliceDropDown.ItemsData = 1:app.slices;
            app.SliceDropDown.Value = app.slice;

            % setup channel selector
            chnlopts = cell(1,app.channels);
            for ii = 1:app.channels
                chnlopts{ii} = ['Channel ',num2str(ii)];
            end
            app.mainChannel = params.mainChannel;
            app.channel = app.mainChannel;
            app.ChannelDropDown.Items = chnlopts;
            app.ChannelDropDown.ItemsData = 1:app.channels;
            app.ChannelDropDown.Value = app.mainChannel;

            if (refTime>0)
                app.ReferenceTimeLabel.Text = strcat("Reference Calc. Time: ",num2str(refTime)," s");
            end

            if (frameTime>0)
                app.FrameTimeLabel.Text = strcat("Frame Calc. Time: ",num2str(frameTime)," s/frame");
            end
            
            % prepare elements for motion plot
            app.PlotMetricDropDown.ItemsData = 1:3;
            app.PlotMetricDropDown.Enable = app.mtn;
            app.inBoundsAcc = ones(app.frames,1)>0;
            app.FilterAccelerationCheckBox.Enable = app.mtn;
            app.FilterAccelerationCheckBox.Value = app.mtn&&app.checkAcc;
            app.MaxAccEditField.Enable = app.mtn&&app.checkAcc;
            app.MaxAccEditField.Value = app.maxAcc;
            if (app.mtn)
                % calculate derivatives
                if (params.bidirectional)
                    app.pos = app.pos((1:2:end),:);
                end
                app.vel = zeros(size(app.pos));
                app.acc = zeros(size(app.pos));

                if (app.frames > 2)
                    app.vel(2:(end-1),1:2) = (app.pos(3:end,1:2)-app.pos(1:(end-2),1:2))/2;
                    app.acc(2:(end-1),1:2) = app.pos(3:end,1:2)+app.pos(1:(end-2),1:2)-2*app.pos(2:(end-1),1:2);
                    app.pos(:,3) = sqrt(app.pos(:,1).^2+app.pos(:,2).^2);
                    app.vel(:,3) = sqrt(app.vel(:,1).^2+app.vel(:,2).^2);
                    app.acc(:,3) = sqrt(app.acc(:,1).^2+app.acc(:,2).^2);
                    app.vel(end,:) = app.vel((end-1),:);
                    app.acc(end,:) = app.acc((end-1),:);
                end
                
                if (params.bidirectional)
                    app.pos = repelem(app.pos,2,1);
                    app.vel = repelem(app.vel,2,1);
                    app.acc = repelem(app.acc,2,1);
                end
                app.inBoundsAcc = app.maxAcc > app.acc(:,3);

                % edges to plot out of bounds regions on motion plot
                tempInd = 2:app.frames;
                tempInBounds = tempInBounds>0;
                startEdges = tempInd((~tempInBounds(2:end))&tempInBounds(1:(end-1)));
                if (~tempInBounds(1))
                    startEdges = [1, startEdges];
                end
                endEdges = tempInd(tempInBounds(2:end)&(~tempInBounds(1:(end-1))))-1;
                if (~tempInBounds(end))
                    endEdges = [endEdges, app.frames];
                end
                widths = endEdges-startEdges;
                widths = widths + 0.5*(widths==0);

                % draw motion plot elements
                hold(app.MtnPlotAxes,'on');
                upperLim = max([max(app.acc,[],'all'),max(app.vel,[],'all'),max(app.pos,[],'all')]);
                upperLim = ceil(upperLim*1.25);
                lowerLim = min([min(app.acc,[],'all'),min(app.vel,[],'all'),min(app.pos,[],'all')]);
                lowerLim = floor(lowerLim);
                for ii=1:length(startEdges)
                    rectangle(app.MtnPlotAxes,'Position',[startEdges(ii),lowerLim,widths(ii),(upperLim-lowerLim)],'FaceColor',[1,0.7,0.7],'EdgeColor','none');
                end
                app.mtnXPlotHandle = plot(app.MtnPlotAxes,1:app.frames,zeros(1,app.frames),'green');
                app.mtnYPlotHandle = plot(app.MtnPlotAxes,1:app.frames,zeros(1,app.frames),'red');
                app.mtnAbsPlotHandle = plot(app.MtnPlotAxes,1:app.frames,zeros(1,app.frames),'blue');
                xlim(app.MtnPlotAxes,[1,app.frames]);
                hold(app.MtnPlotAxes,'off');

                app.framePnt = images.roi.Point(app.MtnPlotAxes);
                app.framePnt.Deletable = false;
                app.framePnt.InteractionsAllowed = 'none';
                app.accBar = images.roi.Line(app.MtnPlotAxes);
                app.accBar.Color = 'black';
                app.accBar.MarkerSize = 0.1;
                app.accBar.LineWidth = 1;
                app.accBar.DrawingArea = [-10,0,app.frames+20,10000];
                app.accBar.Deletable = false;
                app.accBar.InteractionsAllowed = 'translate';
                addlistener(app.accBar,'ROIMoved',@app.mvMaxAccBar);
                app.chngPlotType([]);
            else
                app.vel = zeros(size(app.pos));
                app.acc = zeros(size(app.pos));
            end

            % draw first frame and clean up
            app.loadFrame();
            app.setScrubber();
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.WindowStyle = "modal";
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: FrameBackButton
        function prvFrame(app, event)
            if (app.index > 1)
                app.stopVid();
                app.index = app.index - 1;
                app.loadFrame();
                app.setScrubber();
            end
        end

        % Button pushed function: FrameFwdButton
        function advFrame(app, event)
            if (app.index < app.frames)
                app.stopVid();
                app.index = app.index+1;
                app.loadFrame();
                app.setScrubber();
            end
        end

        % Value changed function: PlayButton
        function playPause(app, event)
            if (~app.playing)
                app.startVid();
            else
                app.stopVid();
            end
        end

        % Value changed function: ChannelDropDown
        function chngChannel(app, event)
            % change color channel
            app.channel = app.ChannelDropDown.Value;
            if (~app.playing)
                app.loadFrame();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopVid();
            app.params.checkAcc = app.checkAcc;
            app.params.maxAcc = app.maxAcc;
            if (app.checkAcc)
                app.params.mtnOverlap = true;
            end
            app.callingApp.closePreview(app.params);
            delete(app);
        end

        % Button down function: ProgressAxes
        function barClk(app, event)
            % not sure if this is needed (data should not take long to
            % access)
            flag = false;
            if (app.playing)
                app.stopVid();
                flag = true;
            end

            % update video time based on where scrubber is clicked and
            % update ui
            coord = app.ProgressAxes.CurrentPoint;
            app.index = round(coord(1)*app.frames);
            if (app.index < 1)
                app.index = 1;
            end
            app.loadFrame();
            app.setScrubber();

            if (flag)
                app.startVid();
            end

        end

        % Button pushed function: SettingsButton
        function opnPlaybackSettings(app, event)
            if (app.playing)
                app.stopVid();
            end
            
            if (isempty(app.PlaybackDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.PlaybackDlgBox = helper_apps.playback_helper_app(app,app.framerate,(app.avg*app.rollingAvg));
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case 'space'
                    app.playPause([]);
                case 'leftarrow'
                    app.prvFrame([]);
                case 'rightarrow'
                    app.advFrame([]);
            end
        end

        % Value changed function: SliceDropDown
        function chngSlice(app, event)
            app.slice = app.SliceDropDown.Value;
            app.imhandleRef.CData = repmat(app.refImg(:,:,app.slice),1,1,3);
            if (~app.playing)
                app.loadFrame();
            end
        end

        % Value changed function: MaxAccEditField
        function chngMaxAcc(app, event)
            app.maxAcc = app.MaxAccEditField.Value;
            app.updateAccBar();
            app.inBoundsAcc = app.maxAcc > app.acc(:,3);
            if (~app.playing)
                app.loadFrame();
            end
        end

        % Value changed function: FilterAccelerationCheckBox
        function chngCheckAcc(app, event)
            app.checkAcc = app.FilterAccelerationCheckBox.Value;
            app.MaxAccEditField.Enable = app.checkAcc;
            if (app.checkAcc && (app.plotType==app.ACCELERATION))
                app.updateAccBar();
                app.accBar.Visible = 'on';
            else
                app.accBar.Visible = 'off';
            end

            if (~app.playing)
                app.loadFrame();
            end
        end

        % Value changed function: ShowAESROIsCheckBox
        function setShowAes(app, event)
            app.showAes = app.ShowAESROIsCheckBox.Value;
            if (~app.playing)
                app.loadFrame();
            end
        end

        % Value changed function: ShowSampleROIsCheckBox
        function setShowSmpl(app, event)
            app.showSmpl = app.ShowSampleROIsCheckBox.Value;
            if (~app.playing)
                app.loadFrame();
            end
        end

        % Value changed function: PlotMetricDropDown
        function chngPlotType(app, event)
            app.plotType = app.PlotMetricDropDown.Value;
            switch(app.plotType)
                case app.POSITION
                    y = app.pos;
                    ylabel(app.MtnPlotAxes,'Pos');
                    app.MtnLabel.Text = 'Current Pos.:';
                    app.accBar.Visible = 'off';
                case app.VELOCITY
                    y = app.vel;
                    ylabel(app.MtnPlotAxes,'Vel');
                    app.MtnLabel.Text = 'Current Vel.:';
                    app.accBar.Visible = 'off';
                case app.ACCELERATION
                    y = app.acc;
                    ylabel(app.MtnPlotAxes,'Acc');
                    app.MtnLabel.Text = 'Current Acc.:';
                    if (app.checkAcc)
                        app.accBar.Visible = 'on';
                        app.updateAccBar();
                    else
                        app.accBar.Visible = 'off';
                    end
            end

            app.mtnXPlotHandle.YData = y(:,1)';
            app.mtnYPlotHandle.YData = y(:,2)';
            app.mtnAbsPlotHandle.YData = y(:,3)';
            maxVal = max(y,[],'all');
            minVal = min(y,[],'all');
            if (maxVal==minVal)
                ylim(app.MtnPlotAxes,[minVal-1,minVal+1]);
            else
                ylim(app.MtnPlotAxes,[floor(minVal),ceil(maxVal*1.25)]);
            end
            if (~app.playing)
                app.setScrubber();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Preview';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ReferenceFrameAxes = uiaxes(app.GridLayout);
            title(app.ReferenceFrameAxes, 'Reference Image')
            app.ReferenceFrameAxes.XTick = [];
            app.ReferenceFrameAxes.YTick = [];
            app.ComparisonFrameAxes = uiaxes(app.GridLayout);
            title(app.ComparisonFrameAxes, 'Comparison Video')
            app.ComparisonFrameAxes.XTick = [];
            app.ComparisonFrameAxes.YTick = [];
            app.OriginalFrameAxes = uiaxes(app.GridLayout);
            title(app.OriginalFrameAxes, 'Original Video')
            app.OriginalFrameAxes.XTick = [];
            app.OriginalFrameAxes.YTick = [];
            app.MotionFrameAxes = uiaxes(app.GridLayout);
            title(app.MotionFrameAxes, 'Motion Corrected Video')
            app.MotionFrameAxes.XTick = [];
            app.MotionFrameAxes.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ProgressAxes = uiaxes(app.GridLayout3);
            app.ProgressAxes.XTick = [];
            app.ProgressAxes.YTick = [];
            app.ProgressAxes.ButtonDownFcn = createCallbackFcn(app, @barClk, true);
            app.FrameBackButton = uibutton(app.GridLayout3, 'push');
            app.FrameBackButton.ButtonPushedFcn = createCallbackFcn(app, @prvFrame, true);
            app.FrameBackButton.Icon = fullfile(pathToMLAPP, '+images', 'framebackward.png');
            app.FrameBackButton.Text = '';
            app.FrameFwdButton = uibutton(app.GridLayout3, 'push');
            app.FrameFwdButton.ButtonPushedFcn = createCallbackFcn(app, @advFrame, true);
            app.FrameFwdButton.Icon = fullfile(pathToMLAPP, '+images', 'frameforward.png');
            app.FrameFwdButton.Text = '';
            app.PlayButton = uibutton(app.GridLayout3, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @playPause, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, '+images', 'play.png');
            app.PlayButton.Text = '';
            app.FrameLabel = uilabel(app.GridLayout3);
            app.FrameLabel.Text = '0/0';
            app.ChannelDropDown = uidropdown(app.GridLayout3);
            app.ChannelDropDown.Items = {'Channel 1'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelDropDown.Value = 'Channel 1';
            app.SettingsButton = uibutton(app.GridLayout3, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @opnPlaybackSettings, true);
            app.SettingsButton.Text = 'Settings';
            app.SliceDropDown = uidropdown(app.GridLayout3);
            app.SliceDropDown.Items = {'Slice 1'};
            app.SliceDropDown.ValueChangedFcn = createCallbackFcn(app, @chngSlice, true);
            app.SliceDropDown.Value = 'Slice 1';
            app.ReferenceTimeLabel = uilabel(app.GridLayout);
            app.ReferenceTimeLabel.Text = ' Ref. Calc. Time: NA';
            app.FrameTimeLabel = uilabel(app.GridLayout);
            app.FrameTimeLabel.Text = ' Frame Calc. Time: NA';
            app.ShowAESROIsCheckBox = uicheckbox(app.GridLayout);
            app.ShowAESROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @setShowAes, true);
            app.ShowAESROIsCheckBox.Text = 'Show AES ROI''s';
            app.ShowAESROIsCheckBox.Value = true;
            app.ShowSampleROIsCheckBox = uicheckbox(app.GridLayout);
            app.ShowSampleROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @setShowSmpl, true);
            app.ShowSampleROIsCheckBox.Text = 'Show Sample ROI''s';
            app.ShowSampleROIsCheckBox.Value = true;
            app.MotionPanel = uipanel(app.GridLayout);
            app.MotionPanel.Title = 'Motion';
            app.GridLayout4 = uigridlayout(app.MotionPanel);
            app.MtnPlotAxes = uiaxes(app.GridLayout4);
            ylabel(app.MtnPlotAxes, 'Acc')
            zlabel(app.MtnPlotAxes, 'Z')
            app.MtnPlotAxes.XTick = [];
            app.PlotMetricDropDown = uidropdown(app.GridLayout4);
            app.PlotMetricDropDown.Items = {'Displacement', 'Velocity', 'Acceleration'};
            app.PlotMetricDropDown.ValueChangedFcn = createCallbackFcn(app, @chngPlotType, true);
            app.PlotMetricDropDown.Value = 'Acceleration';
            app.MtnLabel = uilabel(app.GridLayout4);
            app.MtnLabel.Text = 'NA:';
            app.MtnValLabel = uilabel(app.GridLayout4);
            app.MtnValLabel.Text = 'NA';
            app.MaxAccLabel = uilabel(app.GridLayout4);
            app.MaxAccLabel.Text = 'Max Acc.: ';
            app.MaxAccEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MaxAccEditField.Limits = [0 10000];
            app.MaxAccEditField.ValueChangedFcn = createCallbackFcn(app, @chngMaxAcc, true);
            app.FilterAccelerationCheckBox = uicheckbox(app.GridLayout4);
            app.FilterAccelerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @chngCheckAcc, true);
            app.FilterAccelerationCheckBox.Text = 'Filter Acceleration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preview_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end