classdef preview_params_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        CancelButton             matlab.ui.control.Button
        OkayButton               matlab.ui.control.Button
        NumberofFramesEditField  matlab.ui.control.NumericEditField
        EndFrameLabel            matlab.ui.control.Label
        StartFrameEditField      matlab.ui.control.NumericEditField
        StartFrameLabel          matlab.ui.control.Label
        VideosDropDown           matlab.ui.control.DropDown
        VideoLabel               matlab.ui.control.Label
    end

    %%%%%%%%%%%%%%
    % simple app to select video and frame range to generate data
    % processing preview from
    %%%%%%%%%%%%%%

    properties (Access = private)
        callingApp
        bidirectional
        params
        isVolume
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, filenames, params)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.VideosDropDown.Items = filenames;
            app.VideosDropDown.Value = filenames{1};
            app.bidirectional = params.bidirectional;
            app.isVolume = params.volume;
            app.params = params;

            data = cell(length(filenames),1);
            for ii = 1:length(filenames)
                data{ii} = ii;
            end
            app.VideosDropDown.ItemsData = data;

            if ((callingApp.v(1) < 9) || ((callingApp.v(1)==9) && (callingApp.v(2)<14)))
                disp('Matlab versions before 2023 cannot read large Tiff stacks. Limiting frame count in video player.')
                app.NumberofFramesEditField.Limits = [2,64000];
            end

            helper_apps.centerApp(app,callingApp);
            app.UIFigure.WindowStyle = "modal";
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: StartFrameEditField
        function setStartFrame(app, event)
            % make sure you start on an odd frame if slow axis 
            % bidirectional scanning was used (makes processing easier)
            value = app.StartFrameEditField.Value;
            if (app.bidirectional || app.isVolume)
                value = floor((value-1)/2)*2+1;
                app.StartFrameEditField.Value = value;
            end
        end

        % Value changed function: NumberofFramesEditField
        function setNumFrames(app, event)
            % make sure you start on an even frame if slow axis 
            % bidirectional scanning was used (not sure if I actually need 
            % to do this)
            value = app.NumberofFramesEditField.Value;
            if (app.bidirectional || app.isVolume)
                value = ceil((value)/2)*2;
                app.NumberofFramesEditField.Value = value;
            end
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            app.callingApp.previewParamsClbk(app.params,app.VideosDropDown.Value,app.StartFrameEditField.Value,(app.StartFrameEditField.Value + app.NumberofFramesEditField.Value - 1));
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.callingApp.previewParamsClbk(app.params,0,0,0);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.previewParamsClbk(app.params,0,0,0);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Preview Clip Range';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.VideoLabel = uilabel(app.GridLayout);
            app.VideoLabel.Text = 'Video:';
            app.VideosDropDown = uidropdown(app.GridLayout);
            app.VideosDropDown.Items = {};
            app.VideosDropDown.Value = {};
            app.StartFrameLabel = uilabel(app.GridLayout);
            app.StartFrameLabel.Text = 'Start Frame:';
            app.StartFrameEditField = uieditfield(app.GridLayout, 'numeric');
            app.StartFrameEditField.Limits = [1 Inf];
            app.StartFrameEditField.RoundFractionalValues = 'on';
            app.StartFrameEditField.ValueDisplayFormat = '%.0f';
            app.StartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @setStartFrame, true);
            app.StartFrameEditField.Value = 1;
            app.EndFrameLabel = uilabel(app.GridLayout);
            app.EndFrameLabel.Text = 'Number of Frames:';
            app.NumberofFramesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofFramesEditField.Limits = [2 Inf];
            app.NumberofFramesEditField.RoundFractionalValues = 'on';
            app.NumberofFramesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofFramesEditField.ValueChangedFcn = createCallbackFcn(app, @setNumFrames, true);
            app.NumberofFramesEditField.Value = 500;
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preview_params_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end