classdef reslicer_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        RevertButton                  matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        OkayButton                    matlab.ui.control.Button
        MappingPanel                  matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        InputChannelEditField         matlab.ui.control.NumericEditField
        InputChannelEditFieldLabel    matlab.ui.control.Label
        ListBox                       matlab.ui.control.ListBox
        PlacementOrderEditField       matlab.ui.control.NumericEditField
        PlacementOrderEditFieldLabel  matlab.ui.control.Label
        ReverseOrderCheckBox          matlab.ui.control.CheckBox
        OutputChannelEditField        matlab.ui.control.NumericEditField
        OutputChannelEditFieldLabel   matlab.ui.control.Label
        OutputPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        OutputSlicesLabel             matlab.ui.control.Label
        SlicesLabel_2                 matlab.ui.control.Label
        OutputChannelsEditField       matlab.ui.control.NumericEditField
        ChannelsEditFieldLabel        matlab.ui.control.Label
        InputPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        InputSlicesLabel              matlab.ui.control.Label
        SlicesLabel                   matlab.ui.control.Label
        InputChannelsLabel            matlab.ui.control.Label
        ChannelsLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingApp
        reslicer
        invMap

        outputChannel
        blocks
        pos
        inputChannel
        reversed

        channelsIn
        channelsOut
        slicesIn
        slicesOut

        ListBoxStrings
    end
    
    methods (Access = private)
        
        function updateText(app)
            for sl=1:app.slicesOut
                app.ListBoxStrings{sl} = strcat('Channel: ',num2str(app.invMap(sl,app.outputChannel,2)),', Slice: ',num2str(app.invMap(sl,app.outputChannel,1)));
            end
            app.ListBox.Items = app.ListBoxStrings;
            app.ListBox.ItemsData = 1:app.slicesOut;
        end

        function validateMap(app)
            check = (ones(app.channelsIn,app.channelsOut)>0);
            for sl=1:app.slicesOut
                for ch=1:app.channelsOut
                    check(app.invMap(sl,ch,1),app.invMap(sl,ch,2))=false;
                end
            end
            app.OkayButton.Enable = (sum(check,'all') == 0);
        end

        function setActiveItems(app)
            app.ListBox.Value = (app.slicesIn*(app.pos-1)+1):(app.slicesIn*app.pos);
        end

        function mapBlock(app)
            if (app.reversed(app.outputChannel,app.pos))
                for sl=1:app.slicesIn
                    app.invMap((app.slicesIn*(app.pos-1)+sl),app.outputChannel,:) = [(app.slicesIn-sl+1),app.inputChannel];
                end
            else
                for sl=1:app.slicesIn
                    app.invMap((app.slicesIn*(app.pos-1)+sl),app.outputChannel,:) = [sl,app.inputChannel];
                end
            end
        end

        function updateList(app)
            app.updateText();
            app.setActiveItems();
            app.validateMap();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, reslicer)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.reslicer = reslicer;
            app.invMap = reslicer.invMap;
            app.channelsIn = reslicer.channelsIn;
            app.channelsOut = reslicer.channelsOut;
            app.slicesIn = reslicer.slicesIn;
            app.slicesOut = reslicer.slicesOut;
            app.InputChannelsLabel.Text = num2str(app.channelsIn);
            app.InputSlicesLabel.Text = num2str(app.slicesIn);
            app.OutputSlicesLabel.Text = num2str(app.slicesOut);
            app.OutputChannelsEditField.Limits = [1,app.channelsIn];
            app.OutputChannelsEditField.Value = app.channelsOut;
            app.OutputChannelEditField.Value = 1;
            app.OutputChannelEditField.Enable = (app.channelsOut>1);
            if (app.channelsOut>1)
                app.OutputChannelEditField.Limits = [1,app.channelsOut];
            end
            app.blocks = app.channelsIn/app.channelsOut;
            app.reversed = zeros(app.channelsOut,app.blocks)>0;
            for ch=1:app.channelsOut
                for p=1:app.blocks
                    app.reversed(ch,p) = (app.invMap((app.slicesIn*(p-1)+1),ch,1) > app.invMap((app.slicesIn*p),ch,1));
                end
            end
            app.ListBoxStrings = string(zeros(app.slicesOut,1));
            app.InputChannelEditField.Limits = [1,app.channelsIn];
            app.PlacementOrderEditField.Enable = (app.blocks>1);
            if (app.blocks>1)
                app.PlacementOrderEditField.Limits = [1,app.blocks];
            end
            app.chngCurrentChannel([]);
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: OutputChannelEditField
        function chngCurrentChannel(app, event)
            app.outputChannel = app.OutputChannelEditField.Value;
            app.pos = 1;
            app.PlacementOrderEditField.Value = 1;
            app.inputChannel = app.invMap(1,app.outputChannel,2);
            app.InputChannelEditField.Value = app.inputChannel;
            app.updateText();
            app.setActiveItems();
            app.ReverseOrderCheckBox.Value = app.reversed(app.outputChannel,app.pos);
        end

        % Value changed function: PlacementOrderEditField
        function chngPlacement(app, event)
            targetPos = app.PlacementOrderEditField.Value;
            app.reversed(app.outputChannel,app.pos) = app.reversed(app.outputChannel,targetPos);
            app.inputChannel = app.invMap(app.slicesIn*targetPos,app.outputChannel,2);
            app.mapBlock();

            app.pos = app.PlacementOrderEditField.Value;
            app.reversed(app.outputChannel,app.pos) = app.ReverseOrderCheckBox.Value;
            app.inputChannel = app.InputChannelEditField.Value;
            app.mapBlock();
            app.updateList();
        end

        % Value changed function: ReverseOrderCheckBox
        function flipBlock(app, event)
            app.reversed(app.outputChannel,app.pos) = app.ReverseOrderCheckBox.Value;
            app.mapBlock();
            app.updateList();
        end

        % Value changed function: InputChannelEditField
        function chngInputChannel(app, event)
            app.inputChannel = app.InputChannelEditField.Value;
            app.mapBlock();
            app.updateList();
        end

        % Value changed function: ListBox
        function listClicked(app, event)
            value = app.ListBox.Value;
            if (iscell(value))
                value = value{1};
            end
            app.pos = ceil(value/app.slicesIn);
            app.inputChannel = app.invMap((app.pos*app.slicesIn),app.outputChannel,2);
            app.InputChannelEditField.Value = app.inputChannel;
            app.PlacementOrderEditField.Value = app.pos;
            app.ReverseOrderCheckBox.Value = app.reversed(app.outputChannel,app.pos);
            app.setActiveItems();
        end

        % Value changed function: OutputChannelsEditField
        function chngNumChannels(app, event)
            value = app.OutputChannelsEditField.Value;
            rat = app.channelsIn/value;
            if (floor(rat)~=rat)
                return;
            end

            app.blocks = rat;
            app.PlacementOrderEditField.Enable = (app.blocks>1);
            if (app.blocks > 1)
                app.PlacementOrderEditField.Limits = [1,app.blocks];
            end
            app.channelsOut = value;
            app.OutputChannelEditField.Enable = (app.channelsOut>1);
            if (app.channelsOut>1)
                app.OutputChannelEditField.Limits = [1,app.channelsOut];
            end
            app.slicesOut = app.blocks*app.slicesIn;
            app.OutputSlicesLabel.Text = num2str(app.slicesOut);
            app.invMap = zeros(app.slicesOut,app.channelsOut,2);
            for sl2=1:app.slicesOut
                for ch2=1:app.channelsOut
                    ind = sl2+(ch2-1)*app.slicesOut;
                    ch1 = ceil(ind/app.slicesIn);
                    sl1 = ind-(ch1-1)*app.slicesIn;
                    app.invMap(sl2,ch2,:) = [sl1,ch1];
                end
            end
            app.reversed = (zeros(app.channelsOut,app.blocks)>0);
            app.ListBoxStrings = string(zeros(app.slicesOut,1));
            app.OutputChannelEditField.Value = 1;
            app.chngCurrentChannel();
        end

        % Button pushed function: RevertButton
        function revert(app, event)
            app.reslicer.resetMap();
            app.callingApp.closeReslicerDlg();
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.callingApp.closeReslicerDlg();
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            app.reslicer.setInverseMap(app.invMap);
            app.callingApp.closeReslicerDlg();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeReslicerDlg();
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Reslice';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputPanel = uipanel(app.GridLayout);
            app.InputPanel.Title = 'Input';
            app.GridLayout2 = uigridlayout(app.InputPanel);
            app.ChannelsLabel = uilabel(app.GridLayout2);
            app.ChannelsLabel.Text = 'Channels:';
            app.InputChannelsLabel = uilabel(app.GridLayout2);
            app.InputChannelsLabel.Text = '1';
            app.SlicesLabel = uilabel(app.GridLayout2);
            app.SlicesLabel.Text = 'Slices:';
            app.InputSlicesLabel = uilabel(app.GridLayout2);
            app.InputSlicesLabel.Text = '1';
            app.OutputPanel = uipanel(app.GridLayout);
            app.OutputPanel.Title = 'Output';
            app.GridLayout3 = uigridlayout(app.OutputPanel);
            app.ChannelsEditFieldLabel = uilabel(app.GridLayout3);
            app.ChannelsEditFieldLabel.Text = 'Channels:';
            app.OutputChannelsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.OutputChannelsEditField.Limits = [1 Inf];
            app.OutputChannelsEditField.RoundFractionalValues = 'on';
            app.OutputChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @chngNumChannels, true);
            app.OutputChannelsEditField.Value = 1;
            app.SlicesLabel_2 = uilabel(app.GridLayout3);
            app.SlicesLabel_2.Text = 'Slices:';
            app.OutputSlicesLabel = uilabel(app.GridLayout3);
            app.OutputSlicesLabel.Text = '1';
            app.MappingPanel = uipanel(app.GridLayout);
            app.MappingPanel.Title = 'Mapping';
            app.GridLayout4 = uigridlayout(app.MappingPanel);
            app.OutputChannelEditFieldLabel = uilabel(app.GridLayout4);
            app.OutputChannelEditFieldLabel.Text = 'Output Channel:';
            app.OutputChannelEditField = uieditfield(app.GridLayout4, 'numeric');
            app.OutputChannelEditField.Limits = [1 Inf];
            app.OutputChannelEditField.ValueChangedFcn = createCallbackFcn(app, @chngCurrentChannel, true);
            app.OutputChannelEditField.Value = 1;
            app.ReverseOrderCheckBox = uicheckbox(app.GridLayout4);
            app.ReverseOrderCheckBox.ValueChangedFcn = createCallbackFcn(app, @flipBlock, true);
            app.ReverseOrderCheckBox.Text = 'Reverse Order';
            app.PlacementOrderEditFieldLabel = uilabel(app.GridLayout4);
            app.PlacementOrderEditFieldLabel.Text = 'Placement Order:';
            app.PlacementOrderEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PlacementOrderEditField.Limits = [1 Inf];
            app.PlacementOrderEditField.ValueChangedFcn = createCallbackFcn(app, @chngPlacement, true);
            app.PlacementOrderEditField.Value = 1;
            app.ListBox = uilistbox(app.GridLayout4);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @listClicked, true);
            app.ListBox.Value = {};
            app.InputChannelEditFieldLabel = uilabel(app.GridLayout4);
            app.InputChannelEditFieldLabel.Text = 'Input Channel:';
            app.InputChannelEditField = uieditfield(app.GridLayout4, 'numeric');
            app.InputChannelEditField.Limits = [1 Inf];
            app.InputChannelEditField.ValueChangedFcn = createCallbackFcn(app, @chngInputChannel, true);
            app.InputChannelEditField.Value = 1;
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.RevertButton = uibutton(app.GridLayout, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @revert, true);
            app.RevertButton.Text = 'Revert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reslicer_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end