classdef stack_select_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        AllStacksPanel       matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        Listbox              matlab.ui.control.ListBox
        RemoveButton         matlab.ui.control.Button
        AddButton            matlab.ui.control.Button
        ReferenceStackPanel  matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        SetReferenceButton   matlab.ui.control.Button
        refLabel             matlab.ui.control.Label
    end

    %%%%%%%%%%%%%%%%%%%%%%
    % Helper window to select videos for processing.
    % Can also swap out video used for reference image.
    %%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        callingApp
        vidFiles
        vidPaths
    end
    
    methods (Access = public)
        
        % called after new reference image is generated to reorder
        % files/paths
        function updateRef(app, vidFiles, vidPaths)
            app.refLabel.Text = vidFiles{1};
            app.vidFiles = vidFiles;
            app.vidPaths = vidPaths;
            app.Listbox.Items = app.vidFiles;
            app.setButtonsActive(true);
        end

        % Used to set window button activity. Mostly just to make sure that
        % the user cannot interact with the window while a reference image
        % is being generated.
        function setButtonsActive(app, enable)
            app.AddButton.Enable = enable;
            app.RemoveButton.Enable = enable;
            app.SetReferenceButton.Enable = enable;
            app.Listbox.Enable = enable;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, vidFiles, vidPaths)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            if (~isempty(vidFiles))
                app.refLabel.Text = vidFiles{1};
                app.Listbox.Items = vidFiles;
                app.vidFiles = vidFiles;
                app.vidPaths = vidPaths;
            end
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.WindowStyle = "modal";
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: AddButton
        function addVids(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % cheese to prevent main window from being thrown into the background
            [tempFiles, tempPath] = uigetfile('*.tif','Select Source Stacks',app.vidPaths{1},'MultiSelect','on');
            delete(f);

            % check if user canceled
            if (isequal(tempFiles,0))
                return;
            end

            if (~iscell(tempFiles))
                tempFiles = {tempFiles};
            end

            % make sure selected file are new
            indKeep = ones(1,length(tempFiles));
            for ind1 = 1:length(indKeep)
                for ind2 = 1:length(app.vidFiles)
                    if (isequal(tempFiles(ind1),app.vidFiles(ind2)))
                        indKeep(ind1) = 0;
                        break;
                    end
                end
            end
            % add filenamess and paths to list and update information on
            % main app
            tempFiles = tempFiles(indKeep>0);
            tempPaths = cell(1,length(tempFiles));
            tempPaths(:) = {tempPath};
            app.vidFiles = [app.vidFiles, tempFiles];
            app.vidPaths = [app.vidPaths, tempPaths];
            app.Listbox.Items = app.vidFiles;
            app.callingApp.updateVidFiles(app.vidFiles,app.vidPaths);
        end

        % Button pushed function: RemoveButton
        function removeVids(app, event)
            % find indices of selected items
            toRemove = app.Listbox.Value;
            indRemove = zeros(1,length(app.vidFiles));
            for ind1 = 1:length(toRemove)
                for ind2 = 2:length(app.vidFiles)
                    if (isequal(toRemove(ind1),app.vidFiles(ind2)))
                        indRemove(ind2) = 1;
                    end
                end
            end
            % remove filenames and paths from list and update main app
            indRemove = indRemove > 0;
            app.Listbox.Value = cell(0);
            app.vidFiles = app.vidFiles(~indRemove);
            app.vidPaths = app.vidPaths(~indRemove);
            app.Listbox.Items = app.vidFiles;
            app.callingApp.updateVidFiles(app.vidFiles,app.vidPaths);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeVids();
            delete(app)
        end

        % Button pushed function: SetReferenceButton
        function loadRef(app, event)
            % make sure selection is meaningful
            refFile = app.Listbox.Value;
            if (isempty(refFile) || length(refFile)>1)
                return;
            end

            % find the index
            ind = 1;
            while(~isequal(refFile{1},app.vidFiles{ind}))
                ind = ind + 1;
            end

            % tell main app to swap reference file and generate new image
            app.setButtonsActive(false);
            drawnow;
            app.callingApp.loadStackRelay(app.vidFiles{ind},app.vidPaths{ind});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Video Manager';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'thumbnail_compression_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ReferenceStackPanel = uipanel(app.GridLayout);
            app.ReferenceStackPanel.Title = 'Reference Stack';
            app.GridLayout3 = uigridlayout(app.ReferenceStackPanel);
            app.refLabel = uilabel(app.GridLayout3);
            app.refLabel.Text = 'None';
            app.SetReferenceButton = uibutton(app.GridLayout3, 'push');
            app.SetReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @loadRef, true);
            app.SetReferenceButton.Text = 'Set Reference';
            app.AllStacksPanel = uipanel(app.GridLayout);
            app.AllStacksPanel.Title = 'All Stacks';
            app.GridLayout2 = uigridlayout(app.AllStacksPanel);
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @addVids, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.GridLayout2, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @removeVids, true);
            app.RemoveButton.Text = 'Remove';
            app.Listbox = uilistbox(app.GridLayout2);
            app.Listbox.Items = {};
            app.Listbox.Multiselect = 'on';
            app.Listbox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stack_select_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end