classdef small_channel_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        SlicesSpinner          matlab.ui.control.Spinner
        SlicesSpinnerLabel     matlab.ui.control.Label
        VolumeCheckBox         matlab.ui.control.CheckBox
        BidirectionalCheckBox  matlab.ui.control.CheckBox
        ChannelsSpinner        matlab.ui.control.Spinner
        ChannelsLabel          matlab.ui.control.Label
        CancelButton           matlab.ui.control.Button
        OkayButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            params.channels = app.ChannelsSpinner.Value;
            params.bidirectional = app.BidirectionalCheckBox.Value;
            params.volume = app.VolumeCheckBox.Value;
            if (params.volume)
                params.slices = app.SlicesSpinner.Value;
            else
                params.slices = false;
            end
            app.callingApp.chnlClbk(params);
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.callingApp.closeChnlClbk();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeChnlClbk();
            delete(app);
        end

        % Value changed function: VolumeCheckBox
        function enableVolume(app, event)
            if (app.VolumeCheckBox.Value)
                app.SlicesSpinner.Enable = true;
            else
                app.SlicesSpinner.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Stack Details';
            app.UIFigure.Icon = 'tiff_viewer_app_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ChannelsLabel = uilabel(app.GridLayout);
            app.ChannelsLabel.Text = 'Channels:';
            app.ChannelsSpinner = uispinner(app.GridLayout);
            app.ChannelsSpinner.Limits = [1 Inf];
            app.ChannelsSpinner.RoundFractionalValues = 'on';
            app.ChannelsSpinner.Value = 1;
            app.BidirectionalCheckBox = uicheckbox(app.GridLayout);
            app.BidirectionalCheckBox.Text = 'Bidirectional';
            app.VolumeCheckBox = uicheckbox(app.GridLayout);
            app.VolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @enableVolume, true);
            app.VolumeCheckBox.Text = 'Volume';
            app.SlicesSpinnerLabel = uilabel(app.GridLayout);
            app.SlicesSpinnerLabel.Text = 'Slices:';
            app.SlicesSpinner = uispinner(app.GridLayout);
            app.SlicesSpinner.Limits = [1 Inf];
            app.SlicesSpinner.RoundFractionalValues = 'on';
            app.SlicesSpinner.Enable = 'off';
            app.SlicesSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = small_channel_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end