classdef tiff_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImageMenu               matlab.ui.container.Menu
        LoadStackMenu           matlab.ui.container.Menu
        WindowLevelMenu         matlab.ui.container.Menu
        BidirectionalShiftMenu  matlab.ui.container.Menu
        PlaybackOptionsMenu     matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        SetFrameEditField       matlab.ui.control.NumericEditField
        GoToLabel               matlab.ui.control.Label
        StopButton              matlab.ui.control.Button
        SliceSpinner            matlab.ui.control.Spinner
        SliceLabel              matlab.ui.control.Label
        frameLabel              matlab.ui.control.Label
        FrameBackwardButton     matlab.ui.control.Button
        FrameForwardButton      matlab.ui.control.Button
        PlayButton              matlab.ui.control.StateButton
        ChannelDropDown         matlab.ui.control.DropDown
        progressAxes            matlab.ui.control.UIAxes
        ImageAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % file info
        tempFile
        tempPath
        file
        path
        
        % related to display
        tin
        currentFrame;
        channels
        channel
        slices
        slice
        isVolume
        frames
        index
        resetCounter
        resetCount=5000;
        window
        dispImage
        imhandle
        projImages
        showProj
        wlCounter
        wlResetCount
        
        % related to animation of video (including rolling average)
        tmr
        playing
        framerate = 15;
        frmBfr
        frmBfrInd
        frmBfrSz
        frmBfrFlag
        avg

        % related to frameBar UI element
        frameBar
        hitbar
        playIcon
        pauseIcon

        % related to deinterlacing raw bidirectional data
        bidirectional
        yShift
        autoShiftVal
        h
        h2
        bidirectionalBuffer

        % helper apps
        interruptDialog
        chnlDlgBox
        wlDlgBox
        yShiftDlgBox
        playbackDlgBox
    end

    properties (Access = public)
        % matlab version
        v
    end
    
    properties (Access = public)
        % matlab date used for version compatability issues
        matlabDate
    end
    
    methods (Access = public)
        
        % called to read in file info
        function chnlClbk(app,params)
            app.channels = params.channels;
            app.bidirectional = params.bidirectional;
            app.isVolume = params.volume;
            app.slices = params.slices;
            app.slice = 1;
            app.playing = false;
            delete(app.chnlDlgBox);
            app.chnlDlgBox = [];
            app.interruptDialog.Title = "Counting Frames...";
            app.interruptDialog.Indeterminate = 'on';

            % open stream and count frames
            tempTin = Tiff(fullfile(app.tempPath,app.tempFile));
            tempFrames = 1;
            app.interruptDialog.Cancelable = "on";
            notifyCount = 500;
            if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                cutoff = 64000;
                while(~tempTin.lastDirectory() && (tempFrames<cutoff))
                    if (mod(tempFrames,notifyCount)==0)
                        if (app.interruptDialog.CancelRequested)
                            close(tempTin);
                            close(app.interruptDialog);
                            return;
                        end

                        app.interruptDialog.Message = ['Frames: ',num2str(tempFrames)];
                    end
                    tempTin.nextDirectory();
                    tempFrames = tempFrames+1;
                end

                if (tempFrames==cutoff)
                    warndlg(['Current matlab version limited to 2^16 frame stacks. Only first ',num2str(cutoff),' frames accessible.'],'Too many frames');
                end
            else
                while(~tempTin.lastDirectory())
                    if (mod(tempFrames,notifyCount)==0)
                        if (app.interruptDialog.CancelRequested)
                            close(tempTin);
                            close(app.interruptDialog);
                            return;
                        end
                        app.interruptDialog.Message = ['Frames: ',num2str(tempFrames)];
                    end
                    tempTin.nextDirectory();
                    tempFrames = tempFrames+1;
                end
            end
            app.interruptDialog.Cancelable = 'off';
            if (~isempty(app.tin))
                close(app.tin);
                clear app.tin;
            end
            close(tempTin);
            clear tempTin;
            app.frames = (1 + app.bidirectional) * tempFrames / app.channels;
            if (app.isVolume)
                app.frames = floor(tempFrames/(app.channels*app.slices));
                app.SliceSpinner.Enable = true;
                app.SliceSpinner.Limits = [1,app.slices];
            else
                app.frames = floor(tempFrames*(1+app.bidirectional)/app.channels);
                app.SliceSpinner.Enable = false;
            end
            app.SliceSpinner.Value = 1;

            stackManager = StackTracker(params);
            tempImages = stackManager.buildProjection(fullfile(app.tempPath,app.tempFile),min(app.frames,1500),app.interruptDialog);
            if (app.bidirectional)
                app.autoShiftVal = 0;
                if (app.isVolume)
                    app.projImages = zeros(size(tempImages,1),size(tempImages,2),app.slices,app.channels);
                    for sl=1:app.slices
                        for ch=1:app.channels
                            im1 = squeeze(tempImages(:,:,sl,2*ch-1));
                            im2 = squeeze(tempImages(:,:,sl,2*ch));
                            temp = xcorr2((im1 - mean(im1,'all')),(im2 - mean(im2,'all')));
                            temp = temp(:,size(im1,2));
                            [~, ind] = max(temp);
                            app.autoShiftVal = app.autoShiftVal + size(im1,1)-ind;
                            app.projImages(:,:,sl,ch) = im1;
                        end
                    end
                    app.autoShiftVal = round(app.autoShiftVal/(app.channels*app.slices));
                else
                    app.projImages = zeros(size(tempImages,1),size(tempImages,2),1,app.channels);
                    for ch=1:app.channels
                        im1 = squeeze(tempImages(:,:,2*ch-1));
                        im2 = squeeze(tempImages(:,:,2*ch));
                        temp = xcorr2((im1 - mean(im1,'all')),(im2 - mean(im2,'all')));
                        temp = temp(:,size(im1,2));
                        [~, ind] = max(temp);
                        app.autoShiftVal = app.autoShiftVal + size(im1,1)-ind;
                        app.projImages(:,:,1,ch) = im1;
                    end
                    app.autoShiftVal = round(app.autoShiftVal/app.channels);
                end
            else
                if (app.isVolume)
                    app.projImages = tempImages;
                else
                    app.projImages = zeros(size(tempImages,1),size(tempImages,2),1,app.channels);
                    for ch=1:app.channels
                        app.projImages(:,:,1,ch) = tempImages(:,:,ch);
                    end
                end
            end
            app.file = app.tempFile;
            app.path = app.tempPath;
            app.tin = Tiff(fullfile(app.path,app.file));

            % finish setup
            app.ImageAxes.Title.String = strcat("file: ", app.tempFile);
            app.resetCounter = 0;
            app.index = 1;
            app.channel = 1;
            app.window = zeros(app.channels,2);
            chnlopts = cell(1,app.channels);

            for ii = 1:app.channels
                chnlopts{ii} = strcat('Channel ',num2str(ii));
                app.tin.setDirectory(ii);
                app.window(ii,1) = min(app.projImages(:,:,:,ii),[],'all');
                app.window(ii,2) = max(app.projImages(:,:,:,ii),[],'all');
                if (app.window(ii,1)==app.window(ii,2))
                    app.window(ii,2) = app.window(ii,2)+0.1;
                end
            end

            if (app.bidirectional && ~app.isVolume)
                app.h = stackManager.htot;
                app.h2 = stackManager.h;
                app.bidirectionalBuffer = zeros(app.h,size(app.projImages,2));
            else
                app.h2 = size(app.projImages,1);
            end

            app.showProj = false;
            app.ChannelDropDown.Items = chnlopts;
            app.ChannelDropDown.ItemsData = 1:app.channels;
            app.ChannelDropDown.Value = 1;
            app.dispImage = zeros(size(app.projImages,1),size(app.projImages,2),3);
            app.imhandle = image(app.ImageAxes, app.dispImage);
            set(app.ImageAxes,'xlim',[0,size(app.projImages,2)]);
            set(app.ImageAxes,'ylim',[0,app.h2]);
            close(app.interruptDialog);
            app.yShift = app.autoShiftVal;
            app.frmBfrFlag = true;
            app.loadFrame();
            app.setBar();

            app.StopButton.Enable = true;
            app.PlayButton.Enable = true;
            app.FrameBackwardButton.Enable = true;
            app.FrameForwardButton.Enable = true;
            app.ChannelDropDown.Enable = true;
            app.WindowLevelMenu.Enable = true;
            app.PlaybackOptionsMenu.Enable = true;
            app.BidirectionalShiftMenu.Enable = app.bidirectional;
            app.SetFrameEditField.Enable = true;
            app.SetFrameEditField.Limits = [1,app.frames];
        end

        % called when file loading is cancelled
        function closeChnlClbk(app)
            delete(app.chnlDlgBox);
            app.chnlDlgBox = [];
            close(app.interruptDialog);
        end

        function chngImgBnds(app,window)
            app.window(app.channel,:) = window;
            tempTiff = (app.currentFrame-app.window(app.channel,1))/(app.window(app.channel,2)-app.window(app.channel,1));
            for ii=1:3
                app.dispImage(:,:,ii) = tempTiff;
            end
            app.imhandle.CData = app.dispImage;
        end

        function closeBC(app)
            if (~isempty(app.wlDlgBox))
                delete(app.wlDlgBox);
                app.wlDlgBox = [];
            end
        end

        function chngYShift(app, yShift)
            app.yShift = yShift;
            if (app.showProj)
                app.currentFrame = circshift(app.projImages(:,:,app.slice,app.channel),app.yShift,1);
                app.chngImgBnds(app.window(app.channel,:));
            else
                app.loadFrame();
            end
        end

        function closeShift(app)
            if (~isempty(app.yShiftDlgBox))
                delete(app.yShiftDlgBox);
                app.yShiftDlgBox = [];
            end
        end

        function shift = autoYShift(app)
            shift = app.autoShiftVal;
            app.chngYShift(app.autoShiftVal);
        end

        function playbackClbk(app,framerate,average,rollingAverage)
            if (app.framerate~=framerate)
                app.tmr = [];
                app.framerate = framerate;
                app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.framerate)/1000),'TasksToExecute',Inf);   
            end

            if (average)
                if (~app.avg || (app.frmBfrSz ~= rollingAverage))
                    app.frmBfrSz = rollingAverage;
                    app.frmBfrFlag = true;
                end
            end

            app.avg = average;
        end

        function closePlayback(app)
            if (~isempty(app.playbackDlgBox))
                delete(app.playbackDlgBox);
                app.playbackDlgBox = [];
            end
        end

        function playNext(app,obj,evt)
            if (app.index < app.frames)
                app.index = app.index+1;
            else
                app.index = 1;
            end
            app.loadFrame();
            app.setBar();
        end

        function setBar(app)
            app.frameLabel.Text = strcat(num2str(app.index),"/",num2str(app.frames));
            app.frameBar.Position = [0,0,app.index/app.frames,1];
        end

        function loadFrame(app)
            if (mod(app.resetCounter,app.resetCount)==0)
                app.resetCounter = 0;
                close(app.tin);
                clear app.tin;
                app.tin = Tiff(fullfile(app.path,app.file));
            end
            app.resetCounter = app.resetCounter+1;

            if (app.isVolume)
                if (app.bidirectional)
                    if (mod(app.index,2)==1)
                        app.tin.setDirectory((app.index-1)*app.channels*app.slices+app.channels*(app.slice-1)+app.channel)
                        tempTiff = circshift(double(app.tin.read()),app.yShift,1);
                    else
                        app.tin.setDirectory(app.index*app.channels*app.slices-app.channels*app.slice+app.channel);
                        tempTiff = flipud(double(app.tin.read()));
                    end
                else
                    app.tin.setDirectory((app.index-1)*app.channels*app.slices+app.channels*(app.slice-1)+app.channel);
                    tempTiff = double(app.tin.read);
                end
            else
                if (app.bidirectional)
                    if (mod(app.index,2)==1)
                        app.tin.setDirectory((app.index-1)*app.channels/2+app.channel);
                        app.bidirectionalBuffer = double(app.tin.read());
                        tempTiff = circshift(app.bidirectionalBuffer(1:app.h2,:),app.yShift,1);
                    else
                        if (app.playing)
                            tempTiff = flipud(app.bidirectionalBuffer((app.h-app.h2+1):app.h,:));
                        else
                            app.tin.setDirectory((app.index-2)*app.channels/2+app.channel);
                            app.bidirectionalBuffer = double(app.tin.read());
                            tempTiff = flipud(app.bidirectionalBuffer((app.h-app.h2+1):app.h,:));
                        end
                    end
                else
                    app.tin.setDirectory((app.index-1)*app.channels+app.channel);
                    tempTiff = double(app.tin.read());
                end
            end

            if (app.avg)
                if (app.frmBfrFlag)
                    app.frmBfrFlag = false;
                    app.wlCounter=0;
                    app.frmBfrInd = 1;
                    app.currentFrame = tempTiff;
                    app.frmBfr = repmat(tempTiff/app.frmBfrSz,1,1,app.frmBfrSz);
                else
                    app.frmBfrInd = mod(app.frmBfrInd,app.frmBfrSz)+1;
                    app.currentFrame = app.currentFrame - app.frmBfr(:,:,app.frmBfrInd);
                    app.frmBfr(:,:,app.frmBfrInd) = tempTiff/app.frmBfrSz;
                    app.currentFrame = app.currentFrame + app.frmBfr(:,:,app.frmBfrInd);
                end
            else
                if (app.frmBfrFlag)
                    app.wlCounter=0;
                    app.frmBfrFlag = false;
                end
                app.currentFrame = tempTiff;
            end

            tempTiff = (app.currentFrame-app.window(app.channel,1))/(app.window(app.channel,2)-app.window(app.channel,1));
            for ii=1:3
                app.dispImage(:,:,ii) = tempTiff;
            end
            app.imhandle.CData = app.dispImage;

            if (app.showProj)
                app.showProj = false;
                app.wlCounter=0;
            end

            if (~isempty(app.wlDlgBox))
                if (mod(app.wlCounter,app.wlResetCount)==0)
                    app.wlCounter = 0;
                    app.wlDlgBox.reinit(app.currentFrame,app.window(app.channel,:));
                end
                app.wlCounter = app.wlCounter+1;
            end
        end

        function stopVid(app)
            if (app.playing)
                stop(app.tmr);
                app.playing = false;
                app.PlayButton.Value = false;
                app.PlayButton.Icon = app.playIcon;
            end
        end

        function startVid(app)
            if (~app.playing)
                start(app.tmr);
                app.playing = true;
                app.PlayButton.Value = true;
                app.PlayButton.Icon = app.pauseIcon;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.frameBar = rectangle(app.progressAxes,'Position',[0,0,0.5,1]);
            app.frameBar.FaceColor = [0.3,0.3,0.3];
            app.hitbar = rectangle(app.progressAxes,'Position',[0,0,1,1]);
            app.frameBar.HitTest = "off";
            app.hitbar.HitTest = "off";
            app.progressAxes.Toolbar.Visible = "off";
            app.ImageAxes.Title.Interpreter = 'none';
            app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.framerate)/1000),'TasksToExecute',Inf);
            app.playIcon = imread('play.png');
            app.pauseIcon = imread('pause.png');
            daspect(app.ImageAxes,[1,1,1]);
            app.avg = false;
            app.wlResetCount = app.framerate*2;
            app.frmBfrSz = 10;

            temp = ver('Matlab');
            temp = split(temp.Version,'.');
            app.v = [str2double(temp{1}),str2double(temp{2})];
        end

        % Value changed function: PlayButton
        function playPause(app, event)
            if (app.playing)
                app.stopVid();
            else
                app.startVid();
            end
        end

        % Menu selected function: LoadStackMenu
        function loadStack(app, event)
            if (isempty(app.chnlDlgBox))
                app.stopVid();
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % cheese to prevent main window from being thrown into the background
                [app.tempFile, app.tempPath] = uigetfile('.tif');
                delete(f);
    
                if ~isequal(app.tempFile,0)
                    app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Waiting for channel info...");
                    app.interruptDialog.Value = 0;
                    app.UIFigure.Pointer = 'watch';
                    drawnow;
                    app.chnlDlgBox = helper_apps.small_channel_helper_app(app);
                    app.UIFigure.Pointer = 'arrow';
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.PlayButton.Value)
                stop(app.tmr);
            end

            app.closeChnlClbk();
            app.closeBC();
            app.closeShift();
            app.closePlayback();

            if(~isempty(app.tin))
                close(app.tin);
            end
            delete(app)
        end

        % Button pushed function: FrameForwardButton
        function advFrame(app, event)
            if (app.index < app.frames)
                app.stopVid();
                app.index = app.index+1;
                app.loadFrame();
                app.setBar();
            end
        end

        % Button pushed function: FrameBackwardButton
        function prvFrame(app, event)
            if (app.index > 1)
                app.stopVid();
                app.frmBfrFlag = true;
                app.index = app.index - 1;
                app.loadFrame();
                app.setBar();
            end
        end

        % Button down function: progressAxes
        function barClck(app, event)
            if (~isempty(app.tin))
                flag = false;
                if (app.playing)
                    app.stopVid();
                    flag = true;
                end

                coord = app.progressAxes.CurrentPoint;
                app.index = round(coord(1)*app.frames);
                if (app.index < 1)
                    app.index = app.index;
                end
                app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Seeking Frame...");
                app.interruptDialog.Value = 0;
                app.frmBfrFlag = true;
                app.loadFrame();
                close(app.interruptDialog);
                app.setBar();

                if (flag)
                    app.startVid();
                end
            end
        end

        % Value changed function: ChannelDropDown
        function chngChnl(app, event)
            app.channel = app.ChannelDropDown.Value;
            if (app.showProj)
                if (app.bidirectional)
                    app.currentFrame = circshift(app.projImages(:,:,app.slice,app.channel),app.yShift,1);
                else
                    app.currentFrame = app.projImages(:,:,app.slice,app.channel);
                end
                app.chngImgBnds(app.window(app.channel,:));

                if (~isempty(app.wlDlgBox))
                    app.wlDlgBox.reinit(app.currentFrame,app.window(app.channel,:));
                end
            else
                flag = false;
                if (app.PlayButton.Value)
                    app.stopVid();
                    flag = true;
                end
                app.frmBfrFlag = true;
                app.loadFrame();
                if (flag)
                    app.startVid();
                end
            end
        end

        % Menu selected function: WindowLevelMenu
        function opnWL(app, event)
            if (isempty(app.wlDlgBox))
                app.stopVid();
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.wlDlgBox = helper_apps.BC_channel_helper_app(app,app.currentFrame,app.window(app.channel,:));
                app.UIFigure.Pointer = 'arrow';
                app.wlCounter = 1;
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if (~isempty(event.Modifier))
                if (strcmp(event.Modifier,'control') && strcmp(event.Character,'o'))
                    app.loadStack([]);
                end
            end
        end

        % Menu selected function: BidirectionalShiftMenu
        function opnShift(app, event)
            if (isempty(app.yShiftDlgBox) && ~isempty(app.tin))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.yShiftDlgBox = helper_apps.bidirectional_shift_helper_app(app,app.yShift);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: SliceSpinner
        function chngSlice(app, event)
            app.slice = app.SliceSpinner.Value;
            if (app.showProj)
                if (app.bidirectional)
                    app.currentFrame = circshift(app.projImages(:,:,app.slice,app.channel),app.yShift,1);
                else
                    app.currentFrame = app.projImages(:,:,app.slice,app.channel);
                end
                app.chngImgBnds(app.window(app.channel,:));

                if (~isempty(app.wlDlgBox))
                    app.wlDlgBox.reinit(app.currentFrame,app.window(app.channel,:));
                end
            else
                flag = false;
                if (app.PlayButton.Value)
                    app.stopVid();
                    flag = true;
                end
                app.frmBfrFlag = true;
                app.loadFrame();
                if (flag)
                    app.startVid();
                end
            end
        end

        % Button pushed function: StopButton
        function stop(app, event)
            if (~app.showProj)
                if (app.PlayButton.Value)
                    app.stopVid();
                end

                app.showProj = true;
                if (app.bidirectional)
                    app.currentFrame = circshift(app.projImages(:,:,app.slice,app.channel),app.yShift,1);
                else
                    app.currentFrame = app.projImages(:,:,app.slice,app.channel);
                end
                app.chngImgBnds(app.window(app.channel,:));

                if (~isempty(app.wlDlgBox))
                    app.wlDlgBox.reinit(app.currentFrame,app.window(app.channel,:));
                end
            end
        end

        % Menu selected function: PlaybackOptionsMenu
        function opnPlayback(app, event)
            if (isempty(app.playbackDlgBox))
                if (app.playing)
                    app.stopVid();
                end

                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.playbackDlgBox = helper_apps.playback_helper_app(app,app.framerate,app.avg,app.frmBfrSz);
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: SetFrameEditField
        function setFrame(app, event)
            app.stopVid();
            app.frmBfrFlag = true;
            app.index = app.SetFrameEditField.Value;
            app.loadFrame();
            app.setBar();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Tiff Viewer';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'tiff_viewer_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ImageMenu = uimenu(app.UIFigure);
            app.ImageMenu.Text = 'Image';
            app.LoadStackMenu = uimenu(app.ImageMenu);
            app.LoadStackMenu.MenuSelectedFcn = createCallbackFcn(app, @loadStack, true);
            app.LoadStackMenu.Text = 'Load Stack';
            app.WindowLevelMenu = uimenu(app.ImageMenu);
            app.WindowLevelMenu.MenuSelectedFcn = createCallbackFcn(app, @opnWL, true);
            app.WindowLevelMenu.Enable = 'off';
            app.WindowLevelMenu.Text = 'Window/Level';
            app.BidirectionalShiftMenu = uimenu(app.ImageMenu);
            app.BidirectionalShiftMenu.MenuSelectedFcn = createCallbackFcn(app, @opnShift, true);
            app.BidirectionalShiftMenu.Enable = 'off';
            app.BidirectionalShiftMenu.Text = 'Bidirectional Shift';
            app.PlaybackOptionsMenu = uimenu(app.ImageMenu);
            app.PlaybackOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @opnPlayback, true);
            app.PlaybackOptionsMenu.Enable = 'off';
            app.PlaybackOptionsMenu.Text = 'Playback Options';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageAxes = uiaxes(app.GridLayout);
            title(app.ImageAxes, 'Title')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.ImageAxes.YTickLabel = '';
            app.ImageAxes.ZTick = [];
            app.ImageAxes.ZTickLabel = '';
            app.progressAxes = uiaxes(app.GridLayout);
            app.progressAxes.XLim = [0 1];
            app.progressAxes.XTick = [];
            app.progressAxes.XTickLabel = '';
            app.progressAxes.YTick = [];
            app.progressAxes.YTickLabel = '';
            app.progressAxes.ZTick = [];
            app.progressAxes.ZTickLabel = '';
            app.progressAxes.ButtonDownFcn = createCallbackFcn(app, @barClck, true);
            app.ChannelDropDown = uidropdown(app.GridLayout);
            app.ChannelDropDown.Items = {'Channel 1'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @chngChnl, true);
            app.ChannelDropDown.Enable = 'off';
            app.ChannelDropDown.Value = 'Channel 1';
            app.PlayButton = uibutton(app.GridLayout, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @playPause, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'play.png');
            app.PlayButton.Text = '';
            app.FrameForwardButton = uibutton(app.GridLayout, 'push');
            app.FrameForwardButton.ButtonPushedFcn = createCallbackFcn(app, @advFrame, true);
            app.FrameForwardButton.Icon = fullfile(pathToMLAPP, 'frameforward.png');
            app.FrameForwardButton.Enable = 'off';
            app.FrameForwardButton.Text = '';
            app.FrameBackwardButton = uibutton(app.GridLayout, 'push');
            app.FrameBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @prvFrame, true);
            app.FrameBackwardButton.Icon = fullfile(pathToMLAPP, 'framebackward.png');
            app.FrameBackwardButton.Enable = 'off';
            app.FrameBackwardButton.Text = '';
            app.frameLabel = uilabel(app.GridLayout);
            app.frameLabel.Text = '0/0';
            app.SliceLabel = uilabel(app.GridLayout);
            app.SliceLabel.Text = 'Slice: ';
            app.SliceSpinner = uispinner(app.GridLayout);
            app.SliceSpinner.Limits = [1 2];
            app.SliceSpinner.RoundFractionalValues = 'on';
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @chngSlice, true);
            app.SliceSpinner.Enable = 'off';
            app.SliceSpinner.Value = 1;
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stop, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'stop.png');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '';
            app.GoToLabel = uilabel(app.GridLayout);
            app.GoToLabel.Text = 'Go To: ';
            app.SetFrameEditField = uieditfield(app.GridLayout, 'numeric');
            app.SetFrameEditField.Limits = [1 Inf];
            app.SetFrameEditField.RoundFractionalValues = 'on';
            app.SetFrameEditField.ValueChangedFcn = createCallbackFcn(app, @setFrame, true);
            app.SetFrameEditField.Enable = 'off';
            app.SetFrameEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tiff_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end