classdef BC_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        RngSpinner           matlab.ui.control.Spinner
        RangeSpinnerLabel    matlab.ui.control.Label
        CntrSpinner          matlab.ui.control.Spinner
        CenterSpinnerLabel   matlab.ui.control.Label
        MaxSpinner           matlab.ui.control.Spinner
        MaximumSpinnerLabel  matlab.ui.control.Label
        MinSpinner           matlab.ui.control.Spinner
        MinimumSpinnerLabel  matlab.ui.control.Label
        HistogramAxes        matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%
    % helper window for setting brightness/contrast in main window when 
    % looking at images (does not affect how data is processed)
    %%%%%%%%%%%%%%%

    properties (Access = private)
        callingApp
        imhandle
        histhandle
        histCentRect
        histLeftRect
        histRightRect
        histMax
        nobWidth = 3;
        defaultNobHeight = 25;
        nobHeight

        highVal
        lowVal

        realRange
        rangePadding = 10;
    end
    
    methods (Access = public)
        
        % listener for when low value nob is dragged in histogram
        function leftRectMv(app,src,evt)
            % validate bc values
            if (app.histRightRect.Position(1) < app.histLeftRect.Position(1))
                tempPos = app.histLeftRect.Position;
                tempPos(1) = app.histRightRect.Position(1) - app.nobWidth;
                app.histLeftRect.Position = tempPos;
            end
            app.lowVal = app.histLeftRect.Position(1) + app.nobWidth/2;

            % update ui
            app.updateRects();
            app.updateFields();         
            app.updateImage();
        end

        % listerner for when high value nob is dragged in histogram
        function rightRectMv(app,src,evt)
            % validate bc values
            if (app.histRightRect.Position(1) < app.histLeftRect.Position(1))
                tempPos = app.histRightRect.Position;
                tempPos(1) = app.histLeftRect.Position(1) + app.nobWidth;
                app.histRightRect.Position = tempPos;
            end
            app.highVal = app.histRightRect.Position(1) + app.nobWidth/2;

            % update ui
            app.updateRects();
            app.updateFields();
            app.updateImage();
        end

        % listener for when range rectangle is dragged in histogram
        function centRectMv(app,src,evt)
            app.lowVal = app.histCentRect.Position(1);
            app.highVal = app.histCentRect.Position(1) + app.histCentRect.Position(3);
            app.updateRects();
            app.updateFields();
            app.updateImage();
        end

        % update histogram ui elements based on bc values
        function updateRects(app)
            app.histCentRect.Position = [app.lowVal,0,(app.highVal-app.lowVal),app.histMax];
            app.histLeftRect.Position = [app.lowVal-app.nobWidth/2,(app.histMax - app.nobHeight)/2,app.nobWidth,app.nobHeight];
            app.histRightRect.Position = [app.highVal-app.nobWidth/2,(app.histMax - app.nobHeight) / 2,app.nobWidth,app.nobHeight];
        end

        % update number fields based on bc values
        function updateFields(app)
            app.MinSpinner.Value = app.lowVal;
            app.MaxSpinner.Value = app.highVal;
            app.CntrSpinner.Value = (app.highVal + app.lowVal)/2;
            app.RngSpinner.Value = app.highVal - app.lowVal;
        end

        % call main app to update display based on selected bc
        function updateImage(app)
            app.callingApp.chngImgBnds(app.percToReal([app.lowVal,app.highVal]));
        end

        % called on initiating app or when color channel is changed
        % (changing content and selected bc of reference image)
        function reinit(app, image, window)
            maxVal = max(image,[],'all');
            minVal = min(image,[],'all');
            app.realRange = maxVal - minVal;
            app.highVal = app.realToPerc(window(2));
            app.lowVal = app.realToPerc(window(1));
            image = (image-minVal)/(maxVal-minVal);

            if (~isempty(app.histCentRect))
                delete(app.histCentRect);
                delete(app.histLeftRect);
                delete(app.histRightRect);
                app.histCentRect = [];
                app.histLeftRect = [];
                app.histRightRect = [];
                delete(app.histhandle);
                app.histhandle = [];
            end

            % build histogram
            [counts,bins] = imhist(image);
            bins = app.realToPerc((bins*(app.realRange))+minVal);
            counts = log10(counts+(counts==0));
            app.histhandle = bar(app.HistogramAxes,bins,counts);
            app.histMax = max(counts);
            set(app.HistogramAxes,'ylim',[0,app.histMax]);
            disableDefaultInteractivity(app.HistogramAxes);
            app.nobHeight = app.histMax * app.defaultNobHeight / 100;
            
            % build dragable rectangle indicating current bc range
            app.histCentRect = images.roi.Rectangle(app.HistogramAxes);
            app.histCentRect.Deletable = 0;
            app.histCentRect.DrawingArea = [0,0,100,app.histMax];
            app.histCentRect.InteractionsAllowed = "translate";
            app.histCentRect.MarkerSize = 1;
            addlistener(app.histCentRect,'ROIMoved',@app.centRectMv);
            addlistener(app.histCentRect,'MovingROI',@app.centRectMv);

            % build dragable rectangle for selecting lower bound
            app.histLeftRect = images.roi.Rectangle(app.HistogramAxes);
            app.histLeftRect.Deletable = 0;
            app.histLeftRect.DrawingArea = [-app.nobWidth/2,app.histMax/2 - app.nobHeight / 2,100+app.nobWidth/2,app.nobHeight];
            app.histLeftRect.InteractionsAllowed = "translate";
            addlistener(app.histLeftRect,'ROIMoved',@app.leftRectMv);
            addlistener(app.histLeftRect,'MovingROI',@app.leftRectMv);
            app.histLeftRect.MarkerSize = 1;
            app.histLeftRect.FaceAlpha = 0.5;

            % build dragable rectangle for selecting upper bound
            app.histRightRect = images.roi.Rectangle(app.HistogramAxes);
            app.histRightRect.Deletable = 0;
            app.histRightRect.DrawingArea = [-app.nobWidth/2,app.histMax / 2 - app.nobHeight / 2,100+app.nobWidth/2,app.nobHeight];
            app.histRightRect.InteractionsAllowed = "translate";
            addlistener(app.histRightRect,'ROIMoved',@app.rightRectMv);
            addlistener(app.histRightRect,'MovingROI',@app.rightRectMv);
            app.histRightRect.MarkerSize = 1;
            app.histRightRect.FaceAlpha = 0.5;

            % update ui to current values
            app.updateRects();
            app.updateFields();
        end

        function y = percToReal(app,x)
            y=app.realRange*(x-app.rangePadding)/(100-2*app.rangePadding);
        end

        function y = realToPerc(app,x)
            y=x*(100-2*app.rangePadding)/app.realRange+app.rangePadding;
        end

        function minimize(app)
            app.UIFigure.WindowState = "minimized";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, image, window)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            parentPos = callingApp.UIFigure.Position;
            pos = app.UIFigure.Position;
            movegui(app.UIFigure,[round(parentPos(1)+parentPos(3)/2-pos(3)/2),round(parentPos(2)+parentPos(4)/2)-pos(4)/2]);
            app.reinit(image,window);
            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: MaxSpinner, MinSpinner
        function chngHiLo(app, event)
            % validate bc values
            if(app.MinSpinner.Value > app.MaxSpinner.Value)
                if(event.Source == app.MinSpinner)
                    app.MinSpinner.Value = event.PreviousValue;
                else
                    app.MaxSpinner.Value = event.PreviousValue;
                end
            end
            app.lowVal = app.MinSpinner.Value;
            app.highVal = app.MaxSpinner.Value;

            % update ui
            app.updateRects();
            app.updateFields();            
            app.updateImage();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.closeBC();
            delete(app);
        end

        % Value changed function: CntrSpinner, RngSpinner
        function chngCntrRng(app, event)
            % validate bc values
            cntrVal = app.CntrSpinner.Value;
            rngVal = app.RngSpinner.Value;
            if (((cntrVal + rngVal / 2) > 100) || ((cntrVal - rngVal / 2) < 0))
                if (event.Source == app.RngSpinner)
                    app.RngSpinner.Value = event.PreviousValue;
                else
                    app.CntrSpinner.Value = event.PreviousValue;
                end
            end
            app.lowVal = app.CntrSpinner.Value - app.RngSpinner.Value / 2;
            app.highVal = app.CntrSpinner.Value + app.RngSpinner.Value / 2;

            % update ui
            app.updateRects();
            app.updateFields();
            app.updateImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Window/Level';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'trace_viewer_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HistogramAxes = uiaxes(app.GridLayout);
            title(app.HistogramAxes, 'histogram')
            xlabel(app.HistogramAxes, 'pixel value')
            ylabel(app.HistogramAxes, 'log(frequency)')
            zlabel(app.HistogramAxes, 'Z')
            app.HistogramAxes.XLim = [0 100];
            app.MinimumSpinnerLabel = uilabel(app.GridLayout);
            app.MinimumSpinnerLabel.Text = 'Minimum';
            app.MinSpinner = uispinner(app.GridLayout);
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @chngHiLo, true);
            app.MaximumSpinnerLabel = uilabel(app.GridLayout);
            app.MaximumSpinnerLabel.Text = 'Maximum';
            app.MaxSpinner = uispinner(app.GridLayout);
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @chngHiLo, true);
            app.CenterSpinnerLabel = uilabel(app.GridLayout);
            app.CenterSpinnerLabel.Text = 'Center';
            app.CntrSpinner = uispinner(app.GridLayout);
            app.CntrSpinner.ValueChangedFcn = createCallbackFcn(app, @chngCntrRng, true);
            app.RangeSpinnerLabel = uilabel(app.GridLayout);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RngSpinner = uispinner(app.GridLayout);
            app.RngSpinner.ValueChangedFcn = createCallbackFcn(app, @chngCntrRng, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BC_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end