classdef trace_plot_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        ChannelLabel            matlab.ui.control.Label
        ChannelLabel_2          matlab.ui.control.Label
        BGLabel                 matlab.ui.control.Label
        FrequencyLabel          matlab.ui.control.Label
        ROILabel                matlab.ui.control.Label
        Label1                  matlab.ui.control.Label
        FrequencyFilterHzLabel  matlab.ui.control.Label
        Label3                  matlab.ui.control.Label
        FilenameLabel           matlab.ui.control.Label
        ROITypeLabel            matlab.ui.control.Label
        LiveUpdateCheckBox      matlab.ui.control.CheckBox
        TraceAxes               matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Helper app for displaying trace data outside of main app
    % acts as member of linked list
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        traceHandler
        channel
        traceType
        index
        roiname
        filename

        callingApp

        frame
        updating
        
        hasVid
        plotLine
        plotY1
        plotY2
    end

    properties (GetAccess = public)
        nextNode
        previousNode
    end
    
    methods (Access = public)
        % I mostly just adjust the properies directly
        function setPreviousNode(app, previousNode)
            app.previousNode = previousNode;
        end

        function setNextNode(app, nextNode)
            app.nextNode = nextNode;
        end

        % delete all member of linked list
        function deleteAll(app)
            if (~isempty(app.nextNode))
                app.nextNode.deleteAll();
            end

            delete(app);
        end

        % update progress bar to align with video
        function updateTime(app,frame)
            app.frame = frame;
            if (app.updating && app.hasVid)
                x=app.frame/app.traceHandler.fps;
                app.plotLine.Position = [x,app.plotY1;x,app.plotY2];
            end

            % continue call down chain
            if (~isempty(app.nextNode))
                app.nextNode.updateTime(frame);
            end
        end

        % set to not update if video file is wrong (would cause problems
        % with progress bar)
        function changeFile(app,filename)
            if (strcmp(filename,app.filename))
                app.updating = true;
                app.LiveUpdateCheckBox.Enable = true;
                app.LiveUpdateCheckBox.Value = true;
            else
                app.updating = false;
                app.LiveUpdateCheckBox.Value = false;
                app.LiveUpdateCheckBox.Enable = false;
            end

            if (app.hasVid)
                app.plotLine.Visible = app.updating;
            end

            % continue call down chain
            if (~isempty(app.nextNode))
                app.nextNode.changeFile(filename);
            end
        end

        function minimizeAll(app)
            app.UIFigure.WindowState = "minimized";

            if (~isempty(app.nextNode))
                app.nextNode.minimizeAll();
            end
        end

        % callback when progress bar is moved
        function plotLineMv(app,~,~)
            app.frame = round(app.plotLine.Position(1,1)*app.traceHandler.fps);
            app.callingApp.setFrame(app.frame);
        end

        function plotLineClck(app,~,~)
            app.callingApp.stopVid();
        end

        % replot trace (external call)
        function update(app)
            if (app.updating)
                app.setTrace();
            end

            % continue call down chain
            if (~isempty(app.nextNode))
                app.nextNode.update();
            end
        end

        % plot current trace (internal call)
        function setTrace(app)
            if (~isempty(app.plotLine))
                delete(app.plotLine);
                app.plotLine = [];
            end
            app.traceHandler.plotTrace(app.TraceAxes,app.channel,app.traceType,app.index);
            if ((app.traceType==TraceType.AES)||(app.traceType==TraceType.Sample))
                switch(app.traceHandler.sbtrBG)
                    case(Background.Electric)
                        app.BGLabel.Text = "Electric";
                    case(Background.Fluorescent)
                        app.BgLabel.Text = "Fluorescent";
                    case(Background.None)
                        app.BgLabel.Text = "None";
                end
            end

            if ((~app.traceHandler.filtering)||(app.traceType==TraceType.Motion))
                app.FrequencyLabel.Text = "NA";
            else
                app.FrequencyLabel.Text = num2str(app.traceHandler.filterF);
            end

            % need to rebuild progress bar everytime plot is remade
            if (app.hasVid)
                app.plotLine = images.roi.Line(app.TraceAxes);
                app.plotLine.InteractionsAllowed = "translate";
                app.plotLine.MarkerSize = 0.1;
                app.plotLine.Color = 'k';
                app.plotLine.LineWidth = 2*app.plotLine.LineWidth;
                addlistener(app.plotLine,'ROIMoved',@app.plotLineMv);
                addlistener(app.plotLine,'ROIClicked',@app.plotLineClck);
                tempX = app.TraceAxes.XLim;
                tempY = app.TraceAxes.YLim;
                app.plotY1 = tempY(1);
                app.plotY2 = tempY(2);
                x = app.frame/app.traceHandler.fps;
                app.plotLine.Position = [x,tempY(1);x,tempY(2)];
                app.plotLine.DrawingArea = [tempX(1),tempY(1),(tempX(2)-tempX(1)),(tempY(2)-tempY(1))];
                if (app.updating)
                    app.plotLine.Visible = 'on';
                else
                    app.plotLine.Visible = 'off';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, nextNode, traceHandler, channel, traceType, index, frame, filename)
            app.callingApp = callingApp;
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            parentPos = callingApp.UIFigure.Position;
            pos = app.UIFigure.Position;
            movegui(app.UIFigure,[round(parentPos(1)+parentPos(3)/2-pos(3)/2),round(parentPos(2)+parentPos(4)/2)-pos(4)/2]);
            app.nextNode = nextNode;
            app.traceHandler = traceHandler;
            app.channel = channel;
            app.traceType = traceType;
            app.index = index;
            app.frame = frame;
            app.hasVid=(frame~=0);
            app.filename = filename;
            app.updating = true;
            app.TraceAxes.Title.Interpreter = 'none';
            grid(app.TraceAxes,'on');
            box(app.TraceAxes,'on');
            
            app.FilenameLabel.Text = filename;
            app.FilenameLabel.Tooltip = filename;
            switch (traceType)
                case(TraceType.AES)
                    app.ROILabel.Text = 'AES';
                    app.ChannelLabel.Text = num2str(app.channel);
                    switch(app.traceHandler.sbtrBG)
                        case(Background.Electric)
                            app.BGLabel.Text = "Electric";
                        case(Background.Fluorescent)
                            app.BgLabel.Text = "Fluorescent";
                        case(Background.None)
                            app.BgLabel.Text = "None";
                    end
                case(TraceType.Sample)
                    app.ROILabel.Text = 'Sample';
                    app.ChannelLabel.Text = num2str(app.channel);
                    switch(app.traceHandler.sbtrBG)
                        case(Background.Electric)
                            app.BGLabel.Text = "Electric";
                        case(Background.Fluorescent)
                            app.BgLabel.Text = "Fluorescent";
                        case(Background.None)
                            app.BgLabel.Text = "None";
                    end
                case(TraceType.BG)
                    app.ROILabel.Text = 'Electric Background';
                    app.ChannelLabel.Text = num2str(app.channel);
                    app.BGLabel.Text = "NA";
                case(TraceType.Fl)
                    app.ROILabel.Text = 'Fluorescent Background';
                    app.ChannelLabel.Text = num2str(app.channel);
                    app.BGLabel.Text = "NA";
                case(TraceType.Motion)
                    app.ROILabel.Text = 'Motion';
                    app.ChannelLabel.Text = "NA";
                    app.BGLabel.Text = "NA";
            end

            if ((~app.traceHandler.filtering)||(app.traceType==TraceType.Motion))
                app.FrequencyLabel.Text = "NA";
            else
                app.FrequencyLabel.Text = num2str(app.traceHandler.filterF);
            end

            if (~isempty(nextNode))
                nextNode.previousNode = app;
            end

            if ((callingApp.v(1)>9)||((callingApp.v(1)==9)&&(callingApp.v(2)>9)))
                app.UIFigure.WindowStyle = "alwaysontop";
            end

            app.setTrace();
            app.UIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % make sure linked list is intact before deletion
            if (~isempty(app.nextNode))
                app.nextNode.setPreviousNode(app.previousNode);
            end

            if (isempty(app.previousNode))
                app.callingApp.setFirstFigNode(app.nextNode);
            else
                app.previousNode.setNextNode(app.nextNode);
            end

            delete(app);
        end

        % Value changed function: LiveUpdateCheckBox
        function setUpdating(app, event)
            app.updating = app.LiveUpdateCheckBox.Value;
            if (app.hasVid)
                app.plotLine.Visible = app.updating;
            end

            if (app.updating)
                app.setTrace();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Trace Plot';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'trace_viewer_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TraceAxes = uiaxes(app.GridLayout);
            title(app.TraceAxes, 'Title')
            xlabel(app.TraceAxes, 'X')
            ylabel(app.TraceAxes, 'Y')
            zlabel(app.TraceAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LiveUpdateCheckBox = uicheckbox(app.GridLayout2);
            app.LiveUpdateCheckBox.ValueChangedFcn = createCallbackFcn(app, @setUpdating, true);
            app.LiveUpdateCheckBox.Text = 'Live Update';
            app.LiveUpdateCheckBox.Value = true;
            app.ROITypeLabel = uilabel(app.GridLayout2);
            app.ROITypeLabel.Text = 'Filename:';
            app.FilenameLabel = uilabel(app.GridLayout2);
            app.FilenameLabel.Tooltip = {''};
            app.FilenameLabel.Text = 'Label2';
            app.Label3 = uilabel(app.GridLayout2);
            app.Label3.Text = 'ROI Type:';
            app.FrequencyFilterHzLabel = uilabel(app.GridLayout2);
            app.FrequencyFilterHzLabel.Text = 'Frequency Filter (Hz):';
            app.Label1 = uilabel(app.GridLayout2);
            app.Label1.Text = 'BG Subtraction:';
            app.ROILabel = uilabel(app.GridLayout2);
            app.ROILabel.Text = 'Label3';
            app.FrequencyLabel = uilabel(app.GridLayout2);
            app.FrequencyLabel.Text = 'Label4';
            app.BGLabel = uilabel(app.GridLayout2);
            app.BGLabel.Text = 'Label5';
            app.ChannelLabel_2 = uilabel(app.GridLayout2);
            app.ChannelLabel_2.Text = 'Channel:';
            app.ChannelLabel = uilabel(app.GridLayout2);
            app.ChannelLabel.Text = 'Label7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_plot_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end