classdef trace_settings_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        XLabelEditField            matlab.ui.control.EditField
        XLabelEditFieldLabel       matlab.ui.control.Label
        YLabelEditField            matlab.ui.control.EditField
        YLabelEditFieldLabel       matlab.ui.control.Label
        SbtrBGButtonGroup          matlab.ui.container.ButtonGroup
        FluorescenceButton         matlab.ui.control.RadioButton
        ElectricButton             matlab.ui.control.RadioButton
        NoneButton                 matlab.ui.control.RadioButton
        UserFunctionPanel          matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        FunctionClearButton        matlab.ui.control.Button
        UserFunctionLabel          matlab.ui.control.Label
        FunctionLoadButton         matlab.ui.control.Button
        PhotonCalEditField         matlab.ui.control.NumericEditField
        PhotonCalValuephpxLabel    matlab.ui.control.Label
        FramerateHzEditField       matlab.ui.control.NumericEditField
        FramerateHzEditFieldLabel  matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        OkayButton                 matlab.ui.control.Button
        OverlapCheckBox            matlab.ui.control.CheckBox
        FilterEditField            matlab.ui.control.NumericEditField
        FilterFWHMHzLabel          matlab.ui.control.Label
        FilterCheckBox             matlab.ui.control.CheckBox
    end

    %%%%%%%%%%%%%%%%%%%%%%
    % Helper app for adjusting how traces are processed before display
    %%%%%%%%%%%%%%%%%%%%%%
    
    properties (Access = private)
        callingApp
        traceHandler
        previousFnc
        previousUsingFnc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, traceHandler)
            app.callingApp = callingApp;
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            parentPos = callingApp.UIFigure.Position;
            pos = app.UIFigure.Position;
            movegui(app.UIFigure,[round(parentPos(1)+parentPos(3)/2-pos(3)/2),round(parentPos(2)+parentPos(4)/2)-pos(4)/2]);
            app.traceHandler = traceHandler;

            if (traceHandler.traceZeroed==Background.Electric)
                app.ElectricButton.Value = true;
                app.FluorescenceButton.Enable = false;
                app.NoneButton.Enable = false;
            elseif (traceHandler.traceZeroed==Background.Fluorescent)
                app.FluorescenceButton.Value = true;
                app.ElectricButton.Enable = false;
                app.NoneButton.Enable = false;
            else
                if (traceHandler.sbtrBG==Background.Electric)
                    app.ElectricButton.Value = true;
                elseif (traceHandler.sbtrBG==Background.Fluorescent)
                    app.FluorescenceButton.Value = true;
                else
                    app.NoneButton.Value = true;
                end
                
                app.ElectricButton.Enable = traceHandler.hasBg;
                app.FluorescenceButton.Enable = traceHandler.hasFl;
            end

            if (traceHandler.hasOverlap)
                app.OverlapCheckBox.Enable = true;
                app.OverlapCheckBox.Value = traceHandler.shOver;
            end

            app.FilterCheckBox.Value = traceHandler.filtering;
            app.FilterEditField.Value = traceHandler.filterF;
            app.FilterEditField.Enable = traceHandler.filtering;
            app.PhotonCalEditField.Value = traceHandler.photonCal;
            app.FramerateHzEditField.Value = traceHandler.fps;

            app.YLabelEditField.Value = app.traceHandler.ySmplTitle;
            app.XLabelEditField.Value = app.traceHandler.xTitle;
            app.FunctionLoadButton.Enable = traceHandler.hasSmpl;
            app.FunctionClearButton.Enable = traceHandler.usingFnc;
            app.previousFnc = traceHandler.usrFnc;
            if (app.traceHandler.usingFnc)
                app.UserFunctionLabel.Text = func2str(traceHandler.usrFnc);
            else
                app.UserFunctionLabel.Text = 'None';
            end

            app.UIFigure.WindowStyle = "modal";
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: FilterCheckBox
        function filter(app, event)
            app.FilterEditField.Enable = app.FilterCheckBox.Value;
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            val = Background.None;
            if (app.ElectricButton.Value)
                val = Background.Electric;
            elseif (app.FluorescenceButton.Value)
                val = Background.Fluorescent;
            end
            app.traceHandler.subtractBG(val);
            app.traceHandler.setFPS(app.FramerateHzEditField.Value);
            app.traceHandler.showOverlap(app.OverlapCheckBox.Value);
            app.traceHandler.setFilter((app.FilterCheckBox.Value*app.FilterEditField.Value));
            app.traceHandler.setPhotonCal((app.PhotonCalEditField.Value));
            app.traceHandler.setXTitle(app.XLabelEditField.Value);
            app.traceHandler.setYTitle(app.YLabelEditField.Value);
            app.traceHandler.buildTraces();
            app.callingApp.setTraceOps();
            delete(app);
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.traceHandler.setUsrFnc(app.previousFnc);
            app.callingApp.closeTraceOps();
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.cancel(event);
        end

        % Button pushed function: FunctionLoadButton
        function slctUsrFnc(app, event)
            [tempFile, tempPath] = uigetfile('.m','Select function file',app.callingApp.path);
            figure(app.callingApp.UIFigure);
            figure(app.UIFigure);
            if (isequal(tempFile,0))
                return;
            end

            tempDir = cd;

            fncName = split(tempFile,'.');
            fncName = fncName{1};
            cd(tempPath);
            fnc = str2func(fncName);
            cd(tempDir);

            if (app.traceHandler.setUsrFnc(fnc))
                app.UserFunctionLabel.Text = fncName;
                app.FunctionClearButton.Enable = true;
                app.callingApp.path = tempPath;
            end
        end

        % Button pushed function: FunctionClearButton
        function clearUsrFnc(app, event)
            app.traceHandler.setUsrFnc([]);
            app.UserFunctionLabel.Text = 'None';
            app.FunctionClearButton.Enable = false;
        end

        % Value changed function: PhotonCalEditField
        function chngPhCal(app, event)
            if (app.PhotonCalEditField.Value==0)
                app.PhotoCalEditField.Value = event.PreviousValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Trace Settings';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+images', 'trace_viewer_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FilterCheckBox = uicheckbox(app.GridLayout);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter, true);
            app.FilterCheckBox.Text = 'Lowpass Filter';
            app.FilterFWHMHzLabel = uilabel(app.GridLayout);
            app.FilterFWHMHzLabel.Text = 'Filter Frequency (Hz):';
            app.FilterEditField = uieditfield(app.GridLayout, 'numeric');
            app.FilterEditField.Limits = [0 Inf];
            app.OverlapCheckBox = uicheckbox(app.GridLayout);
            app.OverlapCheckBox.Text = 'Show Out of Bounds';
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.FramerateHzEditFieldLabel = uilabel(app.GridLayout);
            app.FramerateHzEditFieldLabel.Text = 'Framerate (Hz):';
            app.FramerateHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.FramerateHzEditField.Limits = [1e-06 Inf];
            app.FramerateHzEditField.Value = 1e-06;
            app.PhotonCalValuephpxLabel = uilabel(app.GridLayout);
            app.PhotonCalValuephpxLabel.Text = 'Photon Cal. Value (ph/px):';
            app.PhotonCalEditField = uieditfield(app.GridLayout, 'numeric');
            app.PhotonCalEditField.Limits = [0 Inf];
            app.PhotonCalEditField.ValueChangedFcn = createCallbackFcn(app, @chngPhCal, true);
            app.PhotonCalEditField.Value = 1;
            app.UserFunctionPanel = uipanel(app.GridLayout);
            app.UserFunctionPanel.Title = 'User Function';
            app.GridLayout2 = uigridlayout(app.UserFunctionPanel);
            app.FunctionLoadButton = uibutton(app.GridLayout2, 'push');
            app.FunctionLoadButton.ButtonPushedFcn = createCallbackFcn(app, @slctUsrFnc, true);
            app.FunctionLoadButton.Text = 'Load';
            app.UserFunctionLabel = uilabel(app.GridLayout2);
            app.UserFunctionLabel.Text = 'None';
            app.FunctionClearButton = uibutton(app.GridLayout2, 'push');
            app.FunctionClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearUsrFnc, true);
            app.FunctionClearButton.Enable = 'off';
            app.FunctionClearButton.Text = 'Clear';
            app.SbtrBGButtonGroup = uibuttongroup(app.GridLayout);
            app.SbtrBGButtonGroup.Title = 'Background Subtraction';
            app.NoneButton = uiradiobutton(app.SbtrBGButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.ElectricButton = uiradiobutton(app.SbtrBGButtonGroup);
            app.ElectricButton.Text = 'Electric';
            app.FluorescenceButton = uiradiobutton(app.SbtrBGButtonGroup);
            app.FluorescenceButton.Text = 'Fluorescence';
            app.YLabelEditFieldLabel = uilabel(app.GridLayout);
            app.YLabelEditFieldLabel.Text = 'Y Label:';
            app.YLabelEditField = uieditfield(app.GridLayout, 'text');
            app.XLabelEditFieldLabel = uilabel(app.GridLayout);
            app.XLabelEditFieldLabel.Text = 'X Label:';
            app.XLabelEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_settings_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end