classdef trace_viewer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        LoadDatasetMenu             matlab.ui.container.Menu
        VisualizationMenu           matlab.ui.container.Menu
        WindowLevelMenu             matlab.ui.container.Menu
        PlaybackOptionsMenu         matlab.ui.container.Menu
        TraceOptionsMenu            matlab.ui.container.Menu
        MotionTraceMenu             matlab.ui.container.Menu
        BackgroundFluorescenceMenu  matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        FileDropDown                matlab.ui.control.DropDown
        PlayButton                  matlab.ui.control.StateButton
        EjectTraceButton            matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        frameLabel                  matlab.ui.control.Label
        ChannelDropDown             matlab.ui.control.DropDown
        FrameForwardButton          matlab.ui.control.Button
        FrameBackwardButton         matlab.ui.control.Button
        ScrubberAxes                matlab.ui.control.UIAxes
        TraceAxes                   matlab.ui.control.UIAxes
        SampleROIPanel              matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        ShowSmplCheckBox            matlab.ui.control.CheckBox
        SmplListbox                 matlab.ui.control.ListBox
        AESROIPanel                 matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        ShowAESCheckBox             matlab.ui.control.CheckBox
        AESListbox                  matlab.ui.control.ListBox
        ImageAxes                   matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % App used for viewing output from motion correction app
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        % file variables
        basenames
        fIndex
        
        % variables used for producing image
        imhandle
        projImages
        currentFrame
        dispImage
        contrImage
        vidContrWndw
        projContrWndw
        aesImage
        smplImage
        expImage
        slctImage
        bgSlct
        aesSlct
        bgMasks
        showAES
        showSmpl
        alpha = 0.25;
        alphaExp = 0.2;
        
        % variables for adjusting masks in motion corrected video
        usingProj
        hasImage
        hasVid
        mtnCorrected
        mtnTrace
        mtnAesImage
        mtnExpImage
        mtnSlctImage
        tempImgStorage

        % channel variables
        channel
        channels

        % handlers
        maskHandler
        traceHandler
        smplInd
        aesInd

        % other dynamic ui elements
        frameBar
        hitBar
        tins
        frames
        frame
        playing
        fr = 15;
        tmr
        frmBfr
        frmBfrInd
        frmBfrSz
        avg
        pauseIcon
        playIcon
        stopIcon
        stopActiveIcon
        plotLine
        plotY1
        plotY2
        traceType
        roiIndex

        % helper apps
        interruptDialog
        BCDlgBox
        PlaybackDlgBox
        TracePlotBox
        TraceDlgBox
    end

    properties (GetAccess = public)
        % matlab version
        v
    end

    properties (GetAccess = public, SetAccess = public)
        % most recent accessed directory
        path
    end
    
    methods (Access = public)
        %% video player functions
        % enable video player buttons
        function enablePlayer(app,enable)
            app.FrameBackwardButton.Enable = enable;
            app.FrameForwardButton.Enable = enable;
            app.PlayButton.Enable = enable;
            app.StopButton.Enable = enable;
        end

        % advance frame (timer call for playing video)
        function playNext(app,obj,evt)
            if (app.frame < app.frames(app.fIndex))
                app.frame = app.frame+1;
            else
                app.frame = 1;
            end
            app.loadFrame();
            app.drawImage();
            app.setBar();
        end

        function startVid(app)
            if (~app.playing)
                start(app.tmr);
                app.playing = true;
                app.PlayButton.Value = true;
                app.PlayButton.Icon = app.pauseIcon;
            end
        end

        function stopVid(app)
            if (app.playing)
                stop(app.tmr);
                app.playing = false;
                app.PlayButton.Value = false;
                app.PlayButton.Icon = app.playIcon;
            end
        end

        % load frame
        function loadFrame(app)
            app.tins{app.fIndex}.setDirectory((app.frame-1)*app.channels+app.channel);
            if (app.avg)
                if ((~app.playing) || (app.frame==1))
                    app.currentFrame = double(app.tins{app.fIndex}.read());
                    app.frmBfr = repmat((app.currentFrame/app.frmBfrSz),1,1,app.frmBfrSz);
                    app.frmBfrInd = 1;
                else
                    app.frmBfrInd = mod(app.frmBfrInd,app.frmBfrSz)+1;
                    app.currentFrame = app.currentFrame - app.frmBfr(:,:,app.frmBfrInd);
                    app.frmBfr(:,:,app.frmBfrInd) = double(app.tins{app.fIndex}.read())/app.frmBfrSz;
                    app.currentFrame = app.currentFrame+app.frmBfr(:,:,app.frmBfrInd);
                end
            else
                app.currentFrame = double(app.tins{app.fIndex}.read());
            end
            app.contrImage = (app.currentFrame-app.vidContrWndw(app.fIndex,app.channel,1))/(app.vidContrWndw(app.fIndex,app.channel,2)-app.vidContrWndw(app.fIndex,app.channel,1));
            app.contrImage = (app.contrImage > 0) .* (app.contrImage < 1) .* app.contrImage + (app.contrImage >= 1);

            % nonsense so that masks track with motion correction data
            if (app.mtnCorrected)
                app.adjMtnMask();
            end
        end

        function adjMtnMask(app)
            if (app.aesSlct || app.bgSlct)
                app.mtnSlctImage = circshift(app.slctImage,app.mtnTrace(app.frame,:));
            elseif (app.showSmpl)
                app.mtnSlctImage = app.slctImage;
            else
                app.mtnSlctImage = 0*app.mtnSlctImage;
            end

            if (app.showAES)
                app.tempImgStorage(:,:,1) = circshift(app.maskHandler.roiAesImage,app.mtnTrace(app.frame,:));
                app.tempImgStorage(:,:,2) = circshift(app.maskHandler.getExpMask(),app.mtnTrace(app.frame,:));
                app.mtnAesImage = (app.tempImgStorage(:,:,1) - app.showSmpl * app.maskHandler.roiSmplImage - app.mtnSlctImage) > 0;
                app.mtnExpImage = (app.tempImgStorage(:,:,2) - app.tempImgStorage(:,:,1) - app.showSmpl * app.maskHandler.roiSmplImage - app.mtnSlctImage) > 0;
            else
                app.mtnAesImage = 0*app.mtnAesImage;
                app.mtnExpImage = 0*app.mtnExpImage;
            end
        end

        % set scrubber time
        function setBar(app)
            app.frameLabel.Text = strcat(num2str(app.frame),"/",num2str(app.frames(app.fIndex)));
            app.frameBar.Position = [0,0,app.frame/app.frames(app.fIndex),1];
            if (app.hasVid)
                x=app.frame/app.traceHandler.fps;
                app.plotLine.Position=[x,app.plotY1;x,app.plotY2];
                if (~isempty(app.TracePlotBox))
                    app.TracePlotBox.updateTime(app.frame);
                end
            end
        end

        %% function for drawing images and masks
        % update masks based model (does not account for motion correction
        % to reduce unnecessary calls
        function updateMasks(app)
            app.maskHandler.buildRoiImage();
            if (app.bgSlct)
                app.slctImage = app.bgMasks(:,:,app.channel);
            else
                app.slctImage = app.maskHandler.roiSlctImage;
                if (~app.aesSlct)
                    app.mtnSlctImage = app.slctImage;
                end
            end

            if (app.showSmpl)
                app.smplImage = app.showSmpl*((app.maskHandler.roiSmplImage - app.slctImage) > 0);
            else
                app.smplImage = 0*app.smplImage;
            end
                        
            if (app.showAES)
                app.aesImage = (app.maskHandler.roiAesImage - app.showSmpl * app.maskHandler.roiSmplImage - app.slctImage) > 0;
                app.expImage = (app.maskHandler.getExpMask() - app.maskHandler.roiAesImage - app.showSmpl * app.maskHandler.roiSmplImage - app.slctImage) > 0;
            else
                app.aesImage = 0*app.aesImage;
                app.expImage = 0*app.expImage;
            end
        end

        % draw currently loaded frame to canvas
        function drawImage(app)
            if (~app.usingProj && app.mtnCorrected)
                app.dispImage(:,:,1) = app.contrImage .* (1 - app.alpha * (app.smplImage + app.mtnAesImage)) + app.alpha * (app.smplImage + app.mtnAesImage);
                app.dispImage(:,:,2) = app.contrImage .* (1 - app.alpha * (app.mtnSlctImage + app.mtnAesImage)) + app.alpha * (app.mtnSlctImage + app.mtnAesImage);
                app.dispImage(:,:,3) = app.contrImage .* (1 - app.alphaExp * (app.mtnExpImage)) + app.alphaExp * app.mtnExpImage;
            else
                app.dispImage(:,:,1) = app.contrImage .* (1 - app.alpha * (app.smplImage + app.aesImage)) + app.alpha * (app.smplImage + app.aesImage);
                app.dispImage(:,:,2) = app.contrImage .* (1 - app.alpha * (app.slctImage + app.aesImage)) + app.alpha * (app.slctImage + app.aesImage);
                app.dispImage(:,:,3) = app.contrImage .* (1 - app.alphaExp * (app.expImage)) + app.alphaExp * app.expImage;
            end
            app.imhandle.CData = app.dispImage;
        end

        % callback when clicking on masks display image
        function ImgClk(app,src,evt)
            coord = ceil(evt.IntersectionPoint);
            app.maskHandler.selectCoord(coord,false);
            slct = app.maskHandler.roiSmplSlct;
            if (sum(slct)>0)
                app.bgSlct = false;
                app.aesSlct = false;
                app.AESListbox.Value = cell(0);
                slct = app.smplInd(slct);
                if (length(slct) > 1)
                    slct = slct(1);
                    app.maskHandler.selectRoiSmpl({slct});
                end
                app.SmplListbox.Value = slct;
                app.setTrace(TraceType.Sample,slct);
            else
                app.SmplListbox.Value = cell(0);
                if (app.mtnCorrected && ~app.usingProj)
                    app.maskHandler.selectCoord([(coord(1)-app.mtnTrace(app.frame,2)),(coord(2)-app.mtnTrace(app.frame,1))],false);
                end
                slct = app.maskHandler.roiAesSlct;
                if (sum(slct)>0)
                    app.aesSlct = true;
                    app.bgSlct = false;
                    slct = app.aesInd(slct);
                    if (length(slct)>1)
                        slct = slct(1);
                        app.maskHandler.selectRoiAes({slct});
                    end
                    app.AESListbox.Value = slct;
                    app.setTrace(TraceType.AES,slct);
                else
                    app.aesSlct = false;
                    app.AESListbox.Value = cell(0);
                    if (app.mtnCorrected && ~app.usingProj)
                        app.bgSlct = app.bgMasks((coord(2)-app.mtnTrace(app.frame,1)),(coord(1)-app.mtnTrace(app.frame,2)),app.channel)>0;
                    else
                        app.bgSlct = app.bgMasks(coord(2),coord(1),app.channel)>0;
                    end

                    if (app.bgSlct)
                        app.setTrace(TraceType.BG,0);
                    else
                        app.setTrace(TraceType.None,0);
                    end
                end
            end
            app.updateMasks();
            if(~app.usingProj && app.mtnCorrected)
                app.adjMtnMask();
            end
            app.drawImage();
        end

        % switch between projection and video on primary display
        function enableProj(app,enable)
            app.usingProj = enable;
            if (enable)
                app.ImageAxes.Title.String = ['Projection: ', app.basenames{app.fIndex}];
                app.StopButton.Icon = app.stopActiveIcon;
                app.plotLine.Visible='off';
                if (~isempty(app.BCDlgBox))
                    app.BCDlgBox.reinit(app.projImages(:,:,app.channel,app.fIndex),app.projContrWndw(app.fIndex,app.channel,:));
                end
            else
                app.ImageAxes.Title.String = ['Video: ', app.basenames{app.fIndex}];
                app.StopButton.Icon = app.stopIcon;
                app.plotLine.Visible='on';
                if (~isempty(app.BCDlgBox))
                    app.BCDlgBox.reinit(app.currentFrame,app.vidContrWndw(app.fIndex,app.channel,:));
                end
            end
        end

        %% update plotted trace
        % plot different trace
        function setTrace(app,traceType,roiIndex)
            if (~isempty(app.plotLine))
                delete(app.plotLine);
                app.plotLine = [];
            end

            status = app.traceHandler.plotTrace(app.TraceAxes,app.channel,traceType,roiIndex);
            
            % need to re-build frame tracking bar whenever you replot
            if (app.hasVid)
                app.plotLine = images.roi.Line(app.TraceAxes);
                app.plotLine.InteractionsAllowed = "translate";
                app.plotLine.MarkerSize = 0.1;
                app.plotLine.LineWidth = 2*app.plotLine.LineWidth;
                app.plotLine.Color = 'k';
                addlistener(app.plotLine,'ROIMoved',@app.plotLineMv);
                addlistener(app.plotLine,'ROIClicked',@app.plotLineClck);
                tempX = app.TraceAxes.XLim;
                tempY = app.TraceAxes.YLim;
                app.plotY1 = tempY(1);
                app.plotY2 = tempY(2);
                x = app.frame/app.traceHandler.fps;
                app.plotLine.Position = [x,tempY(1);x,tempY(2)];
                app.plotLine.DrawingArea = [tempX(1),tempY(1),(tempX(2)-tempX(1)),(tempY(2)-tempY(1))];
                app.plotLine.Visible = ~app.usingProj;
            end
            
            if (status)
                app.traceType = traceType;
                app.roiIndex = roiIndex;
            else
                app.traceType = TraceType.None;
                app.roiIndex = 0;
            end
            app.EjectTraceButton.Enable = (app.traceType~=TraceType.None);
        end

        %% callback functions
        % callback for changing window/level
        function chngImgBnds(app,bounds)
            if (app.usingProj)
                app.projContrWndw(app.fIndex,app.channel,:) = [bounds(1),bounds(2)];
                app.contrImage = (app.projImages(:,:,app.channel,app.fIndex)-bounds(1))/(bounds(2)-bounds(1));
                app.contrImage = (app.contrImage > 0) .* (app.contrImage < 1) .* app.contrImage + (app.contrImage >= 1);
            else
                app.vidContrWndw(app.fIndex,app.channel,:) = [bounds(1),bounds(2)];
                if (~app.playing)
                    app.contrImage = (app.currentFrame - bounds(1))/(bounds(2)-bounds(1));
                    app.contrImage = (app.contrImage > 0) .* (app.contrImage < 1) .* app.contrImage + (app.contrImage >= 1);
                end
            end
            app.drawImage();
        end

        % helper app updates trace handler model
        % just need to replot and delete helper app
        function setTraceOps(app)
            app.setTrace(app.traceType,app.roiIndex);
            if (~isempty(app.TracePlotBox))
                app.TracePlotBox.update();
            end

            app.TraceDlgBox = [];
        end

        % change framerate rolling average for video playback
        function playbackClbk(app,fr,avg,bfrSz)
            if (fr~=app.fr)
                app.tmr = [];
                app.fr = fr;
                app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.fr)/1000),'TasksToExecute',Inf);
            end
            
            app.avg = avg;
            if (avg)
                app.frmBfrSz = bfrSz;
            end
        end

        % called to update video/external traces when progress line is
        % moved on primary trace
        function plotLineClck(app,~,~)
            app.stopVid();
        end

        function plotLineMv(app,~,~)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.stopVid();
            app.frame = round(app.traceHandler.fps * app.plotLine.Position(1,1));
            app.frame = min(app.frame,app.frames(app.fIndex));
            app.loadFrame();
            app.drawImage();
            app.frameLabel.Text = strcat(num2str(app.frame),"/",num2str(app.frames(app.fIndex)));
            app.frameBar.Position = [0,0,app.frame/app.frames(app.fIndex),1];
            if (~isempty(app.TracePlotBox))
                app.TracePlotBox.updateTime(app.frame);
            end
            app.UIFigure.Pointer = 'arrow';
        end

        function closeBC(app)
            app.BCDlgBox = [];
        end

        function closePlayback(app)
            app.PlaybackDlgBox = [];
        end

        %% functions called by external trace plot figures
        function closeTraceOps(app)
            app.TraceDlgBox = [];
        end

        function setFirstFigNode(app,tracePlot)
            app.TracePlotBox = tracePlot;
        end

        function setFrame(app,frame)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.frame = min(frame,app.frames(app.fIndex));
            app.loadFrame();
            if (app.usingProj)
                app.enableProj(false);
            end
            app.drawImage();
            app.setBar();
            app.UIFigure.Pointer = 'arrow';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Title.Interpreter = 'none';
            app.TraceAxes.Title.Interpreter = 'none';
            grid(app.TraceAxes,'on');
            box(app.TraceAxes,'on');
            app.frameBar = rectangle(app.ScrubberAxes,'Position',[0,0,0.5,1]);
            app.frameBar.FaceColor = [0.3,0.3,0.3];
            app.hitBar = rectangle(app.ScrubberAxes,'Position',[0,0,1,1]);
            app.frameBar.HitTest = "off";
            app.hitBar.HitTest = "off";
            app.tmr = timer('TimerFcn',@app.playNext,'ExecutionMode','fixedRate','Period',(round(1000/app.fr)/1000),'TasksToExecute',Inf);
            app.playing = false;

            absPath = fileparts(mfilename('fullpath'));
            app.playIcon = imread(fullfile(absPath,'+helper_apps/+images/play.png'));
            app.pauseIcon = imread(fullfile(absPath,'+helper_apps/+images/pause.png'));
            app.stopIcon = imread(fullfile(absPath,'+helper_apps/+images/stop.png'));
            app.stopActiveIcon = imread(fullfile(absPath,'+helper_apps/+images/stop_active.png'));
            daspect(app.ImageAxes,[1,1,1]);

            app.avg = false;
            app.frmBfrSz = 15;

            temp = ver('Matlab');
            temp = split(temp.Version,'.');
            app.v = [str2double(temp{1}),str2double(temp{2})];
        end

        % Menu selected function: LoadDatasetMenu
        function loadData(app, event)
            app.stopVid();
            if (isempty(app.path))
                tempPath = uigetdir('Processed Data Folder');
            else
                tempPath = uigetdir(app.path,'Processed Data Folder');
            end
            figure(app.UIFigure);
            if (tempPath == 0)
                return;
            end

            app.path = tempPath;
            app.interruptDialog = uiprogressdlg(app.UIFigure,"Title","Setting up...");
            app.interruptDialog.Cancelable = "off";
            app.interruptDialog.Indeterminate = "on";
            try
                parser = AESOutputParser(tempPath,app.interruptDialog);
            catch ME
                msgbox('Unable to parse input folder.','Error','error');
                close(app.interruptDialog);
                app.interruptDialog = [];
                rethrow(ME);
                return;
            end

            if (~parser.status)
                msgbox('Unable to parse input folder.','Error','error');
                close(app.interruptDialog);
                app.interruptDialog = [];
                return;
            end

            app.maskHandler = [];
            app.maskHandler = parser.maskHandler;
            app.traceHandler = TraceHandler(parser);
            
            app.channels = parser.channels;

            channelopts = cell(1,app.channels);
            for ch=1:app.channels
                channelopts{ch} = ['Channel ',num2str(ch)];
            end
            app.ChannelDropDown.Items = channelopts;
            app.ChannelDropDown.ItemsData = 1:app.channels;
            app.ChannelDropDown.Value = 1;
            app.ChannelDropDown.Enable = app.channels > 1;

            app.basenames = parser.basenames;
            numVids = length(app.basenames);
            app.FileDropDown.Items = app.basenames';
            app.FileDropDown.ItemsData = 1:numVids;
            app.FileDropDown.Value = 1;
            app.FileDropDown.Enable = numVids>1;
            app.fIndex = 1;

            app.hasImage = ~isempty(parser.projImages);
            if (app.hasImage)
                app.projImages = parser.projImages;
                app.ImageAxes.Title.String = ['Projection: ',app.basenames{1}];
            else
                app.ImageAxes.Title.String = ['No Video: ',app.basenames{1}];
                app.projImages = zeros(parser.dimension(1),parser.dimension(2),app.channels,numVids);
            end
            app.WindowLevelMenu.Enable = app.hasImage;
            app.PlaybackOptionsMenu.Enable = app.hasImage;

            app.dispImage = zeros(size(app.projImages,1),size(app.projImages,2),3);
            app.contrImage = zeros(size(app.projImages,1),size(app.projImages,2));
            app.projContrWndw = zeros(numVids,app.channels,2);
            app.bgMasks = zeros(size(app.projImages,1),size(app.projImages,2),size(app.projImages,3));
            app.bgSlct = false;
            app.aesSlct = false;
            for ch=1:app.channels
                for fl=1:numVids
                    if (app.hasImage)
                        app.projContrWndw(fl,ch,1) = min(app.projImages(:,:,ch,fl),[],'all');
                        app.projContrWndw(fl,ch,2) = max(app.projImages(:,:,ch,fl),[],'all');
                    else
                        app.projContrWndw(fl,ch,1) = 0;
                        app.projContrWndw(fl,ch,2) = 1;
                    end
                end
                app.maskHandler.setChannel(ch);
                app.bgMasks(:,:,ch) = (app.maskHandler.getExpMask()==0);
            end

            app.aesImage = zeros(parser.dimension(1),parser.dimension(2));
            app.slctImage = zeros(parser.dimension(1),parser.dimension(2));
            app.smplImage = zeros(parser.dimension(1),parser.dimension(2));
            app.expImage = zeros(parser.dimension(1),parser.dimension(2));

            app.imhandle = image(app.ImageAxes, app.dispImage,'ButtonDownFcn',@app.ImgClk);
            app.imhandle.CDataMapping = 'direct';
            set(app.ImageAxes,'xlim',[0,parser.dimension(2)]);
            set(app.ImageAxes,'ylim',[0,parser.dimension(1)]);

            app.frames = parser.frames;
            if (~isempty(app.tins))
                for fl=1:length(app.tins)
                    close(app.tins{fl});
                end
                app.tins = [];
            end

            app.hasVid = ~isempty(parser.vidFiles);
            if (app.hasVid)
                app.enablePlayer(true);
                app.tins = cell(numVids);
                app.vidContrWndw = zeros(numVids,app.channels,2);
                for fl=1:numVids
                    app.tins{fl} = Tiff(parser.vidFiles{fl},'r');
                    app.frame = 1;
                    tempTiff = double(app.tins{fl}.read());
                    app.vidContrWndw(fl,1,1) = min(tempTiff,[],'all');
                    app.vidContrWndw(fl,1,2) = max(tempTiff,[],'all');
                    for ch=2:app.channels
                        app.tins{fl}.nextDirectory();
                        tempTiff = double(app.tins{fl}.read());
                        app.vidContrWndw(fl,ch,1) = min(tempTiff,[],'all');
                        app.vidContrWndw(fl,ch,2) = max(tempTiff,[],'all');
                    end
                    app.tins{fl}.setDirectory(1);
                end

                % older versions of matlab use a 16 bit int to track the
                % directory in the tiff library
                if ((app.v(1) < 9) || ((app.v(1)==9) && (app.v(2)<14)))
                    disp('Matlab versions before 2023 cannot read large Tiff stacks. Limiting frame count in video player.')
                    app.frames = min(app.frames,64000);
                end
            else
                app.enablePlayer(false);
            end

            if (isempty(app.traceHandler.mtnTraces))
                app.mtnCorrected = false;
            else
                app.mtnCorrected = true;
                app.mtnTrace = fliplr(app.traceHandler.getMtnTrace());
                app.mtnSlctImage = zeros(size(app.projImages,1),size(app.projImages,2));
                app.mtnAesImage = zeros(size(app.projImages,1),size(app.projImages,2));
                app.mtnExpImage = zeros(size(app.projImages,1),size(app.projImages,2));
                app.tempImgStorage = zeros(size(app.projImages,1),size(app.projImages,2),3);
            end
            app.MotionTraceMenu.Enable = app.mtnCorrected;
            app.BackgroundFluorescenceMenu.Enable = app.traceHandler.hasFl;

            if (~isempty(app.TracePlotBox))
                app.TracePlotBox.changeFile(app.basenames(app.fIndex));
            end

            app.maskHandler.setChannel(1);
            app.usingProj = true;
            app.StopButton.Icon = app.stopActiveIcon;
            app.showAES = true;
            app.showSmpl = true;
            app.chngChannel([]);
            app.drawImage();
            app.TraceOptionsMenu.Enable = true;
            app.ShowSmplCheckBox.Enable = true;
            app.ShowAESCheckBox.Enable = true;
            close(app.interruptDialog);
            app.interruptDialog = [];
        end

        % Menu selected function: WindowLevelMenu
        function opnBc(app, event)
            if (isempty(app.BCDlgBox))
                if (app.playing)
                    app.stopVid();
                end

                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (app.usingProj)
                    app.BCDlgBox = helper_apps.BC_helper_app(app,app.projImages(:,:,app.channel,app.fIndex),app.projContrWndw(app.fIndex,app.channel,:));
                else
                    app.BCDlgBox = helper_apps.BC_helper_app(app,app.currentFrame,app.vidContrWndw(app.fIndex,app.channel,:));
                end
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Menu selected function: PlaybackOptionsMenu
        function opnPlayback(app, event)
            if (app.playing)
                app.stopVid();
            end

            if (isempty(app.PlaybackDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.PlaybackDlgBox = helper_apps.playback_helper_app(app,app.fr,(app.frmBfrSz*app.avg));
                app.UIFigure.Pointer = 'arrow';

                if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>9)))
                    if (~isempty(app.TracePlotBox))
                        app.TracePlotBox.minimizeAll();
                    end

                    if (~isempty(app.BCDlgBox))
                        app.BCDlgBox.minimize();
                    end
                end
            end
        end

        % Button pushed function: FrameBackwardButton
        function prvFrame(app, event)
            if (app.frame>1)
                app.stopVid();
                app.frame = app.frame-1;
                app.loadFrame();
                if (app.usingProj)
                    app.enableProj(false);
                end
                app.drawImage();
                app.setBar();
                x=app.frame/app.traceHandler.fps;
                app.plotLine.Position=[x,app.plotY1;x,app.plotY2];
            end
        end

        % Button pushed function: FrameForwardButton
        function nxtFrame(app, event)
            if (app.frame < app.frames(app.fIndex))
                app.stopVid();
                app.frame = app.frame+1;
                app.loadFrame();
                if (app.usingProj)
                    app.enableProj(false);
                end
                app.drawImage();
                app.setBar();
            end
        end

        % Value changed function: PlayButton
        function playPause(app, event)
            if (app.playing)
                app.stopVid();
            else
                app.startVid();
                if (app.usingProj)
                    app.enableProj(false);
                end
            end
        end

        % Button pushed function: StopButton
        function showProj(app, event)
            if (~app.usingProj)
                app.stopVid();
                app.enableProj(true);
                app.contrImage = (app.projImages(:,:,app.channel,app.fIndex)-app.projContrWndw(app.fIndex,app.channel,1))/(app.projContrWndw(app.fIndex,app.channel,2)-app.projContrWndw(app.fIndex,app.channel,1));
                app.contrImage = (app.contrImage > 0) .* (app.contrImage < 1) .* app.contrImage + (app.contrImage >= 1);
                app.drawImage();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (~isempty(app.BCDlgBox))
                delete(app.BCDlgBox);
            end

            if (~isempty(app.PlaybackDlgBox))
                delete(app.PlaybackDlgBox);
            end

            if (~isempty(app.TracePlotBox))
                app.TracePlotBox.deleteAll();
            end

            if (~isempty(app.TraceDlgBox))
                delete(app.TraceDlgBox);
            end

            if (~isempty(app.tins))
                for fl=1:length(app.tins)
                    close(app.tins{fl});
                end
            end

            delete(app);
        end

        % Value changed function: ChannelDropDown
        function chngChannel(app, event)
            if (~app.usingProj)
                app.stopVid();
            end
            
            app.channel = app.ChannelDropDown.Value;
            app.maskHandler.setChannel(app.channel);
            app.updateMasks();
            % change roi lists
            app.AESListbox.Value = cell(0);
            app.SmplListbox.Value = cell(0);

            names = app.maskHandler.getAesNames();
            app.aesInd = 1:length(names);
            if (~isempty(names))
                names = [{'All AES Masks'}, names];
            end
            data = cell(length(names),1);
            for ii=1:length(names)
                data{ii}=ii-1;
            end
            app.AESListbox.Items = names;
            app.AESListbox.ItemsData = data;

            names = app.maskHandler.getSmplNames();
            app.smplInd = 1:length(names);
            if (~isempty(names))
                names = [{'All Sample Masks'}, names];
            end
            data = cell(length(names),1);
            for ii=1:length(names)
                data{ii}=ii-1;
            end
            app.SmplListbox.Items = names;
            app.SmplListbox.ItemsData = data;

            % update image and window/level helper app if open
            if (app.hasImage)
                if (app.usingProj)
                    app.contrImage = (app.projImages(:,:,app.channel,app.fIndex)-app.projContrWndw(app.fIndex,app.channel,1))/(app.projContrWndw(app.fIndex,app.channel,2)-app.projContrWndw(app.fIndex,app.channel,1));
                    app.contrImage = (app.contrImage > 0) .* (app.contrImage < 1) .* app.contrImage + (app.contrImage >= 1);
                    if (~isempty(app.BCDlgBox))
                        app.BCDlgBox.reinit(app.projImages(:,:,app.channel,app.fIndex),app.projContrWndw(app.fIndex,app.channel,:));
                    end
                else
                    app.loadFrame();
                    if (~isempty(app.BCDlgBox))
                        app.BCDlgBox.reinit(app.currentFrame,app.vidContrWndw(app.fIndex,app.channel,:));
                    end
                end
            end

            app.setTrace(TraceType.None,0);
            app.drawImage();
        end

        % Value changed function: ShowAESCheckBox
        function enableAesRoi(app, event)
            app.showAES = app.ShowAESCheckBox.Value;
            app.AESListbox.Enable = app.showAES;
            if (~app.showAES)
                app.AESListbox.Value = cell(0);
                app.maskHandler.selectRoiAes([]);
                if (app.traceType==TraceType.AES)
                    app.setTrace(TraceType.None,0);
                end
            end
            
            app.maskHandler.enableRoiAes(app.showAES);
            app.updateMasks();
            if (~app.usingProj && app.mtnCorrected)
                app.adjMtnMask();
            end
            app.drawImage();
        end

        % Value changed function: ShowSmplCheckBox
        function enableSmplRoi(app, event)
            app.showSmpl = app.ShowSmplCheckBox.Value;
            app.SmplListbox.Enable = app.showSmpl;
            if (~app.showSmpl)
                app.SmplListbox.Value = cell(0);
                app.maskHandler.selectRoiSmpl([]);
                if (app.traceType==TraceType.Sample)
                    app.setTrace(TraceType.None,0);
                end
            end

            app.maskHandler.enableRoiSmpl(app.showSmpl);
            app.updateMasks();
            if (~app.usingProj && app.mtnCorrected)
                app.adjMtnMask();
            end
            app.drawImage();
        end

        % Button down function: ScrubberAxes
        function scrubberClbk(app, event)
            if (~isempty(app.tins) && ~app.usingProj)
                app.UIFigure.Pointer = 'watch';
                drawnow;
                flag = false;
                if (app.playing)
                    app.stopVid();
                    flag = true;
                end

                % update video time based on where scrubber is clicked and
                % update ui
                coord = app.ScrubberAxes.CurrentPoint;
                app.frame = round(coord(1)*app.frames(app.fIndex));
                if (app.frame < 1)
                    app.frame = 1;
                end
                app.loadFrame();
                app.drawImage();
                app.setBar();

                if (flag)
                    app.startVid();
                end
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: AESListbox
        function slctRoiAes(app, event)
            value = app.AESListbox.Value;
            if (value==0)
                values = cell(length(app.aesInd),1);
                for ii=1:length(app.aesInd)
                    values{ii} = ii;
                end
                app.maskHandler.selectRoiAes(values);
            else
                app.maskHandler.selectRoiAes({value});
            end
            app.maskHandler.selectRoiSmpl([]);
            app.SmplListbox.Value = cell(0);
            app.bgSlct = false;
            app.aesSlct = true;
            app.updateMasks();
            if (app.mtnCorrected && ~app.usingProj)
                app.adjMtnMask();
            end
            app.setTrace(TraceType.AES,value);
            app.drawImage();
        end

        % Value changed function: SmplListbox
        function slctRoiSmpl(app, event)
            value = app.SmplListbox.Value;
            if (value==0)
                values = cell(length(app.smplInd),1);
                for ii=1:length(app.smplInd)
                    values{ii} = ii;
                end
                app.maskHandler.selectRoiSmpl(values);
            else
                app.maskHandler.selectRoiSmpl({value});
            end
            app.maskHandler.selectRoiAes([]);
            app.AESListbox.Value = cell(0);
            app.aesSlct = false;
            app.bgSlct = false;
            app.updateMasks();
            if (app.mtnCorrected && ~app.usingProj)
                app.adjMtnMask();
            end
            app.setTrace(TraceType.Sample,value);
            app.drawImage();
        end

        % Button pushed function: EjectTraceButton
        function ejectTrace(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            if (isempty(app.TracePlotBox))
                app.TracePlotBox = helper_apps.trace_plot_helper_app(app,[],app.traceHandler,app.channel,app.traceType,app.roiIndex,(app.hasVid*app.frame),app.basenames{app.fIndex});
            else
                app.TracePlotBox = helper_apps.trace_plot_helper_app(app,app.TracePlotBox,app.traceHandler,app.channel,app.traceType,app.roiIndex,(app.hasVid*app.frame),app.basenames{app.fIndex});
            end
            app.UIFigure.Pointer = 'arrow';
        end

        % Menu selected function: TraceOptionsMenu
        function opnTraceOps(app, event)
            if (isempty(app.TraceDlgBox))
                app.UIFigure.Pointer = 'watch';
                drawnow;
                if (app.playing)
                    app.stopVid();
                end

                app.TraceDlgBox = helper_apps.trace_settings_helper_app(app,app.traceHandler);

                if ((app.v(1)>9)||((app.v(1)==9)&&(app.v(2)>9)))
                    if (~isempty(app.TracePlotBox))
                        app.TracePlotBox.minimizeAll();
                    end
    
                    if (~isempty(app.BCDlgBox))
                        app.BCDlgBox.minimize();
                    end
                end
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Value changed function: FileDropDown
        function chngFile(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.fIndex = app.FileDropDown.Value;
            app.traceHandler.setFile(app.fIndex);
            if (app.mtnCorrected)
                app.mtnTrace = fliplr(app.traceHandler.getMtnTrace());
            end
            app.frame = 1;
            if (app.hasImage)
                if (app.usingProj)
                    app.usingProj = false;
                    app.showProj([]);
                else
                    app.stopVid();
                    app.loadFrame();
                    app.drawImage();
                    if (~isempty(app.BCDlgBox))
                        app.BCDlgBox.reinit(app.currentFrame,app.vidContrWndw(app.fIndex,app.channel,:));
                    end
                end
            end

            if (~isempty(app.TracePlotBox))
                app.TracePlotBox.changeFile(app.basenames{app.fIndex});
                app.TracePlotBox.update();
            end

            app.setTrace(app.traceType,app.roiIndex);
            if (app.hasImage && ~app.usingProj)
                app.setBar();
            end
            app.UIFigure.Pointer = 'arrow';
        end

        % Menu selected function: MotionTraceMenu
        function showMtnTrace(app, event)
            app.maskHandler.selectRoiAes([]);
            app.maskHandler.selectRoiSmpl([]);
            app.AESListbox.Value = cell(0);
            app.SmplListbox.Value = cell(0);
            app.aesSlct = false;
            app.bgSlct = false;
            app.updateMasks();
            if (app.mtnCorrected && ~app.usingProj)
                app.adjMtnMask();
            end
            app.setTrace(TraceType.Motion,0);
            app.drawImage();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case 'space'
                    if (app.PlayButton.Enable)
                        app.playPause([]);
                    end
                case 'leftarrow'
                    if (app.FrameBackwardButton.Enable)
                        app.prvFrame([]);
                    end
                case 'rightarrow'
                    if (app.FrameForwardButton.Enable)
                        app.nxtFrame([]);
                    end
            end
        end

        % Menu selected function: BackgroundFluorescenceMenu
        function showFlTrace(app, event)
            app.maskHandler.selectRoiAes([]);
            app.maskHandler.selectRoiSmpl([]);
            app.AESListbox.Value = cell(0);
            app.SmplListbox.Value = cell(0);
            app.aesSlct = false;
            app.bgSlct = false;
            app.updateMasks();
            if (app.mtnCorrected && ~app.usingProj)
                app.adjMtnMask();
            end
            app.setTrace(TraceType.Fl,0);
            app.drawImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Trace Viewer';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '+helper_apps', '+images', 'trace_viewer_app_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDatasetMenu = uimenu(app.FileMenu);
            app.LoadDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @loadData, true);
            app.LoadDatasetMenu.Text = 'Load Dataset';
            app.VisualizationMenu = uimenu(app.UIFigure);
            app.VisualizationMenu.Text = 'Visualization';
            app.WindowLevelMenu = uimenu(app.VisualizationMenu);
            app.WindowLevelMenu.MenuSelectedFcn = createCallbackFcn(app, @opnBc, true);
            app.WindowLevelMenu.Enable = 'off';
            app.WindowLevelMenu.Text = 'Window/Level';
            app.PlaybackOptionsMenu = uimenu(app.VisualizationMenu);
            app.PlaybackOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @opnPlayback, true);
            app.PlaybackOptionsMenu.Enable = 'off';
            app.PlaybackOptionsMenu.Text = 'Playback Options';
            app.TraceOptionsMenu = uimenu(app.VisualizationMenu);
            app.TraceOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @opnTraceOps, true);
            app.TraceOptionsMenu.Enable = 'off';
            app.TraceOptionsMenu.Text = 'Trace Options';
            app.MotionTraceMenu = uimenu(app.VisualizationMenu);
            app.MotionTraceMenu.MenuSelectedFcn = createCallbackFcn(app, @showMtnTrace, true);
            app.MotionTraceMenu.Enable = 'off';
            app.MotionTraceMenu.Text = 'Motion Trace';
            app.BackgroundFluorescenceMenu = uimenu(app.VisualizationMenu);
            app.BackgroundFluorescenceMenu.MenuSelectedFcn = createCallbackFcn(app, @showFlTrace, true);
            app.BackgroundFluorescenceMenu.Enable = 'off';
            app.BackgroundFluorescenceMenu.Text = 'Background Fluorescence';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageAxes = uiaxes(app.GridLayout);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            zlabel(app.ImageAxes, 'Z')
            app.AESROIPanel = uipanel(app.GridLayout);
            app.AESROIPanel.Title = 'AES ROI';
            app.GridLayout2 = uigridlayout(app.AESROIPanel);
            app.AESListbox = uilistbox(app.GridLayout2);
            app.AESListbox.Items = {};
            app.AESListbox.ValueChangedFcn = createCallbackFcn(app, @slctRoiAes, true);
            app.AESListbox.Value = {};
            app.ShowAESCheckBox = uicheckbox(app.GridLayout2);
            app.ShowAESCheckBox.ValueChangedFcn = createCallbackFcn(app, @enableAesRoi, true);
            app.ShowAESCheckBox.Enable = 'off';
            app.ShowAESCheckBox.Text = 'Show';
            app.ShowAESCheckBox.Value = true;
            app.SampleROIPanel = uipanel(app.GridLayout);
            app.SampleROIPanel.Title = 'Sample ROI';
            app.GridLayout3 = uigridlayout(app.SampleROIPanel);
            app.SmplListbox = uilistbox(app.GridLayout3);
            app.SmplListbox.Items = {};
            app.SmplListbox.ValueChangedFcn = createCallbackFcn(app, @slctRoiSmpl, true);
            app.SmplListbox.Value = {};
            app.ShowSmplCheckBox = uicheckbox(app.GridLayout3);
            app.ShowSmplCheckBox.ValueChangedFcn = createCallbackFcn(app, @enableSmplRoi, true);
            app.ShowSmplCheckBox.Enable = 'off';
            app.ShowSmplCheckBox.Text = 'Show';
            app.ShowSmplCheckBox.Value = true;
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.TraceAxes = uiaxes(app.GridLayout4);
            title(app.TraceAxes, 'Title')
            xlabel(app.TraceAxes, 'X')
            ylabel(app.TraceAxes, 'Y')
            zlabel(app.TraceAxes, 'Z')
            app.ScrubberAxes = uiaxes(app.GridLayout4);
            app.ScrubberAxes.XTick = [];
            app.ScrubberAxes.YTick = [];
            app.ScrubberAxes.ButtonDownFcn = createCallbackFcn(app, @scrubberClbk, true);
            app.FrameBackwardButton = uibutton(app.GridLayout4, 'push');
            app.FrameBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @prvFrame, true);
            app.FrameBackwardButton.Icon = fullfile(pathToMLAPP, '+helper_apps', '+images', 'framebackward.png');
            app.FrameBackwardButton.Enable = 'off';
            app.FrameBackwardButton.Text = '';
            app.FrameForwardButton = uibutton(app.GridLayout4, 'push');
            app.FrameForwardButton.ButtonPushedFcn = createCallbackFcn(app, @nxtFrame, true);
            app.FrameForwardButton.Icon = fullfile(pathToMLAPP, '+helper_apps', '+images', 'frameforward.png');
            app.FrameForwardButton.Enable = 'off';
            app.FrameForwardButton.Text = '';
            app.ChannelDropDown = uidropdown(app.GridLayout4);
            app.ChannelDropDown.Items = {'Channel 1'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @chngChannel, true);
            app.ChannelDropDown.Enable = 'off';
            app.ChannelDropDown.Value = 'Channel 1';
            app.frameLabel = uilabel(app.GridLayout4);
            app.frameLabel.Text = '0/0';
            app.StopButton = uibutton(app.GridLayout4, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @showProj, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, '+helper_apps', '+images', 'stop.png');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '';
            app.EjectTraceButton = uibutton(app.GridLayout4, 'push');
            app.EjectTraceButton.ButtonPushedFcn = createCallbackFcn(app, @ejectTrace, true);
            app.EjectTraceButton.Enable = 'off';
            app.EjectTraceButton.Text = 'Pop-Out Trace';
            app.PlayButton = uibutton(app.GridLayout4, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @playPause, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Icon = fullfile(pathToMLAPP, '+helper_apps', '+images', 'play.png');
            app.PlayButton.Text = '';
            app.FileDropDown = uidropdown(app.GridLayout4);
            app.FileDropDown.Items = {'No File'};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @chngFile, true);
            app.FileDropDown.Enable = 'off';
            app.FileDropDown.Value = 'No File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_viewer_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end